classdef AddAnimal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SpeciesTabGroup            matlab.ui.container.TabGroup
        LabmouseTab                matlab.ui.container.Tab
        tagged_switch              matlab.ui.control.Switch
        SexButtonGroup             matlab.ui.container.ButtonGroup
        UnknownButton_2            matlab.ui.control.RadioButton
        MaleButton                 matlab.ui.control.RadioButton
        FemaleButton               matlab.ui.control.RadioButton
        EarpunchButtonGroup        matlab.ui.container.ButtonGroup
        NoneButton                 matlab.ui.control.RadioButton
        LButton                    matlab.ui.control.RadioButton
        RButton                    matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        DOB_known_switch           matlab.ui.control.Switch
        GenotypeDropDownLabel      matlab.ui.control.Label
        GenotypeDropDown           matlab.ui.control.DropDown
        TagIDEditFieldLabel        matlab.ui.control.Label
        TagIDEditField             matlab.ui.control.NumericEditField
        CageEditFieldLabel         matlab.ui.control.Label
        CageEditField              matlab.ui.control.NumericEditField
        DOBDatePickerLabel         matlab.ui.control.Label
        DOBDatePicker              matlab.ui.control.DatePicker
        SparrowTab                 matlab.ui.container.Tab
        PerhapssomedayLabel        matlab.ui.control.Label
        AddanimaltodatabaseButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        login_user % who logged in
        logger_app % AnimalLogger app object
    end
    
    methods (Access = private)
        
        function load_genotypes(app)
            genotypes = fetchn(sl.Genotype,'genotype_name');
            %put WT first
            ind = find(strcmp(genotypes,'WT'));
            if ~isempty(ind)
                g{1} = 'WT';
                g = [g; genotypes(setdiff(1:length(genotypes), ind))];
            else
                g = genotypes;
            end
            set(app.GenotypeDropDown,'Items',g);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, logger_app, login_user)
            app.login_user = login_user;
            app.logger_app = logger_app;
            
            %datajoint setup
            setenv('DJ_USER', 'sl_user')
            setenv('DJ_HOST', 'vfsmdatajoint01.fsm.northwestern.edu')
            %setenv('DJ_HOST', 'localhost')
            setenv('DJ_PASS', 'r0zsNB0xk6wc5BgA!')
            dj.conn();
            
            %load genotypes
            app.load_genotypes()
            
            %set initial state
            set(app.TagIDEditField,'Enable',false);
            set(app.DOBDatePicker,'Enable',false);
        end

        % Value changed function: tagged_switch
        function tagged_switchValueChanged(app, event)
            value = app.tagged_switch.Value;
            if strcmp(value,'Untagged')
                set(app.TagIDEditField,'Enable',false)
            else
                set(app.TagIDEditField,'Enable',true);
            end
        end

        % Value changed function: DOB_known_switch
        function DOB_known_switchValueChanged(app, event)
            value = app.DOB_known_switch.Value;
            if strcmp(value,'DOB unknown')
                set(app.DOBDatePicker,'Enable',false);                
            else
                set(app.DOBDatePicker,'Enable',true);
            end
        end

        % Button pushed function: AddanimaltodatabaseButton
        function AddanimaltodatabaseButtonPushed(app, event)
            %make insert key
            key = struct;  
            %check isTagged
            if strcmp(get(app.tagged_switch,'value'),'Untagged')
                key.is_tagged = 'F';
            else
                key.is_tagged = 'T';
                key.tag_id = get(app.TagIDEditField,'value');                
            end                        
                      
            %get genotype
            key.genotype_name = get(app.GenotypeDropDown, 'value');
            
            %TODO 
            key.species = get(get(app.SpeciesTabGroup, 'SelectedTab'), 'Title');
            
            %check DOB
            if strcmp(get(app.DOB_known_switch,'value'),'DOB known')
                key.dob = string(get(app.DOBDatePicker,'value'));
            end
            
            %sex
            key.sex = get(get(app.SexButtonGroup,'SelectedObject'), 'text');
            %ear punch
            key.punch = get(get(app.EarpunchButtonGroup,'SelectedObject'), 'text');
            
            %cage
            key_cage.cage_number = get(app.CageEditField,'value');
            key_cage.user_name = app.login_user;
            key_cage.date = string(datetime(date,'format','yyyy-MM-dd')); %current date
            
            %add the animal
            disp('Adding animal with key:');
            key
            key_cage
            add_animal(key, key_cage);        
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.logger_app)
                try
                    app.logger_app.loadAnimalTable([]);
                catch ME
                   disp('table not reloaded');
                end
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SpeciesTabGroup = uitabgroup(app.UIFigure);
            app.LabmouseTab = uitab(app.SpeciesTabGroup);
            app.LabmouseTab.Title = 'Lab mouse';
            app.tagged_switch = uiswitch(app.LabmouseTab, 'slider');
            app.tagged_switch.Items = {'Untagged', 'Tag'};
            app.tagged_switch.ValueChangedFcn = createCallbackFcn(app, @tagged_switchValueChanged, true);
            app.tagged_switch.Value = 'Untagged';
            app.SexButtonGroup = uibuttongroup(app.LabmouseTab);
            app.SexButtonGroup.Title = 'Sex';
            app.UnknownButton_2 = uiradiobutton(app.SexButtonGroup);
            app.UnknownButton_2.Text = 'Unknown';
            app.UnknownButton_2.Value = true;
            app.MaleButton = uiradiobutton(app.SexButtonGroup);
            app.MaleButton.Text = 'Male';
            app.FemaleButton = uiradiobutton(app.SexButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.EarpunchButtonGroup = uibuttongroup(app.LabmouseTab);
            app.EarpunchButtonGroup.Title = 'Ear punch';
            app.NoneButton = uiradiobutton(app.EarpunchButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.LButton = uiradiobutton(app.EarpunchButtonGroup);
            app.LButton.Text = 'L';
            app.RButton = uiradiobutton(app.EarpunchButtonGroup);
            app.RButton.Text = 'R';
            app.BothButton = uiradiobutton(app.EarpunchButtonGroup);
            app.BothButton.Text = 'Both';
            app.DOB_known_switch = uiswitch(app.LabmouseTab, 'slider');
            app.DOB_known_switch.Items = {'DOB unknown', 'DOB known'};
            app.DOB_known_switch.ValueChangedFcn = createCallbackFcn(app, @DOB_known_switchValueChanged, true);
            app.DOB_known_switch.Value = 'DOB unknown';
            app.GenotypeDropDownLabel = uilabel(app.LabmouseTab);
            app.GenotypeDropDownLabel.Text = 'Genotype';
            app.GenotypeDropDown = uidropdown(app.LabmouseTab);
            app.TagIDEditFieldLabel = uilabel(app.LabmouseTab);
            app.TagIDEditFieldLabel.Text = 'Tag ID #';
            app.TagIDEditField = uieditfield(app.LabmouseTab, 'numeric');
            app.TagIDEditField.Limits = [0 Inf];
            app.TagIDEditField.RoundFractionalValues = 'on';
            app.TagIDEditField.ValueDisplayFormat = '%.0f';
            app.CageEditFieldLabel = uilabel(app.LabmouseTab);
            app.CageEditFieldLabel.Text = 'Cage #';
            app.CageEditField = uieditfield(app.LabmouseTab, 'numeric');
            app.CageEditField.Limits = [0 Inf];
            app.CageEditField.RoundFractionalValues = 'on';
            app.CageEditField.ValueDisplayFormat = '%.0f';
            app.DOBDatePickerLabel = uilabel(app.LabmouseTab);
            app.DOBDatePickerLabel.Text = 'DOB';
            app.DOBDatePicker = uidatepicker(app.LabmouseTab);
            app.DOBDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SparrowTab = uitab(app.SpeciesTabGroup);
            app.SparrowTab.Title = 'Sparrow';
            app.PerhapssomedayLabel = uilabel(app.SparrowTab);
            app.PerhapssomedayLabel.Text = 'Perhaps someday';
            app.AddanimaltodatabaseButton = uibutton(app.UIFigure, 'push');
            app.AddanimaltodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddanimaltodatabaseButtonPushed, true);
            app.AddanimaltodatabaseButton.Text = 'Add animal to database';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddAnimal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end