classdef AddGenotype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GenotypenameEditFieldLabel   matlab.ui.control.Label
        GenotypenameEditField        matlab.ui.control.EditField
        DescriptionTextAreaLabel     matlab.ui.control.Label
        DescriptionTextArea          matlab.ui.control.TextArea
        SourceEditFieldLabel         matlab.ui.control.Label
        SourceEditField              matlab.ui.control.EditField
        CatalogEditFieldLabel        matlab.ui.control.Label
        CatalogEditField             matlab.ui.control.EditField
        AddgenotypetodatabaseButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddgenotypetodatabaseButton
        function AddgenotypetodatabaseButtonPushed(app, event)
            key.genotype_name = get(app.GenotypenameEditField, 'value');
            key.source = get(app.SourceEditField, 'value');
            desc = get(app.DescriptionTextArea, 'value');
            desc_flat = strcat(cell2mat(desc'));
            key.description = desc_flat;
            key.catalog_number = get(app.CatalogEditField, 'value');  
            try                
                insert(sl_test.Genotype, key);
                msgbox(['Genotype ' key.genotype_name ' insert successful']);
            catch ME
                errordlg(['Genotype ' key.genotype_name ' insert failed']);
                rethrow(ME);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GenotypenameEditFieldLabel = uilabel(app.UIFigure);
            app.GenotypenameEditFieldLabel.Text = 'Genotype name';
            app.GenotypenameEditField = uieditfield(app.UIFigure, 'text');
            app.DescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.UIFigure);
            app.SourceEditFieldLabel = uilabel(app.UIFigure);
            app.SourceEditFieldLabel.Text = 'Source';
            app.SourceEditField = uieditfield(app.UIFigure, 'text');
            app.CatalogEditFieldLabel = uilabel(app.UIFigure);
            app.CatalogEditFieldLabel.Text = 'Catalog #';
            app.CatalogEditField = uieditfield(app.UIFigure, 'text');
            app.AddgenotypetodatabaseButton = uibutton(app.UIFigure, 'push');
            app.AddgenotypetodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddgenotypetodatabaseButtonPushed, true);
            app.AddgenotypetodatabaseButton.Text = 'Add genotype to database';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddGenotype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end