classdef AnalysisPipelineBuilder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisPipelineBuilderUIFigure  matlab.ui.Figure
        WorkingDataGroupPanel           matlab.ui.container.Panel
        CellsListBoxLabel               matlab.ui.control.Label
        CellsListBox_W                  matlab.ui.control.ListBox
        SymphonydatasetsListBoxLabel    matlab.ui.control.Label
        SymphonydatasetsListBox_W       matlab.ui.control.ListBox
        OtherdataListBoxLabel           matlab.ui.control.Label
        OtherdataListBox_W              matlab.ui.control.ListBox
        AnimalsListBoxLabel             matlab.ui.control.Label
        AnimalsListBox_W                matlab.ui.control.ListBox
        TotalsPanel_2                   matlab.ui.container.Panel
        animalsEditField_2Label         matlab.ui.control.Label
        animalsEditField_W              matlab.ui.control.EditField
        cellsEditField_3Label           matlab.ui.control.Label
        cellsEditField_W                matlab.ui.control.EditField
        epochsEditField_4Label          matlab.ui.control.Label
        epochsEditField_W               matlab.ui.control.EditField
        eyesEditField_2Label            matlab.ui.control.Label
        eyesEditField_W                 matlab.ui.control.EditField
        datasetsEditField_4Label        matlab.ui.control.Label
        datasetsEditField_W             matlab.ui.control.EditField
        otherfilesEditField_4Label      matlab.ui.control.Label
        otherfilesEditField_W           matlab.ui.control.EditField
        SavetofileButton                matlab.ui.control.Button
        ClearWorkingGroupButton         matlab.ui.control.Button
        AttachDataGrouptopipelineButton  matlab.ui.control.Button
        DitchanimalButton               matlab.ui.control.Button
        DitchcellButton                 matlab.ui.control.Button
        DitchdatasetButton              matlab.ui.control.Button
        DitchotherdataButton            matlab.ui.control.Button
        BuildanalysisPanel              matlab.ui.container.Panel
        FuncTypeDropDown_1              matlab.ui.control.DropDown
        FuncNameDropDown_1              matlab.ui.control.DropDown
        FunctiontypeLabel               matlab.ui.control.Label
        FunctionnameLabel               matlab.ui.control.Label
        StepsSpinnerLabel               matlab.ui.control.Label
        StepsSpinner                    matlab.ui.control.Spinner
        TestButton_1                    matlab.ui.control.Button
        WritetodatabaseButton_1         matlab.ui.control.Button
        Exporttoh5Button_1              matlab.ui.control.Button
        OverwriteCheckBox_1             matlab.ui.control.CheckBox
        SaveButton_1                    matlab.ui.control.Button
        EditButton_1                    matlab.ui.control.Button
        ParametersetLabel               matlab.ui.control.Label
        ParamSetDropDown_1              matlab.ui.control.DropDown
        RunstepLabel                    matlab.ui.control.Label
        overwriteLabel                  matlab.ui.control.Label
        LabelStep_3                     matlab.ui.control.Label
        FuncTypeDropDown_3              matlab.ui.control.DropDown
        FuncNameDropDown_3              matlab.ui.control.DropDown
        TestButton_3                    matlab.ui.control.Button
        WritetodatabaseButton_3         matlab.ui.control.Button
        Exporttoh5Button_3              matlab.ui.control.Button
        OverwriteCheckBox_3             matlab.ui.control.CheckBox
        SaveButton_3                    matlab.ui.control.Button
        EditButton_3                    matlab.ui.control.Button
        ParamSetDropDown_3              matlab.ui.control.DropDown
        FuncNameDropDown_4              matlab.ui.control.DropDown
        TestButton_4                    matlab.ui.control.Button
        WritetodatabaseButton_4         matlab.ui.control.Button
        Exporttoh5Button_4              matlab.ui.control.Button
        OverwriteCheckBox_4             matlab.ui.control.CheckBox
        SaveButton_4                    matlab.ui.control.Button
        EditButton_4                    matlab.ui.control.Button
        ParamSetDropDown_4              matlab.ui.control.DropDown
        FuncNameDropDown_5              matlab.ui.control.DropDown
        TestButton_5                    matlab.ui.control.Button
        WritetodatabaseButton_5         matlab.ui.control.Button
        Exporttoh5Button_5              matlab.ui.control.Button
        OverwriteCheckBox_5             matlab.ui.control.CheckBox
        SaveButton_5                    matlab.ui.control.Button
        EditButton_5                    matlab.ui.control.Button
        ParamSetDropDown_5              matlab.ui.control.DropDown
        FuncNameDropDown_6              matlab.ui.control.DropDown
        TestButton_6                    matlab.ui.control.Button
        WritetodatabaseButton_6         matlab.ui.control.Button
        Exporttoh5Button_6              matlab.ui.control.Button
        OverwriteCheckBox_6             matlab.ui.control.CheckBox
        SaveButton_6                    matlab.ui.control.Button
        EditButton_6                    matlab.ui.control.Button
        ParamSetDropDown_6              matlab.ui.control.DropDown
        FuncNameDropDown_7              matlab.ui.control.DropDown
        TestButton_7                    matlab.ui.control.Button
        WritetodatabaseButton_7         matlab.ui.control.Button
        Exporttoh5Button_7              matlab.ui.control.Button
        OverwriteCheckBox_7             matlab.ui.control.CheckBox
        SaveButton_7                    matlab.ui.control.Button
        EditButton_7                    matlab.ui.control.Button
        ParamSetDropDown_7              matlab.ui.control.DropDown
        FuncTypeDropDown_7              matlab.ui.control.DropDown
        LabelStep_7                     matlab.ui.control.Label
        FuncNameDropDown_8              matlab.ui.control.DropDown
        TestButton_8                    matlab.ui.control.Button
        WritetodatabaseButton_8         matlab.ui.control.Button
        Exporttoh5Button_8              matlab.ui.control.Button
        OverwriteCheckBox_8             matlab.ui.control.CheckBox
        SaveButton_8                    matlab.ui.control.Button
        EditButton_8                    matlab.ui.control.Button
        ParamSetDropDown_8              matlab.ui.control.DropDown
        Label_9                         matlab.ui.control.Label
        FuncTypeDropDown_8              matlab.ui.control.DropDown
        LabelStep_8                     matlab.ui.control.Label
        FuncNameDropDown_9              matlab.ui.control.DropDown
        TestButton_9                    matlab.ui.control.Button
        WritetodatabaseButton_9         matlab.ui.control.Button
        Exporttoh5Button_9              matlab.ui.control.Button
        OverwriteCheckBox_9             matlab.ui.control.CheckBox
        SaveButton_9                    matlab.ui.control.Button
        EditButton_9                    matlab.ui.control.Button
        ParamSetDropDown_9              matlab.ui.control.DropDown
        FuncTypeDropDown_9              matlab.ui.control.DropDown
        LabelStep_9                     matlab.ui.control.Label
        FuncNameDropDown_10             matlab.ui.control.DropDown
        TestButton_10                   matlab.ui.control.Button
        WritetodatabaseButton_10        matlab.ui.control.Button
        Exporttoh5Button_10             matlab.ui.control.Button
        OverwriteCheckBox_10            matlab.ui.control.CheckBox
        SaveButton_10                   matlab.ui.control.Button
        EditButton_10                   matlab.ui.control.Button
        ParamSetDropDown_10             matlab.ui.control.DropDown
        Label_13                        matlab.ui.control.Label
        FuncTypeDropDown_10             matlab.ui.control.DropDown
        LabelStep_10                    matlab.ui.control.Label
        FuncNameDropDown_11             matlab.ui.control.DropDown
        TestButton_11                   matlab.ui.control.Button
        WritetodatabaseButton_11        matlab.ui.control.Button
        Exporttoh5Button_11             matlab.ui.control.Button
        OverwriteCheckBox_11            matlab.ui.control.CheckBox
        SaveButton_11                   matlab.ui.control.Button
        EditButton_11                   matlab.ui.control.Button
        ParamSetDropDown_11             matlab.ui.control.DropDown
        Label_15                        matlab.ui.control.Label
        LabelStep_11                    matlab.ui.control.Label
        FuncTypeDropDown_11             matlab.ui.control.DropDown
        FuncNameDropDown_12             matlab.ui.control.DropDown
        TestButton_12                   matlab.ui.control.Button
        WritetodatabaseButton_12        matlab.ui.control.Button
        Exporttoh5Button_12             matlab.ui.control.Button
        OverwriteCheckBox_12            matlab.ui.control.CheckBox
        SaveButton_12                   matlab.ui.control.Button
        EditButton_12                   matlab.ui.control.Button
        ParamSetDropDown_12             matlab.ui.control.DropDown
        Label_17                        matlab.ui.control.Label
        FuncTypeDropDown_12             matlab.ui.control.DropDown
        LabelStep_12                    matlab.ui.control.Label
        FuncNameDropDown_13             matlab.ui.control.DropDown
        TestButton_13                   matlab.ui.control.Button
        WritetodatabaseButton_13        matlab.ui.control.Button
        Exporttoh5Button_13             matlab.ui.control.Button
        OverwriteCheckBox_13            matlab.ui.control.CheckBox
        SaveButton_13                   matlab.ui.control.Button
        EditButton_13                   matlab.ui.control.Button
        ParamSetDropDown_13             matlab.ui.control.DropDown
        Label_19                        matlab.ui.control.Label
        FuncTypeDropDown_13             matlab.ui.control.DropDown
        LabelStep_13                    matlab.ui.control.Label
        FuncNameDropDown_14             matlab.ui.control.DropDown
        TestButton_14                   matlab.ui.control.Button
        WritetodatabaseButton_14        matlab.ui.control.Button
        Exporttoh5Button_14             matlab.ui.control.Button
        OverwriteCheckBox_14            matlab.ui.control.CheckBox
        SaveButton_14                   matlab.ui.control.Button
        EditButton_14                   matlab.ui.control.Button
        ParamSetDropDown_14             matlab.ui.control.DropDown
        Label_21                        matlab.ui.control.Label
        FuncTypeDropDown_14             matlab.ui.control.DropDown
        LabelStep_14                    matlab.ui.control.Label
        FuncNameDropDown_15             matlab.ui.control.DropDown
        TestButton_15                   matlab.ui.control.Button
        WritetodatabaseButton_15        matlab.ui.control.Button
        Exporttoh5Button_15             matlab.ui.control.Button
        OverwriteCheckBox_15            matlab.ui.control.CheckBox
        SaveButton_15                   matlab.ui.control.Button
        EditButton_15                   matlab.ui.control.Button
        ParamSetDropDown_15             matlab.ui.control.DropDown
        Label_23                        matlab.ui.control.Label
        FuncTypeDropDown_15             matlab.ui.control.DropDown
        LabelStep_15                    matlab.ui.control.Label
        FuncNameDropDown_16             matlab.ui.control.DropDown
        TestButton_16                   matlab.ui.control.Button
        WritetodatabaseButton_16        matlab.ui.control.Button
        Exporttoh5Button_16             matlab.ui.control.Button
        OverwriteCheckBox_16            matlab.ui.control.CheckBox
        SaveButton_16                   matlab.ui.control.Button
        EditButton_16                   matlab.ui.control.Button
        ParamSetDropDown_16             matlab.ui.control.DropDown
        Label_25                        matlab.ui.control.Label
        FuncTypeDropDown_16             matlab.ui.control.DropDown
        LabelStep_16                    matlab.ui.control.Label
        FuncNameDropDown_17             matlab.ui.control.DropDown
        TestButton_17                   matlab.ui.control.Button
        WritetodatabaseButton_17        matlab.ui.control.Button
        Exporttoh5Button_17             matlab.ui.control.Button
        OverwriteCheckBox_17            matlab.ui.control.CheckBox
        SaveButton_17                   matlab.ui.control.Button
        EditButton_17                   matlab.ui.control.Button
        ParamSetDropDown_17             matlab.ui.control.DropDown
        Label_27                        matlab.ui.control.Label
        FuncTypeDropDown_17             matlab.ui.control.DropDown
        LabelStep_17                    matlab.ui.control.Label
        FuncNameDropDown_18             matlab.ui.control.DropDown
        TestButton_18                   matlab.ui.control.Button
        WritetodatabaseButton_18        matlab.ui.control.Button
        Exporttoh5Button_18             matlab.ui.control.Button
        OverwriteCheckBox_18            matlab.ui.control.CheckBox
        SaveButton_18                   matlab.ui.control.Button
        EditButton_18                   matlab.ui.control.Button
        ParamSetDropDown_18             matlab.ui.control.DropDown
        Label_29                        matlab.ui.control.Label
        FuncTypeDropDown_18             matlab.ui.control.DropDown
        LabelStep_18                    matlab.ui.control.Label
        FuncNameDropDown_19             matlab.ui.control.DropDown
        TestButton_19                   matlab.ui.control.Button
        WritetodatabaseButton_19        matlab.ui.control.Button
        Exporttoh5Button_19             matlab.ui.control.Button
        OverwriteCheckBox_19            matlab.ui.control.CheckBox
        SaveButton_19                   matlab.ui.control.Button
        EditButton_19                   matlab.ui.control.Button
        ParamSetDropDown_19             matlab.ui.control.DropDown
        LabelStep_19                    matlab.ui.control.Label
        FuncTypeDropDown_19             matlab.ui.control.DropDown
        FuncNameDropDown_20             matlab.ui.control.DropDown
        TestButton_20                   matlab.ui.control.Button
        WritetodatabaseButton_20        matlab.ui.control.Button
        Exporttoh5Button_20             matlab.ui.control.Button
        OverwriteCheckBox_20            matlab.ui.control.CheckBox
        SaveButton_20                   matlab.ui.control.Button
        EditButton_20                   matlab.ui.control.Button
        ParamSetDropDown_20             matlab.ui.control.DropDown
        FuncTypeDropDown_20             matlab.ui.control.DropDown
        LabelStep_20                    matlab.ui.control.Label
        FuncTypeDropDown_4              matlab.ui.control.DropDown
        FuncTypeDropDown_5              matlab.ui.control.DropDown
        FuncTypeDropDown_6              matlab.ui.control.DropDown
        LabelStep_4                     matlab.ui.control.Label
        LabelStep_5                     matlab.ui.control.Label
        LabelStep_6                     matlab.ui.control.Label
        LabelStep_1                     matlab.ui.control.Label
        AttachanalysisstepstopipelineButton  matlab.ui.control.Button
        LabelStep_2                     matlab.ui.control.Label
        FuncTypeDropDown_2              matlab.ui.control.DropDown
        FuncNameDropDown_2              matlab.ui.control.DropDown
        ParamSetDropDown_2              matlab.ui.control.DropDown
        TestButton_2                    matlab.ui.control.Button
        WritetodatabaseButton_2         matlab.ui.control.Button
        SaveButton_2                    matlab.ui.control.Button
        EditButton_2                    matlab.ui.control.Button
        OverwriteCheckBox_2             matlab.ui.control.CheckBox
        Exporttoh5Button_2              matlab.ui.control.Button
        PipelinePanel                   matlab.ui.container.Panel
        SwitchpipelineButton            matlab.ui.control.Button
        RunButton_Full                  matlab.ui.control.Button
        h5exporttoolButton              matlab.ui.control.Button
        overwriteCheckBox_Full          matlab.ui.control.CheckBox
        SaveasnewpipelineButton         matlab.ui.control.Button
        LoaddataPanel                   matlab.ui.container.Panel
        CellsListBox_2Label             matlab.ui.control.Label
        CellsListBox_L                  matlab.ui.control.ListBox
        SymphonydatasetsListBox_2Label  matlab.ui.control.Label
        SymphonydatasetsListBox_L       matlab.ui.control.ListBox
        OtherdataListBox_2Label         matlab.ui.control.Label
        OtherdataListBox_L              matlab.ui.control.ListBox
        AnimalsListBox_2Label           matlab.ui.control.Label
        AnimalsListBox_L                matlab.ui.control.ListBox
        TotalsPanel_3                   matlab.ui.container.Panel
        animalsEditField_2Label_2       matlab.ui.control.Label
        animalsEditField_L              matlab.ui.control.EditField
        cellsEditField_3Label_2         matlab.ui.control.Label
        cellsEditField_L                matlab.ui.control.EditField
        epochsEditField_4Label_2        matlab.ui.control.Label
        epochsEditField_L               matlab.ui.control.EditField
        eyesEditField_2Label_2          matlab.ui.control.Label
        eyesEditField_L                 matlab.ui.control.EditField
        datasetsEditField_4Label_2      matlab.ui.control.Label
        datasetsEditField_L             matlab.ui.control.EditField
        otherfilesEditField_4Label_2    matlab.ui.control.Label
        otherfilesEditField_L           matlab.ui.control.EditField
        AlltoWGButton                   matlab.ui.control.Button
        ClearLoadedButton               matlab.ui.control.Button
        LoadqueryButton                 matlab.ui.control.Button
        QueryeditorButton               matlab.ui.control.Button
        LoadDataGroupButton             matlab.ui.control.Button
        LoadcellnamesButton             matlab.ui.control.Button
        LoadsinglecellButton            matlab.ui.control.Button
        cell_idEditField                matlab.ui.control.EditField
        AttachotherdataButton_2         matlab.ui.control.Button
        FilterbyepochfunctionButton     matlab.ui.control.Button
        AnimaltoWGButton                matlab.ui.control.Button
        CelltoWGButton                  matlab.ui.control.Button
        DatasettoWGButton               matlab.ui.control.Button
        OtherfilestoWGButton            matlab.ui.control.Button
        MergecompatibleLampLabel        matlab.ui.control.Label
        MergecompatibleLamp             matlab.ui.control.Lamp
        AttachquerytopipelineButton     matlab.ui.control.Button
        QueryTextAreaLabel              matlab.ui.control.Label
        QueryTextArea                   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        loadedQuery = []; % current query result for the "loaded" panel
        loadedQueryToDisplay = []; % loaded query further sub-divided by what is clicked in the list box
        workingQuery = []; % same as above but for working panel
        workingQueryToDisplay = []; % same as above but for working panel
        searchTable = []; % joined table on which to run queries
        analysisParams = {}; % param struct for each analysis step (or empty)
        loadedQueryState = []; % QueryState (from query builder) that is currently loaded
        pipeline =[]; % Pipeline that we are currently working on
        exportPreferences = {} % exportState for each analysis step (or empty)
    end
    
    properties (Access = public)
        analysisOutput = []; % struct from last run analysis, including entities and results
    end
    
    methods (Access = private)
        
        function updateLoadedListBoxes(app,caller)
            if isempty(app.loadedQueryToDisplay) || app.loadedQueryToDisplay.count==0
                set(app.AnimalsListBox_L,'items',{});
                set(app.CellsListBox_L,'items',{});
                set(app.SymphonydatasetsListBox_L,'items',{});
                set(app.OtherdataListBox_L,'items',{});
                
                set(app.animalsEditField_L,'value',num2str(0));
                set(app.cellsEditField_L,'value',num2str(0));
                set(app.datasetsEditField_L,'value',num2str(0));
                set(app.eyesEditField_L, 'value', num2str(0));
                set(app.epochsEditField_L, 'value', num2str(0));
            else      
                if isempty(caller)
                    animals = sl.Animal & app.loadedQueryToDisplay.proj;
                    set(app.AnimalsListBox_L,'items',string(num2str(fetchn(animals,'animal_id'))))
                end
                
                if isempty(caller) || strcmp(caller, 'animals')
                    cells = sl.SymphonyRecordedCell & app.loadedQueryToDisplay.proj;
                    set(app.CellsListBox_L,'items',fetchn(cells,'cell_id'));
                end
                
                datasets = sl.Dataset & app.loadedQueryToDisplay.proj;
                set(app.SymphonydatasetsListBox_L,'items',fetchn(datasets,'dataset_name'));  
                
                eyes = sl.Eye & app.loadedQueryToDisplay.proj; %TODO not quite right, includes non-recorded eyes

                %epochs = sl.Epoch & app.loadedQueryToDisplay.proj;
                 
                %set counts only for empty caller
                if isempty(caller)
                    set(app.animalsEditField_L,'value',num2str(animals.count));
                    set(app.cellsEditField_L,'value',num2str(cells.count));
                    set(app.datasetsEditField_L,'value',num2str(datasets.count));
                    set(app.eyesEditField_L, 'value', num2str(eyes.count));
                    ep_count = app.getEpochCountInQuery(app.loadedQuery);
                    set(app.epochsEditField_L, 'value', num2str(ep_count));
                end
            end
        end
        
        function updateWorkingListBoxes(app,caller)
            if isempty(app.workingQueryToDisplay) || app.workingQueryToDisplay.count==0
                set(app.AnimalsListBox_W,'items',{});
                set(app.CellsListBox_W,'items',{});
                set(app.SymphonydatasetsListBox_W,'items',{});
                set(app.OtherdataListBox_W,'items',{});
                
                set(app.animalsEditField_W,'value',num2str(0));
                set(app.cellsEditField_W,'value',num2str(0));
                set(app.datasetsEditField_W,'value',num2str(0));
                set(app.eyesEditField_W, 'value', num2str(0));
                set(app.epochsEditField_W, 'value', num2str(0));
            else     
                if isempty(caller)
                    animals = sl.Animal & app.workingQueryToDisplay.proj;
                    set(app.AnimalsListBox_W,'items',string(num2str(fetchn(animals,'animal_id'))))
                end
                
                if isempty(caller) || strcmp(caller, 'animals')
                    cells = sl.SymphonyRecordedCell & app.workingQueryToDisplay.proj;
                    set(app.CellsListBox_W,'items',fetchn(cells,'cell_id'));
                end
               
                datasets = sl.Dataset & app.workingQueryToDisplay.proj;               
                set(app.SymphonydatasetsListBox_W,'items',fetchn(datasets,'dataset_name'));
                
                eyes = sl.Eye & app.workingQueryToDisplay.proj; %TODO not quite right, includes non-recorded eyes

                %epochs = sl.Epoch & app.workingQueryToDisplay.proj;
                
                if isempty(caller) || contains(caller,'_set')
                    animals = sl.Animal & app.workingQuery.proj;
                    cells = sl.SymphonyRecordedCell & app.workingQuery.proj;

                    set(app.animalsEditField_W,'value',num2str(animals.count));
                    set(app.cellsEditField_W,'value',num2str(cells.count));
                    set(app.datasetsEditField_W,'value',num2str(datasets.count));
                    set(app.eyesEditField_W, 'value', num2str(eyes.count));
                    ep_count = app.getEpochCountInQuery(app.workingQuery);
                    set(app.epochsEditField_W, 'value', num2str(ep_count));
                end
            end
        end
        
        function result = checkMergeCompatibility(app)
            if isempty(app.searchTable)
                result = false;
                set(app.MergecompatibleLamp,'color','white');
            elseif isempty(app.workingQuery) || isequal(app.workingQuery.header, app.searchTable.header)
                result = true;
                set(app.MergecompatibleLamp,'color','green');
            else
                result = false;
                set(app.MergecompatibleLamp,'color','red');
            end
        end
        
        function addToWorkingGroup(app, queryToAdd)
            if app.checkMergeCompatibility()
                if isempty(app.workingQuery)
                    app.workingQuery = queryToAdd;
                else
                    primaryKeyStruct_W = app.workingQuery.fetch();
                    primaryKeyStruct_L = queryToAdd.fetch();
                    mergedStruct = [primaryKeyStruct_W; primaryKeyStruct_L];
                    app.workingQuery = app.searchTable & mergedStruct;
                    disp('Merge success!');
                end
                app.workingQueryToDisplay = app.workingQuery;
                app.updateWorkingListBoxes([]);
                app.checkMergeCompatibility()
            else
                disp('Cannot merge incompatible DataGroups.');
            end
            
        end
        
        function [ep_count, ep_in_datasets] = getEpochCountInQuery(app, query)
            ep_q = sl.Epoch & query;
            dataset_structs = query.fetch('epoch_ids');
            all_ep = [];
            for i=1:length(dataset_structs)
                all_ep = [all_ep, struct('epoch_number',num2cell(dataset_structs(i).epoch_ids))];
            end
            ep_in_datasets = ep_q & all_ep;
            ep_count = ep_in_datasets.count;
        end
        
        function updateFunctionsMenu(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            func_menu_name = ['FuncNameDropDown' caller_num];
            
            switch event.Value
                case 'Epoch'
                    d = dir([getenv('user_epoch_functions_folder') '*.m']);
                case 'Dataset'
                    d = dir([getenv('user_dataset_functions_folder') '*.m']);
                case 'Cell'
                    d = dir([getenv('user_cell_functions_folder') '*.m']);
                case 'Multi-cell'
                    d = dir([getenv('user_multicell_functions_folder') '*.m']);                    
            end
            N = length(d);
            fnames = cell(1,N);
            for i=1:N
                fnames{i} = strtok(d(i).name,'.');
            end
            set(app.(func_menu_name), 'items', fnames);  
            app.updateParamsMenu(event);
        end
        
        function runAnalysisFunction(app, event)
            caller = event.Source.Tag;
            [callerPrefix, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            func_menu_name = ['FuncNameDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];

            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            
            %get params
            if length(app.analysisParams) >= caller_num_int && ...
                    ~isempty(app.analysisParams{caller_num_int})
                P = app.analysisParams{caller_num_int};
            else
                P = [];
            end
            
            switch funcType
                case 'Epoch'
                    [ep_count, ep_in_datasets] = app.getEpochCountInQuery(app.workingQuery);
                    all_ep_struct = ep_in_datasets.fetch();
                    fprintf('Running %s on %d epochs...\n', funcName, ep_count);
                    
                    for i=1:ep_count
                        ep = sl.Epoch & all_ep_struct(i);
                        eval(sprintf('R=%s(ep,P);', funcName));
                        app.analysisOutput(i).input = all_ep_struct(i);
                        app.analysisOutput(i).result = R;
                    end
                    fprintf('done\n');
                case 'Dataset'
                    datasets = sl.Dataset & app.workingQuery.proj;
                    all_ds_struct = datasets.fetch();
                    fprintf('Running %s on %d datasets...\n', funcName, datasets);
                    for i=1:datasets.count
                        curDataSet = sl.Dataset & all_ds_struct(i);
                        eval(sprintf('R=%s(curDataSet,P);', funcName));
                        app.analysisOutput(i).input = all_ds_struct(i);
                        app.analysisOutput(i).result = R;
                    end
                    fprintf('done\n');
                case 'Cell'
                    
                case 'Mulit-cell'
                   
            end
            
            switch callerPrefix
                case 'testButton'
                    assignin('base','analysisOutput',app.analysisOutput);  
                case 'writetodatabaseButton'
                    switch funcType
                        case 'Epoch' 
                            disp('Writing epoch results to database');
                            for i=1:length(app.analysisOutput)
                                key = app.analysisOutput(i).input;
                                key.epoch_func_name = funcName;
                                key.param_struct = P;
                                key.result = app.analysisOutput(i).result;
                                %TODO add pipeline name
                                key.pipeline_name = app.pipeline;
                                if get(app.(['OverwriteCheckBox' caller_num]), 'value')
                                    insert(sl.EpochResult, key, 'REPLACE');
                                else
                                    try
                                        insert(sl.EpochResult, key);                                    
                                    catch
                                        disp([funcName ': duplicate result: did not overwrite']);
                                    end
                                end
                            end
                            disp('done');
                            
                        case 'Dataset'
                            
                        case 'Cell'
                            
                        case 'Mulit-cell'
                            
                    end
                    
                case 'exporttoh5Button'
                    
            end
                      
        end
        
        function updateParamsMenu(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            func_menu_name = ['FuncNameDropDown' caller_num];
            param_menu_name = ['ParamSetDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];

            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            
            switch funcType
                case 'Epoch'
                    param_folder = [getenv('user_epoch_functions_folder') funcName '_params' filesep];
                case 'Dataset'
                    param_folder = [getenv('user_dataset_functions_folder') funcName '_params' filesep];
                case 'Cell'
                    param_folder = [getenv('user_cell_functions_folder') funcName '_params' filesep];
                case 'Mulit-cell'
                    param_folder = [getenv('user_multicell_functions_folder') funcName '_params' filesep];
            end            
            
            if exist(param_folder, 'dir')
                d = dir([param_folder,'*.mat']);
                L = length(d);
                paramSets = cell(1,L);
                for i=1:L
                    paramSets{i} = strtok(d(i).name,'.');
                end
                set(app.(param_menu_name), 'items', paramSets);
            else
                set(app.(param_menu_name), 'items', {});
            end     
            
            app.loadParamSet(event);
        end
        
        function loadParamSet(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            func_menu_name = ['FuncNameDropDown' caller_num];
            param_menu_name = ['ParamSetDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];
           
            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            paramSet_name = get(app.(param_menu_name), 'value');
            
            switch funcType
                case 'Epoch'
                    param_folder = [getenv('user_epoch_functions_folder') funcName '_params' filesep];
                case 'Dataset'
                    param_folder = [getenv('user_dataset_functions_folder') funcName '_params' filesep];
                case 'Cell'
                    param_folder = [getenv('user_cell_functions_folder') funcName '_params' filesep];
                case 'Mulit-cell'
                    param_folder = [getenv('user_multicell_functions_folder') funcName '_params' filesep];
            end
            if ~isempty(paramSet_name)
                if exist(param_folder, 'dir')
                    disp('loading');
                    try
                        load([param_folder paramSet_name '.mat'], 'P'); %structure P stores the params
                        fprintf('Param set %s loaded.\n', paramSet_name);
                        app.analysisParams{caller_num_int} = P;
                    catch ME
                        app.analysisParams{caller_num_int} = []; %set to empty
                        fprintf('Error loading param set %s\n', [param_folder paramSet_name '.mat']);
                        %rethrow(ME);
                    end
                    
                else
                    app.analysisParams{caller_num_int} = []; %set to empty
                    fprintf('No param folder folder %s\n', param_folder);
                end
            end
        end
        
        function saveParamSet(app,event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            func_menu_name = ['FuncNameDropDown' caller_num];
            param_menu_name = ['ParamSetDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];
           
            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            paramSet_name = get(app.(param_menu_name), 'value');
            
            switch funcType
                case 'Epoch'
                    param_folder = [getenv('user_epoch_functions_folder') funcName '_params' filesep];
                case 'Dataset'
                    param_folder = [getenv('user_dataset_functions_folder') funcName '_params' filesep];
                case 'Cell'
                    param_folder = [getenv('user_cell_functions_folder') funcName '_params' filesep];
                case 'Mulit-cell'
                    param_folder = [getenv('user_multicell_functions_folder') funcName '_params' filesep];
            end            
            
            if length(app.analysisParams) >= caller_num_int && ~isempty(app.analysisParams{caller_num_int})
                if ~exist(param_folder, 'dir')
                    fprintf('Making new parameter folder for %s\n', funcName);
                    mkdir(param_folder);
                end
                P = app.analysisParams{caller_num_int};
                com.mathworks.mwswing.MJFileChooserPerPlatform.setUseSwingDialog(1);
                %this fixed crash on replacing same param set file
                if isempty(paramSet_name)
                    [fname, fpath] = uiputfile('*.mat','Save new parameter set as...',param_folder);
                    
                else
                    [fname, fpath] = uiputfile('*.mat','Save new parameter set as...',[param_folder paramSet_name]);                    
                end
                drawnow; pause(2);
                %this fixed crash on replacing same param set file
                if fname
                    save([fpath fname], 'P');
                    app.updateParamsMenu(event);
                end
            end           
        end
        
        function editParamsTable(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            
            if length(app.analysisParams) >= caller_num_int && ...
                    ~isempty(app.analysisParams{caller_num_int})
                P = app.analysisParams{caller_num_int};
            else
                P = struct; 
            end
            
            T = table('Size',[10,2],'VariableTypes',{'string','string'});
            T.Properties.VariableNames = {'Parameter','Value'};
            
            if ~isempty(P)
                param_names = fieldnames(P);
                for i=1:length(param_names)
                    T{i,1} = {num2str(param_names{i})};
                    %test for vector
                    val = P.(param_names{i});
                    if iscell(val)
                        V = '{';
                        for n=1:length(val)
                            tempVal = val{n};
                            if ischar(tempVal)
                                V = [V '''' tempVal '''', ','];
                            elseif isnumeric(tempVal) && ~isscalar(tempVal)
                                V = [V mat2str(tempVal), ','];
                            else
                                V = [V num2str(tempVal) ','];
                            end
                        end
                        V = [V(1:end-1), '}'];
                        T{i,2} = {V};
                    elseif isnumeric(val) && ~isscalar(val)
                        T{i,2} = {mat2str(val)};
                    else
                        T{i,2} = {num2str(val)};
                    end
                end                
            end
                                                
            h = AppDataHandle;
            paramSetName = get(app.(['ParamSetDropDown' caller_num]), 'value');        
            paramTable_dlg = ParamTable_dlg(T,paramSetName,h);
            waitfor(paramTable_dlg);
            Tnew = h.data.table;
            
            Pnew = struct;
            for i=1:height(Tnew)
                if ~ismissing(Tnew{i,1}) && ~isempty(Tnew{i,1})
                    try
                        val = evalin("base", Tnew{i,2});
                    catch
                        val = Tnew{i,2};
                    end
                    Pnew.(Tnew{i,1}) = val;
                end
            end
            app.analysisParams{caller_num_int} = Pnew;
            
            
%             if isempty(param_names) %starting from scratch
%                 Ncols = 10; %hopefully this is enough parameters
%                 blank = true;
%             else
%                 Ncols = length(param_names);
%                 blank = false;
%             end
%             T = cell2table(cell(2,10));
%             T.Properties.RowNames = {'Parameter', 'Value'};
%             T.Properties.VariableNames = {};
%             
%             if ~blank
%                 for i=1:Ncols
%                     T{1,i} = {param_names{i}};
%                     T{2,i} = P.(param_names{i});
%                 end
%             end
%             
%             f = uifigure;
%             uitable(f,'Data',T,'ColumnEditable',true);
        end
        
        function updateAnalysisStepVisibility(app)           
            value = app.StepsSpinner.Value;
            N_entries = 20;
            itemList = {'FuncTypeDropDown_' ...
                'FuncNameDropDown_', ...
                'ParamSetDropDown_', ...
                'EditButton_', ...
                'SaveButton_', ...
                'TestButton_', ...
                'WritetodatabaseButton_', ...
                'OverwriteCheckBox_', ...
                'Exporttoh5Button_', ...
                'LabelStep_', ...
                };
            for e=1:value
                for i = 1:length(itemList)  
                    set(app.([itemList{i} num2str(e)]), 'Visible', true);
                end
            end
            for e=value+1:N_entries
                for i = 1:length(itemList)  
                    set(app.([itemList{i} num2str(e)]), 'Visible', false);
                end
            end
        end  

        function loadPipeline(app)
           %set panel names
           set(app.LoaddataPanel, 'title', [app.pipeline ': load data']);
           set(app.WorkingDataGroupPanel, 'title', [app.pipeline ': working DataGroup']);
           set(app.BuildanalysisPanel, 'title', [app.pipeline ': build analysis']);
           set(app.PipelinePanel, 'title', [app.pipeline ': pipeline control']);

           pipeline_dir = [getenv('pipelines_folder') app.pipeline filesep];
           if exist(pipeline_dir, 'dir') %load query
               if exist([pipeline_dir 'query.mat'], 'file')
                   load([pipeline_dir 'query.mat'],'queryState');
                   app.loadedQueryState = queryState;
                   app.loadedQuery = parseQueryStruct(queryState);
                   set(app.QueryTextArea, 'value', parseQueryStruct(queryState, true));
                   commandStr = 'app.searchTable=';
                   currentTables = queryState.currentTables;
                   N = length(currentTables);
                   for i=1:N
                       commandStr = [commandStr, 'sl.' currentTables{i} '*'];
                   end
                   %remove trailing *
                   commandStr = [commandStr(1:end-1) ,';'];
                   eval(commandStr);
               else
                   set(app.QueryTextArea, 'value', '');
                   app.loadedQueryState =[];
                   app.loadedQuery = [];                   
               end
               
               if exist([pipeline_dir 'dataGroup.mat'], 'file') %load dataGroup
                    load([pipeline_dir 'dataGroup.mat'],'workingGroup');
                    app.searchTable = workingGroup.searchTable;                    
                    app.workingQuery = workingGroup.searchTable & workingGroup.primaryKeyStruct;
               else
                   app.workingQuery = [];
               end
               
               if exist([pipeline_dir 'analysisSteps.mat'], 'file') %load analysis steps
                   load([pipeline_dir 'analysisSteps.mat'],'analysisSteps', 'exportStates');
                   Nsteps = length(analysisSteps);
                   set(app.StepsSpinner, 'value', Nsteps);                   
                   for i=1:Nsteps
                       set(app.(['FuncTypeDropDown_' num2str(i)]), 'value', analysisSteps(i).funcType);
                       event.Source.Tag = ['FuncTypeDropDown_' num2str(i)];
                       event.Value = get(app.(['FuncTypeDropDown_' num2str(i)]), 'value');
                       app.(['FuncTypeDropDown_' num2str(i) 'ValueChanged'])(event);
                       
                       set(app.(['FuncNameDropDown_' num2str(i)]), 'value', analysisSteps(i).funcName);                       
                       app.(['FuncNameDropDown_' num2str(i) 'ValueChanged'])(event);
                       set(app.(['ParamSetDropDown_' num2str(i)]), 'value', analysisSteps(i).paramSet);
                       app.(['ParamSetDropDown_' num2str(i) 'ValueChanged'])(event);
                       
                       if ~isempty(exportStates(i))
                           app.exportPreferences{i} = exportStates(i);
                       end
                   end
               else
                  Nsteps = 1;
                  set(app.StepsSpinner, 'value', Nsteps);
                  event.Source.Tag = 'FuncTypeDropDown_1';
                  event.Value = 'Epoch';
                  app.FuncTypeDropDown_1ValueChanged(event);
                  app.FuncNameDropDown_1ValueChanged(event);
               end
           else
               set(app.QueryTextArea, 'value', '');
               app.loadedQueryState =[];
               app.loadedQuery = [];
               app.workingQuery = [];
           end
           app.loadedQueryToDisplay = app.loadedQuery;
           app.updateLoadedListBoxes([]);
           app.workingQueryToDisplay = app.workingQuery;
           app.updateWorkingListBoxes([]);
           app.checkMergeCompatibility();
           app.updateAnalysisStepVisibility();
        end
        
        function openH5ExportTool(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            
            if length(app.analysisOutput) < caller_num_int || isempty(app.analysisOutput{caller_num_int})
                %need to run analysis first
                app.(['TestButton' caller_num 'Pushed']);            
            end
            
            h = AppDataHandle;
            h5app = H5ExportTool(app.pipeline,get(app.(['FuncTypeDropDown' caller_num]), 'value'), app.analysisOutput{caller_num_int}, h);
            waitfor(h5app);
            app.exportPreferences{caller_num_int} = h.data.exportState;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %load pipeline
           h = AppDataHandle;
           loader = PipelineLoader(h);
           waitfor(loader);
           app.pipeline = h.data.pipeline_name;
           
           if isempty(app.pipeline)
               disp('Error: must select a working pipeline')
               delete(app);
           else
              app.loadPipeline(); 
           end
                      
        end

        % Button pushed function: LoadsinglecellButton
        function LoadsinglecellButtonPushed(app, event)
            cell_id = get(app.cell_idEditField, 'value');
            app.searchTable = sl.SymphonyRecordedCell * sl.Dataset;
            app.loadedQuery = app.searchTable & sprintf('cell_id="%s"', cell_id);
            app.loadedQueryToDisplay = app.loadedQuery;
            app.updateLoadedListBoxes([]);
            app.checkMergeCompatibility();
        end

        % Button pushed function: LoadcellnamesButton
        function LoadcellnamesButtonPushed(app, event)
            [fname, fpath] = uigetfile('*.txt','Select plain text file with cell names.');
            if fname
                cell_ids = importdata([fpath, fname]);      
                app.searchTable = sl.SymphonyRecordedCell * sl.Dataset;
                app.loadedQuery = app.searchTable & struct('cell_id', cell_ids);
                app.loadedQueryToDisplay = app.loadedQuery;
                app.updateLoadedListBoxes([]);
                app.checkMergeCompatibility();
            end
        end

        % Value changed function: AnimalsListBox_L
        function AnimalsListBox_LValueChanged(app, event)
            value = app.AnimalsListBox_L.Value;
            app.loadedQueryToDisplay = app.loadedQuery & sprintf('animal_id=%s', value);
            app.updateLoadedListBoxes('animals');
        end

        % Value changed function: CellsListBox_L
        function CellsListBox_LValueChanged(app, event)
            value = app.CellsListBox_L.Value;
            app.loadedQueryToDisplay = app.loadedQuery & sprintf('cell_id="%s"', value);
            app.updateLoadedListBoxes('cells');
        end

        % Button pushed function: LoadqueryButton
        function LoadqueryButtonPushed(app, event)
            [fname, fpath] = uigetfile('.mat','Load query file',getenv('queryFolder'));
            if fname
                load([fpath fname], 'queryState');
                app.loadedQueryState = queryState;
                app.loadedQuery = parseQueryStruct(queryState);
                set(app.QueryEditField,'value',parseQueryStruct(queryState, true));
                commandStr = 'app.searchTable=';
                currentTables = queryState.currentTables;
                N = length(currentTables);
                for i=1:N
                    commandStr = [commandStr, 'sl.' currentTables{i} '*'];
                end
                %remove trailing *
                commandStr = [commandStr(1:end-1) ,';'];
                eval(commandStr);
                app.loadedQueryToDisplay = app.loadedQuery;
                app.updateLoadedListBoxes([]);
                app.checkMergeCompatibility();
            end
        end

        % Button pushed function: QueryeditorButton
        function QueryeditorButtonPushed(app, event)
            h = AppDataHandle;
            qb_app = QueryBuilder(h);
            waitfor(qb_app);
            if isfield(h.data, 'queryResult')
                app.loadedQuery = h.data.queryResult;
                app.searchTable = h.data.searchTable;
                app.loadedQueryToDisplay = app.loadedQuery;
                app.updateLoadedListBoxes([]);
                app.checkMergeCompatibility();
            end
        end

        % Button pushed function: ClearLoadedButton
        function ClearLoadedButtonPushed(app, event)
            app.loadedQuery = [];
            app.loadedQueryToDisplay = app.loadedQuery;
            app.searchTable = [];
            app.updateLoadedListBoxes();
            app.checkMergeCompatibility();
        end

        % Button pushed function: FilterbyepochfunctionButton
        function FilterbyepochfunctionButtonPushed(app, event)
            fname = uigetfile('*.m', 'Select your filtering function', getenv('epochFilterFuncFolder'));
            if fname
                commandName = strtok(fname, '.'); 
                if ~isempty(app.loadedQuery)
                    %ep_query = sl.Epoch & app.loadedQuery.proj;
                    [~, ep_query] = getEpochCountInQuery(app, app.loadedQuery);
                    q_struct = ep_query.fetch('protocol_params');
                    fprintf('running filter function on %d epochs\n', length(q_struct));
                    tic;x
                    for i=1:length(q_struct) %check each epoch
                        epochForTest = q_struct(i);
                        epochForQuery = rmfield(q_struct(i), 'protocol_params');                        
                        passed = eval([commandName '(epochForTest);']);
                        thisTempEntry = sl.TempFilter & epochForQuery;                        
                        while thisTempEntry.exists
                            disp('waiting for another user working on this epoch')
                            pause(1);
                            thisTempEntry = sl.TempFilter & epochForTest;
                        end
                        epochForInsert = epochForQuery;
                        epochForInsert.passed = passed;
                        insert(sl.TempFilter,epochForInsert);                                                
                    end
                    fprintf('Time elapsed: %f seconds\n', toc);
                    %now run the filter by using restriction with temp table
                    passed_epochs = sl.TempFilter & 'passed=1';
                    fprintf('%d epochs passed.\n', passed_epochs.count);
                    %app.loadedQuery = app.loadedQuery & passed_epochs;
                    %drop empty datasets
                    datasets = sl.Dataset & proj(app.loadedQuery) & passed_epochs;
                    d_struct = fetch(datasets);
                    %d_struct_clean = rmfield(d_struct, 'passed');
                    d_struct_withEpochNums = fetch(datasets,'epoch_ids');
                    for i=1:length(d_struct)
%                         i
                        theseEpochNums = fetchn(passed_epochs & d_struct(i), 'epoch_number');
                        if isempty(intersect(d_struct_withEpochNums(i).epoch_ids, theseEpochNums))
                            app.loadedQuery = app.loadedQuery - d_struct(i);
                        end
%                         app.loadedQuery 
%                         pause;
                    end
                    app.loadedQueryToDisplay = app.loadedQuery;
                    app.updateLoadedListBoxes([]);
                    %and delete the temp filter entries
                    q_struct = ep_query.fetch();
                    delQuick(sl.TempFilter & q_struct);
                    disp('deleted TempFilter')
                end
            end
        end

        % Button pushed function: AlltoWGButton
        function AlltoWGButtonPushed(app, event)
            app.addToWorkingGroup(app.loadedQuery);
        end

        % Button pushed function: AnimaltoWGButton
        function AnimaltoWGButtonPushed(app, event)
            selected_animal = get(app.AnimalsListBox_L,'value');
            if ~isempty(selected_animal)
                app.addToWorkingGroup(app.loadedQuery & sprintf('animal_id=%s', selected_animal));
            end
        end

        % Button pushed function: CelltoWGButton
        function CelltoWGButtonPushed(app, event)
            selected_cell =  get(app.CellsListBox_L,'value');
            selected_animal = get(app.AnimalsListBox_L,'value');
            if ~isempty(selected_cell) && ~isempty(selected_animal)
                app.addToWorkingGroup(app.loadedQuery ...
                    & sprintf('cell_id="%s"', selected_cell) ...
                    & sprintf('animal_id=%s', selected_animal));
            end
        end

        % Button pushed function: DatasettoWGButton
        function DatasettoWGButtonPushed(app, event)
            selected_dataset = get(app.SymphonydatasetsListBox_L,'value');
            selected_cell =  get(app.CellsListBox_L,'value');
            selected_animal = get(app.AnimalsListBox_L,'value');
            if ~isempty(selected_dataset) && ~isempty(selected_cell) && ~isempty(selected_animal)
                app.addToWorkingGroup(app.loadedQuery ...
                    & sprintf('dataset_name="%s"', selected_dataset) ...
                    & sprintf('cell_id="%s"', selected_cell) ...
                    & sprintf('animal_id=%s', selected_animal))
            end
        end

        % Button pushed function: ClearWorkingGroupButton
        function ClearWorkingGroupButtonPushed(app, event)
            app.workingQuery = [];
            app.workingQueryToDisplay = app.workingQuery;
            app.updateWorkingListBoxes([]);
            app.checkMergeCompatibility();
        end

        % Value changed function: AnimalsListBox_W
        function AnimalsListBox_WValueChanged(app, event)
            value = app.AnimalsListBox_W.Value;
            app.workingQueryToDisplay = app.workingQuery & sprintf('animal_id=%s', value);
            app.updateWorkingListBoxes('animals');
        end

        % Value changed function: CellsListBox_W
        function CellsListBox_WValueChanged(app, event)
            value = app.CellsListBox_W.Value;
            app.workingQueryToDisplay = app.workingQuery & sprintf('cell_id="%s"', value);
            app.updateWorkingListBoxes('cells');
        end

        % Button pushed function: DitchanimalButton
        function DitchanimalButtonPushed(app, event)
            value = app.AnimalsListBox_W.Value;
            if ~isempty(value)
                app.workingQuery = app.workingQuery - sprintf('animal_id=%s', value);
                if app.workingQuery.count==0
                    app.ClearWorkingGroupButtonPushed(event);
                else
                    app.workingQueryToDisplay = app.workingQuery;
                    app.updateWorkingListBoxes([]);
                end
            end  
        end

        % Button pushed function: DitchcellButton
        function DitchcellButtonPushed(app, event)
            value = app.CellsListBox_W.Value;
            if ~isempty(value)
                app.workingQuery = app.workingQuery - sprintf('cell_id="%s"', value);
                if app.workingQuery.count==0
                    app.ClearWorkingGroupButtonPushed(event);
                else
                    app.workingQueryToDisplay = app.workingQuery;
                    app.updateWorkingListBoxes('cells_set');
                    app.AnimalsListBox_WValueChanged(event);
                end
            end
        end

        % Button pushed function: DitchdatasetButton
        function DitchdatasetButtonPushed(app, event)
            value = app.SymphonydatasetsListBox_W.Value;
            selected_cell =  get(app.CellsListBox_W,'value');
            selected_animal = get(app.AnimalsListBox_W,'value');
            if ~isempty(value) && ~isempty(selected_cell) && ~isempty(selected_animal)
                subtract_struct = struct('dataset_name', value, ...
                    'cell_id', selected_cell, ...
                    'animal_id', str2double(selected_animal));
                app.workingQuery = app.workingQuery - subtract_struct;
                if app.workingQuery.count==0
                    app.ClearWorkingGroupButtonPushed(event);
                else
                    app.workingQueryToDisplay = app.workingQuery;
                    app.updateWorkingListBoxes('datasets_set');
                    app.CellsListBox_WValueChanged(event);
                end
            end
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
                [fname, fpath] = uiputfile('*.mat', 'Save DataGroup file...',getenv('workingGroupsFolder'));
                if fname
                    workingGroup.primaryKeyStruct = app.workingQuery.fetch();
                    workingGroup.searchTable = app.searchTable;    
                    save([fpath, fname], 'workingGroup', '-mat');
                end
        end

        % Button pushed function: LoadDataGroupButton
        function LoadDataGroupButtonPushed(app, event)
             [fname, fpath] = uigetfile('*.mat', 'Load DataGroup file...',getenv('workingGroupsFolder'));
                if fname
                    load([fpath, fname], 'workingGroup');
                    app.searchTable = workingGroup.searchTable;                    
                    app.loadedQuery = workingGroup.searchTable & workingGroup.primaryKeyStruct;
                    app.loadedQueryToDisplay = app.loadedQuery;
                    app.updateLoadedListBoxes([]);
                    app.checkMergeCompatibility();
                end
        end

        % Value changed function: FuncTypeDropDown_1
        function FuncTypeDropDown_1ValueChanged(app, event)
            app.updateFunctionsMenu(event);
        end

        % Button pushed function: TestButton_1
        function TestButton_1Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: EditButton_1
        function EditButton_1Pushed(app, event)
            app.editParamsTable(event);
        end

        % Value changed function: FuncNameDropDown_1
        function FuncNameDropDown_1ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: ParamSetDropDown_1
        function ParamSetDropDown_1ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Button pushed function: SaveButton_1
        function SaveButton_1Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: WritetodatabaseButton_1
        function WritetodatabaseButton_1Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: AttachquerytopipelineButton
        function AttachquerytopipelineButtonPushed(app, event)
            pipelines_dir = getenv('pipelines_folder');
            if ~isempty(app.pipeline)
                pipeline_name = app.pipeline;
                if ~isempty(app.loadedQueryState)
                    if ~exist([pipelines_dir pipeline_name], 'dir')
                        mkdir([pipelines_dir pipeline_name]);
                    end                    
                    queryState = app.loadedQueryState;
                    save([pipelines_dir pipeline_name filesep 'query.mat'], 'queryState');
                    fprintf('Query attached to pipeline: %s\n', pipeline_name);
                end                    
            end            
        end

        % Button pushed function: SwitchpipelineButton
        function SwitchpipelineButtonPushed(app, event)
           h = AppDataHandle;
           loader = PipelineLoader(h);
           waitfor(loader);
           app.pipeline = h.data.pipeline_name;
           
           if isempty(app.pipeline)
               disp('Error: must select a working pipeline')
               delete(app);
           else
              app.loadPipeline(); 
           end
        end

        % Button pushed function: AttachDataGrouptopipelineButton
        function AttachDataGrouptopipelineButtonPushed(app, event)
            pipelines_dir = getenv('pipelines_folder');
            if ~isempty(app.pipeline)
                pipeline_name = app.pipeline;
                if ~isempty(app.workingQuery)
                    if ~exist([pipelines_dir pipeline_name], 'dir')
                        mkdir([pipelines_dir pipeline_name]);
                    end                    
                    workingGroup.primaryKeyStruct = app.workingQuery.fetch();
                    workingGroup.searchTable = app.searchTable;  
                    save([pipelines_dir pipeline_name filesep 'dataGroup.mat'], 'workingGroup');
                    fprintf('DataGroup attached to pipeline: %s\n', pipeline_name);
                end                    
            end  
        end

        % Callback function
        function EditQueryButtonValueChanged(app, event)
            value = app.EditQueryButton.Value;
            if value
                set(app.QueryEditField, 'editable', true);
            else
                set(app.QueryEditField, 'editable', false);
            end
        end

        % Value changed function: StepsSpinner
        function StepsSpinnerValueChanged(app, event)
            value = event.Value;
            app.updateAnalysisStepVisibility();
            if value > event.PreviousValue
                %initialize this last entry
                ev.Source.Tag = ['FuncTypeDropDown_' num2str(value)];
                ev.Value = 'Epoch';
                app.(['FuncTypeDropDown_' num2str(value) 'ValueChanged'])(ev);
                app.(['FuncNameDropDown_' num2str(value) 'ValueChanged'])(ev);
            end
        end

        % Button pushed function: 
        % AttachanalysisstepstopipelineButton
        function AttachanalysisstepstopipelineButtonPushed(app, event)
            pipelines_dir = getenv('pipelines_folder');
            if ~isempty(app.pipeline)
                pipeline_name = app.pipeline;
                if ~isempty(app.workingQuery)
                    if ~exist([pipelines_dir pipeline_name], 'dir')
                        mkdir([pipelines_dir pipeline_name]);
                    end                    
                    Nsteps = get(app.StepsSpinner, 'value');
                    s = struct('funcType','','funcName','','paramSet','');
                    analysisSteps = repmat(s,1,Nsteps);
                    exportStates = struct;
                    for i=1:Nsteps
                        analysisSteps(i).funcType = get(app.(['FuncTypeDropDown_' num2str(i)]), 'value');
                        analysisSteps(i).funcName = get(app.(['FuncNameDropDown_' num2str(i)]), 'value');
                        analysisSteps(i).paramSet = get(app.(['ParamSetDropDown_' num2str(i)]), 'value');
                        if length(app.exportPreferences) >= i
                            exportStates(i) = app.exportPreferences{i};
                        end
                    end
                    save([pipelines_dir pipeline_name filesep 'analysisSteps.mat'], 'analysisSteps', 'exportStates');
                    fprintf('%d analysis steps attached to pipeline: %s\n', Nsteps, pipeline_name);
                end                    
            end  
        end

        % Callback function
        function FuncTypeDropDown_2ValueChanged(app, event)
            app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_3
        function FuncTypeDropDown_3ValueChanged(app, event)
            app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_4
        function FuncTypeDropDown_4ValueChanged(app, event)
            app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_5
        function FuncTypeDropDown_5ValueChanged(app, event)
            app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_6
        function FuncTypeDropDown_6ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_7
        function FuncTypeDropDown_7ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_8
        function FuncTypeDropDown_8ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_9
        function FuncTypeDropDown_9ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_10
        function FuncTypeDropDown_10ValueChanged(app, event)
          app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_11
        function FuncTypeDropDown_11ValueChanged(app, event)
          app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_12
        function FuncTypeDropDown_12ValueChanged(app, event)
          app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_13
        function FuncTypeDropDown_13ValueChanged(app, event)
          app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_14
        function FuncTypeDropDown_14ValueChanged(app, event)
          app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_15
        function FuncTypeDropDown_15ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_16
        function FuncTypeDropDown_16ValueChanged(app, event)
          app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_17
        function FuncTypeDropDown_17ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_18
        function FuncTypeDropDown_18ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_19
        function FuncTypeDropDown_19ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_20
        function FuncTypeDropDown_20ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_2ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_3
        function FuncNameDropDown_3ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_4
        function FuncNameDropDown_4ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_5
        function FuncNameDropDown_5ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_6
        function FuncNameDropDown_6ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_7
        function FuncNameDropDown_7ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_8
        function FuncNameDropDown_8ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_9
        function FuncNameDropDown_9ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_10
        function FuncNameDropDown_10ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_11
        function FuncNameDropDown_11ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_12
        function FuncNameDropDown_12ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_13
        function FuncNameDropDown_13ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_14
        function FuncNameDropDown_14ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_15
        function FuncNameDropDown_15ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_16
        function FuncNameDropDown_16ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_17
        function FuncNameDropDown_17ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_18
        function FuncNameDropDown_18ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_19
        function FuncNameDropDown_19ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_20
        function FuncNameDropDown_20ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Callback function
        function ParamSetDropDown_2ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_3
        function ParamSetDropDown_3ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_4
        function ParamSetDropDown_4ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_5
        function ParamSetDropDown_5ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_6
        function ParamSetDropDown_6ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_7
        function ParamSetDropDown_7ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_8
        function ParamSetDropDown_8ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_9
        function ParamSetDropDown_9ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_10
        function ParamSetDropDown_10ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_11
        function ParamSetDropDown_11ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_12
        function ParamSetDropDown_12ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_13
        function ParamSetDropDown_13ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_14
        function ParamSetDropDown_14ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_15
        function ParamSetDropDown_15ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_16
        function ParamSetDropDown_16ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_17
        function ParamSetDropDown_17ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_18
        function ParamSetDropDown_18ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_19
        function ParamSetDropDown_19ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_20
        function ParamSetDropDown_20ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Callback function
        function EditButton_2Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_3
        function EditButton_3Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_4
        function EditButton_4Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_5
        function EditButton_5Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_6
        function EditButton_6Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_7
        function EditButton_7Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_8
        function EditButton_8Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_9
        function EditButton_9Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_10
        function EditButton_10Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_11
        function EditButton_11Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_12
        function EditButton_12Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_13
        function EditButton_13Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_14
        function EditButton_14Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_15
        function EditButton_15Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_16
        function EditButton_16Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_17
        function EditButton_17Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_18
        function EditButton_18Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_19
        function EditButton_19Pushed(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_20
        function EditButton_20Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function SaveButton_2Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_4
        function SaveButton_4Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_5
        function SaveButton_5Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_6
        function SaveButton_6Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_7
        function SaveButton_7Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_8
        function SaveButton_8Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_9
        function SaveButton_9Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_10
        function SaveButton_10Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_11
        function SaveButton_11Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_12
        function SaveButton_12Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_13
        function SaveButton_13Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_14
        function SaveButton_14Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_15
        function SaveButton_15Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_16
        function SaveButton_16Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_17
        function SaveButton_17Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_18
        function SaveButton_18Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_19
        function SaveButton_19Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_20
        function SaveButton_20Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function TestButton_2Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_3
        function TestButton_3Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_4
        function TestButton_4Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_5
        function TestButton_5Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_6
        function TestButton_6Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_7
        function TestButton_7Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_8
        function TestButton_8Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_9
        function TestButton_9Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_10
        function TestButton_10Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_11
        function TestButton_11Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_12
        function TestButton_12Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_13
        function TestButton_13Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_14
        function TestButton_14Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_15
        function TestButton_15Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_16
        function TestButton_16Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_17
        function TestButton_17Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_18
        function TestButton_18Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_19
        function TestButton_19Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: TestButton_20
        function TestButton_20Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_2Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_3
        function WritetodatabaseButton_3Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_4
        function WritetodatabaseButton_4Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_5
        function WritetodatabaseButton_5Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_6
        function WritetodatabaseButton_6Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_7
        function WritetodatabaseButton_7Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_8
        function WritetodatabaseButton_8Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_9
        function WritetodatabaseButton_9Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_10
        function WritetodatabaseButton_10Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_11
        function WritetodatabaseButton_11Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_12
        function WritetodatabaseButton_12Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_13
        function WritetodatabaseButton_13Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_14
        function WritetodatabaseButton_14Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_15
        function WritetodatabaseButton_15Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_16
        function WritetodatabaseButton_16Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_17
        function WritetodatabaseButton_17Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_18
        function WritetodatabaseButton_18Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_19
        function WritetodatabaseButton_19Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_20
        function WritetodatabaseButton_20Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Value changed function: FuncTypeDropDown_2
        function FuncTypeDropDown_2ValueChanged2(app, event)
            app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncNameDropDown_2
        function FuncNameDropDown_2ValueChanged2(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: ParamSetDropDown_2
        function ParamSetDropDown_2ValueChanged2(app, event)
            app.loadParamSet(event);
        end

        % Button pushed function: EditButton_2
        function EditButton_2Pushed2(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed2(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: TestButton_2
        function TestButton_2Pushed2(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: WritetodatabaseButton_2
        function WritetodatabaseButton_2Pushed2(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: SaveasnewpipelineButton
        function SaveasnewpipelineButtonPushed(app, event)
            h = AppDataHandle;
            loader = NewPipeline(h);
            waitfor(loader);
            app.pipeline = h.data.pipeline_name;
            app.AttachDataGrouptopipelineButtonPushed(event);
            app.AttachanalysisstepstopipelineButtonPushed(event);
            app.AttachquerytopipelineButtonPushed(event);
            app.loadPipeline();
        end

        % Button pushed function: Exporttoh5Button_1
        function Exporttoh5Button_1Pushed(app, event)
            app.openH5ExportTool(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalysisPipelineBuilderUIFigure = uifigure('Visible', 'off');
            app.AnalysisPipelineBuilderUIFigure.Name = 'AnalysisPipelineBuilder';
            app.WorkingDataGroupPanel = uipanel(app.AnalysisPipelineBuilderUIFigure);
            app.WorkingDataGroupPanel.Title = 'Working DataGroup';
            app.CellsListBoxLabel = uilabel(app.WorkingDataGroupPanel);
            app.CellsListBoxLabel.Text = 'Cells';
            app.CellsListBox_W = uilistbox(app.WorkingDataGroupPanel);
            app.CellsListBox_W.Items = {};
            app.CellsListBox_W.ValueChangedFcn = createCallbackFcn(app, @CellsListBox_WValueChanged, true);
            app.CellsListBox_W.Value = {};
            app.SymphonydatasetsListBoxLabel = uilabel(app.WorkingDataGroupPanel);
            app.SymphonydatasetsListBoxLabel.Text = 'Symphony datasets';
            app.SymphonydatasetsListBox_W = uilistbox(app.WorkingDataGroupPanel);
            app.SymphonydatasetsListBox_W.Items = {};
            app.SymphonydatasetsListBox_W.Value = {};
            app.OtherdataListBoxLabel = uilabel(app.WorkingDataGroupPanel);
            app.OtherdataListBoxLabel.Text = 'Other data';
            app.OtherdataListBox_W = uilistbox(app.WorkingDataGroupPanel);
            app.OtherdataListBox_W.Items = {};
            app.OtherdataListBox_W.Value = {};
            app.AnimalsListBoxLabel = uilabel(app.WorkingDataGroupPanel);
            app.AnimalsListBoxLabel.Text = 'Animals';
            app.AnimalsListBox_W = uilistbox(app.WorkingDataGroupPanel);
            app.AnimalsListBox_W.Items = {};
            app.AnimalsListBox_W.ValueChangedFcn = createCallbackFcn(app, @AnimalsListBox_WValueChanged, true);
            app.AnimalsListBox_W.Value = {};
            app.TotalsPanel_2 = uipanel(app.WorkingDataGroupPanel);
            app.TotalsPanel_2.Title = 'Totals';
            app.animalsEditField_2Label = uilabel(app.TotalsPanel_2);
            app.animalsEditField_2Label.Text = 'animals';
            app.animalsEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.animalsEditField_W.Editable = 'off';
            app.cellsEditField_3Label = uilabel(app.TotalsPanel_2);
            app.cellsEditField_3Label.Text = 'cells';
            app.cellsEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.cellsEditField_W.Editable = 'off';
            app.epochsEditField_4Label = uilabel(app.TotalsPanel_2);
            app.epochsEditField_4Label.Text = 'epochs';
            app.epochsEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.epochsEditField_W.Editable = 'off';
            app.eyesEditField_2Label = uilabel(app.TotalsPanel_2);
            app.eyesEditField_2Label.Text = 'eyes';
            app.eyesEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.eyesEditField_W.Editable = 'off';
            app.datasetsEditField_4Label = uilabel(app.TotalsPanel_2);
            app.datasetsEditField_4Label.Text = 'datasets';
            app.datasetsEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.datasetsEditField_W.Editable = 'off';
            app.otherfilesEditField_4Label = uilabel(app.TotalsPanel_2);
            app.otherfilesEditField_4Label.Text = 'other files';
            app.otherfilesEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.otherfilesEditField_W.Editable = 'off';
            app.SavetofileButton = uibutton(app.TotalsPanel_2, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
            app.ClearWorkingGroupButton = uibutton(app.TotalsPanel_2, 'push');
            app.ClearWorkingGroupButton.ButtonPushedFcn = createCallbackFcn(app, @ClearWorkingGroupButtonPushed, true);
            app.ClearWorkingGroupButton.Text = 'Clear';
            app.AttachDataGrouptopipelineButton = uibutton(app.TotalsPanel_2, 'push');
            app.AttachDataGrouptopipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AttachDataGrouptopipelineButtonPushed, true);
            app.AttachDataGrouptopipelineButton.Text = 'Attach DataGroup to pipeline';
            app.DitchanimalButton = uibutton(app.WorkingDataGroupPanel, 'push');
            app.DitchanimalButton.ButtonPushedFcn = createCallbackFcn(app, @DitchanimalButtonPushed, true);
            app.DitchanimalButton.Text = 'Ditch animal';
            app.DitchcellButton = uibutton(app.WorkingDataGroupPanel, 'push');
            app.DitchcellButton.ButtonPushedFcn = createCallbackFcn(app, @DitchcellButtonPushed, true);
            app.DitchcellButton.Text = 'Ditch cell';
            app.DitchdatasetButton = uibutton(app.WorkingDataGroupPanel, 'push');
            app.DitchdatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DitchdatasetButtonPushed, true);
            app.DitchdatasetButton.Text = 'Ditch dataset';
            app.DitchotherdataButton = uibutton(app.WorkingDataGroupPanel, 'push');
            app.DitchotherdataButton.Text = 'Ditch other data';
            app.BuildanalysisPanel = uipanel(app.AnalysisPipelineBuilderUIFigure);
            app.BuildanalysisPanel.Title = 'Build analysis';
            app.FuncTypeDropDown_1 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_1.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_1.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_1ValueChanged, true);
            app.FuncTypeDropDown_1.Tag = 'FuncTypeDropDown_1';
            app.FuncTypeDropDown_1.Value = 'Epoch';
            app.FuncNameDropDown_1 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_1.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_1ValueChanged, true);
            app.FuncNameDropDown_1.Tag = 'FuncNameDownDown_1';
            app.FunctiontypeLabel = uilabel(app.BuildanalysisPanel);
            app.FunctiontypeLabel.Text = 'Function type';
            app.FunctionnameLabel = uilabel(app.BuildanalysisPanel);
            app.FunctionnameLabel.Text = 'Function name';
            app.StepsSpinnerLabel = uilabel(app.BuildanalysisPanel);
            app.StepsSpinnerLabel.Text = 'Steps';
            app.StepsSpinner = uispinner(app.BuildanalysisPanel);
            app.StepsSpinner.Limits = [1 20];
            app.StepsSpinner.RoundFractionalValues = 'on';
            app.StepsSpinner.ValueChangedFcn = createCallbackFcn(app, @StepsSpinnerValueChanged, true);
            app.StepsSpinner.Value = 1;
            app.TestButton_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_1.ButtonPushedFcn = createCallbackFcn(app, @TestButton_1Pushed, true);
            app.TestButton_1.Tag = 'testButton_1';
            app.TestButton_1.Text = 'Test';
            app.WritetodatabaseButton_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_1.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_1Pushed, true);
            app.WritetodatabaseButton_1.Tag = 'writetodatabaseButton_1';
            app.WritetodatabaseButton_1.Text = 'Write to database';
            app.Exporttoh5Button_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_1.ButtonPushedFcn = createCallbackFcn(app, @Exporttoh5Button_1Pushed, true);
            app.Exporttoh5Button_1.Tag = 'exporttoh5Button_1';
            app.Exporttoh5Button_1.Text = 'Export to .h5';
            app.OverwriteCheckBox_1 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_1.Text = '';
            app.SaveButton_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_1.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_1Pushed, true);
            app.SaveButton_1.Tag = 'saveparamsButton_1';
            app.SaveButton_1.Text = 'Save';
            app.EditButton_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_1.ButtonPushedFcn = createCallbackFcn(app, @EditButton_1Pushed, true);
            app.EditButton_1.Tag = 'editparamsButton_1';
            app.EditButton_1.Text = 'Edit';
            app.ParametersetLabel = uilabel(app.BuildanalysisPanel);
            app.ParametersetLabel.Text = 'Parameter set';
            app.ParamSetDropDown_1 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_1.Items = {};
            app.ParamSetDropDown_1.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_1ValueChanged, true);
            app.ParamSetDropDown_1.Tag = 'ParamSetDropDown_1';
            app.ParamSetDropDown_1.Value = {};
            app.RunstepLabel = uilabel(app.BuildanalysisPanel);
            app.RunstepLabel.Text = 'Run step';
            app.overwriteLabel = uilabel(app.BuildanalysisPanel);
            app.overwriteLabel.Text = 'overwrite';
            app.LabelStep_3 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_3.Tag = 'FuncTypeDropDown_3';
            app.LabelStep_3.Text = '3';
            app.FuncTypeDropDown_3 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_3.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_3ValueChanged, true);
            app.FuncTypeDropDown_3.Tag = 'FuncTypeDropDown_3';
            app.FuncTypeDropDown_3.Value = 'Epoch';
            app.FuncNameDropDown_3 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_3ValueChanged, true);
            app.FuncNameDropDown_3.Tag = 'FuncNameDownDown_3';
            app.TestButton_3 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_3.ButtonPushedFcn = createCallbackFcn(app, @TestButton_3Pushed, true);
            app.TestButton_3.Tag = 'testButton_3';
            app.TestButton_3.Text = 'Test';
            app.WritetodatabaseButton_3 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_3.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_3Pushed, true);
            app.WritetodatabaseButton_3.Tag = 'writetodatabaseButton_3';
            app.WritetodatabaseButton_3.Text = 'Write to database';
            app.Exporttoh5Button_3 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_3.Tag = 'exporttoh5Button_3';
            app.Exporttoh5Button_3.Text = 'Export to .h5';
            app.OverwriteCheckBox_3 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_3.Text = '';
            app.SaveButton_3 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Tag = 'saveparamsButton_3';
            app.SaveButton_3.Text = 'Save';
            app.EditButton_3 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_3.ButtonPushedFcn = createCallbackFcn(app, @EditButton_3Pushed, true);
            app.EditButton_3.Tag = 'editparamsButton_3';
            app.EditButton_3.Text = 'Edit';
            app.ParamSetDropDown_3 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_3.Items = {};
            app.ParamSetDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_3ValueChanged, true);
            app.ParamSetDropDown_3.Tag = 'ParamSetDropDown_3';
            app.ParamSetDropDown_3.Value = {};
            app.FuncNameDropDown_4 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_4.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_4ValueChanged, true);
            app.FuncNameDropDown_4.Tag = 'FuncNameDownDown_4';
            app.TestButton_4 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_4.ButtonPushedFcn = createCallbackFcn(app, @TestButton_4Pushed, true);
            app.TestButton_4.Tag = 'testButton_4';
            app.TestButton_4.Text = 'Test';
            app.WritetodatabaseButton_4 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_4.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_4Pushed, true);
            app.WritetodatabaseButton_4.Tag = 'writetodatabaseButton_4';
            app.WritetodatabaseButton_4.Text = 'Write to database';
            app.Exporttoh5Button_4 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_4.Tag = 'exporttoh5Button_4';
            app.Exporttoh5Button_4.Text = 'Export to .h5';
            app.OverwriteCheckBox_4 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_4.Text = '';
            app.SaveButton_4 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_4Pushed, true);
            app.SaveButton_4.Tag = 'saveparamsButton_4';
            app.SaveButton_4.Text = 'Save';
            app.EditButton_4 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_4.ButtonPushedFcn = createCallbackFcn(app, @EditButton_4Pushed, true);
            app.EditButton_4.Tag = 'editparamsButton_4';
            app.EditButton_4.Text = 'Edit';
            app.ParamSetDropDown_4 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_4.Items = {};
            app.ParamSetDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_4ValueChanged, true);
            app.ParamSetDropDown_4.Tag = 'ParamSetDropDown_4';
            app.ParamSetDropDown_4.Value = {};
            app.FuncNameDropDown_5 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_5.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_5ValueChanged, true);
            app.FuncNameDropDown_5.Tag = 'FuncNameDownDown_5';
            app.TestButton_5 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_5.ButtonPushedFcn = createCallbackFcn(app, @TestButton_5Pushed, true);
            app.TestButton_5.Tag = 'testButton_5';
            app.TestButton_5.Text = 'Test';
            app.WritetodatabaseButton_5 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_5.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_5Pushed, true);
            app.WritetodatabaseButton_5.Tag = 'writetodatabaseButton_5';
            app.WritetodatabaseButton_5.Text = 'Write to database';
            app.Exporttoh5Button_5 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_5.Tag = 'exporttoh5Button_5';
            app.Exporttoh5Button_5.Text = 'Export to .h5';
            app.OverwriteCheckBox_5 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_5.Text = '';
            app.SaveButton_5 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_5Pushed, true);
            app.SaveButton_5.Tag = 'saveparamsButton_5';
            app.SaveButton_5.Text = 'Save';
            app.EditButton_5 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_5.ButtonPushedFcn = createCallbackFcn(app, @EditButton_5Pushed, true);
            app.EditButton_5.Tag = 'editparamsButton_5';
            app.EditButton_5.Text = 'Edit';
            app.ParamSetDropDown_5 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_5.Items = {};
            app.ParamSetDropDown_5.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_5ValueChanged, true);
            app.ParamSetDropDown_5.Tag = 'ParamSetDropDown_5';
            app.ParamSetDropDown_5.Value = {};
            app.FuncNameDropDown_6 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_6.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_6ValueChanged, true);
            app.FuncNameDropDown_6.Tag = 'FuncNameDownDown_6';
            app.TestButton_6 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_6.ButtonPushedFcn = createCallbackFcn(app, @TestButton_6Pushed, true);
            app.TestButton_6.Tag = 'testButton_6';
            app.TestButton_6.Text = 'Test';
            app.WritetodatabaseButton_6 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_6.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_6Pushed, true);
            app.WritetodatabaseButton_6.Tag = 'writetodatabaseButton_6';
            app.WritetodatabaseButton_6.Text = 'Write to database';
            app.Exporttoh5Button_6 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_6.Tag = 'exporttoh5Button_6';
            app.Exporttoh5Button_6.Text = 'Export to .h5';
            app.OverwriteCheckBox_6 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_6.Text = '';
            app.SaveButton_6 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_6.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_6Pushed, true);
            app.SaveButton_6.Tag = 'saveparamsButton_6';
            app.SaveButton_6.Text = 'Save';
            app.EditButton_6 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_6.ButtonPushedFcn = createCallbackFcn(app, @EditButton_6Pushed, true);
            app.EditButton_6.Tag = 'editparamsButton_6';
            app.EditButton_6.Text = 'Edit';
            app.ParamSetDropDown_6 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_6.Items = {};
            app.ParamSetDropDown_6.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_6ValueChanged, true);
            app.ParamSetDropDown_6.Tag = 'ParamSetDropDown_6';
            app.ParamSetDropDown_6.Value = {};
            app.FuncNameDropDown_7 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_7.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_7ValueChanged, true);
            app.FuncNameDropDown_7.Tag = 'FuncNameDownDown_7';
            app.TestButton_7 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_7.ButtonPushedFcn = createCallbackFcn(app, @TestButton_7Pushed, true);
            app.TestButton_7.Tag = 'testButton_7';
            app.TestButton_7.Text = 'Test';
            app.WritetodatabaseButton_7 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_7.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_7Pushed, true);
            app.WritetodatabaseButton_7.Tag = 'writetodatabaseButton_7';
            app.WritetodatabaseButton_7.Text = 'Write to database';
            app.Exporttoh5Button_7 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_7.Tag = 'exporttoh5Button_7';
            app.Exporttoh5Button_7.Text = 'Export to .h5';
            app.OverwriteCheckBox_7 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_7.Text = '';
            app.SaveButton_7 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_7.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_7Pushed, true);
            app.SaveButton_7.Tag = 'saveparamsButton_7';
            app.SaveButton_7.Text = 'Save';
            app.EditButton_7 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_7.ButtonPushedFcn = createCallbackFcn(app, @EditButton_7Pushed, true);
            app.EditButton_7.Tag = 'editparamsButton_7';
            app.EditButton_7.Text = 'Edit';
            app.ParamSetDropDown_7 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_7.Items = {};
            app.ParamSetDropDown_7.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_7ValueChanged, true);
            app.ParamSetDropDown_7.Tag = 'ParamSetDropDown_7';
            app.ParamSetDropDown_7.Value = {};
            app.FuncTypeDropDown_7 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_7.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_7.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_7ValueChanged, true);
            app.FuncTypeDropDown_7.Tag = 'FuncTypeDropDown_7';
            app.FuncTypeDropDown_7.Value = 'Epoch';
            app.LabelStep_7 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_7.Tag = 'FuncTypeDropDown_7';
            app.LabelStep_7.Text = '7';
            app.FuncNameDropDown_8 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_8.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_8ValueChanged, true);
            app.FuncNameDropDown_8.Tag = 'FuncNameDownDown_8';
            app.TestButton_8 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_8.ButtonPushedFcn = createCallbackFcn(app, @TestButton_8Pushed, true);
            app.TestButton_8.Tag = 'testButton_8';
            app.TestButton_8.Text = 'Test';
            app.WritetodatabaseButton_8 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_8.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_8Pushed, true);
            app.WritetodatabaseButton_8.Tag = 'writetodatabaseButton_8';
            app.WritetodatabaseButton_8.Text = 'Write to database';
            app.Exporttoh5Button_8 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_8.Tag = 'exporttoh5Button_8';
            app.Exporttoh5Button_8.Text = 'Export to .h5';
            app.OverwriteCheckBox_8 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_8.Text = '';
            app.SaveButton_8 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_8.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_8Pushed, true);
            app.SaveButton_8.Tag = 'saveparamsButton_8';
            app.SaveButton_8.Text = 'Save';
            app.EditButton_8 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_8.ButtonPushedFcn = createCallbackFcn(app, @EditButton_8Pushed, true);
            app.EditButton_8.Tag = 'editparamsButton_8';
            app.EditButton_8.Text = 'Edit';
            app.ParamSetDropDown_8 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_8.Items = {};
            app.ParamSetDropDown_8.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_8ValueChanged, true);
            app.ParamSetDropDown_8.Tag = 'ParamSetDropDown_8';
            app.ParamSetDropDown_8.Value = {};
            app.Label_9 = uilabel(app.BuildanalysisPanel);
            app.Label_9.Tag = 'FuncTypeDropDown_8';
            app.Label_9.Text = '';
            app.FuncTypeDropDown_8 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_8.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_8.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_8ValueChanged, true);
            app.FuncTypeDropDown_8.Tag = 'FuncTypeDropDown_8';
            app.FuncTypeDropDown_8.Value = 'Epoch';
            app.LabelStep_8 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_8.Tag = 'FuncTypeDropDown_8';
            app.LabelStep_8.Text = '8';
            app.FuncNameDropDown_9 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_9.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_9ValueChanged, true);
            app.FuncNameDropDown_9.Tag = 'FuncNameDownDown_9';
            app.TestButton_9 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_9.ButtonPushedFcn = createCallbackFcn(app, @TestButton_9Pushed, true);
            app.TestButton_9.Tag = 'testButton_9';
            app.TestButton_9.Text = 'Test';
            app.WritetodatabaseButton_9 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_9.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_9Pushed, true);
            app.WritetodatabaseButton_9.Tag = 'writetodatabaseButton_9';
            app.WritetodatabaseButton_9.Text = 'Write to database';
            app.Exporttoh5Button_9 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_9.Tag = 'exporttoh5Button_9';
            app.Exporttoh5Button_9.Text = 'Export to .h5';
            app.OverwriteCheckBox_9 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_9.Text = '';
            app.SaveButton_9 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_9.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_9Pushed, true);
            app.SaveButton_9.Tag = 'saveparamsButton_9';
            app.SaveButton_9.Text = 'Save';
            app.EditButton_9 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_9.ButtonPushedFcn = createCallbackFcn(app, @EditButton_9Pushed, true);
            app.EditButton_9.Tag = 'editparamsButton_9';
            app.EditButton_9.Text = 'Edit';
            app.ParamSetDropDown_9 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_9.Items = {};
            app.ParamSetDropDown_9.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_9ValueChanged, true);
            app.ParamSetDropDown_9.Tag = 'ParamSetDropDown_9';
            app.ParamSetDropDown_9.Value = {};
            app.FuncTypeDropDown_9 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_9.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_9.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_9ValueChanged, true);
            app.FuncTypeDropDown_9.Tag = 'FuncTypeDropDown_9';
            app.FuncTypeDropDown_9.Value = 'Epoch';
            app.LabelStep_9 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_9.Tag = 'FuncTypeDropDown_9';
            app.LabelStep_9.Text = '9';
            app.FuncNameDropDown_10 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_10.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_10ValueChanged, true);
            app.FuncNameDropDown_10.Tag = 'FuncNameDownDown_10';
            app.TestButton_10 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_10.ButtonPushedFcn = createCallbackFcn(app, @TestButton_10Pushed, true);
            app.TestButton_10.Tag = 'testButton_10';
            app.TestButton_10.Text = 'Test';
            app.WritetodatabaseButton_10 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_10.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_10Pushed, true);
            app.WritetodatabaseButton_10.Tag = 'writetodatabaseButton_10';
            app.WritetodatabaseButton_10.Text = 'Write to database';
            app.Exporttoh5Button_10 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_10.Tag = 'exporttoh5Button_10';
            app.Exporttoh5Button_10.Text = 'Export to .h5';
            app.OverwriteCheckBox_10 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_10.Text = '';
            app.SaveButton_10 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_10.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_10Pushed, true);
            app.SaveButton_10.Tag = 'saveparamsButton_10';
            app.SaveButton_10.Text = 'Save';
            app.EditButton_10 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_10.ButtonPushedFcn = createCallbackFcn(app, @EditButton_10Pushed, true);
            app.EditButton_10.Tag = 'editparamsButton_10';
            app.EditButton_10.Text = 'Edit';
            app.ParamSetDropDown_10 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_10.Items = {};
            app.ParamSetDropDown_10.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_10ValueChanged, true);
            app.ParamSetDropDown_10.Tag = 'ParamSetDropDown_10';
            app.ParamSetDropDown_10.Value = {};
            app.Label_13 = uilabel(app.BuildanalysisPanel);
            app.Label_13.Tag = 'FuncTypeDropDown_10';
            app.Label_13.Text = '';
            app.FuncTypeDropDown_10 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_10.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_10.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_10ValueChanged, true);
            app.FuncTypeDropDown_10.Tag = 'FuncTypeDropDown_10';
            app.FuncTypeDropDown_10.Value = 'Epoch';
            app.LabelStep_10 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_10.Tag = 'FuncTypeDropDown_10';
            app.LabelStep_10.Text = '10';
            app.FuncNameDropDown_11 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_11.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_11ValueChanged, true);
            app.FuncNameDropDown_11.Tag = 'FuncNameDownDown_11';
            app.TestButton_11 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_11.ButtonPushedFcn = createCallbackFcn(app, @TestButton_11Pushed, true);
            app.TestButton_11.Tag = 'testButton_11';
            app.TestButton_11.Text = 'Test';
            app.WritetodatabaseButton_11 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_11.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_11Pushed, true);
            app.WritetodatabaseButton_11.Tag = 'writetodatabaseButton_11';
            app.WritetodatabaseButton_11.Text = 'Write to database';
            app.Exporttoh5Button_11 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_11.Tag = 'exporttoh5Button_11';
            app.Exporttoh5Button_11.Text = 'Export to .h5';
            app.OverwriteCheckBox_11 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_11.Text = '';
            app.SaveButton_11 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_11.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_11Pushed, true);
            app.SaveButton_11.Tag = 'saveparamsButton_11';
            app.SaveButton_11.Text = 'Save';
            app.EditButton_11 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_11.ButtonPushedFcn = createCallbackFcn(app, @EditButton_11Pushed, true);
            app.EditButton_11.Tag = 'editparamsButton_11';
            app.EditButton_11.Text = 'Edit';
            app.ParamSetDropDown_11 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_11.Items = {};
            app.ParamSetDropDown_11.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_11ValueChanged, true);
            app.ParamSetDropDown_11.Tag = 'ParamSetDropDown_11';
            app.ParamSetDropDown_11.Value = {};
            app.Label_15 = uilabel(app.BuildanalysisPanel);
            app.Label_15.Tag = 'FuncTypeDropDown_11';
            app.Label_15.Text = '';
            app.LabelStep_11 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_11.Tag = 'FuncTypeDropDown_11';
            app.LabelStep_11.Text = '11';
            app.FuncTypeDropDown_11 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_11.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_11.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_11ValueChanged, true);
            app.FuncTypeDropDown_11.Tag = 'FuncTypeDropDown_11';
            app.FuncTypeDropDown_11.Value = 'Epoch';
            app.FuncNameDropDown_12 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_12.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_12ValueChanged, true);
            app.FuncNameDropDown_12.Tag = 'FuncNameDownDown_12';
            app.TestButton_12 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_12.ButtonPushedFcn = createCallbackFcn(app, @TestButton_12Pushed, true);
            app.TestButton_12.Tag = 'testButton_12';
            app.TestButton_12.Text = 'Test';
            app.WritetodatabaseButton_12 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_12.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_12Pushed, true);
            app.WritetodatabaseButton_12.Tag = 'writetodatabaseButton_12';
            app.WritetodatabaseButton_12.Text = 'Write to database';
            app.Exporttoh5Button_12 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_12.Tag = 'exporttoh5Button_12';
            app.Exporttoh5Button_12.Text = 'Export to .h5';
            app.OverwriteCheckBox_12 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_12.Text = '';
            app.SaveButton_12 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_12.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_12Pushed, true);
            app.SaveButton_12.Tag = 'saveparamsButton_12';
            app.SaveButton_12.Text = 'Save';
            app.EditButton_12 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_12.ButtonPushedFcn = createCallbackFcn(app, @EditButton_12Pushed, true);
            app.EditButton_12.Tag = 'editparamsButton_12';
            app.EditButton_12.Text = 'Edit';
            app.ParamSetDropDown_12 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_12.Items = {};
            app.ParamSetDropDown_12.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_12ValueChanged, true);
            app.ParamSetDropDown_12.Tag = 'ParamSetDropDown_12';
            app.ParamSetDropDown_12.Value = {};
            app.Label_17 = uilabel(app.BuildanalysisPanel);
            app.Label_17.Tag = 'FuncTypeDropDown_12';
            app.Label_17.Text = '';
            app.FuncTypeDropDown_12 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_12.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_12.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_12ValueChanged, true);
            app.FuncTypeDropDown_12.Tag = 'FuncTypeDropDown_12';
            app.FuncTypeDropDown_12.Value = 'Epoch';
            app.LabelStep_12 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_12.Tag = 'FuncTypeDropDown_12';
            app.LabelStep_12.Text = '12';
            app.FuncNameDropDown_13 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_13.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_13ValueChanged, true);
            app.FuncNameDropDown_13.Tag = 'FuncNameDownDown_13';
            app.TestButton_13 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_13.ButtonPushedFcn = createCallbackFcn(app, @TestButton_13Pushed, true);
            app.TestButton_13.Tag = 'testButton_13';
            app.TestButton_13.Text = 'Test';
            app.WritetodatabaseButton_13 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_13.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_13Pushed, true);
            app.WritetodatabaseButton_13.Tag = 'writetodatabaseButton_13';
            app.WritetodatabaseButton_13.Text = 'Write to database';
            app.Exporttoh5Button_13 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_13.Tag = 'exporttoh5Button_13';
            app.Exporttoh5Button_13.Text = 'Export to .h5';
            app.OverwriteCheckBox_13 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_13.Text = '';
            app.SaveButton_13 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_13.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_13Pushed, true);
            app.SaveButton_13.Tag = 'saveparamsButton_13';
            app.SaveButton_13.Text = 'Save';
            app.EditButton_13 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_13.ButtonPushedFcn = createCallbackFcn(app, @EditButton_13Pushed, true);
            app.EditButton_13.Tag = 'editparamsButton_13';
            app.EditButton_13.Text = 'Edit';
            app.ParamSetDropDown_13 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_13.Items = {};
            app.ParamSetDropDown_13.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_13ValueChanged, true);
            app.ParamSetDropDown_13.Tag = 'ParamSetDropDown_13';
            app.ParamSetDropDown_13.Value = {};
            app.Label_19 = uilabel(app.BuildanalysisPanel);
            app.Label_19.Tag = 'FuncTypeDropDown_13';
            app.Label_19.Text = '';
            app.FuncTypeDropDown_13 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_13.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_13.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_13ValueChanged, true);
            app.FuncTypeDropDown_13.Tag = 'FuncTypeDropDown_13';
            app.FuncTypeDropDown_13.Value = 'Epoch';
            app.LabelStep_13 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_13.Tag = 'FuncTypeDropDown_13';
            app.LabelStep_13.Text = '13';
            app.FuncNameDropDown_14 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_14.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_14ValueChanged, true);
            app.FuncNameDropDown_14.Tag = 'FuncNameDownDown_14';
            app.TestButton_14 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_14.ButtonPushedFcn = createCallbackFcn(app, @TestButton_14Pushed, true);
            app.TestButton_14.Tag = 'testButton_14';
            app.TestButton_14.Text = 'Test';
            app.WritetodatabaseButton_14 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_14.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_14Pushed, true);
            app.WritetodatabaseButton_14.Tag = 'writetodatabaseButton_14';
            app.WritetodatabaseButton_14.Text = 'Write to database';
            app.Exporttoh5Button_14 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_14.Tag = 'exporttoh5Button_14';
            app.Exporttoh5Button_14.Text = 'Export to .h5';
            app.OverwriteCheckBox_14 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_14.Text = '';
            app.SaveButton_14 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_14.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_14Pushed, true);
            app.SaveButton_14.Tag = 'saveparamsButton_14';
            app.SaveButton_14.Text = 'Save';
            app.EditButton_14 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_14.ButtonPushedFcn = createCallbackFcn(app, @EditButton_14Pushed, true);
            app.EditButton_14.Tag = 'editparamsButton_14';
            app.EditButton_14.Text = 'Edit';
            app.ParamSetDropDown_14 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_14.Items = {};
            app.ParamSetDropDown_14.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_14ValueChanged, true);
            app.ParamSetDropDown_14.Tag = 'ParamSetDropDown_14';
            app.ParamSetDropDown_14.Value = {};
            app.Label_21 = uilabel(app.BuildanalysisPanel);
            app.Label_21.Tag = 'FuncTypeDropDown_14';
            app.Label_21.Text = '';
            app.FuncTypeDropDown_14 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_14.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_14.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_14ValueChanged, true);
            app.FuncTypeDropDown_14.Tag = 'FuncTypeDropDown_14';
            app.FuncTypeDropDown_14.Value = 'Epoch';
            app.LabelStep_14 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_14.Tag = 'FuncTypeDropDown_14';
            app.LabelStep_14.Text = '14';
            app.FuncNameDropDown_15 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_15.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_15ValueChanged, true);
            app.FuncNameDropDown_15.Tag = 'FuncNameDownDown_15';
            app.TestButton_15 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_15.ButtonPushedFcn = createCallbackFcn(app, @TestButton_15Pushed, true);
            app.TestButton_15.Tag = 'testButton_15';
            app.TestButton_15.Text = 'Test';
            app.WritetodatabaseButton_15 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_15.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_15Pushed, true);
            app.WritetodatabaseButton_15.Tag = 'writetodatabaseButton_15';
            app.WritetodatabaseButton_15.Text = 'Write to database';
            app.Exporttoh5Button_15 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_15.Tag = 'exporttoh5Button_15';
            app.Exporttoh5Button_15.Text = 'Export to .h5';
            app.OverwriteCheckBox_15 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_15.Text = '';
            app.SaveButton_15 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_15.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_15Pushed, true);
            app.SaveButton_15.Tag = 'saveparamsButton_15';
            app.SaveButton_15.Text = 'Save';
            app.EditButton_15 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_15.ButtonPushedFcn = createCallbackFcn(app, @EditButton_15Pushed, true);
            app.EditButton_15.Tag = 'editparamsButton_15';
            app.EditButton_15.Text = 'Edit';
            app.ParamSetDropDown_15 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_15.Items = {};
            app.ParamSetDropDown_15.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_15ValueChanged, true);
            app.ParamSetDropDown_15.Tag = 'ParamSetDropDown_15';
            app.ParamSetDropDown_15.Value = {};
            app.Label_23 = uilabel(app.BuildanalysisPanel);
            app.Label_23.Tag = 'FuncTypeDropDown_15';
            app.Label_23.Text = '';
            app.FuncTypeDropDown_15 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_15.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_15.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_15ValueChanged, true);
            app.FuncTypeDropDown_15.Tag = 'FuncTypeDropDown_15';
            app.FuncTypeDropDown_15.Value = 'Epoch';
            app.LabelStep_15 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_15.Tag = 'FuncTypeDropDown_15';
            app.LabelStep_15.Text = '15';
            app.FuncNameDropDown_16 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_16.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_16ValueChanged, true);
            app.FuncNameDropDown_16.Tag = 'FuncNameDownDown_16';
            app.TestButton_16 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_16.ButtonPushedFcn = createCallbackFcn(app, @TestButton_16Pushed, true);
            app.TestButton_16.Tag = 'testButton_16';
            app.TestButton_16.Text = 'Test';
            app.WritetodatabaseButton_16 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_16.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_16Pushed, true);
            app.WritetodatabaseButton_16.Tag = 'writetodatabaseButton_16';
            app.WritetodatabaseButton_16.Text = 'Write to database';
            app.Exporttoh5Button_16 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_16.Tag = 'exporttoh5Button_16';
            app.Exporttoh5Button_16.Text = 'Export to .h5';
            app.OverwriteCheckBox_16 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_16.Text = '';
            app.SaveButton_16 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_16.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_16Pushed, true);
            app.SaveButton_16.Tag = 'saveparamsButton_16';
            app.SaveButton_16.Text = 'Save';
            app.EditButton_16 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_16.ButtonPushedFcn = createCallbackFcn(app, @EditButton_16Pushed, true);
            app.EditButton_16.Tag = 'editparamsButton_16';
            app.EditButton_16.Text = 'Edit';
            app.ParamSetDropDown_16 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_16.Items = {};
            app.ParamSetDropDown_16.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_16ValueChanged, true);
            app.ParamSetDropDown_16.Tag = 'ParamSetDropDown_16';
            app.ParamSetDropDown_16.Value = {};
            app.Label_25 = uilabel(app.BuildanalysisPanel);
            app.Label_25.Tag = 'FuncTypeDropDown_16';
            app.Label_25.Text = '';
            app.FuncTypeDropDown_16 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_16.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_16.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_16ValueChanged, true);
            app.FuncTypeDropDown_16.Tag = 'FuncTypeDropDown_16';
            app.FuncTypeDropDown_16.Value = 'Epoch';
            app.LabelStep_16 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_16.Tag = 'FuncTypeDropDown_16';
            app.LabelStep_16.Text = '16';
            app.FuncNameDropDown_17 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_17.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_17ValueChanged, true);
            app.FuncNameDropDown_17.Tag = 'FuncNameDownDown_17';
            app.TestButton_17 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_17.ButtonPushedFcn = createCallbackFcn(app, @TestButton_17Pushed, true);
            app.TestButton_17.Tag = 'testButton_17';
            app.TestButton_17.Text = 'Test';
            app.WritetodatabaseButton_17 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_17.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_17Pushed, true);
            app.WritetodatabaseButton_17.Tag = 'writetodatabaseButton_17';
            app.WritetodatabaseButton_17.Text = 'Write to database';
            app.Exporttoh5Button_17 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_17.Tag = 'exporttoh5Button_17';
            app.Exporttoh5Button_17.Text = 'Export to .h5';
            app.OverwriteCheckBox_17 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_17.Text = '';
            app.SaveButton_17 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_17.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_17Pushed, true);
            app.SaveButton_17.Tag = 'saveparamsButton_17';
            app.SaveButton_17.Text = 'Save';
            app.EditButton_17 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_17.ButtonPushedFcn = createCallbackFcn(app, @EditButton_17Pushed, true);
            app.EditButton_17.Tag = 'editparamsButton_17';
            app.EditButton_17.Text = 'Edit';
            app.ParamSetDropDown_17 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_17.Items = {};
            app.ParamSetDropDown_17.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_17ValueChanged, true);
            app.ParamSetDropDown_17.Tag = 'ParamSetDropDown_17';
            app.ParamSetDropDown_17.Value = {};
            app.Label_27 = uilabel(app.BuildanalysisPanel);
            app.Label_27.Tag = 'FuncTypeDropDown_17';
            app.Label_27.Text = '';
            app.FuncTypeDropDown_17 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_17.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_17.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_17ValueChanged, true);
            app.FuncTypeDropDown_17.Tag = 'FuncTypeDropDown_17';
            app.FuncTypeDropDown_17.Value = 'Epoch';
            app.LabelStep_17 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_17.Tag = 'FuncTypeDropDown_17';
            app.LabelStep_17.Text = '17';
            app.FuncNameDropDown_18 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_18.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_18ValueChanged, true);
            app.FuncNameDropDown_18.Tag = 'FuncNameDownDown_18';
            app.TestButton_18 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_18.ButtonPushedFcn = createCallbackFcn(app, @TestButton_18Pushed, true);
            app.TestButton_18.Tag = 'testButton_18';
            app.TestButton_18.Text = 'Test';
            app.WritetodatabaseButton_18 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_18.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_18Pushed, true);
            app.WritetodatabaseButton_18.Tag = 'writetodatabaseButton_18';
            app.WritetodatabaseButton_18.Text = 'Write to database';
            app.Exporttoh5Button_18 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_18.Tag = 'exporttoh5Button_18';
            app.Exporttoh5Button_18.Text = 'Export to .h5';
            app.OverwriteCheckBox_18 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_18.Text = '';
            app.SaveButton_18 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_18.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_18Pushed, true);
            app.SaveButton_18.Tag = 'saveparamsButton_18';
            app.SaveButton_18.Text = 'Save';
            app.EditButton_18 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_18.ButtonPushedFcn = createCallbackFcn(app, @EditButton_18Pushed, true);
            app.EditButton_18.Tag = 'editparamsButton_18';
            app.EditButton_18.Text = 'Edit';
            app.ParamSetDropDown_18 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_18.Items = {};
            app.ParamSetDropDown_18.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_18ValueChanged, true);
            app.ParamSetDropDown_18.Tag = 'ParamSetDropDown_18';
            app.ParamSetDropDown_18.Value = {};
            app.Label_29 = uilabel(app.BuildanalysisPanel);
            app.Label_29.Tag = 'FuncTypeDropDown_18';
            app.Label_29.Text = '';
            app.FuncTypeDropDown_18 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_18.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_18.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_18ValueChanged, true);
            app.FuncTypeDropDown_18.Tag = 'FuncTypeDropDown_18';
            app.FuncTypeDropDown_18.Value = 'Epoch';
            app.LabelStep_18 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_18.Tag = 'FuncTypeDropDown_18';
            app.LabelStep_18.Text = '18';
            app.FuncNameDropDown_19 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_19.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_19ValueChanged, true);
            app.FuncNameDropDown_19.Tag = 'FuncNameDownDown_19';
            app.TestButton_19 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_19.ButtonPushedFcn = createCallbackFcn(app, @TestButton_19Pushed, true);
            app.TestButton_19.Tag = 'testButton_19';
            app.TestButton_19.Text = 'Test';
            app.WritetodatabaseButton_19 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_19.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_19Pushed, true);
            app.WritetodatabaseButton_19.Tag = 'writetodatabaseButton_19';
            app.WritetodatabaseButton_19.Text = 'Write to database';
            app.Exporttoh5Button_19 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_19.Tag = 'exporttoh5Button_19';
            app.Exporttoh5Button_19.Text = 'Export to .h5';
            app.OverwriteCheckBox_19 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_19.Text = '';
            app.SaveButton_19 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_19.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_19Pushed, true);
            app.SaveButton_19.Tag = 'saveparamsButton_19';
            app.SaveButton_19.Text = 'Save';
            app.EditButton_19 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_19.ButtonPushedFcn = createCallbackFcn(app, @EditButton_19Pushed, true);
            app.EditButton_19.Tag = 'editparamsButton_19';
            app.EditButton_19.Text = 'Edit';
            app.ParamSetDropDown_19 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_19.Items = {};
            app.ParamSetDropDown_19.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_19ValueChanged, true);
            app.ParamSetDropDown_19.Tag = 'ParamSetDropDown_19';
            app.ParamSetDropDown_19.Value = {};
            app.LabelStep_19 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_19.Tag = 'FuncTypeDropDown_19';
            app.LabelStep_19.Text = '19';
            app.FuncTypeDropDown_19 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_19.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_19.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_19ValueChanged, true);
            app.FuncTypeDropDown_19.Tag = 'FuncTypeDropDown_19';
            app.FuncTypeDropDown_19.Value = 'Epoch';
            app.FuncNameDropDown_20 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_20.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_20ValueChanged, true);
            app.FuncNameDropDown_20.Tag = 'FuncNameDownDown_20';
            app.TestButton_20 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_20.ButtonPushedFcn = createCallbackFcn(app, @TestButton_20Pushed, true);
            app.TestButton_20.Tag = 'testButton_20';
            app.TestButton_20.Text = 'Test';
            app.WritetodatabaseButton_20 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_20.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_20Pushed, true);
            app.WritetodatabaseButton_20.Tag = 'writetodatabaseButton_20';
            app.WritetodatabaseButton_20.Text = 'Write to database';
            app.Exporttoh5Button_20 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_20.Tag = 'exporttoh5Button_20';
            app.Exporttoh5Button_20.Text = 'Export to .h5';
            app.OverwriteCheckBox_20 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_20.Text = '';
            app.SaveButton_20 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_20.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_20Pushed, true);
            app.SaveButton_20.Tag = 'saveparamsButton_20';
            app.SaveButton_20.Text = 'Save';
            app.EditButton_20 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_20.ButtonPushedFcn = createCallbackFcn(app, @EditButton_20Pushed, true);
            app.EditButton_20.Tag = 'editparamsButton_20';
            app.EditButton_20.Text = 'Edit';
            app.ParamSetDropDown_20 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_20.Items = {};
            app.ParamSetDropDown_20.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_20ValueChanged, true);
            app.ParamSetDropDown_20.Tag = 'ParamSetDropDown_20';
            app.ParamSetDropDown_20.Value = {};
            app.FuncTypeDropDown_20 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_20.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_20.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_20ValueChanged, true);
            app.FuncTypeDropDown_20.Tag = 'FuncTypeDropDown_20';
            app.FuncTypeDropDown_20.Value = 'Epoch';
            app.LabelStep_20 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_20.Tag = 'FuncTypeDropDown_20';
            app.LabelStep_20.Text = '20';
            app.FuncTypeDropDown_4 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_4.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_4.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_4ValueChanged, true);
            app.FuncTypeDropDown_4.Tag = 'FuncTypeDropDown_4';
            app.FuncTypeDropDown_4.Value = 'Epoch';
            app.FuncTypeDropDown_5 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_5.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_5.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_5ValueChanged, true);
            app.FuncTypeDropDown_5.Tag = 'FuncTypeDropDown_5';
            app.FuncTypeDropDown_5.Value = 'Epoch';
            app.FuncTypeDropDown_6 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_6.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_6.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_6ValueChanged, true);
            app.FuncTypeDropDown_6.Tag = 'FuncTypeDropDown_6';
            app.FuncTypeDropDown_6.Value = 'Epoch';
            app.LabelStep_4 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_4.Tag = 'FuncTypeDropDown_3';
            app.LabelStep_4.Text = '4';
            app.LabelStep_5 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_5.Tag = 'FuncTypeDropDown_3';
            app.LabelStep_5.Text = '5';
            app.LabelStep_6 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_6.Tag = 'FuncTypeDropDown_3';
            app.LabelStep_6.Text = '6';
            app.LabelStep_1 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_1.Tag = 'FuncTypeDropDown_2';
            app.LabelStep_1.Text = '1';
            app.AttachanalysisstepstopipelineButton = uibutton(app.BuildanalysisPanel, 'push');
            app.AttachanalysisstepstopipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AttachanalysisstepstopipelineButtonPushed, true);
            app.AttachanalysisstepstopipelineButton.Text = 'Attach analysis steps to pipeline';
            app.LabelStep_2 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_2.Tag = 'FuncTypeDropDown_2';
            app.LabelStep_2.Text = '2';
            app.FuncTypeDropDown_2 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_2.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_2ValueChanged2, true);
            app.FuncTypeDropDown_2.Tag = 'FuncTypeDropDown_2';
            app.FuncTypeDropDown_2.Value = 'Epoch';
            app.FuncNameDropDown_2 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_2ValueChanged2, true);
            app.FuncNameDropDown_2.Tag = 'FuncNameDownDown_2';
            app.ParamSetDropDown_2 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_2.Items = {};
            app.ParamSetDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_2ValueChanged2, true);
            app.ParamSetDropDown_2.Tag = 'ParamSetDropDown_2';
            app.ParamSetDropDown_2.Value = {};
            app.TestButton_2 = uibutton(app.BuildanalysisPanel, 'push');
            app.TestButton_2.ButtonPushedFcn = createCallbackFcn(app, @TestButton_2Pushed2, true);
            app.TestButton_2.Tag = 'testButton_2';
            app.TestButton_2.Text = 'Test';
            app.WritetodatabaseButton_2 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_2.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_2Pushed2, true);
            app.WritetodatabaseButton_2.Tag = 'writetodatabaseButton_2';
            app.WritetodatabaseButton_2.Text = 'Write to database';
            app.SaveButton_2 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed2, true);
            app.SaveButton_2.Tag = 'saveparamsButton_2';
            app.SaveButton_2.Text = 'Save';
            app.EditButton_2 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_2.ButtonPushedFcn = createCallbackFcn(app, @EditButton_2Pushed2, true);
            app.EditButton_2.Tag = 'editparamsButton_2';
            app.EditButton_2.Text = 'Edit';
            app.OverwriteCheckBox_2 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteCheckBox_2.Tag = 'OverwriteCheckBox_2';
            app.OverwriteCheckBox_2.Text = '';
            app.Exporttoh5Button_2 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_2.Tag = 'exporttoh5Button_2';
            app.Exporttoh5Button_2.Text = 'Export to .h5';
            app.PipelinePanel = uipanel(app.AnalysisPipelineBuilderUIFigure);
            app.PipelinePanel.Title = 'Pipeline';
            app.SwitchpipelineButton = uibutton(app.PipelinePanel, 'push');
            app.SwitchpipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchpipelineButtonPushed, true);
            app.SwitchpipelineButton.Text = 'Switch pipeline';
            app.RunButton_Full = uibutton(app.PipelinePanel, 'push');
            app.RunButton_Full.Text = 'Run';
            app.h5exporttoolButton = uibutton(app.PipelinePanel, 'push');
            app.h5exporttoolButton.Text = '.h5 export tool';
            app.overwriteCheckBox_Full = uicheckbox(app.PipelinePanel);
            app.overwriteCheckBox_Full.Text = 'overwrite';
            app.SaveasnewpipelineButton = uibutton(app.PipelinePanel, 'push');
            app.SaveasnewpipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasnewpipelineButtonPushed, true);
            app.SaveasnewpipelineButton.Text = 'Save as new pipeline';
            app.LoaddataPanel = uipanel(app.AnalysisPipelineBuilderUIFigure);
            app.LoaddataPanel.Title = 'Load data';
            app.CellsListBox_2Label = uilabel(app.LoaddataPanel);
            app.CellsListBox_2Label.Text = 'Cells';
            app.CellsListBox_L = uilistbox(app.LoaddataPanel);
            app.CellsListBox_L.Items = {};
            app.CellsListBox_L.ValueChangedFcn = createCallbackFcn(app, @CellsListBox_LValueChanged, true);
            app.CellsListBox_L.Value = {};
            app.SymphonydatasetsListBox_2Label = uilabel(app.LoaddataPanel);
            app.SymphonydatasetsListBox_2Label.Text = 'Symphony datasets';
            app.SymphonydatasetsListBox_L = uilistbox(app.LoaddataPanel);
            app.SymphonydatasetsListBox_L.Items = {};
            app.SymphonydatasetsListBox_L.Value = {};
            app.OtherdataListBox_2Label = uilabel(app.LoaddataPanel);
            app.OtherdataListBox_2Label.Text = 'Other data';
            app.OtherdataListBox_L = uilistbox(app.LoaddataPanel);
            app.OtherdataListBox_L.Items = {};
            app.OtherdataListBox_L.Value = {};
            app.AnimalsListBox_2Label = uilabel(app.LoaddataPanel);
            app.AnimalsListBox_2Label.Text = 'Animals';
            app.AnimalsListBox_L = uilistbox(app.LoaddataPanel);
            app.AnimalsListBox_L.Items = {};
            app.AnimalsListBox_L.ValueChangedFcn = createCallbackFcn(app, @AnimalsListBox_LValueChanged, true);
            app.AnimalsListBox_L.Value = {};
            app.TotalsPanel_3 = uipanel(app.LoaddataPanel);
            app.TotalsPanel_3.Title = 'Totals';
            app.animalsEditField_2Label_2 = uilabel(app.TotalsPanel_3);
            app.animalsEditField_2Label_2.Text = 'animals';
            app.animalsEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.animalsEditField_L.Editable = 'off';
            app.cellsEditField_3Label_2 = uilabel(app.TotalsPanel_3);
            app.cellsEditField_3Label_2.Text = 'cells';
            app.cellsEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.cellsEditField_L.Editable = 'off';
            app.epochsEditField_4Label_2 = uilabel(app.TotalsPanel_3);
            app.epochsEditField_4Label_2.Text = 'epochs';
            app.epochsEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.epochsEditField_L.Editable = 'off';
            app.eyesEditField_2Label_2 = uilabel(app.TotalsPanel_3);
            app.eyesEditField_2Label_2.Text = 'eyes';
            app.eyesEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.eyesEditField_L.Editable = 'off';
            app.datasetsEditField_4Label_2 = uilabel(app.TotalsPanel_3);
            app.datasetsEditField_4Label_2.Text = 'datasets';
            app.datasetsEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.datasetsEditField_L.Editable = 'off';
            app.otherfilesEditField_4Label_2 = uilabel(app.TotalsPanel_3);
            app.otherfilesEditField_4Label_2.Text = 'other files';
            app.otherfilesEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.otherfilesEditField_L.Editable = 'off';
            app.AlltoWGButton = uibutton(app.TotalsPanel_3, 'push');
            app.AlltoWGButton.ButtonPushedFcn = createCallbackFcn(app, @AlltoWGButtonPushed, true);
            app.AlltoWGButton.Text = 'All to WG';
            app.ClearLoadedButton = uibutton(app.TotalsPanel_3, 'push');
            app.ClearLoadedButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLoadedButtonPushed, true);
            app.ClearLoadedButton.Text = 'Clear';
            app.LoadqueryButton = uibutton(app.LoaddataPanel, 'push');
            app.LoadqueryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadqueryButtonPushed, true);
            app.LoadqueryButton.Text = 'Load query';
            app.QueryeditorButton = uibutton(app.LoaddataPanel, 'push');
            app.QueryeditorButton.ButtonPushedFcn = createCallbackFcn(app, @QueryeditorButtonPushed, true);
            app.QueryeditorButton.Text = 'Query editor';
            app.LoadDataGroupButton = uibutton(app.LoaddataPanel, 'push');
            app.LoadDataGroupButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataGroupButtonPushed, true);
            app.LoadDataGroupButton.Text = 'Load DataGroup';
            app.LoadcellnamesButton = uibutton(app.LoaddataPanel, 'push');
            app.LoadcellnamesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcellnamesButtonPushed, true);
            app.LoadcellnamesButton.Text = 'Load cell names';
            app.LoadsinglecellButton = uibutton(app.LoaddataPanel, 'push');
            app.LoadsinglecellButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsinglecellButtonPushed, true);
            app.LoadsinglecellButton.Text = 'Load single cell';
            app.cell_idEditField = uieditfield(app.LoaddataPanel, 'text');
            app.AttachotherdataButton_2 = uibutton(app.LoaddataPanel, 'push');
            app.AttachotherdataButton_2.Text = 'Attach other data';
            app.FilterbyepochfunctionButton = uibutton(app.LoaddataPanel, 'push');
            app.FilterbyepochfunctionButton.ButtonPushedFcn = createCallbackFcn(app, @FilterbyepochfunctionButtonPushed, true);
            app.FilterbyepochfunctionButton.Text = 'Filter by epoch function';
            app.AnimaltoWGButton = uibutton(app.LoaddataPanel, 'push');
            app.AnimaltoWGButton.ButtonPushedFcn = createCallbackFcn(app, @AnimaltoWGButtonPushed, true);
            app.AnimaltoWGButton.Text = 'Animal to WG';
            app.CelltoWGButton = uibutton(app.LoaddataPanel, 'push');
            app.CelltoWGButton.ButtonPushedFcn = createCallbackFcn(app, @CelltoWGButtonPushed, true);
            app.CelltoWGButton.Text = 'Cell to WG';
            app.DatasettoWGButton = uibutton(app.LoaddataPanel, 'push');
            app.DatasettoWGButton.ButtonPushedFcn = createCallbackFcn(app, @DatasettoWGButtonPushed, true);
            app.DatasettoWGButton.Text = 'Dataset to WG';
            app.OtherfilestoWGButton = uibutton(app.LoaddataPanel, 'push');
            app.OtherfilestoWGButton.Text = 'Other files to WG';
            app.MergecompatibleLampLabel = uilabel(app.LoaddataPanel);
            app.MergecompatibleLampLabel.Text = 'Merge compatible';
            app.MergecompatibleLamp = uilamp(app.LoaddataPanel);
            app.AttachquerytopipelineButton = uibutton(app.LoaddataPanel, 'push');
            app.AttachquerytopipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AttachquerytopipelineButtonPushed, true);
            app.AttachquerytopipelineButton.Text = 'Attach query to pipeline';
            app.QueryTextAreaLabel = uilabel(app.LoaddataPanel);
            app.QueryTextAreaLabel.Text = 'Query';
            app.QueryTextArea = uitextarea(app.LoaddataPanel);
            % Show the figure after all components are created
            app.AnalysisPipelineBuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisPipelineBuilder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalysisPipelineBuilderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisPipelineBuilderUIFigure)
        end
    end
end