classdef AnimalInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainTabGroup                   matlab.ui.container.TabGroup
        LoginTab                       matlab.ui.container.Tab
        PasswordEditFieldLabel         matlab.ui.control.Label
        PasswordEditField              matlab.ui.control.EditField
        LoginButton                    matlab.ui.control.Button
        UserEditFieldLabel             matlab.ui.control.Label
        LoginUserEditField             matlab.ui.control.EditField
        AnimalLoggerTab                matlab.ui.container.Tab
        AnimalTable                    matlab.ui.control.Table
        EventTabGroup                  matlab.ui.container.TabGroup
        GenotypedTab                   matlab.ui.container.Tab
        ResultsDropDownLabel           matlab.ui.control.Label
        GenotypeResultsDropDown        matlab.ui.control.DropDown
        DeceasedTab                    matlab.ui.container.Tab
        ReasonDropDownLabel            matlab.ui.control.Label
        DeceasedReasonDropDown         matlab.ui.control.DropDown
        GavebirthTab                   matlab.ui.container.Tab
        NumberofpupsEditFieldLabel     matlab.ui.control.Label
        NumberofpupsEditField          matlab.ui.control.EditField
        AssigncageTab                  matlab.ui.container.Tab
        ReasonDropDown_2Label          matlab.ui.control.Label
        MoveReasonDropDown             matlab.ui.control.DropDown
        TocageEditFieldLabel           matlab.ui.control.Label
        TocageEditField                matlab.ui.control.EditField
        IPinjectionTab                 matlab.ui.container.Tab
        SubstanceDropDownLabel         matlab.ui.control.Label
        SubstanceDropDown_IP           matlab.ui.control.DropDown
        ConcentrationmgKgEditFieldLabel_2  matlab.ui.control.Label
        IP_inj_ConcentrationEditField  matlab.ui.control.EditField
        EyeinjectionTab                matlab.ui.container.Tab
        SubstanceDropDownLabel_2       matlab.ui.control.Label
        SubstanceDropDown_Eye          matlab.ui.control.DropDown
        EyeDropDownLabel               matlab.ui.control.Label
        WhichEyeDropDown               matlab.ui.control.DropDown
        Dilution1XEditFieldLabel_2     matlab.ui.control.Label
        Dilution_EyeEditField          matlab.ui.control.EditField
        BraininjectionTab              matlab.ui.container.Tab
        SubstanceDropDown_3Label       matlab.ui.control.Label
        SubstanceDropDown_Brain        matlab.ui.control.DropDown
        CoordinatesLabel               matlab.ui.control.Label
        TargetDropDownLabel            matlab.ui.control.Label
        BrainTargetDropDown            matlab.ui.control.DropDown
        HemisphereDropDownLabel        matlab.ui.control.Label
        HemisphereDropDown             matlab.ui.control.DropDown
        Dilution1XEditFieldLabel_3     matlab.ui.control.Label
        Dilution_BrainEditField        matlab.ui.control.EditField
        APEditField_2Label             matlab.ui.control.Label
        APEditField                    matlab.ui.control.EditField
        MLEditField_2Label             matlab.ui.control.Label
        MLEditField                    matlab.ui.control.EditField
        DVEditField_2Label             matlab.ui.control.Label
        DVEditField                    matlab.ui.control.EditField
        AngleEditField_2Label          matlab.ui.control.Label
        AngleEditField                 matlab.ui.control.EditField
        HandledTab                     matlab.ui.container.Tab
        DurtionminsEditFieldLabel      matlab.ui.control.Label
        HandlingDurationEditField      matlab.ui.control.EditField
        SocialbehaviorsessionTab       matlab.ui.container.Tab
        RootfilenameEditFieldLabel     matlab.ui.control.Label
        SocialB_filenameEditField      matlab.ui.control.EditField
        RecordBehavior_switch          matlab.ui.control.RockerSwitch
        StimulusanimalIDs0foremptyLabel  matlab.ui.control.Label
        Lamp_isRecorded                matlab.ui.control.Lamp
        ExperimenttypeDropDownLabel    matlab.ui.control.Label
        ExperimenttypeDropDown         matlab.ui.control.DropDown
        DurationminsEditFieldLabel_2   matlab.ui.control.Label
        SocialB_DurationminsEditField  matlab.ui.control.EditField
        AEditFieldLabel_2              matlab.ui.control.Label
        AnimalAEditField               matlab.ui.control.EditField
        BLabel                         matlab.ui.control.Label
        AnimalBEditField               matlab.ui.control.EditField
        CLabel                         matlab.ui.control.Label
        AnimalCEditField               matlab.ui.control.EditField
        ReserveforprojectTab           matlab.ui.container.Tab
        ProjectDropDownLabel           matlab.ui.control.Label
        ProjectDropDown                matlab.ui.control.DropDown
        ReserveforrigTab               matlab.ui.container.Tab
        RignameDropDownLabel           matlab.ui.control.Label
        RignameDropDown                matlab.ui.control.DropDown
        CalendarrefreshtokenEditFieldLabel  matlab.ui.control.Label
        CalendarrefreshtokenEditField  matlab.ui.control.EditField
        ReauthorizeButton              matlab.ui.control.Button
        AnimalSelectionPanel           matlab.ui.container.Panel
        SearchButton                   matlab.ui.control.Button
        FilterPanel                    matlab.ui.container.Panel
        GenotypeDropDownLabel          matlab.ui.control.Label
        Filter_GenotypeDropDown        matlab.ui.control.DropDown
        StatusDropDownLabel            matlab.ui.control.Label
        Filter_GenotypeStatusDropDown  matlab.ui.control.DropDown
        EyeInjSwitchLabel              matlab.ui.control.Label
        EyeInjSwitch                   matlab.ui.control.ToggleSwitch
        BehaviorexpSwitchLabel         matlab.ui.control.Label
        BehaviorexpSwitch              matlab.ui.control.ToggleSwitch
        SexDropDownLabel               matlab.ui.control.Label
        Filter_SexDropDown             matlab.ui.control.DropDown
        BraininjSwitchLabel            matlab.ui.control.Label
        BraininjSwitch                 matlab.ui.control.ToggleSwitch
        NotreservedSwitchLabel         matlab.ui.control.Label
        NotreservedSwitch              matlab.ui.control.ToggleSwitch
        ProjectLabel                   matlab.ui.control.Label
        Filter_ProjectDropDown         matlab.ui.control.DropDown
        ResetButton                    matlab.ui.control.Button
        ID_type_switch                 matlab.ui.control.Switch
        AgerangeweeksPanel             matlab.ui.container.Panel
        toLabel                        matlab.ui.control.Label
        ageLow_edit                    matlab.ui.control.EditField
        ageHigh_edit                   matlab.ui.control.EditField
        Search_DJIDEditField           matlab.ui.control.EditField
        Search_TagIDEditField          matlab.ui.control.EditField
        PersonDropDownLabel            matlab.ui.control.Label
        PersonDropDown                 matlab.ui.control.DropDown
        EventdateDatePickerLabel       matlab.ui.control.Label
        EventdateDatePicker            matlab.ui.control.DatePicker
        SubmitevententryButton         matlab.ui.control.Button
        NotesTextArea_2Label           matlab.ui.control.Label
        EventNotesTextArea             matlab.ui.control.TextArea
        EventTimeM                     matlab.ui.control.DropDown
        EventTimeAMPM                  matlab.ui.control.DropDown
        TimeLabel_2                    matlab.ui.control.Label
        AlleventsforselectedanimalButton  matlab.ui.control.Button
        EventTimeH                     matlab.ui.control.DropDown
        ViewlogsLabel                  matlab.ui.control.Label
        EventsforallanimalsButton      matlab.ui.control.Button
        TypeDropDownLabel              matlab.ui.control.Label
        EventTypeDropDown              matlab.ui.control.DropDown
        StatusLampLabel                matlab.ui.control.Label
        StatusLamp_Logger              matlab.ui.control.Lamp
        EventInsertStatusEdit          matlab.ui.control.EditField
        AddAnimalTab                   matlab.ui.container.Tab
        SpeciesTabGroup                matlab.ui.container.TabGroup
        LabmouseTab                    matlab.ui.container.Tab
        tagged_switch                  matlab.ui.control.Switch
        SexButtonGroup                 matlab.ui.container.ButtonGroup
        UnknownButton                  matlab.ui.control.RadioButton
        MaleButton                     matlab.ui.control.RadioButton
        FemaleButton                   matlab.ui.control.RadioButton
        EarpunchButtonGroup            matlab.ui.container.ButtonGroup
        NoneButton                     matlab.ui.control.RadioButton
        LButton                        matlab.ui.control.RadioButton
        RButton                        matlab.ui.control.RadioButton
        BothButton                     matlab.ui.control.RadioButton
        DOB_known_switch               matlab.ui.control.Switch
        GenotypeDropDownLabel_2        matlab.ui.control.Label
        GenotypeDropDown               matlab.ui.control.DropDown
        TagIDEditField_2Label          matlab.ui.control.Label
        TagIDEditField                 matlab.ui.control.NumericEditField
        CageEditFieldLabel             matlab.ui.control.Label
        CageEditField                  matlab.ui.control.NumericEditField
        DOBDatePickerLabel             matlab.ui.control.Label
        DOBDatePicker                  matlab.ui.control.DatePicker
        SparrowTab                     matlab.ui.container.Tab
        PerhapssomedayLabel            matlab.ui.control.Label
        AddanimaltodatabaseButton      matlab.ui.control.Button
        StatusLampLabel_2              matlab.ui.control.Label
        StatusLamp_Animal              matlab.ui.control.Lamp
        AnimalInsertStatusEdit         matlab.ui.control.EditField
        AddGenotypeTab                 matlab.ui.container.Tab
        GenotypenameEditFieldLabel     matlab.ui.control.Label
        GenotypenameEditField          matlab.ui.control.EditField
        DescriptionTextAreaLabel       matlab.ui.control.Label
        DescriptionTextArea            matlab.ui.control.TextArea
        SourceEditFieldLabel           matlab.ui.control.Label
        SourceEditField                matlab.ui.control.EditField
        CatalogEditFieldLabel          matlab.ui.control.Label
        CatalogEditField               matlab.ui.control.EditField
        AddgenotypetodatabaseButton    matlab.ui.control.Button
        GenotypesindatabaseListBoxLabel  matlab.ui.control.Label
        GenotypesindatabaseListBox     matlab.ui.control.ListBox
        StatusLampLabel_3              matlab.ui.control.Label
        StatusLamp_Genotype            matlab.ui.control.Lamp
        GenotypeInsertStatusEdit       matlab.ui.control.EditField
        AddSubstanceTab                matlab.ui.container.Tab
        SubstancenameEditFieldLabel    matlab.ui.control.Label
        SubstancenameEditField         matlab.ui.control.EditField
        DescriptionTextArea_2Label     matlab.ui.control.Label
        DescriptionTextArea_substance  matlab.ui.control.TextArea
        SourceEditField_2Label         matlab.ui.control.Label
        SourceEditField_substance      matlab.ui.control.EditField
        CatalogEditField_2Label        matlab.ui.control.Label
        CatalogEditField_substance     matlab.ui.control.EditField
        StoragelocationLabel           matlab.ui.control.Label
        StorageLocationTextArea_substance  matlab.ui.control.TextArea
        AddsubstancetodatabaseButton   matlab.ui.control.Button
        SubstancesindatabaseListBoxLabel  matlab.ui.control.Label
        SubstancesindatabaseListBox    matlab.ui.control.ListBox
        StatusLampLabel_4              matlab.ui.control.Label
        StatusLamp_Substance           matlab.ui.control.Lamp
        SubstanceInsertStatusEdit      matlab.ui.control.EditField
        AddBrainAreaTab                matlab.ui.container.Tab
        AbbreviationEditFieldLabel     matlab.ui.control.Label
        AbbreviationEditField          matlab.ui.control.EditField
        LongnameLabel                  matlab.ui.control.Label
        BrainLongNameTextArea          matlab.ui.control.TextArea
        BrainareasindatabaseListBoxLabel  matlab.ui.control.Label
        BrainareasindatabaseListBox    matlab.ui.control.ListBox
        AddbrainareatodatabaseButton   matlab.ui.control.Button
        StatusLampLabel_5              matlab.ui.control.Label
        StatusLamp_BrainArea           matlab.ui.control.Lamp
        BrainAreaInsertStatusEdit      matlab.ui.control.EditField
        LogTab                         matlab.ui.container.Tab
        LogTextArea                    matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        accessible_tabs = {'Login'} % Which tabs user is allowed to select
        user_password=''; %property that will store the password entered by user
        prev_password_length=0;
        selected_animal_id = [];% animal_id for current selection
        login_user; % User who logged in
        ax_ageslider % axis for age slider
        l_ageslider  % line for age slider
        filter_ageLow = 0; % lower age limit
        filter_ageHigh = 200; % upper age limit
        filter_genotype_status = 'All'; % genotype_status to filter
        filter_struct = []; % struct as query object from filter panel
        filter_check_eye_inj = false; % booleon about whether to include only injected
        filter_check_brain_inj = false; % booleon about whether to include only injected
        filter_check_beh = false; % booleon about whether to include only behavior animals
        filter_check_reserved = false; %booleon about whether to only include unreserved
        filter_project = 'All'; % reserved project to filter
        connection % DJ connection object
        cal_refresh_response; %for google calendar
        cal_access_response = []; %for google calendar        
        cal_access_token_time =[]; % time of last access token request
        calDir; % directory for calendar access file
    end
    
    methods (Access = private)
        
        function init_AddAnimalTab(app)
            app.AddAnimal_load_genotypes();
            
            %set initial state
            set(app.Search_DJIDEditField,'Enable',true);
            set(app.DOBDatePicker,'Enable',true);
            set(app.StatusLamp_Animal,'color','white');
            set(app.AnimalInsertStatusEdit,'value','');
        end
        
        function init_AddGenotypeTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            genotypes = fetchn(sl.Genotype,'genotype_name');
            %put WT first
            ind = find(strcmp(genotypes,'WT'));
            if ~isempty(ind)
                g{1} = 'WT';
                g = [g; genotypes(setdiff(1:length(genotypes), ind))];
            else
                g = genotypes;
            end
            set(app.GenotypesindatabaseListBox,'Items',g);
            if resetStatusLamp
                set(app.StatusLamp_Genotype,'color','white');
                set(app.GenotypeInsertStatusEdit,'value','');
            end
        end
        
        function init_AddSubstanceTab(app,resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            substances = fetchn(sl.InjectionSubstance,'substance_name');
            set(app.SubstancesindatabaseListBox,'Items',substances);    
            if resetStatusLamp
                set(app.StatusLamp_Substance,'color','white');
                set(app.SubstanceInsertStatusEdit,'value','');
            end
        end       
        
        function init_AddBrainAreaTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            areas = fetchn(sl.BrainArea,'target');
            set(app.BrainareasindatabaseListBox,'Items',areas); 
            if resetStatusLamp
                set(app.StatusLamp_BrainArea,'color','white');
                set(app.BrainAreaInsertStatusEdit,'value','');
            end
        end        
        
        function AddAnimal_load_genotypes(app)
            genotypes = fetchn(sl.Genotype,'genotype_name');
            %put WT first
            ind = find(strcmp(genotypes,'WT'));
            if ~isempty(ind)
                g{1} = 'WT';
                g = [g; genotypes(setdiff(1:length(genotypes), ind))];
            else
                g = genotypes;
            end
            set(app.GenotypeDropDown,'Items',g);
        end
        
        function loadSubstanceMenus(app)
            substances = fetchn(sl.InjectionSubstance,'substance_name');
            set(app.SubstanceDropDown_IP,'items',substances);
            set(app.SubstanceDropDown_Eye,'items',substances);
            set(app.SubstanceDropDown_Brain,'items',substances);
        end
        
        function loadBrainAreas(app)
            brainAreas = fetchn(sl.BrainArea,'target');
            set(app.BrainTargetDropDown,'items',brainAreas);
        end
        
        function loadUsers(app)
            users = fetchn(sl.User,'user_name'); %eventually sl.UserActive
            %put login_user first
            ind = find(strcmp(users,app.login_user));
            if ~isempty(ind)
                u{1} = app.login_user;
                u = [u; users(setdiff(1:length(users), ind))];
            else
                u = users;
            end            
            set(app.PersonDropDown,'items',u);
            if ~isempty(ind)
                set(app.PersonDropDown,'value',app.login_user);                
            end
        end
        
        function loadSocialExperimentTypes(app)
            exps = fetchn(sl.SocialBehaviorExperimentType,'purpose');
            set(app.ExperimenttypeDropDown,'items',exps);
        end
        
        function loadRigs(app)
            rigs = fetchn(sl.Rig,'rig_name');
            set(app.RignameDropDown,'items',rigs);
        end
        
        function loadEventTypes(app)
            ch = get(app.EventTabGroup,'children');
            N = length(ch);
            eventNames = cell(N,1);
            for i=1:N
                eventNames{i} = get(ch(i),'Title');
            end
            set(app.EventTypeDropDown,'items',eventNames)
        end
        
        function loadProjects(app)
            projects = fetchn(sl.Project,'project_name');
            set(app.ProjectDropDown,'items',projects);
            set(app.Filter_ProjectDropDown,'items',['All'; projects]);
        end
        
        function init_AnimalLoggerTab(app)
            % Create the 1D age slider axes
%             app.ax_ageslider = axes( ...
%                 app.AgerangeweeksPanel,'Color','none','YColor','none','XLim',[0,25],'YTick',[], ...
%                 'XTick',[0 5 10 15 20 25], ...
%                 'XTickLabel',{'0','5','10','15','20','25+'}, ...
%                 'TickDir','both','TickLength',[0.03,0.035],'XMinorTick', ...
%                 'on','Units','normalized','Position',[.1,.7,0.7,0.8] ...
%                 );
%             % Disable the interactivity & toolbar visibility
%             disableDefaultInteractivity(app.ax_ageslider);
%             app.ax_ageslider.Toolbar.Visible = 'off';
%             % Add the line ROI
%             app.l_ageslider = images.roi.Line(app.ax_ageslider ...
%                 ,'Position',[4,0;25,0]);
            %set(app.ageLow_edit, 'Value', '4');
            %set(app.ageHigh_edit, 'Value', '25');
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            %addlistener(app.l_ageslider,'MovingROI',@(varargin)lMoving(app,app.l_ageslider));
            %app.filter_ageLow = 4;
            %app.filter_ageHigh = 25;
            
            %load genotypes
            app.loadGenotypeMenu();
            
            %load substances
            app.loadSubstanceMenus();
            
            %load brain areas
            app.loadBrainAreas();
            
            %load social behavior experiment types
            app.loadSocialExperimentTypes();
            
            %load users
            app.loadUsers();
            
            %load rigs
            app.loadRigs();
            
            %load projects
            app.loadProjects();
            
            %load event types for log menu
            app.loadEventTypes();
            
            %set id switch
            set(app.ID_type_switch,'value','Tag ID');
            set(app.Search_DJIDEditField,'Enable',false);
            
            %set filter switches
            set(app.EyeInjSwitch,'value','Don''t check');
            set(app.BraininjSwitch,'value','Don''t check');
            set(app.BehaviorexpSwitch,'value','Don''t check');
            set(app.NotreservedSwitch,'value','Don''t check');
            app.filter_check_eye_inj = false;
            app.filter_check_brain_inj = false;
            app.filter_check_beh = false;
            app.filter_check_reserved = false;
            
            %set menus and age boxes
            set(app.Filter_SexDropDown,'value','All');
            set(app.ageLow_edit,'value','');
            set(app.ageHigh_edit,'value','');
            
            %make initial filter struct
            app.filter_genotype_status = 'All';
            app.filter_ageLow = str2double(app.ageLow_edit.Value);
            app.filter_ageHigh = str2double(app.ageHigh_edit.Value);
            
            filter_query = [];
            
            %set status lamp to grey and empty text field
            set(app.StatusLamp_Logger,'color','white');
            set(app.EventInsertStatusEdit, 'value', '');
            
            %calendar init
            if isdeployed
                app.calDir = '/home/matlabuser';
            else
                app.calDir = pwd;
            end
            fid = fopen([app.calDir filesep 'gcal_access.txt'],'r');
            app.cal_refresh_response.access_token = fgetl(fid);
            app.cal_refresh_response.refresh_token = fgetl(fid);
            app.cal_refresh_response.expires_in = 3599;
            app.cal_refresh_response.scope = 'https://www.googleapis.com/auth/calendar';
            app.cal_refresh_response.token_type = 'Bearer';
    
            %app.cal_refresh_response.refresh_token = '1//0f4-lbZ90smKbCgYIARAAGA8SNwF-L9IrI9vFDovfyvZzzi5caMaTLx-1_CRoLFeuLuo2RcEEtIhrkyMdG0YSNKRYoLWOT4kK2l4';            
            %app.cal_refresh_response.access_token = 'ya29.a0AfH6SMClV7vcfiNv6ebcsTY4UrDavnikDD9ZpsBlYj6pXt3Ysr0qn-tPU8dWhcRTDXt3unnq6TQEpTl0uc6Qm-LShA5OHvarXggtfnkWnv2Im181Pe5l5nzdjowiwCIsuStZAdSRnqhRQmJeKQqLQfuJvbNtslpZh0A';
            
            %load animal table
            app.loadAnimalTable(filter_query);
        end
        
        function loadAnimalTable(app, filter_query)
            % change title to table loading...
            set(app.AnimalSelectionPanel, 'Title', 'Table loading...');
            %and make table invisible
            set(app.AnimalTable,'visible','off');
            drawnow;

            if isempty(app.filter_ageLow) || isnan(app.filter_ageLow)
                app.filter_ageLow = 0;
            end
            if isempty(app.filter_ageHigh) || isnan(app.filter_ageHigh)
                app.filter_ageHigh = 200;
            end
            
            if ~isempty(filter_query) %run specified query instead of stored filter
                allAnimals = fetch(sl.AnimalEventDeceased.living() & filter_query,'*');
            else
                
                % make age restriction
                max_dob = char(datetime(datetime('now') - 7*app.filter_ageLow,'format','yyyy-MM-dd'));
                if app.filter_ageHigh >= 25 %25 weeks considered inf
                    min_dob = '1900-01-01'; %really old mouse!
                else
                    min_dob = char(datetime(datetime('now') - 7*app.filter_ageHigh,'format','yyyy-MM-dd'));
                end
                ageQuery = {['dob >= ' '"' min_dob '"'], ['dob <= ' '"' max_dob '"']};
                allAnimals = fetch(sl.AnimalEventDeceased.living() ...
                    & app.filter_struct & ageQuery,'*');
            end
            if isempty(allAnimals)
                D = table;
            else
                animal_ids = [allAnimals.animal_id]';
                nAnimals = length(animal_ids);
                cage_numbers = sl.Animal.cageNumber(animal_ids);
                genotype_status = sl.Animal.genotypeStatus(animal_ids);
                project_name = sl.Animal.reservedProject(animal_ids);
                hasBehavior = sl.Animal.hasEvent('SocialBehaviorSession',animal_ids);
                hasBrainInjection = sl.Animal.hasEvent('BrainInjection',animal_ids);
                hasEyeInjection = sl.Animal.hasEvent('EyeInjection',animal_ids);
                hasIPinjection = sl.Animal.hasEvent('IPinjection',animal_ids);
                hasRigReservation = sl.Animal.hasEvent('ReservedForSession',animal_ids);
                age = sl.Animal.age(animal_ids);
                T = struct2table(allAnimals);                
                if isempty(cage_numbers)
                    T.cage_number = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(cage_numbers),'MergeKeys',true,'type','Left');
                end
                if isempty(genotype_status)
                    T.genotype_status = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(genotype_status,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                if isempty(project_name)
                    T.project_name = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(project_name,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                T = outerjoin(T, struct2table(age),'MergeKeys',true,'type','Left'); %every mouse has an age
                T.hasBehavior = hasBehavior;
                T.hasBrainInjection = hasBrainInjection;
                T.hasEyeInjection = hasEyeInjection;
                T.hasIPinjection = hasIPinjection;
                T.hasRigReservation = hasRigReservation;
                N = height(T);
                %if N==1
                %    keyboard;
                %end
                D = table('Size',[N,13],'VariableTypes', ...
                    {'uint32',...   #1 DJ ID
                    'uint32', ...   #2 tag ID
                    'uint32',...    #3 cage number
                    'string',...    #4 genotype
                    'string',...    #5 genotype status
                    'logical',...   #6 IP inj
                    'string',...    #7 sex
                    'singlenan',... #8 age
                    'logical',...   #9 eye inj
                    'logical',...   #10 brain inj
                    'logical', ...  #11 behavior
                    'logical', ...  #12 reserved for rig
                    'string', ...   #13 reserved project
                    },...
                    'VariableNames', ...
                    {'DJ_ID',...
                    'Tag_ID', ...
                    'Cage_number',...
                    'Genotype'...
                    'Genotype_status',...
                    'IP_inj',...
                    'Sex',...
                    'Age',...
                    'Eye_inj',...
                    'Brain_inj',...
                    'BehaviorExp',...
                    'ReservedForRig', ...
                    'ReservedProject', ...
                    });
                D.DJ_ID = T.animal_id;
                D.Tag_ID = T.tag_id;
                D.Cage_number = T.cage_number;
                D.Genotype = string(T.genotype_name);
                D.Genotype_status = T.genotype_status;
                D.IP_inj = T.hasIPinjection;
                D.Sex = string(T.sex);
                D.Age = T.age;
                D.Eye_inj = T.hasEyeInjection;
                D.Brain_inj = T.hasBrainInjection;
                D.BehaviorExp = T.hasBehavior;
                D.ReservedForRig = T.hasRigReservation;
                D.ReservedProject = string(T.project_name);
                %filter parts that have to be done after table construction
                if app.filter_check_beh
                    ind = D.BehaviorExp;
                    D = D(ind,:);
                end
                if app.filter_check_eye_inj
                    ind = D.Eye_inj;
                    D = D(ind,:);
                end
                if app.filter_check_brain_inj
                    ind = D.Brain_inj;
                    D = D(ind,:);
                end
                if app.filter_check_reserved
                    ind = D.ReservedForRig;
                    D = D(~ind,:);
                end
                if ~strcmp(app.filter_genotype_status,'All')
                    ind = strcmp(D.Genotype_status, app.filter_genotype_status);
                    D = D(ind,:);
                end
                if ~strcmp(app.filter_project,'All')
                    ind = strcmp(D.ReservedProject, app.filter_project);
                    D = D(ind,:);
                end
            end
            N = height(D);
            if N>0
                set(app.AnimalSelectionPanel, 'Title', ...
                    ['Animal Selection: ' num2str(N) ' total']);
            else
                set(app.AnimalSelectionPanel, 'Title', ...
                    'Animal Selection: none found');
            end
            set(app.AnimalTable,'Data',D);
            defaultStyle = uistyle('FontColor','black','FontWeight','normal');
            addStyle(app.AnimalTable,defaultStyle);
            
            %make no animal selected
            app.selected_animal_id = [];

            %make visible again
            set(app.AnimalTable,'visible','on');
            drawnow;
        end
        
        % Function that responds to the movement of the sliders
%         function lMoving(app, source)
%             % Always keep the position rounded to 1 decimal place
%             source.Position(1,1) = round(source.Position(1,1),1);
%             source.Position(2,1) = round(source.Position(2,1),1);
%             % make sure y stays at 0
%             source.Position(1,2) = 0;
%             source.Position(2,2) = 0;
%             %insert numbers into edit boxes
%             app.ageLow_edit.Value = source.Position(1,1);
%             app.filter_ageLow = app.ageLow_edit.Value;
%             app.ageHigh_edit.Value = source.Position(2,1);
%             app.filter_ageHigh = app.ageHigh_edit.Value;
%             % Add any other action here triggered by the movement of the sliders
%             app.loadAnimalTable([]);
%         end
        
        function loadGenotypeMenu(app)
            genotypes = fetchn(sl.Genotype,'genotype_name');
            %put WT first
            ind = find(strcmp(genotypes,'WT'));
            if ~isempty(ind)
                g{1} = 'WT';
                g = [g; genotypes(setdiff(1:length(genotypes), ind))];
            else
                g = genotypes;
            end
            g = ['All'; g];
            set(app.Filter_GenotypeDropDown,'Items',g);
        end
                
        function [calEventAdded, result] = addCalendarEvent(app,key)
            if isempty(app.cal_access_token_time) || isempty(app.cal_access_response) || ...
                    datetime('now') - app.cal_access_token_time > seconds(app.cal_access_response.expires_in)
                %token expired
                try
                    app.cal_access_response = webwrite(...
                        'https://oauth2.googleapis.com/token',...
                        'grant_type','refresh_token',...
                        'client_id','667407419363-59ebnl9973ie08ui05fh1b8go1fvpbsn.apps.googleusercontent.com',...
                        'client_secret','dLydGGCoNOaMD1bA8HQFADqL',...
                        'refresh_token',app.cal_refresh_response.refresh_token,...
                        'redirect_uri','urn:ietf:wg:oauth:2.0:oob'...
                        );
                    app.cal_access_token_time = datetime('now');
                catch
                    result = 'Calendar access code failed: maybe refresh_token expired.';
                    calEventAdded = false;
                    return;
                end
            else
               disp('using saved access token');                 
            end
            
            try
            %make event
            genotype = fetch1(sl.Animal & sprintf('animal_id=%d',key.animal_id), 'genotype_name');
            body = struct();
            body.start.date = key.date;
            body.end.date = key.date;
            body.summary = sprintf('%s: experiment on rig %s with mouse(DJID): %d, genotype: %s', ...
                key.user_name, key.rig_name, key.animal_id, genotype);
            if ~isempty(key.notes)
                body.description = key.notes;
            end        
                        
            %make request
            request = matlab.net.http.RequestMessage(...
                matlab.net.http.RequestMethod.POST,...
                matlab.net.http.HeaderField('Authorization',sprintf('Bearer %s',app.cal_access_response.access_token),...
                'MediaType','application/json'),...
                body);
            
            %post it
            status = request.send('https://www.googleapis.com/calendar/v3/calendars/schwartzlabnu@gmail.com/events');
            if strcmp(status.StatusCode,'OK')
                calEventAdded = true;
                result = 'Calendar entry insert success';
            else
                calEventAdded = false;
                result = 'Calendar entry insert failed';
            end
            catch
                calEventAdded = false;
                result = 'Calendar entry insert failed';
            end
            
        end
    end
    
    methods (Static)
        
        function ind = sortEvents(events)
            ev_structs = fetch(events,'date','time');
            L = length(ev_structs);
            %evTimes = datetime(L,1);
            for i=1:L
               if isempty(ev_structs(i).time)
                   thisTime = '00:00:00';
               else
                   thisTime = ev_structs(i).time;
               end
               evTimes(i) = datetime([ev_structs(i).date, ' ', thisTime]);
            end
            
            [~,ind] = sort(evTimes);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.connection = struct;
            app.connection.isConnected = false;
            %datajoint setup
            %             setenv('DJ_USER', 'sl_user')
            %             if isdeployed
            %                 setenv('DJ_HOST', '127.0.0.1')
            %             else
            %                 setenv('DJ_HOST', 'vfsmdatajoint01.fsm.northwestern.edu')
            %             end
            %             setenv('DJ_PASS', 'r0zsNB0xk6wc5BgA!')
            %             dj.conn();
            
            %start with only login tab enabled (default)
            
            %load user names into login dropdown
            %users = fetchn(sl.User,'user_name'); %eventually sl.UserActive
            %set(app.UserDropDown,'items',users);
            
        end

        % Selection change function: MainTabGroup
        function MainTabGroupSelectionChanged(app, event)
            selectedTab = app.MainTabGroup.SelectedTab;
            
            if ~ismember(get(selectedTab,'Title'),app.accessible_tabs)
                app.MainTabGroup.SelectedTab = event.OldValue;
            else
                selectedTabName = get(selectedTab,'Title');
                
                switch(selectedTabName)
                    case 'AnimalLogger'
                        app.init_AnimalLoggerTab;
                    case 'Add Animal'
                        app.init_AddAnimalTab;
                    case 'Add Genotype'
                        app.init_AddGenotypeTab;
                    case 'Add Substance'
                        app.init_AddSubstanceTab;
                    case 'Add Brain Area'
                        app.init_AddBrainAreaTab;    
                    case 'Login'
                        if app.connection.isConnected
                            app.connection.close;
                            app.connection = struct;
                            app.connection.isConnected = false;
                        end
                        set(app.PasswordEditField,'value','');
                        app.accessible_tabs = {'Login'};
                end
            end
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            val = event.Value;
            current_pass_length=length(val); %get the number of characters present in password edit field
            app.prev_password_length=length(app.user_password);
            
            %if password edit field is empty and is in focus
            if isempty(val)
                app.user_password='';   %set the password property to empty string
                app.prev_password_length=0;  %set the previous password length to zero
                app.PasswordEditField.Value='';  %set the value in edit field to empty string
                
                %if backspace is clicked by user
            elseif(current_pass_length<app.prev_password_length)
                app.user_password = app.user_password(1:current_pass_length);
                app.PasswordEditField.Value = repelem('*', current_pass_length);
            else
                %remove the previous available '*' from the password value obtained from the app
                add_string=erase(val, repelem('*', length(app.user_password)));
                
                %append the chacter to user_passowrd property
                app.user_password=append(app.user_password,add_string);
                
                %mask the password in edit field with the total length of characters stored in user_password
                app.PasswordEditField.Value = repelem('*', length(app.user_password));
            end
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            currentUser = get(app.LoginUserEditField, 'value');            
            if isdeployed
                try
                    app.connection = dj.conn('127.0.0.1', currentUser, app.user_password, [], true);
                catch
                end
                
            else
                try
                    app.connection = dj.conn('vfsmdatajoint01.fsm.northwestern.edu', currentUser, app.user_password, [], true);
                catch
                end
            end
            
            if app.connection.isConnected
                disp('login successful');
                %enable AnimalLogger tab
                app.accessible_tabs = [app.accessible_tabs, ...
                    'AnimalLogger', ...
                    'Add Animal', ...
                    'Add Genotype', ...
                    'Add Substance',...
                    'Add Brain Area',...
                    'Log',...
                    ];
                app.MainTabGroup.SelectedTab = app.AnimalLoggerTab;
                app.login_user = currentUser;
                %initialize the appropriate tabs
                app.init_AnimalLoggerTab
            else
                disp('login failed');
            end
        end

        % Value changed function: DOB_known_switch
        function DOB_known_switchValueChanged(app, event)
            value = app.DOB_known_switch.Value;
            if strcmp(value,'DOB unknown')
                set(app.DOBDatePicker,'Enable',false);
            else
                set(app.DOBDatePicker,'Enable',true);
            end
        end

        % Value changed function: tagged_switch
        function tagged_switchValueChanged(app, event)
            value = app.tagged_switch.Value;
            if strcmp(value,'Untagged')
                set(app.TagIDEditField,'Enable',false)
            else
                set(app.TagIDEditField,'Enable',true);
            end
        end

        % Button pushed function: AddanimaltodatabaseButton
        function AddanimaltodatabaseButtonPushed(app, event)
            %make insert key
            key = struct;
            %check isTagged
            if strcmp(get(app.tagged_switch,'value'),'Untagged')
                key.is_tagged = 'F';
            else
                key.is_tagged = 'T';
                key.tag_id = get(app.TagIDEditField,'value');
            end
            
            %get genotype
            key.genotype_name = get(app.GenotypeDropDown, 'value');
            
            %TODO
            key.species = get(get(app.SpeciesTabGroup, 'SelectedTab'), 'Title');
            
            %check DOB
            if strcmp(get(app.DOB_known_switch,'value'),'DOB known')
                key.dob = string(get(app.DOBDatePicker,'value'));
            end
            
            %sex
            key.sex = get(get(app.SexButtonGroup,'SelectedObject'), 'text');
            %ear punch
            key.punch = get(get(app.EarpunchButtonGroup,'SelectedObject'), 'text');
            
            %cage
            key_cage.cage_number = get(app.CageEditField,'value');
            key_cage.user_name = app.login_user;
            key_cage.date = string(datetime(date,'format','yyyy-MM-dd')); %current date
            
            %add the animal
            disp('Adding animal with key:');
            key
            key_cage
            [inserted, out_txt] = add_animal(key, key_cage);
            if inserted
                set(app.StatusLamp_Animal,'color','green');
            else
                set(app.StatusLamp_Animal,'color','red');
            end
            set(app.AnimalInsertStatusEdit,'value',out_txt);
        end

        % Button pushed function: AddgenotypetodatabaseButton
        function AddgenotypetodatabaseButtonPushed(app, event)
            key.genotype_name = get(app.GenotypenameEditField, 'value');
            key.source = get(app.SourceEditField, 'value');
            desc = get(app.DescriptionTextArea, 'value');
            desc_flat = strcat(cell2mat(desc'));
            key.description = desc_flat;
            key.catalog_number = get(app.CatalogEditField, 'value');
            try
                insert(sl.Genotype, key);                
                disp(['Genotype ' key.genotype_name ' insert successful']);
                set(app.StatusLamp_Genotype,'color','green');
                set(app.GenotypeInsertStatusEdit,'value','Genotype insert successful.')
                app.init_AddGenotypeTab;
            catch ME
                disp(['Genotype ' key.genotype_name ' insert failed']);
                set(app.StatusLamp_Genotype,'color','red');
                set(app.GenotypeInsertStatusEdit,'value','Genotype insert failed.')
                %rethrow(ME);
            end
        end

        % Value changed function: RecordBehavior_switch
        function RecordBehavior_switchValueChanged(app, event)
            value = app.RecordBehavior_switch.Value;
            if strcmp(value,'Recorded')
                app.Lamp_isRecorded.Enable = true;
                app.SocialB_filenameEditField.Enable = true;
            else
                app.Lamp_isRecorded.Enable = false;
                app.SocialB_filenameEditField.Value = ' ';
                app.SocialB_filenameEditField.Enable = false;
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            value = get(app.ID_type_switch,'value');
            q = [];
            if strcmp(value,'DJ ID')
                v = get(app.Search_DJIDEditField,'value');
                if ischar(v)
                    v = str2double(v);
                end
                if ~isnan(v)
                    q.animal_id = v;
                end
            else
                v = get(app.Search_TagIDEditField,'value');
                if ischar(v)
                    v = str2double(v);
                end
                if ~isnan(v)
                    q.tag_id = v;
                end
            end
            if ~isempty(q)
                app.loadAnimalTable(q)
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            q = [];
            app.loadAnimalTable(q)
        end

        % Cell selection callback: AnimalTable
        function AnimalTableCellSelection(app, event)
            indices = event.Indices;
            selectedRow = indices(1,1);
            
            D = get(app.AnimalTable,'Data');
            Nrows = size(D,1);
            
            %select animal
            app.selected_animal_id = D{selectedRow,1};
            
            %make styles
            curStyle = uistyle('FontColor','red','FontWeight','bold');
            oldStyle = uistyle('FontColor','black','FontWeight','normal');
            
            %add style to this row
            addStyle(app.AnimalTable,curStyle,'row',selectedRow);
            
            %put other rows back to normal
            otherRows = setdiff(1:Nrows,selectedRow);
            for i=1:Nrows-1
                addStyle(app.AnimalTable,oldStyle,'row',otherRows(i));
            end
        end

        % Value changed function: Filter_SexDropDown
        function Filter_SexDropDownValueChanged(app, event)
            value = app.Filter_SexDropDown.Value;
            if strcmp(value,'All')
                app.filter_struct = rmfield(app.filter_struct, 'sex');
            else
                app.filter_struct.sex = value;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: Filter_GenotypeDropDown
        function Filter_GenotypeDropDownValueChanged(app, event)
            value = app.Filter_GenotypeDropDown.Value;
            if strcmp(value,'All')
                app.filter_struct = rmfield(app.filter_struct, 'genotype_name');
            else
                app.filter_struct.genotype_name = value;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: Filter_GenotypeStatusDropDown
        function Filter_GenotypeStatusDropDownValueChanged(app, event)
            value = app.Filter_GenotypeStatusDropDown.Value;
            app.filter_genotype_status = value;
            app.loadAnimalTable([]);
        end

        % Value changed function: EyeInjSwitch
        function EyeInjSwitchValueChanged(app, event)
            value = app.EyeInjSwitch.Value;
            if strcmp(value, 'Y')
                app.filter_check_eye_inj = true;
            else
                app.filter_check_eye_inj = false;
            end
            app.loadAnimalTable([]);

        end

        % Value changed function: BehaviorexpSwitch
        function BehaviorexpSwitchValueChanged(app, event)
            value = app.BehaviorexpSwitch.Value;
            if strcmp(value, 'Y')
                app.filter_check_beh = true;
            else
                app.filter_check_beh = false;
            end
            app.loadAnimalTable([]);

        end

        % Value changed function: BraininjSwitch
        function BraininjSwitchValueChanged(app, event)
            value = app.BraininjSwitch.Value;
            if strcmp(value, 'Y')
                app.filter_check_brain_inj = true;
            else
                app.filter_check_brain_inj = false;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: ID_type_switch
        function ID_type_switchValueChanged(app, event)
            value = app.ID_type_switch.Value;
            if strcmp(value,'DJ ID')
                set(app.Search_DJIDEditField, 'Enable', true);
                set(app.Search_TagIDEditField, 'Enable', false);
            else
                set(app.Search_DJIDEditField, 'Enable', false);
                set(app.Search_TagIDEditField, 'Enable', true);
            end
        end

        % Selection change function: EventTabGroup
        function EventTabGroupSelectionChanged(app, event)
            selectedTab = app.EventTabGroup.SelectedTab;
            selectedTabName = get(selectedTab,'Title');
            if strcmp(selectedTabName, 'Assign cage') ...
                    || strcmp(selectedTabName, 'Gave birth') ...
                    || strcmp(selectedTabName, 'Reserve for project') ...
                    || strcmp(selectedTabName, 'Reserve for rig')
                set(app.EventTimeH,'Enable',false);
                set(app.EventTimeM,'Enable',false);
                set(app.EventTimeAMPM,'Enable',false);
            else
                set(app.EventTimeH,'Enable',true);
                set(app.EventTimeM,'Enable',true);
                set(app.EventTimeAMPM,'Enable',true);
            end
        end

        % Button pushed function: SubmitevententryButton
        function SubmitevententryButtonPushed(app, event)
            if isempty(app.selected_animal_id)
                disp('No animal selected');
                set(app.StatusLamp_Logger,'color','red'); 
                set(app.EventInsertStatusEdit,'value','No animal selected'); 
                return;
            end
            selectedTabName = get(get(app.EventTabGroup, 'SelectedTab'), 'Title');
            key.user_name = get(app.PersonDropDown, 'value');
            key.date = datestr(get(app.EventdateDatePicker, 'value'),'yyyy-mm-dd');
            key.animal_id = app.selected_animal_id;
            notes = get(app.EventNotesTextArea, 'value');
            key.notes = strcat(cell2mat(notes'));
            hrs = get(app.EventTimeH, 'value');
            if strcmp(get(app.EventTimeAMPM,'value'), 'PM')
                hrs = rem(hrs, 12) + 12;
            end
            mins = get(app.EventTimeM, 'value');
            key.time = [num2str(hrs,'%02d') ':' num2str(mins,'%02d') ':00'];
            switch selectedTabName
                case "Genotyped"
                    key.genotype_status = get(app.GenotypeResultsDropDown, 'value');
                    key
                    [inserted, out_txt] = add_animalEvent(key,'Genotyped');
                case "Deceased"
                    key.cause = get(app.DeceasedReasonDropDown, 'value');
                    key
                    [inserted, out_txt] = add_animalEvent(key,'Deceased');
                case "Gave birth"
                    key.number_of_pups = str2double(get(app.NumberofpupsEditField, 'value'));
                    key = rmfield(key,'time'); %no time for this one
                    key = rmfield(key,'user_name'); %no user for this one
                    key
                    [inserted, out_txt] = add_animalEvent(key,'GaveBirth');
                case "Assign cage"
                    key.cage_number = str2double(get(app.TocageEditField, 'value'));
                    key.cause = get(app.MoveReasonDropDown, 'value');
                    key = rmfield(key,'time'); %no time for this one
                    key
                    [inserted, out_txt] = add_animalEvent(key,'AssignCage');
                case "IP injection"
                    key.substance_name = get(app.SubstanceDropDown_IP, 'value');
                    key.concentration = str2double(get(app.IP_inj_ConcentrationEditField, 'value'));
                    key
                    [inserted, out_txt] = add_animalEvent(key,'IPinjection');
                case "Eye injection"
                    key.substance_name = get(app.SubstanceDropDown_Eye, 'value');
                    key.dilution = str2double(get(app.Dilution_EyeEditField, 'value'));
                    key.whichEye = get(app.WhichEyeDropDown, 'value');
                    key
                    [inserted, out_txt] = add_animalEvent(key,'EyeInjection');
                case "Brain injection"
                    key.substance_name = get(app.SubstanceDropDown_Brain, 'value');
                    key.dilution = str2double(get(app.Dilution_BrainEditField, 'value'));
                    key.target = get(app.BrainTargetDropDown, 'value');
                    key.hemisphere = get(app.HemisphereDropDown, 'value');
                    key.head_rotation = str2double(get(app.AngleEditField, 'value'));
                    AP = str2double(get(app.APEditField, 'value'));
                    ML = str2double(get(app.MLEditField, 'value'));
                    DV = str2double(get(app.DVEditField, 'value'));
                    key.coordinates = [AP, ML, DV];
                    key
                    [inserted, out_txt] = add_animalEvent(key,'BrainInjection');
                case "Handled"
                    duration_mins = get(app.HandlingDurationEditField, 'value');
                    key.duration = string(duration(minutes(str2double(duration_mins)),'format','hh:mm:ss'));
                    key
                    [inserted, out_txt] = add_animalEvent(key,'Handling');
                case "Social behavior session"
                    key.purpose = get(app.ExperimenttypeDropDown,'value');
                    if strcmp(get(app.RecordBehavior_switch, 'value'), 'Recorded')
                        key.recorded = 'T';
                        key.fname = get(app.SocialB_filenameEditField, 'value');
                    else
                        key.recorded = 'F';
                    end
                    duration_mins = get(app.SocialB_DurationminsEditField, 'value');
                    key.duration = string(duration(minutes(str2double(duration_mins)),'format','hh:mm:ss'));
                    animalA_id = str2double(get(app.AnimalAEditField,'value'));
                    animalB_id = str2double(get(app.AnimalBEditField,'value'));
                    animalC_id = str2double(get(app.AnimalCEditField,'value'));
                    stimAnimals = [];
                    stimArms = {};
                    if animalA_id
                        stimAnimals = [stimAnimals, animalA_id];
                        stimArms = [stimArms, 'A'];
                    end
                    if animalB_id
                        stimAnimals = [stimAnimals, animalB_id];
                        stimArms = [stimArms, 'B'];
                    end
                    if animalC_id
                        stimAnimals = [stimAnimals, animalC_id];
                        stimArms = [stimArms, 'C'];
                    end
                    key.stimAnimals = stimAnimals;
                    key.stimArms = stimArms;
                    key
                    [inserted, out_txt] = add_animalEvent(key,'SocialBehaviorSession');
                case "Reserve for project"
                    key.project_name = get(app.ProjectDropDown, 'value');
                    key = rmfield(key,'time'); %no time for this one
                    key
                    [inserted, out_txt] = add_animalEvent(key,'ReservedForProject');
                case "Reserve for rig"
                    key.rig_name = get(app.RignameDropDown, 'value');
                    key = rmfield(key,'time'); %no time for this one
                    key
                    [inserted, out_txt] = add_animalEvent(key,'ReservedForSession');
                    [calEventAdded, result] = app.addCalendarEvent(key);
                    out_txt = [out_txt '; Cal: ' result];
                    inserted = inserted & calEventAdded;
            end
            if inserted
                set(app.StatusLamp_Logger,'color','green');
                %need to reload current table state
                app.loadAnimalTable([]);
            else
                set(app.StatusLamp_Logger,'color','red');                
            end
            set(app.EventInsertStatusEdit,'value',out_txt);            
        end

        % Value changed function: Filter_ProjectDropDown
        function Filter_ProjectDropDownValueChanged(app, event)
            value = app.Filter_ProjectDropDown.Value;
            app.filter_project = value;
            app.loadAnimalTable([]);
        end

        % Value changed function: NotreservedSwitch
        function NotreservedSwitchValueChanged(app, event)
            value = app.NotreservedSwitch.Value;
            if strcmp(value, 'Y')
                app.filter_check_reserved = true;
            else
                app.filter_check_reserved = false;
            end
            app.loadAnimalTable([]);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.connection.isConnected     
                app.connection.close;
            end
            delete(app)            
        end

        % Value changed function: ageLow_edit
        function ageLow_editValueChanged(app, event)
            app.filter_ageLow = str2double(app.ageLow_edit.Value); 
            app.loadAnimalTable([]);
        end

        % Value changed function: ageHigh_edit
        function ageHigh_editValueChanged(app, event)
            app.filter_ageHigh = str2double(app.ageHigh_edit.Value);  
            app.loadAnimalTable([]);
        end

        % Value changed function: GenotypesindatabaseListBox
        function GenotypesindatabaseListBoxValueChanged(app, event)
            value = app.GenotypesindatabaseListBox.Value;
            thisGenotype = fetch(sl.Genotype & sprintf('genotype_name="%s"',value), '*');
            set(app.GenotypenameEditField, 'value', thisGenotype.genotype_name);
            set(app.DescriptionTextArea, 'value', thisGenotype.description);
            set(app.SourceEditField, 'value', thisGenotype.source);
            set(app.CatalogEditField, 'value', thisGenotype.catalog_number);
        end

        % Button pushed function: AddsubstancetodatabaseButton
        function AddsubstancetodatabaseButtonPushed(app, event)
            key.substance_name = get(app.SubstancenameEditField, 'value');
            key.source = get(app.SourceEditField_substance, 'value');
            desc = get(app.DescriptionTextArea_substance, 'value');
            desc_flat = strcat(cell2mat(desc'));
            key.description = desc_flat;
            location = get(app.StorageLocationTextArea_substance, 'value');
            location_flat = strcat(cell2mat(location'));
            key.storage_location = location_flat;
            key.catalog_number = get(app.CatalogEditField_substance, 'value');
            try
                insert(sl.InjectionSubstance, key);
                disp(['Substance ' key.substance_name ' insert successful']);
                set(app.StatusLamp_Substance,'color','green');
                set(app.SubstanceInsertStatusEdit,'value','Substance insert successful.')
                app.init_AddSubstanceTab(false);
            catch 
                disp(['Substance ' key.substance_name ' insert failed']);
                set(app.StatusLamp_Substance,'color','red');
                set(app.SubstanceInsertStatusEdit,'value','Substance insert failed.')
                app.init_AddSubstanceTab(false);
                %rethrow(ME);
            end          
        end

        % Value changed function: SubstancesindatabaseListBox
        function SubstancesindatabaseListBoxValueChanged(app, event)
            value = app.SubstancesindatabaseListBox.Value;
            thisSubstance = fetch(sl.InjectionSubstance & sprintf('substance_name="%s"',value), '*');
            set(app.SubstancenameEditField, 'value', thisSubstance.substance_name);
            set(app.DescriptionTextArea_substance, 'value', thisSubstance.description);
            set(app.SourceEditField_substance, 'value', thisSubstance.source);
            set(app.CatalogEditField_substance, 'value', thisSubstance.catalog_number);
            set(app.StorageLocationTextArea_substance, 'value', thisSubstance.storage_location);
        end

        % Value changed function: BrainareasindatabaseListBox
        function BrainareasindatabaseListBoxValueChanged(app, event)
            value = app.BrainareasindatabaseListBox.Value;
            thisArea = fetch(sl.BrainArea & sprintf('target="%s"',value), '*');
            set(app.AbbreviationEditField, 'value', thisArea.target);
            set(app.BrainLongNameTextArea, 'value', thisArea.long_name);
        end

        % Button pushed function: AddbrainareatodatabaseButton
        function AddbrainareatodatabaseButtonPushed(app, event)
            key.target = get(app.AbbreviationEditField, 'value');
            long_text = get(app.BrainLongNameTextArea, 'value');
            long_text_flat = strcat(cell2mat(long_text'));
            key.long_name = long_text_flat;
            try
                insert(sl.BrainArea, key);
                disp(['Brain area ' key.target ' insert successful']);
                set(app.StatusLamp_BrainArea,'color','green');
                set(app.BrainAreaInsertStatusEdit,'value','Brain area insert successful.')
                app.init_AddBrainAreaTab(false);
            catch ME
                disp(['Brain area ' key.target ' insert failed']);
                set(app.StatusLamp_BrainArea,'color','red');
                set(app.BrainAreaInsertStatusEdit,'value','Brain area insert failed.')
                %rethrow(ME);
            end

        end

        % Button pushed function: AlleventsforselectedanimalButton
        function AlleventsforselectedanimalButtonPushed(app, event)
            if isempty(app.selected_animal_id)
                disp('cannot show log: no animal selected');
                return;
            end
            events = sl.AnimalEvent.all & sprintf('animal_id=%d',app.selected_animal_id);
            eventOrder = app.sortEvents(events);
            eventStruct = fetch(events,'*');
            L = length(eventStruct);
            logText = cell(L,1);
            for i=1:L
                thisType = eventStruct(eventOrder(i)).event_type;
                thisID = eventStruct(eventOrder(i)).event_id;
                ev = feval(sprintf('sl.AnimalEvent%s',thisType)) & sprintf('event_id=%d',thisID);
                logText{i} = ev.printEvents;
            end           
            set(app.LogTextArea,'value', logText);
            app.MainTabGroup.SelectedTab = app.LogTab;
        end

        % Button pushed function: EventsforallanimalsButton
        function EventsforallanimalsButtonPushed(app, event)
            selectedEventType = get(app.EventTypeDropDown,'value');
            switch selectedEventType
                case 'Genotyped'
                    eventType = 'Genotyped';
                case 'Deceased'
                    eventType = 'Deceased';
                case 'Gave birth'
                    eventType = 'GaveBirth';
                case 'Assign cage'
                    eventType = 'AssignCage';
                case 'IP injection'
                    eventType = 'IPinjection';
                case 'Eye injection'
                    eventType = 'EyeInjection';
                case 'Brain injection'
                    eventType = 'BrainInjection';
                case 'Handled'
                    eventType = 'Handling';
                case 'Social behavior session'
                    eventType = 'SocialBehaviorSession';
                case 'Reserve for project'
                    eventType = 'ReservedForProject';
                case 'Reserve for rig'
                    eventType = 'ReservedForSession';                    
            end           
            D = get(app.AnimalTable,'data');            
            inTableIDs = num2cell(D.DJ_ID);            
            events = sl.AnimalEvent.all & struct('animal_id', inTableIDs) & sprintf('event_type="%s"',eventType);
            eventOrder = app.sortEvents(events);
            eventStruct = fetch(events,'*');
            L = length(eventStruct);
            logText = cell(L,1);
            for i=1:L
                thisID = eventStruct(eventOrder(i)).event_id;
                ev = feval(sprintf('sl.AnimalEvent%s',eventType)) & sprintf('event_id=%d',thisID);
                logText{i} = ev.printEvents;
            end           
            set(app.LogTextArea,'value', logText);
            app.MainTabGroup.SelectedTab = app.LogTab;
        end

        % Button pushed function: ReauthorizeButton
        function ReauthorizeButtonPushed(app, event)
            auth_code = get(app.CalendarrefreshtokenEditField, 'value');
            try
                app.refresh_response = webwrite(...
                    'https://oauth2.googleapis.com/token',...
                    'grant_type','authorization_code',...
                    'client_id','667407419363-59ebnl9973ie08ui05fh1b8go1fvpbsn.apps.googleusercontent.com',...
                    'client_secret','dLydGGCoNOaMD1bA8HQFADqL',...
                    'code',auth_code,...
                    'redirect_uri','urn:ietf:wg:oauth:2.0:oob'...
                    );
                fid = fopen([app.calDir filesep 'gcal_access.txt'], 'w');
                fprintf(fid, '%s\n', refresh_response.access_token);
                fprintf(fid, '%s\n', refresh_response.refresh_token);
                fclose(fid);
                dips('Calendar reauthorization success');
            catch
                disp('Calendar reauthorization failed');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MainTabGroup = uitabgroup(app.UIFigure);
            app.MainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @MainTabGroupSelectionChanged, true);
            app.LoginTab = uitab(app.MainTabGroup);
            app.LoginTab.Title = 'Login';
            app.PasswordEditFieldLabel = uilabel(app.LoginTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.LoginTab, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.LoginButton = uibutton(app.LoginTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.UserEditFieldLabel = uilabel(app.LoginTab);
            app.UserEditFieldLabel.Text = 'User';
            app.LoginUserEditField = uieditfield(app.LoginTab, 'text');
            app.AnimalLoggerTab = uitab(app.MainTabGroup);
            app.AnimalLoggerTab.Title = 'AnimalLogger';
            app.AnimalTable = uitable(app.AnimalLoggerTab);
            app.AnimalTable.ColumnName = {'DJ ID'; 'Tag ID'; 'Cage #'; 'Genotype'; 'Genotype status'; 'IP inj.'; 'Sex'; 'Age (wks)'; 'Eye inj.'; 'Brain inj.'; 'Behavior'; 'Res. for rig'; 'Project'};
            app.AnimalTable.RowName = {};
            app.AnimalTable.ColumnSortable = true;
            app.AnimalTable.CellSelectionCallback = createCallbackFcn(app, @AnimalTableCellSelection, true);
            app.EventTabGroup = uitabgroup(app.AnimalLoggerTab);
            app.EventTabGroup.SelectionChangedFcn = createCallbackFcn(app, @EventTabGroupSelectionChanged, true);
            app.GenotypedTab = uitab(app.EventTabGroup);
            app.GenotypedTab.Title = 'Genotyped';
            app.ResultsDropDownLabel = uilabel(app.GenotypedTab);
            app.ResultsDropDownLabel.Text = 'Results';
            app.GenotypeResultsDropDown = uidropdown(app.GenotypedTab);
            app.GenotypeResultsDropDown.Items = {'Carrier', 'Non-carrier', 'Unknown', 'Het', 'homo'};
            app.GenotypeResultsDropDown.Value = 'Carrier';
            app.DeceasedTab = uitab(app.EventTabGroup);
            app.DeceasedTab.Title = 'Deceased';
            app.ReasonDropDownLabel = uilabel(app.DeceasedTab);
            app.ReasonDropDownLabel.Text = 'Reason';
            app.DeceasedReasonDropDown = uidropdown(app.DeceasedTab);
            app.DeceasedReasonDropDown.Items = {'sacrificed for experiment', 'sacrificed not needed', 'other', 'unknown'};
            app.DeceasedReasonDropDown.Value = 'sacrificed for experiment';
            app.GavebirthTab = uitab(app.EventTabGroup);
            app.GavebirthTab.Title = 'Gave birth';
            app.NumberofpupsEditFieldLabel = uilabel(app.GavebirthTab);
            app.NumberofpupsEditFieldLabel.Text = 'Number of pups';
            app.NumberofpupsEditField = uieditfield(app.GavebirthTab, 'text');
            app.AssigncageTab = uitab(app.EventTabGroup);
            app.AssigncageTab.Title = 'Assign cage';
            app.ReasonDropDown_2Label = uilabel(app.AssigncageTab);
            app.ReasonDropDown_2Label.Text = 'Reason';
            app.MoveReasonDropDown = uidropdown(app.AssigncageTab);
            app.MoveReasonDropDown.Items = {'weaning', 'experiment', 'set as breeder', 'crowding', 'other', 'unknown'};
            app.MoveReasonDropDown.Value = 'weaning';
            app.TocageEditFieldLabel = uilabel(app.AssigncageTab);
            app.TocageEditFieldLabel.Text = 'To cage #';
            app.TocageEditField = uieditfield(app.AssigncageTab, 'text');
            app.IPinjectionTab = uitab(app.EventTabGroup);
            app.IPinjectionTab.Title = 'IP injection';
            app.SubstanceDropDownLabel = uilabel(app.IPinjectionTab);
            app.SubstanceDropDownLabel.Text = 'Substance';
            app.SubstanceDropDown_IP = uidropdown(app.IPinjectionTab);
            app.ConcentrationmgKgEditFieldLabel_2 = uilabel(app.IPinjectionTab);
            app.ConcentrationmgKgEditFieldLabel_2.Text = 'Concentration (mg / Kg)';
            app.IP_inj_ConcentrationEditField = uieditfield(app.IPinjectionTab, 'text');
            app.EyeinjectionTab = uitab(app.EventTabGroup);
            app.EyeinjectionTab.Title = 'Eye injection';
            app.SubstanceDropDownLabel_2 = uilabel(app.EyeinjectionTab);
            app.SubstanceDropDownLabel_2.Text = 'Substance';
            app.SubstanceDropDown_Eye = uidropdown(app.EyeinjectionTab);
            app.EyeDropDownLabel = uilabel(app.EyeinjectionTab);
            app.EyeDropDownLabel.Text = 'Eye';
            app.WhichEyeDropDown = uidropdown(app.EyeinjectionTab);
            app.WhichEyeDropDown.Items = {'Left', 'Right'};
            app.WhichEyeDropDown.Value = 'Left';
            app.Dilution1XEditFieldLabel_2 = uilabel(app.EyeinjectionTab);
            app.Dilution1XEditFieldLabel_2.Text = 'Dilution (1:X)';
            app.Dilution_EyeEditField = uieditfield(app.EyeinjectionTab, 'text');
            app.BraininjectionTab = uitab(app.EventTabGroup);
            app.BraininjectionTab.Title = 'Brain injection';
            app.SubstanceDropDown_3Label = uilabel(app.BraininjectionTab);
            app.SubstanceDropDown_3Label.Text = 'Substance';
            app.SubstanceDropDown_Brain = uidropdown(app.BraininjectionTab);
            app.CoordinatesLabel = uilabel(app.BraininjectionTab);
            app.CoordinatesLabel.Text = 'Coordinates:';
            app.TargetDropDownLabel = uilabel(app.BraininjectionTab);
            app.TargetDropDownLabel.Text = 'Target';
            app.BrainTargetDropDown = uidropdown(app.BraininjectionTab);
            app.BrainTargetDropDown.Items = {'MeA', 'LHA', 'OPN', 'vLGN', 'dLGN', 'SC'};
            app.BrainTargetDropDown.Value = 'MeA';
            app.HemisphereDropDownLabel = uilabel(app.BraininjectionTab);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.BraininjectionTab);
            app.HemisphereDropDown.Items = {'Left', 'Right'};
            app.HemisphereDropDown.Value = 'Left';
            app.Dilution1XEditFieldLabel_3 = uilabel(app.BraininjectionTab);
            app.Dilution1XEditFieldLabel_3.Text = 'Dilution (1:X)';
            app.Dilution_BrainEditField = uieditfield(app.BraininjectionTab, 'text');
            app.APEditField_2Label = uilabel(app.BraininjectionTab);
            app.APEditField_2Label.Text = 'AP';
            app.APEditField = uieditfield(app.BraininjectionTab, 'text');
            app.MLEditField_2Label = uilabel(app.BraininjectionTab);
            app.MLEditField_2Label.Text = 'ML';
            app.MLEditField = uieditfield(app.BraininjectionTab, 'text');
            app.DVEditField_2Label = uilabel(app.BraininjectionTab);
            app.DVEditField_2Label.Text = 'DV';
            app.DVEditField = uieditfield(app.BraininjectionTab, 'text');
            app.AngleEditField_2Label = uilabel(app.BraininjectionTab);
            app.AngleEditField_2Label.Text = 'Angle';
            app.AngleEditField = uieditfield(app.BraininjectionTab, 'text');
            app.HandledTab = uitab(app.EventTabGroup);
            app.HandledTab.Title = 'Handled';
            app.DurtionminsEditFieldLabel = uilabel(app.HandledTab);
            app.DurtionminsEditFieldLabel.Text = 'Durtion (mins)';
            app.HandlingDurationEditField = uieditfield(app.HandledTab, 'text');
            app.SocialbehaviorsessionTab = uitab(app.EventTabGroup);
            app.SocialbehaviorsessionTab.Title = 'Social behavior session';
            app.RootfilenameEditFieldLabel = uilabel(app.SocialbehaviorsessionTab);
            app.RootfilenameEditFieldLabel.Text = 'Root filename';
            app.SocialB_filenameEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.RecordBehavior_switch = uiswitch(app.SocialbehaviorsessionTab, 'rocker');
            app.RecordBehavior_switch.Items = {'Not recorded', 'Recorded'};
            app.RecordBehavior_switch.ValueChangedFcn = createCallbackFcn(app, @RecordBehavior_switchValueChanged, true);
            app.RecordBehavior_switch.Value = 'Recorded';
            app.StimulusanimalIDs0foremptyLabel = uilabel(app.SocialbehaviorsessionTab);
            app.StimulusanimalIDs0foremptyLabel.Text = 'Stimulus animal IDs (0 for empty)';
            app.Lamp_isRecorded = uilamp(app.SocialbehaviorsessionTab);
            app.ExperimenttypeDropDownLabel = uilabel(app.SocialbehaviorsessionTab);
            app.ExperimenttypeDropDownLabel.Text = 'Experiment type';
            app.ExperimenttypeDropDown = uidropdown(app.SocialbehaviorsessionTab);
            app.DurationminsEditFieldLabel_2 = uilabel(app.SocialbehaviorsessionTab);
            app.DurationminsEditFieldLabel_2.Text = 'Duration (mins)';
            app.SocialB_DurationminsEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.AEditFieldLabel_2 = uilabel(app.SocialbehaviorsessionTab);
            app.AEditFieldLabel_2.Text = 'A';
            app.AnimalAEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.BLabel = uilabel(app.SocialbehaviorsessionTab);
            app.BLabel.Text = 'B';
            app.AnimalBEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.CLabel = uilabel(app.SocialbehaviorsessionTab);
            app.CLabel.Text = 'C';
            app.AnimalCEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.ReserveforprojectTab = uitab(app.EventTabGroup);
            app.ReserveforprojectTab.Title = 'Reserve for project';
            app.ProjectDropDownLabel = uilabel(app.ReserveforprojectTab);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.ReserveforprojectTab);
            app.ReserveforrigTab = uitab(app.EventTabGroup);
            app.ReserveforrigTab.Title = 'Reserve for rig';
            app.RignameDropDownLabel = uilabel(app.ReserveforrigTab);
            app.RignameDropDownLabel.Text = 'Rig name';
            app.RignameDropDown = uidropdown(app.ReserveforrigTab);
            app.CalendarrefreshtokenEditFieldLabel = uilabel(app.ReserveforrigTab);
            app.CalendarrefreshtokenEditFieldLabel.Text = 'Calendar refresh token';
            app.CalendarrefreshtokenEditField = uieditfield(app.ReserveforrigTab, 'text');
            app.ReauthorizeButton = uibutton(app.ReserveforrigTab, 'push');
            app.ReauthorizeButton.ButtonPushedFcn = createCallbackFcn(app, @ReauthorizeButtonPushed, true);
            app.ReauthorizeButton.Text = 'Reauthorize';
            app.AnimalSelectionPanel = uipanel(app.AnimalLoggerTab);
            app.AnimalSelectionPanel.Title = 'Animal Selection';
            app.SearchButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.FilterPanel = uipanel(app.AnimalSelectionPanel);
            app.FilterPanel.Title = 'Filter';
            app.GenotypeDropDownLabel = uilabel(app.FilterPanel);
            app.GenotypeDropDownLabel.Text = 'Genotype';
            app.Filter_GenotypeDropDown = uidropdown(app.FilterPanel);
            app.Filter_GenotypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_GenotypeDropDownValueChanged, true);
            app.StatusDropDownLabel = uilabel(app.FilterPanel);
            app.StatusDropDownLabel.Text = 'Status';
            app.Filter_GenotypeStatusDropDown = uidropdown(app.FilterPanel);
            app.Filter_GenotypeStatusDropDown.Items = {'All', 'carrier', 'non-carrier', 'unknown', 'het', 'Homo'};
            app.Filter_GenotypeStatusDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_GenotypeStatusDropDownValueChanged, true);
            app.Filter_GenotypeStatusDropDown.Value = 'All';
            app.EyeInjSwitchLabel = uilabel(app.FilterPanel);
            app.EyeInjSwitchLabel.Text = 'Eye Inj.';
            app.EyeInjSwitch = uiswitch(app.FilterPanel, 'toggle');
            app.EyeInjSwitch.Items = {'Don''t check', 'Y'};
            app.EyeInjSwitch.ValueChangedFcn = createCallbackFcn(app, @EyeInjSwitchValueChanged, true);
            app.EyeInjSwitch.Value = 'Don''t check';
            app.BehaviorexpSwitchLabel = uilabel(app.FilterPanel);
            app.BehaviorexpSwitchLabel.Text = 'Behavior exp.';
            app.BehaviorexpSwitch = uiswitch(app.FilterPanel, 'toggle');
            app.BehaviorexpSwitch.Items = {'Don''t check', 'Y'};
            app.BehaviorexpSwitch.ValueChangedFcn = createCallbackFcn(app, @BehaviorexpSwitchValueChanged, true);
            app.BehaviorexpSwitch.Value = 'Don''t check';
            app.SexDropDownLabel = uilabel(app.FilterPanel);
            app.SexDropDownLabel.Text = 'Sex';
            app.Filter_SexDropDown = uidropdown(app.FilterPanel);
            app.Filter_SexDropDown.Items = {'All', 'Male', 'Female'};
            app.Filter_SexDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_SexDropDownValueChanged, true);
            app.Filter_SexDropDown.Value = 'All';
            app.BraininjSwitchLabel = uilabel(app.FilterPanel);
            app.BraininjSwitchLabel.Text = 'Brain inj.';
            app.BraininjSwitch = uiswitch(app.FilterPanel, 'toggle');
            app.BraininjSwitch.Items = {'Don''t check', 'Y'};
            app.BraininjSwitch.ValueChangedFcn = createCallbackFcn(app, @BraininjSwitchValueChanged, true);
            app.BraininjSwitch.Value = 'Don''t check';
            app.NotreservedSwitchLabel = uilabel(app.FilterPanel);
            app.NotreservedSwitchLabel.Text = 'Not reserved';
            app.NotreservedSwitch = uiswitch(app.FilterPanel, 'toggle');
            app.NotreservedSwitch.Items = {'Don''t check', 'Y'};
            app.NotreservedSwitch.ValueChangedFcn = createCallbackFcn(app, @NotreservedSwitchValueChanged, true);
            app.NotreservedSwitch.Value = 'Don''t check';
            app.ProjectLabel = uilabel(app.FilterPanel);
            app.ProjectLabel.Text = 'Project';
            app.Filter_ProjectDropDown = uidropdown(app.FilterPanel);
            app.Filter_ProjectDropDown.Items = {'All', 'Male', 'Female'};
            app.Filter_ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_ProjectDropDownValueChanged, true);
            app.Filter_ProjectDropDown.Value = 'All';
            app.ResetButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ID_type_switch = uiswitch(app.AnimalSelectionPanel, 'slider');
            app.ID_type_switch.Items = {'Tag ID', 'DJ ID'};
            app.ID_type_switch.ValueChangedFcn = createCallbackFcn(app, @ID_type_switchValueChanged, true);
            app.ID_type_switch.Value = 'Tag ID';
            app.AgerangeweeksPanel = uipanel(app.AnimalSelectionPanel);
            app.AgerangeweeksPanel.Title = 'Age range (weeks)';
            app.toLabel = uilabel(app.AgerangeweeksPanel);
            app.toLabel.Text = 'to';
            app.ageLow_edit = uieditfield(app.AgerangeweeksPanel, 'text');
            app.ageLow_edit.ValueChangedFcn = createCallbackFcn(app, @ageLow_editValueChanged, true);
            app.ageHigh_edit = uieditfield(app.AgerangeweeksPanel, 'text');
            app.ageHigh_edit.ValueChangedFcn = createCallbackFcn(app, @ageHigh_editValueChanged, true);
            app.Search_DJIDEditField = uieditfield(app.AnimalSelectionPanel, 'text');
            app.Search_TagIDEditField = uieditfield(app.AnimalSelectionPanel, 'text');
            app.PersonDropDownLabel = uilabel(app.AnimalLoggerTab);
            app.PersonDropDownLabel.Text = 'Person';
            app.PersonDropDown = uidropdown(app.AnimalLoggerTab);
            app.PersonDropDown.Items = {'David', 'Zach', 'Devon', 'Greg', 'Kayla', 'Sam', 'Sophia', 'Susan'};
            app.PersonDropDown.Value = 'David';
            app.EventdateDatePickerLabel = uilabel(app.AnimalLoggerTab);
            app.EventdateDatePickerLabel.Text = 'Event date';
            app.EventdateDatePicker = uidatepicker(app.AnimalLoggerTab);
            app.EventdateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SubmitevententryButton = uibutton(app.AnimalLoggerTab, 'push');
            app.SubmitevententryButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitevententryButtonPushed, true);
            app.SubmitevententryButton.Text = 'Submit event entry';
            app.NotesTextArea_2Label = uilabel(app.AnimalLoggerTab);
            app.NotesTextArea_2Label.Text = 'Notes';
            app.EventNotesTextArea = uitextarea(app.AnimalLoggerTab);
            app.EventTimeM = uidropdown(app.AnimalLoggerTab);
            app.EventTimeM.Items = {'00', '15', '30', '45'};
            app.EventTimeM.Value = '00';
            app.EventTimeAMPM = uidropdown(app.AnimalLoggerTab);
            app.EventTimeAMPM.Items = {'AM', 'PM'};
            app.EventTimeAMPM.Value = 'AM';
            app.TimeLabel_2 = uilabel(app.AnimalLoggerTab);
            app.TimeLabel_2.Text = 'Time';
            app.AlleventsforselectedanimalButton = uibutton(app.AnimalLoggerTab, 'push');
            app.AlleventsforselectedanimalButton.ButtonPushedFcn = createCallbackFcn(app, @AlleventsforselectedanimalButtonPushed, true);
            app.AlleventsforselectedanimalButton.Text = 'All events for selected animal';
            app.EventTimeH = uidropdown(app.AnimalLoggerTab);
            app.EventTimeH.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.EventTimeH.Value = '9';
            app.ViewlogsLabel = uilabel(app.AnimalLoggerTab);
            app.ViewlogsLabel.Text = 'View logs';
            app.EventsforallanimalsButton = uibutton(app.AnimalLoggerTab, 'push');
            app.EventsforallanimalsButton.ButtonPushedFcn = createCallbackFcn(app, @EventsforallanimalsButtonPushed, true);
            app.EventsforallanimalsButton.Text = 'Events for all animals';
            app.TypeDropDownLabel = uilabel(app.AnimalLoggerTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.EventTypeDropDown = uidropdown(app.AnimalLoggerTab);
            app.StatusLampLabel = uilabel(app.AnimalLoggerTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp_Logger = uilamp(app.AnimalLoggerTab);
            app.EventInsertStatusEdit = uieditfield(app.AnimalLoggerTab, 'text');
            app.EventInsertStatusEdit.Editable = 'off';
            app.AddAnimalTab = uitab(app.MainTabGroup);
            app.AddAnimalTab.Title = 'Add Animal';
            app.SpeciesTabGroup = uitabgroup(app.AddAnimalTab);
            app.LabmouseTab = uitab(app.SpeciesTabGroup);
            app.LabmouseTab.Title = 'Lab mouse';
            app.tagged_switch = uiswitch(app.LabmouseTab, 'slider');
            app.tagged_switch.Items = {'Untagged', 'Tagged'};
            app.tagged_switch.ValueChangedFcn = createCallbackFcn(app, @tagged_switchValueChanged, true);
            app.tagged_switch.Value = 'Tagged';
            app.SexButtonGroup = uibuttongroup(app.LabmouseTab);
            app.SexButtonGroup.Title = 'Sex';
            app.UnknownButton = uiradiobutton(app.SexButtonGroup);
            app.UnknownButton.Text = 'Unknown';
            app.UnknownButton.Value = true;
            app.MaleButton = uiradiobutton(app.SexButtonGroup);
            app.MaleButton.Text = 'Male';
            app.FemaleButton = uiradiobutton(app.SexButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.EarpunchButtonGroup = uibuttongroup(app.LabmouseTab);
            app.EarpunchButtonGroup.Title = 'Ear punch';
            app.NoneButton = uiradiobutton(app.EarpunchButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.LButton = uiradiobutton(app.EarpunchButtonGroup);
            app.LButton.Text = 'L';
            app.RButton = uiradiobutton(app.EarpunchButtonGroup);
            app.RButton.Text = 'R';
            app.BothButton = uiradiobutton(app.EarpunchButtonGroup);
            app.BothButton.Text = 'Both';
            app.DOB_known_switch = uiswitch(app.LabmouseTab, 'slider');
            app.DOB_known_switch.Items = {'DOB unknown', 'DOB known'};
            app.DOB_known_switch.ValueChangedFcn = createCallbackFcn(app, @DOB_known_switchValueChanged, true);
            app.DOB_known_switch.Value = 'DOB known';
            app.GenotypeDropDownLabel_2 = uilabel(app.LabmouseTab);
            app.GenotypeDropDownLabel_2.Text = 'Genotype';
            app.GenotypeDropDown = uidropdown(app.LabmouseTab);
            app.TagIDEditField_2Label = uilabel(app.LabmouseTab);
            app.TagIDEditField_2Label.Text = 'Tag ID #';
            app.TagIDEditField = uieditfield(app.LabmouseTab, 'numeric');
            app.TagIDEditField.Limits = [0 Inf];
            app.TagIDEditField.RoundFractionalValues = 'on';
            app.TagIDEditField.ValueDisplayFormat = '%.0f';
            app.CageEditFieldLabel = uilabel(app.LabmouseTab);
            app.CageEditFieldLabel.Text = 'Cage #';
            app.CageEditField = uieditfield(app.LabmouseTab, 'numeric');
            app.CageEditField.Limits = [0 Inf];
            app.CageEditField.RoundFractionalValues = 'on';
            app.CageEditField.ValueDisplayFormat = '%.0f';
            app.DOBDatePickerLabel = uilabel(app.LabmouseTab);
            app.DOBDatePickerLabel.Text = 'DOB';
            app.DOBDatePicker = uidatepicker(app.LabmouseTab);
            app.DOBDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SparrowTab = uitab(app.SpeciesTabGroup);
            app.SparrowTab.Title = 'Sparrow';
            app.PerhapssomedayLabel = uilabel(app.SparrowTab);
            app.PerhapssomedayLabel.Text = 'Perhaps someday';
            app.AddanimaltodatabaseButton = uibutton(app.AddAnimalTab, 'push');
            app.AddanimaltodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddanimaltodatabaseButtonPushed, true);
            app.AddanimaltodatabaseButton.Text = 'Add animal to database';
            app.StatusLampLabel_2 = uilabel(app.AddAnimalTab);
            app.StatusLampLabel_2.Text = 'Status';
            app.StatusLamp_Animal = uilamp(app.AddAnimalTab);
            app.AnimalInsertStatusEdit = uieditfield(app.AddAnimalTab, 'text');
            app.AnimalInsertStatusEdit.Editable = 'off';
            app.AddGenotypeTab = uitab(app.MainTabGroup);
            app.AddGenotypeTab.Title = 'Add Genotype';
            app.GenotypenameEditFieldLabel = uilabel(app.AddGenotypeTab);
            app.GenotypenameEditFieldLabel.Text = 'Genotype name';
            app.GenotypenameEditField = uieditfield(app.AddGenotypeTab, 'text');
            app.DescriptionTextAreaLabel = uilabel(app.AddGenotypeTab);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.AddGenotypeTab);
            app.SourceEditFieldLabel = uilabel(app.AddGenotypeTab);
            app.SourceEditFieldLabel.Text = 'Source';
            app.SourceEditField = uieditfield(app.AddGenotypeTab, 'text');
            app.CatalogEditFieldLabel = uilabel(app.AddGenotypeTab);
            app.CatalogEditFieldLabel.Text = 'Catalog #';
            app.CatalogEditField = uieditfield(app.AddGenotypeTab, 'text');
            app.AddgenotypetodatabaseButton = uibutton(app.AddGenotypeTab, 'push');
            app.AddgenotypetodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddgenotypetodatabaseButtonPushed, true);
            app.AddgenotypetodatabaseButton.Text = 'Add genotype to database';
            app.GenotypesindatabaseListBoxLabel = uilabel(app.AddGenotypeTab);
            app.GenotypesindatabaseListBoxLabel.Text = 'Genotypes in database';
            app.GenotypesindatabaseListBox = uilistbox(app.AddGenotypeTab);
            app.GenotypesindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @GenotypesindatabaseListBoxValueChanged, true);
            app.StatusLampLabel_3 = uilabel(app.AddGenotypeTab);
            app.StatusLampLabel_3.Text = 'Status';
            app.StatusLamp_Genotype = uilamp(app.AddGenotypeTab);
            app.GenotypeInsertStatusEdit = uieditfield(app.AddGenotypeTab, 'text');
            app.GenotypeInsertStatusEdit.Editable = 'off';
            app.AddSubstanceTab = uitab(app.MainTabGroup);
            app.AddSubstanceTab.Title = 'Add Substance';
            app.SubstancenameEditFieldLabel = uilabel(app.AddSubstanceTab);
            app.SubstancenameEditFieldLabel.Text = 'Substance name';
            app.SubstancenameEditField = uieditfield(app.AddSubstanceTab, 'text');
            app.DescriptionTextArea_2Label = uilabel(app.AddSubstanceTab);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.DescriptionTextArea_substance = uitextarea(app.AddSubstanceTab);
            app.SourceEditField_2Label = uilabel(app.AddSubstanceTab);
            app.SourceEditField_2Label.Text = 'Source';
            app.SourceEditField_substance = uieditfield(app.AddSubstanceTab, 'text');
            app.CatalogEditField_2Label = uilabel(app.AddSubstanceTab);
            app.CatalogEditField_2Label.Text = 'Catalog #';
            app.CatalogEditField_substance = uieditfield(app.AddSubstanceTab, 'text');
            app.StoragelocationLabel = uilabel(app.AddSubstanceTab);
            app.StoragelocationLabel.Text = 'Storage location';
            app.StorageLocationTextArea_substance = uitextarea(app.AddSubstanceTab);
            app.AddsubstancetodatabaseButton = uibutton(app.AddSubstanceTab, 'push');
            app.AddsubstancetodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddsubstancetodatabaseButtonPushed, true);
            app.AddsubstancetodatabaseButton.Text = 'Add substance to database';
            app.SubstancesindatabaseListBoxLabel = uilabel(app.AddSubstanceTab);
            app.SubstancesindatabaseListBoxLabel.Text = 'Substances in database';
            app.SubstancesindatabaseListBox = uilistbox(app.AddSubstanceTab);
            app.SubstancesindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @SubstancesindatabaseListBoxValueChanged, true);
            app.StatusLampLabel_4 = uilabel(app.AddSubstanceTab);
            app.StatusLampLabel_4.Text = 'Status';
            app.StatusLamp_Substance = uilamp(app.AddSubstanceTab);
            app.SubstanceInsertStatusEdit = uieditfield(app.AddSubstanceTab, 'text');
            app.SubstanceInsertStatusEdit.Editable = 'off';
            app.AddBrainAreaTab = uitab(app.MainTabGroup);
            app.AddBrainAreaTab.Title = 'Add Brain Area';
            app.AbbreviationEditFieldLabel = uilabel(app.AddBrainAreaTab);
            app.AbbreviationEditFieldLabel.Text = 'Abbreviation';
            app.AbbreviationEditField = uieditfield(app.AddBrainAreaTab, 'text');
            app.LongnameLabel = uilabel(app.AddBrainAreaTab);
            app.LongnameLabel.Text = 'Long name';
            app.BrainLongNameTextArea = uitextarea(app.AddBrainAreaTab);
            app.BrainareasindatabaseListBoxLabel = uilabel(app.AddBrainAreaTab);
            app.BrainareasindatabaseListBoxLabel.Text = 'Brain areas in database';
            app.BrainareasindatabaseListBox = uilistbox(app.AddBrainAreaTab);
            app.BrainareasindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @BrainareasindatabaseListBoxValueChanged, true);
            app.AddbrainareatodatabaseButton = uibutton(app.AddBrainAreaTab, 'push');
            app.AddbrainareatodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddbrainareatodatabaseButtonPushed, true);
            app.AddbrainareatodatabaseButton.Text = 'Add brain area to database';
            app.StatusLampLabel_5 = uilabel(app.AddBrainAreaTab);
            app.StatusLampLabel_5.Text = 'Status';
            app.StatusLamp_BrainArea = uilamp(app.AddBrainAreaTab);
            app.BrainAreaInsertStatusEdit = uieditfield(app.AddBrainAreaTab, 'text');
            app.BrainAreaInsertStatusEdit.Editable = 'off';
            app.LogTab = uitab(app.MainTabGroup);
            app.LogTab.Title = 'Log';
            app.LogTextArea = uitextarea(app.LogTab);
            app.LogTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnimalInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end