classdef AnimalInterfaceV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnimalInterfaceV2UIFigure       matlab.ui.Figure
        MainTabGroup                    matlab.ui.container.TabGroup
        LoginTab                        matlab.ui.container.Tab
        LoginUserEditField              matlab.ui.control.EditField
        UserEditFieldLabel              matlab.ui.control.Label
        LoginButton                     matlab.ui.control.Button
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        AnimalLoggerTab                 matlab.ui.container.Tab
        ExporttoExcelButton             matlab.ui.control.Button
        EventInsertStatusEdit           matlab.ui.control.EditField
        StatusLamp_Logger               matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        EventTypeDropDown               matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        EventsforallanimalsButton       matlab.ui.control.Button
        ViewlogsLabel                   matlab.ui.control.Label
        EventTimeH                      matlab.ui.control.DropDown
        AlleventsforselectedanimalButton  matlab.ui.control.Button
        TimeLabel_2                     matlab.ui.control.Label
        EventTimeAMPM                   matlab.ui.control.DropDown
        EventTimeM                      matlab.ui.control.DropDown
        EventNotesTextArea              matlab.ui.control.TextArea
        NotesTextArea_2Label            matlab.ui.control.Label
        SubmitevententryButton          matlab.ui.control.Button
        EventdateDatePicker             matlab.ui.control.DatePicker
        EventdateDatePickerLabel        matlab.ui.control.Label
        PersonDropDown                  matlab.ui.control.DropDown
        PersonDropDownLabel             matlab.ui.control.Label
        AnimalSelectionPanel            matlab.ui.container.Panel
        SearchTypeButtonGroup           matlab.ui.container.ButtonGroup
        TagButton                       matlab.ui.control.RadioButton
        CageButton                      matlab.ui.control.RadioButton
        DJIDButton                      matlab.ui.control.RadioButton
        CageNumSearchEditField          matlab.ui.control.EditField
        Search_TagIDEditField           matlab.ui.control.EditField
        Search_DJIDEditField            matlab.ui.control.EditField
        ResetButton                     matlab.ui.control.Button
        FilterPanel                     matlab.ui.container.Panel
        Filter_ProtocolDropDown         matlab.ui.control.DropDown
        ProjectLabel_2                  matlab.ui.control.Label
        Filter_BgDropDown               matlab.ui.control.DropDown
        BgLabel                         matlab.ui.control.Label
        IPInjectionButtonGroup          matlab.ui.container.ButtonGroup
        NoButton_5                      matlab.ui.control.RadioButton
        YesButton_5                     matlab.ui.control.RadioButton
        AnyButton_5                     matlab.ui.control.RadioButton
        BehaviorsessionButtonGroup      matlab.ui.container.ButtonGroup
        NoButton_4                      matlab.ui.control.RadioButton
        YesButton_4                     matlab.ui.control.RadioButton
        AnyButton_4                     matlab.ui.control.RadioButton
        RigreservationButtonGroup       matlab.ui.container.ButtonGroup
        NoButton_3                      matlab.ui.control.RadioButton
        YesButton_3                     matlab.ui.control.RadioButton
        AnyButton_3                     matlab.ui.control.RadioButton
        BrainInjectionButtonGroup       matlab.ui.container.ButtonGroup
        NoButton_2                      matlab.ui.control.RadioButton
        YesButton_2                     matlab.ui.control.RadioButton
        AnyButton_2                     matlab.ui.control.RadioButton
        EyeInjectionButtonGroup         matlab.ui.container.ButtonGroup
        NoButton                        matlab.ui.control.RadioButton
        YesButton                       matlab.ui.control.RadioButton
        AnyButton                       matlab.ui.control.RadioButton
        AgerangeweeksPanel              matlab.ui.container.Panel
        ageHigh_edit                    matlab.ui.control.EditField
        ageLow_edit                     matlab.ui.control.EditField
        toLabel                         matlab.ui.control.Label
        Filter_ProjectDropDown          matlab.ui.control.DropDown
        ProjectLabel                    matlab.ui.control.Label
        Filter_SexDropDown              matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        Filter_GenotypeStatusDropDown   matlab.ui.control.DropDown
        StatusDropDownLabel             matlab.ui.control.Label
        Filter_GenotypeDropDown         matlab.ui.control.DropDown
        AlleleLabel                     matlab.ui.control.Label
        SearchButton                    matlab.ui.control.Button
        EventTabGroup                   matlab.ui.container.TabGroup
        GenotypedTab                    matlab.ui.container.Tab
        Allele2DropDown_4               matlab.ui.control.DropDown
        Allele2DropDown_3Label          matlab.ui.control.Label
        Allele2DropDown_3               matlab.ui.control.DropDown
        Allele2DropDown_2Label          matlab.ui.control.Label
        Allele1DropDown_4               matlab.ui.control.DropDown
        Allele1DropDown_3Label          matlab.ui.control.Label
        LocusDropDown_4                 matlab.ui.control.DropDown
        LocusDropDown_2Label_5          matlab.ui.control.Label
        Allele1DropDown_3               matlab.ui.control.DropDown
        Allele1DropDown_2Label          matlab.ui.control.Label
        LocusDropDown_3                 matlab.ui.control.DropDown
        LocusDropDown_2Label_4          matlab.ui.control.Label
        Allele2DropDown_2               matlab.ui.control.DropDown
        Allele2DropDown_4Label          matlab.ui.control.Label
        Allele2DropDown_1               matlab.ui.control.DropDown
        Allele2DropDownLabel            matlab.ui.control.Label
        Allele1DropDown_2               matlab.ui.control.DropDown
        Allele1DropDown_4Label          matlab.ui.control.Label
        LocusDropDown_2                 matlab.ui.control.DropDown
        LocusDropDown_2Label            matlab.ui.control.Label
        GenotypeEntriesSpinner          matlab.ui.control.Spinner
        EntriesSpinnerLabel             matlab.ui.control.Label
        Allele1DropDown_1               matlab.ui.control.DropDown
        SourceDropDown_2Label_3         matlab.ui.control.Label
        LocusDropDown_1                 matlab.ui.control.DropDown
        SourceDropDown_2Label_2         matlab.ui.control.Label
        GenotypeSourceDropDown          matlab.ui.control.DropDown
        SourceDropDown_2Label           matlab.ui.control.Label
        DeceasedTab                     matlab.ui.container.Tab
        DeceasedReasonDropDown          matlab.ui.control.DropDown
        ReasonDropDownLabel             matlab.ui.control.Label
        AssigncageroomTab               matlab.ui.container.Tab
        RoomDropDown_logger             matlab.ui.control.DropDown
        RoomDropDown_2Label             matlab.ui.control.Label
        TocageEditField                 matlab.ui.control.EditField
        TocageEditFieldLabel            matlab.ui.control.Label
        MoveReasonDropDown              matlab.ui.control.DropDown
        ReasonDropDown_2Label           matlab.ui.control.Label
        AssigntoanimalprotocolTab       matlab.ui.container.Tab
        AnimalprotocolDropDown          matlab.ui.control.DropDown
        AnimalprotocolDropDownLabel     matlab.ui.control.Label
        TagTab                          matlab.ui.container.Tab
        TagearDropDown                  matlab.ui.control.DropDown
        TagearDropDownLabel             matlab.ui.control.Label
        EarpunchDropDown                matlab.ui.control.DropDown
        EarpunchDropDownLabel           matlab.ui.control.Label
        TagEditField                    matlab.ui.control.EditField
        TagEditFieldLabel               matlab.ui.control.Label
        IPinjectionTab                  matlab.ui.container.Tab
        IP_inj_ConcentrationEditField   matlab.ui.control.EditField
        ConcentrationmgKgEditFieldLabel_2  matlab.ui.control.Label
        SubstanceDropDown_IP            matlab.ui.control.DropDown
        SubstanceDropDownLabel          matlab.ui.control.Label
        EyeinjectionTab                 matlab.ui.container.Tab
        Dilution_EyeEditField           matlab.ui.control.EditField
        Dilution1XEditFieldLabel_2      matlab.ui.control.Label
        WhichEyeDropDown                matlab.ui.control.DropDown
        EyeDropDownLabel                matlab.ui.control.Label
        SubstanceDropDown_Eye           matlab.ui.control.DropDown
        SubstanceDropDownLabel_2        matlab.ui.control.Label
        BraininjectionTab               matlab.ui.container.Tab
        AngleEditField                  matlab.ui.control.EditField
        AngleEditField_2Label           matlab.ui.control.Label
        DVEditField                     matlab.ui.control.EditField
        DVEditField_2Label              matlab.ui.control.Label
        MLEditField                     matlab.ui.control.EditField
        MLEditField_2Label              matlab.ui.control.Label
        APEditField                     matlab.ui.control.EditField
        APEditField_2Label              matlab.ui.control.Label
        Dilution_BrainEditField         matlab.ui.control.EditField
        Dilution1XEditFieldLabel_3      matlab.ui.control.Label
        HemisphereDropDown              matlab.ui.control.DropDown
        HemisphereDropDownLabel         matlab.ui.control.Label
        BrainTargetDropDown             matlab.ui.control.DropDown
        TargetDropDownLabel             matlab.ui.control.Label
        CoordinatesLabel                matlab.ui.control.Label
        SubstanceDropDown_Brain         matlab.ui.control.DropDown
        SubstanceDropDown_3Label        matlab.ui.control.Label
        FeedTab                         matlab.ui.container.Tab
        SubstanceDropDown_Feed          matlab.ui.control.DropDown
        SubstanceDropDownLabel_3        matlab.ui.control.Label
        SocialbehaviorsessionTab        matlab.ui.container.Tab
        WindowCStim                     matlab.ui.control.DropDown
        WindowBStim                     matlab.ui.control.DropDown
        WindowAStim                     matlab.ui.control.DropDown
        CLabel                          matlab.ui.control.Label
        BLabel                          matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        WindowLabel                     matlab.ui.control.Label
        DJIDLabel                       matlab.ui.control.Label
        TestanimaltypeDropDown          matlab.ui.control.DropDown
        TestanimaltypeDropDownLabel     matlab.ui.control.Label
        AnimalCEditField                matlab.ui.control.EditField
        AnimalBEditField                matlab.ui.control.EditField
        AnimalAEditField                matlab.ui.control.EditField
        ExperimenttypeDropDown          matlab.ui.control.DropDown
        ExperimenttypeDropDownLabel     matlab.ui.control.Label
        Lamp_isRecorded                 matlab.ui.control.Lamp
        StimulusLabel                   matlab.ui.control.Label
        RecordBehavior_switch           matlab.ui.control.RockerSwitch
        ReserveforprojectTab            matlab.ui.container.Tab
        ProjectDropDown                 matlab.ui.control.DropDown
        ProjectDropDownLabel            matlab.ui.control.Label
        ReserveforrigTab                matlab.ui.container.Tab
        ReauthorizeButton               matlab.ui.control.Button
        CalendarrefreshtokenEditField   matlab.ui.control.EditField
        CalendarrefreshtokenEditFieldLabel  matlab.ui.control.Label
        RignameDropDown                 matlab.ui.control.DropDown
        RignameDropDownLabel            matlab.ui.control.Label
        PairbreedersTab_2               matlab.ui.container.Tab
        RoomDropDown_Pair               matlab.ui.control.DropDown
        RoomDropDown_2Label_5           matlab.ui.control.Label
        NewcagenumberPairEditField      matlab.ui.control.EditField
        NewcagenumberEditFieldLabel     matlab.ui.control.Label
        FemaleDJIDPairEditField         matlab.ui.control.EditField
        FemaleDJIDEditFieldLabel        matlab.ui.control.Label
        MaleDJIDPairEditField           matlab.ui.control.EditField
        MaleDJIDEditFieldLabel          matlab.ui.control.Label
        AnimalTable                     matlab.ui.control.Table
        BreedersTab                     matlab.ui.container.Tab
        GenotypeDropDown_BreedersTab    matlab.ui.control.DropDown
        GenotypeDropDown_2Label         matlab.ui.control.Label
        BreedingpairsLabel              matlab.ui.control.Label
        BreederPairs_status             matlab.ui.control.Label
        SearchTypeButtonGroup_breeders  matlab.ui.container.ButtonGroup
        TagButton_breeders              matlab.ui.control.RadioButton
        CageButton_breeders             matlab.ui.control.RadioButton
        DJIDButton_breeders             matlab.ui.control.RadioButton
        CageNumSearchEditField_breeders  matlab.ui.control.EditField
        Search_TagIDEditField_breeders  matlab.ui.control.EditField
        Search_DJIDEditField_breeders   matlab.ui.control.EditField
        ResetButton_breeders            matlab.ui.control.Button
        SearchButton_breeders           matlab.ui.control.Button
        EventTabGroup_breeders          matlab.ui.container.TabGroup
        SeparatebreedingpairTab         matlab.ui.container.Tab
        RoomDropDown_breeders_sepFemale  matlab.ui.control.DropDown
        RoomDropDown_2Label_4           matlab.ui.control.Label
        RoomDropDown_breeders_sepMale   matlab.ui.control.DropDown
        RoomDropDown_2Label_3           matlab.ui.control.Label
        NewcageforfemaleEditField       matlab.ui.control.EditField
        NewcageforfemaleEditFieldLabel  matlab.ui.control.Label
        NewcageformaleEditField         matlab.ui.control.EditField
        NewcageformaleEditFieldLabel    matlab.ui.control.Label
        SacrificebreedingpairTab        matlab.ui.container.Tab
        LogbirthTab                     matlab.ui.container.Tab
        LogweaningTab                   matlab.ui.container.Tab
        NumberofpupsEditField_weaned    matlab.ui.control.EditField
        NumberofpupsLabel               matlab.ui.control.Label
        EventNotesTextArea_breeders     matlab.ui.control.TextArea
        NotesTextArea_2Label_2          matlab.ui.control.Label
        EventdateDatePicker_breeders    matlab.ui.control.DatePicker
        EventdateDatePicker_2Label      matlab.ui.control.Label
        PersonDropDown_breeders         matlab.ui.control.DropDown
        PersonDropDown_2Label           matlab.ui.control.Label
        EventInsertStatusEdit_breeders  matlab.ui.control.EditField
        StatusLamp_breeders             matlab.ui.control.Lamp
        StatusLampLabel_6               matlab.ui.control.Label
        SubmitevententryButton_breeders  matlab.ui.control.Button
        ExporttoExcelButton_breeders    matlab.ui.control.Button
        BreederCagesTable               matlab.ui.control.Table
        AddAnimalsTab                   matlab.ui.container.Tab
        AddmultipleanimalstodatabaseButton  matlab.ui.control.Button
        AnimalInsertStatusEdit          matlab.ui.control.EditField
        StatusLamp_Animal               matlab.ui.control.Lamp
        StatusLampLabel_2               matlab.ui.control.Label
        AddsingleanimaltodatabaseButton  matlab.ui.control.Button
        SpeciesTabGroup                 matlab.ui.container.TabGroup
        MouseTab                        matlab.ui.container.Tab
        SetsourceButton                 matlab.ui.control.Button
        ExtInfo_E8                      matlab.ui.control.EditField
        SourceDropDown_E8               matlab.ui.control.DropDown
        ExtInfo_E7                      matlab.ui.control.EditField
        SourceDropDown_E7               matlab.ui.control.DropDown
        ExtInfo_E6                      matlab.ui.control.EditField
        SourceDropDown_E6               matlab.ui.control.DropDown
        ExtInfo_E5                      matlab.ui.control.EditField
        SourceDropDown_E5               matlab.ui.control.DropDown
        ExtInfo_E4                      matlab.ui.control.EditField
        SourceDropDown_E4               matlab.ui.control.DropDown
        ExtInfo_E3                      matlab.ui.control.EditField
        SourceDropDown_E3               matlab.ui.control.DropDown
        ExtInfo_E2                      matlab.ui.control.EditField
        SourceDropDown_E2               matlab.ui.control.DropDown
        ExtInfo_E1                      matlab.ui.control.EditField
        ExtinfoLabel                    matlab.ui.control.Label
        AnimalSourceDropDown            matlab.ui.control.DropDown
        ExternalInfoEditField           matlab.ui.control.EditField
        SourceinfoLabel                 matlab.ui.control.Label
        NumberofentriesSlider           matlab.ui.control.Slider
        NumberofentriesSliderLabel      matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        DOB_known_switch_E8             matlab.ui.control.Switch
        tagged_switch_E8                matlab.ui.control.Switch
        TagearButtonGroup_E8            matlab.ui.container.ButtonGroup
        UnknownButton_18                matlab.ui.control.RadioButton
        RButton_18                      matlab.ui.control.RadioButton
        LButton_18                      matlab.ui.control.RadioButton
        NoneButton_18                   matlab.ui.control.RadioButton
        PunchearButtonGroup_E8          matlab.ui.container.ButtonGroup
        BothButton_9                    matlab.ui.control.RadioButton
        RButton_17                      matlab.ui.control.RadioButton
        LButton_17                      matlab.ui.control.RadioButton
        NoneButton_17                   matlab.ui.control.RadioButton
        ProtocolDropDown_E8             matlab.ui.control.DropDown
        RoomDropDown_E8                 matlab.ui.control.DropDown
        SexButtonGroup_E8               matlab.ui.container.ButtonGroup
        FemaleButton_9                  matlab.ui.control.RadioButton
        MaleButton_9                    matlab.ui.control.RadioButton
        UnknownButton_17                matlab.ui.control.RadioButton
        CageEditField_E8                matlab.ui.control.EditField
        TagIDEditField_E8               matlab.ui.control.EditField
        DOBDatePicker_E8                matlab.ui.control.DatePicker
        BackgroundDropDown_E8           matlab.ui.control.DropDown
        DOB_known_switch_E7             matlab.ui.control.Switch
        tagged_switch_E7                matlab.ui.control.Switch
        TagearButtonGroup_E7            matlab.ui.container.ButtonGroup
        UnknownButton_16                matlab.ui.control.RadioButton
        RButton_16                      matlab.ui.control.RadioButton
        LButton_16                      matlab.ui.control.RadioButton
        NoneButton_16                   matlab.ui.control.RadioButton
        PunchearButtonGroup_E7          matlab.ui.container.ButtonGroup
        BothButton_8                    matlab.ui.control.RadioButton
        RButton_15                      matlab.ui.control.RadioButton
        LButton_15                      matlab.ui.control.RadioButton
        NoneButton_15                   matlab.ui.control.RadioButton
        ProtocolDropDown_E7             matlab.ui.control.DropDown
        RoomDropDown_E7                 matlab.ui.control.DropDown
        SexButtonGroup_E7               matlab.ui.container.ButtonGroup
        FemaleButton_8                  matlab.ui.control.RadioButton
        MaleButton_8                    matlab.ui.control.RadioButton
        UnknownButton_15                matlab.ui.control.RadioButton
        CageEditField_E7                matlab.ui.control.EditField
        TagIDEditField_E7               matlab.ui.control.EditField
        DOBDatePicker_E7                matlab.ui.control.DatePicker
        BackgroundDropDown_E7           matlab.ui.control.DropDown
        DOB_known_switch_E6             matlab.ui.control.Switch
        tagged_switch_E6                matlab.ui.control.Switch
        TagearButtonGroup_E6            matlab.ui.container.ButtonGroup
        UnknownButton_14                matlab.ui.control.RadioButton
        RButton_14                      matlab.ui.control.RadioButton
        LButton_14                      matlab.ui.control.RadioButton
        NoneButton_14                   matlab.ui.control.RadioButton
        PunchearButtonGroup_E6          matlab.ui.container.ButtonGroup
        BothButton_7                    matlab.ui.control.RadioButton
        RButton_13                      matlab.ui.control.RadioButton
        LButton_13                      matlab.ui.control.RadioButton
        NoneButton_13                   matlab.ui.control.RadioButton
        ProtocolDropDown_E6             matlab.ui.control.DropDown
        RoomDropDown_E6                 matlab.ui.control.DropDown
        SexButtonGroup_E6               matlab.ui.container.ButtonGroup
        FemaleButton_7                  matlab.ui.control.RadioButton
        MaleButton_7                    matlab.ui.control.RadioButton
        UnknownButton_13                matlab.ui.control.RadioButton
        CageEditField_E6                matlab.ui.control.EditField
        TagIDEditField_E6               matlab.ui.control.EditField
        Label_6                         matlab.ui.control.Label
        DOBDatePicker_E6                matlab.ui.control.DatePicker
        BackgroundDropDown_E6           matlab.ui.control.DropDown
        DOB_known_switch_E5             matlab.ui.control.Switch
        tagged_switch_E5                matlab.ui.control.Switch
        TagearButtonGroup_E5            matlab.ui.container.ButtonGroup
        UnknownButton_12                matlab.ui.control.RadioButton
        RButton_12                      matlab.ui.control.RadioButton
        LButton_12                      matlab.ui.control.RadioButton
        NoneButton_12                   matlab.ui.control.RadioButton
        PunchearButtonGroup_E5          matlab.ui.container.ButtonGroup
        BothButton_6                    matlab.ui.control.RadioButton
        RButton_11                      matlab.ui.control.RadioButton
        LButton_11                      matlab.ui.control.RadioButton
        NoneButton_11                   matlab.ui.control.RadioButton
        ProtocolDropDown_E5             matlab.ui.control.DropDown
        RoomDropDown_E5                 matlab.ui.control.DropDown
        SexButtonGroup_E5               matlab.ui.container.ButtonGroup
        FemaleButton_6                  matlab.ui.control.RadioButton
        MaleButton_6                    matlab.ui.control.RadioButton
        UnknownButton_11                matlab.ui.control.RadioButton
        CageEditField_E5                matlab.ui.control.EditField
        TagIDEditField_E5               matlab.ui.control.EditField
        Label_5                         matlab.ui.control.Label
        DOBDatePicker_E5                matlab.ui.control.DatePicker
        BackgroundDropDown_E5           matlab.ui.control.DropDown
        DOB_known_switch_E4             matlab.ui.control.Switch
        tagged_switch_E4                matlab.ui.control.Switch
        TagearButtonGroup_E4            matlab.ui.container.ButtonGroup
        UnknownButton_10                matlab.ui.control.RadioButton
        RButton_10                      matlab.ui.control.RadioButton
        LButton_10                      matlab.ui.control.RadioButton
        NoneButton_10                   matlab.ui.control.RadioButton
        PunchearButtonGroup_E4          matlab.ui.container.ButtonGroup
        BothButton_5                    matlab.ui.control.RadioButton
        RButton_9                       matlab.ui.control.RadioButton
        LButton_9                       matlab.ui.control.RadioButton
        NoneButton_9                    matlab.ui.control.RadioButton
        ProtocolDropDown_E4             matlab.ui.control.DropDown
        RoomDropDown_E4                 matlab.ui.control.DropDown
        SexButtonGroup_E4               matlab.ui.container.ButtonGroup
        FemaleButton_5                  matlab.ui.control.RadioButton
        MaleButton_5                    matlab.ui.control.RadioButton
        UnknownButton_9                 matlab.ui.control.RadioButton
        CageEditField_E4                matlab.ui.control.EditField
        TagIDEditField_E4               matlab.ui.control.EditField
        Label_4                         matlab.ui.control.Label
        DOBDatePicker_E4                matlab.ui.control.DatePicker
        BackgroundDropDown_E4           matlab.ui.control.DropDown
        DOB_known_switch_E3             matlab.ui.control.Switch
        tagged_switch_E3                matlab.ui.control.Switch
        TagearButtonGroup_E3            matlab.ui.container.ButtonGroup
        UnknownButton_8                 matlab.ui.control.RadioButton
        RButton_8                       matlab.ui.control.RadioButton
        LButton_8                       matlab.ui.control.RadioButton
        NoneButton_8                    matlab.ui.control.RadioButton
        PunchearButtonGroup_E3          matlab.ui.container.ButtonGroup
        BothButton_4                    matlab.ui.control.RadioButton
        RButton_7                       matlab.ui.control.RadioButton
        LButton_7                       matlab.ui.control.RadioButton
        NoneButton_7                    matlab.ui.control.RadioButton
        ProtocolDropDown_E3             matlab.ui.control.DropDown
        RoomDropDown_E3                 matlab.ui.control.DropDown
        SexButtonGroup_E3               matlab.ui.container.ButtonGroup
        FemaleButton_4                  matlab.ui.control.RadioButton
        MaleButton_4                    matlab.ui.control.RadioButton
        UnknownButton_7                 matlab.ui.control.RadioButton
        CageEditField_E3                matlab.ui.control.EditField
        TagIDEditField_E3               matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
        DOBDatePicker_E3                matlab.ui.control.DatePicker
        BackgroundDropDown_E3           matlab.ui.control.DropDown
        DOB_known_switch_E2             matlab.ui.control.Switch
        tagged_switch_E2                matlab.ui.control.Switch
        TagearButtonGroup_E2            matlab.ui.container.ButtonGroup
        UnknownButton_6                 matlab.ui.control.RadioButton
        RButton_6                       matlab.ui.control.RadioButton
        LButton_6                       matlab.ui.control.RadioButton
        NoneButton_6                    matlab.ui.control.RadioButton
        PunchearButtonGroup_E2          matlab.ui.container.ButtonGroup
        BothButton_3                    matlab.ui.control.RadioButton
        RButton_5                       matlab.ui.control.RadioButton
        LButton_5                       matlab.ui.control.RadioButton
        NoneButton_5                    matlab.ui.control.RadioButton
        ProtocolDropDown_E2             matlab.ui.control.DropDown
        RoomDropDown_E2                 matlab.ui.control.DropDown
        SexButtonGroup_E2               matlab.ui.container.ButtonGroup
        FemaleButton_3                  matlab.ui.control.RadioButton
        MaleButton_3                    matlab.ui.control.RadioButton
        UnknownButton_5                 matlab.ui.control.RadioButton
        CageEditField_E2                matlab.ui.control.EditField
        TagIDEditField_E2               matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        DOBDatePicker_E2                matlab.ui.control.DatePicker
        BackgroundDropDown_E2           matlab.ui.control.DropDown
        SetotherentriesButton           matlab.ui.control.Button
        DOB_known_switch_E1             matlab.ui.control.Switch
        tagged_switch_E1                matlab.ui.control.Switch
        TagearButtonGroup_E1            matlab.ui.container.ButtonGroup
        UnknownButton_4                 matlab.ui.control.RadioButton
        RButton_4                       matlab.ui.control.RadioButton
        LButton_4                       matlab.ui.control.RadioButton
        NoneButton_4                    matlab.ui.control.RadioButton
        PunchearButtonGroup_E1          matlab.ui.container.ButtonGroup
        BothButton_2                    matlab.ui.control.RadioButton
        RButton_3                       matlab.ui.control.RadioButton
        LButton_3                       matlab.ui.control.RadioButton
        NoneButton_3                    matlab.ui.control.RadioButton
        ProtocolLabel                   matlab.ui.control.Label
        ProtocolDropDown_E1             matlab.ui.control.DropDown
        SourceLabel                     matlab.ui.control.Label
        SourceDropDown_E1               matlab.ui.control.DropDown
        RoomLabel                       matlab.ui.control.Label
        RoomDropDown_E1                 matlab.ui.control.DropDown
        SexButtonGroup_E1               matlab.ui.container.ButtonGroup
        FemaleButton_2                  matlab.ui.control.RadioButton
        MaleButton_2                    matlab.ui.control.RadioButton
        UnknownButton_3                 matlab.ui.control.RadioButton
        CageLabel                       matlab.ui.control.Label
        CageEditField_E1                matlab.ui.control.EditField
        TagIDLabel                      matlab.ui.control.Label
        TagIDEditField_E1               matlab.ui.control.EditField
        Label_1                         matlab.ui.control.Label
        EntryLabel                      matlab.ui.control.Label
        DOBLabel                        matlab.ui.control.Label
        BackgroundLabel                 matlab.ui.control.Label
        DOBDatePicker_E1                matlab.ui.control.DatePicker
        BackgroundDropDown_E1           matlab.ui.control.DropDown
        MultipleentryLabel              matlab.ui.control.Label
        SingleentryLabel                matlab.ui.control.Label
        ProtocolDropDown                matlab.ui.control.DropDown
        ProtocolDropDownLabel           matlab.ui.control.Label
        CageEditField                   matlab.ui.control.EditField
        CageEditFieldLabel              matlab.ui.control.Label
        SourceTypeDropDown              matlab.ui.control.DropDown
        SourcetypeLabel                 matlab.ui.control.Label
        TagIDEditField                  matlab.ui.control.EditField
        TagIDEditFieldLabel             matlab.ui.control.Label
        RoomDropDown                    matlab.ui.control.DropDown
        RoomDropDownLabel               matlab.ui.control.Label
        TagearButtonGroup               matlab.ui.container.ButtonGroup
        UnknownButton_2                 matlab.ui.control.RadioButton
        RButton_2                       matlab.ui.control.RadioButton
        LButton_2                       matlab.ui.control.RadioButton
        NoneButton_2                    matlab.ui.control.RadioButton
        DOBDatePicker                   matlab.ui.control.DatePicker
        DOBDatePickerLabel              matlab.ui.control.Label
        BackgroundDropDown              matlab.ui.control.DropDown
        BackgroundDropDownLabel         matlab.ui.control.Label
        DOB_known_switch                matlab.ui.control.Switch
        PunchearButtonGroup             matlab.ui.container.ButtonGroup
        BothButton                      matlab.ui.control.RadioButton
        RButton                         matlab.ui.control.RadioButton
        LButton                         matlab.ui.control.RadioButton
        NoneButton                      matlab.ui.control.RadioButton
        SexButtonGroup                  matlab.ui.container.ButtonGroup
        FemaleButton                    matlab.ui.control.RadioButton
        MaleButton                      matlab.ui.control.RadioButton
        UnknownButton                   matlab.ui.control.RadioButton
        tagged_switch                   matlab.ui.control.Switch
        ChameleonTab                    matlab.ui.container.Tab
        PerhapssomedayLabel             matlab.ui.control.Label
        AddGenotypeTab                  matlab.ui.container.Tab
        GenotypeInsertStatusEdit        matlab.ui.control.EditField
        StatusLamp_Genotype             matlab.ui.control.Lamp
        StatusLampLabel_3               matlab.ui.control.Label
        GenotypesindatabaseListBox      matlab.ui.control.ListBox
        GenotypesindatabaseListBoxLabel  matlab.ui.control.Label
        AddgenotypetodatabaseButton     matlab.ui.control.Button
        CatalogEditField                matlab.ui.control.EditField
        CatalogEditFieldLabel           matlab.ui.control.Label
        SourceEditField                 matlab.ui.control.EditField
        SourceEditFieldLabel            matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        GenotypenameEditField           matlab.ui.control.EditField
        GenotypenameEditFieldLabel      matlab.ui.control.Label
        AddSubstanceTab                 matlab.ui.container.Tab
        SubstanceInsertStatusEdit       matlab.ui.control.EditField
        StatusLamp_Substance            matlab.ui.control.Lamp
        StatusLampLabel_4               matlab.ui.control.Label
        SubstancesindatabaseListBox     matlab.ui.control.ListBox
        SubstancesindatabaseListBoxLabel  matlab.ui.control.Label
        AddsubstancetodatabaseButton    matlab.ui.control.Button
        StorageLocationTextArea_substance  matlab.ui.control.TextArea
        StoragelocationLabel            matlab.ui.control.Label
        CatalogEditField_substance      matlab.ui.control.EditField
        CatalogEditField_2Label         matlab.ui.control.Label
        SourceEditField_substance       matlab.ui.control.EditField
        SourceEditField_2Label          matlab.ui.control.Label
        DescriptionTextArea_substance   matlab.ui.control.TextArea
        DescriptionTextArea_2Label      matlab.ui.control.Label
        SubstancenameEditField          matlab.ui.control.EditField
        SubstancenameEditFieldLabel     matlab.ui.control.Label
        AddBrainAreaTab                 matlab.ui.container.Tab
        BrainAreaInsertStatusEdit       matlab.ui.control.EditField
        StatusLamp_BrainArea            matlab.ui.control.Lamp
        StatusLampLabel_5               matlab.ui.control.Label
        AddbrainareatodatabaseButton    matlab.ui.control.Button
        BrainareasindatabaseListBox     matlab.ui.control.ListBox
        BrainareasindatabaseListBoxLabel  matlab.ui.control.Label
        BrainLongNameTextArea           matlab.ui.control.TextArea
        LongnameLabel                   matlab.ui.control.Label
        AbbreviationEditField           matlab.ui.control.EditField
        AbbreviationEditFieldLabel      matlab.ui.control.Label
        AddProjectTab                   matlab.ui.container.Tab
        StatusLamp_Project              matlab.ui.control.Lamp
        StatusLampLabel_7               matlab.ui.control.Label
        ProjectsindatabaseListBox       matlab.ui.control.ListBox
        ProjectsindatabaseListBoxLabel  matlab.ui.control.Label
        ProjectDescriptionTextArea      matlab.ui.control.TextArea
        DescriptionLabel                matlab.ui.control.Label
        ProjectNameEditField            matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ProjectInsertStatusEdit         matlab.ui.control.EditField
        AddprojecttodatabaseButton      matlab.ui.control.Button
        AddBehaviorExperimentTypeTab    matlab.ui.container.Tab
        StatusLamp_BehaviorExp          matlab.ui.control.Lamp
        StatusLampLabel_8               matlab.ui.control.Label
        BehaviorexperimenttypesindatabaseListBox  matlab.ui.control.ListBox
        BehaviorexperimenttypesindatabaseListBoxLabel  matlab.ui.control.Label
        BehaviorExpDescriptionTextArea  matlab.ui.control.TextArea
        DescriptionLabel_2              matlab.ui.control.Label
        BehaviorExpNameEditField        matlab.ui.control.EditField
        NameEditFieldLabel_2            matlab.ui.control.Label
        BehaviorExpInsertStatusEdit     matlab.ui.control.EditField
        AddexperimenttypetodatabaseButton  matlab.ui.control.Button
        LogTab                          matlab.ui.container.Tab
        ExporttotextfileButton          matlab.ui.control.Button
        LogTextArea                     matlab.ui.control.TextArea
        TODOListTab                     matlab.ui.container.Tab
        EditField_TODOquery             matlab.ui.control.EditField
        StatusLamp_TODOquery            matlab.ui.control.Lamp
        StatusLampLabel_10              matlab.ui.control.Label
        QuerynameLabel                  matlab.ui.control.Label
        QueryResultTable                matlab.ui.control.Table
        SubmitnewqueryButton            matlab.ui.control.Button
        QuerynameEditField              matlab.ui.control.EditField
        NameEditFieldLabel_3            matlab.ui.control.Label
        QuerytextTextArea               matlab.ui.control.TextArea
        QuerytextTextAreaLabel          matlab.ui.control.Label
        QueriesListBox                  matlab.ui.control.ListBox
        QueriesListBoxLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        accessible_tabs = {'Login'} % Which tabs user is allowed to select
        user_password=''; %property that will store the password entered by user
        prev_password_length=0;
        selected_animal_id = [];% animal_id for current selection
        login_user; % User who logged in
        ax_ageslider % axis for age slider
        l_ageslider  % line for age slider
        filter_ageLow = 0; % lower age limit
        filter_ageHigh = 200; % upper age limit
        filter_genotype_status = 'All'; % genotype_status to filter
        filter_struct = []; % struct as query object from filter panel
        filter_project = 'All'; % reserved project to filter
        filter_protocol = 'All'; %protocol assignment to filter
        connection % DJ connection object
        cal_refresh_response; %for google calendar
        cal_access_response = []; %for google calendar
        cal_access_token_time =[]; % time of last access token request
        calDir; % directory for calendar access file
        selected_breeder_cage % selected breeder cage in Breeders tab
        todo_queries % Description
        animal_event_list = {...
            'AssignCage', ...
            'AssignProtocol', ...
            'Birth', ...
            'BrainInjection', ...
            'Deceased', ...
            'EyeInjection', ...
            'GenotypeResult', ...
            'IPInjection', ...
            'ReservedForProject', ...
            'ReservedForSession', ...
            'SocialBehaviorSession', ...
            'Tag', ...
            'Weaned'...
            };
    end

    methods (Access = private)

        function init_AddAnimalTab(app)
            app.AddAnimal_load_backgrounds();
            app.AddAnimal_loadRooms();
            app.loadAnimalSourceMenu();
            app.loadProtocolsMenu_addAnimalTab();

            %set initial state
            set(app.Search_DJIDEditField,'Enable',true);
            set(app.DOBDatePicker,'Enable',true);
            set(app.StatusLamp_Animal,'color','white');
            set(app.AnimalInsertStatusEdit,'value','');
            set(app.NumberofentriesSlider, 'value', 0);
            app.updateAnimalEntryVisibility();
        end

        function init_AddGenotypeTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            genotypes = fetchn(sln_animal.GenotypeString,'genotype');
            %put WT first
            ind = find(strcmp(genotypes,'WT'));
            if ~isempty(ind)
                g{1} = 'WT';
                g = [g; genotypes(setdiff(1:length(genotypes), ind))];
            else
                g = genotypes;
            end
            set(app.GenotypesindatabaseListBox,'Items',g);
            if resetStatusLamp
                set(app.StatusLamp_Genotype,'color','white');
                set(app.GenotypeInsertStatusEdit,'value','');
            end
        end

        function init_AddSubstanceTab(app,resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            substances = fetchn(sl.InjectionSubstance,'substance_name');
            set(app.SubstancesindatabaseListBox,'Items',substances);
            if resetStatusLamp
                set(app.StatusLamp_Substance,'color','white');
                set(app.SubstanceInsertStatusEdit,'value','');
            end
        end

        function init_AddBrainAreaTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            areas = fetchn(sl.BrainArea,'target');
            set(app.BrainareasindatabaseListBox,'Items',areas);

            value = app.BrainareasindatabaseListBox.Value;
            if ~isempty(value)
                thisArea = fetch(sl.BrainArea & sprintf('target="%s"',value), '*');
                set(app.AbbreviationEditField, 'value', thisArea.target);
                set(app.BrainLongNameTextArea, 'value', thisArea.long_name);
            end

            if resetStatusLamp
                set(app.StatusLamp_BrainArea,'color','white');
                set(app.BrainAreaInsertStatusEdit,'value','');
            end
        end

        function init_AddProjectTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            projects = fetchn(sl.Project,'project_name');
            set(app.ProjectsindatabaseListBox,'Items',projects);

            value = app.ProjectsindatabaseListBox.Value;
            if ~isempty(value)
                thisProject = fetch(sl.Project & sprintf('project_name="%s"',value), '*');
                set(app.ProjectNameEditField, 'value', thisProject.project_name);
                set(app.ProjectDescriptionTextArea, 'value', thisProject.description);
            end

            if resetStatusLamp
                set(app.StatusLamp_Project,'color','white');
                set(app.ProjectInsertStatusEdit,'value','');
            end
        end

        function init_AddBehaviorExpTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            exps = fetchn(sl.SocialBehaviorExperimentType,'purpose');
            set(app.BehaviorexperimenttypesindatabaseListBox,'Items',exps);


            value = app.BehaviorexperimenttypesindatabaseListBox.Value;
            if ~isempty(value)
                thisExp = fetch(sl.SocialBehaviorExperimentType & sprintf('purpose="%s"',value), '*');
                set(app.BehaviorExpNameEditField, 'value', thisExp.purpose);
                set(app.BehaviorExpDescriptionTextArea, 'value', thisExp.description);
            end

            if resetStatusLamp
                set(app.StatusLamp_BehaviorExp,'color','white');
                set(app.BehaviorExpInsertStatusEdit,'value','');
            end
        end

        function AddAnimal_load_backgrounds(app)
            bg = fetchn(sln_animal.Background,'background_name');
            %put C57 first            
            ind = find(strcmp(bg, 'C57bl/6'));
            bg = bg([ind, setdiff(1:length(bg), ind)]);
            set(app.BackgroundDropDown,'Items',bg);

            N_entries = 8;
            for e=1:N_entries
                set(app.(['BackgroundDropDown_E' num2str(e)]),'Items',bg);
            end
        end

        function loadSubstanceMenus(app)
            substances = fetchn(sln_animal.InjectionSubstance,'substance_name');
            set(app.SubstanceDropDown_IP,'items',substances);
            set(app.SubstanceDropDown_Eye,'items',substances);
            set(app.SubstanceDropDown_Brain,'items',substances);
            set(app.SubstanceDropDown_Feed,'items',substances);
        end

        function loadBrainAreas(app)
            brainAreas = fetchn(sln_animal.BrainArea,'target');
            set(app.BrainTargetDropDown,'items',brainAreas);
        end

        function loadUsers(app)
            users = fetchn(sln_lab.ActiveUser,'user_name');
            %put login_user first
            ind = find(strcmp(users,app.login_user));
            if ~isempty(ind)
                u{1} = app.login_user;
                u = [u; users(setdiff(1:length(users), ind))];
            else
                u = users;
            end
            set(app.PersonDropDown,'items',u);
            if ~isempty(ind)
                set(app.PersonDropDown,'value',app.login_user);
            end
        end

        function loadUsers_breeders(app)
            users = fetchn(sln_lab.ActiveUser,'user_name'); 
            %put login_user first
            ind = find(strcmp(users,app.login_user));
            if ~isempty(ind)
                u{1} = app.login_user;
                u = [u; users(setdiff(1:length(users), ind))];
            else
                u = users;
            end
            set(app.PersonDropDown_breeders,'items',u);
            if ~isempty(ind)
                set(app.PersonDropDown_breeders,'value',app.login_user);
            end
        end

        function loadSocialExperimentTypes(app)
            exps = fetchn(sl_behavior.SocialBehaviorExperimentType,'purpose');
            set(app.ExperimenttypeDropDown,'items',exps);
        end

        function loadTestAnimalTypes(app)
            animal_types = fetchn(sl_behavior.TestAnimalType,'animal_type_name');
            set(app.TestanimaltypeDropDown,'items',animal_types);
        end

        function loadRigs(app)
            rigs = fetchn(sln_lab.Rig,'rig_name');
            rigs{end+1} = 'A and B';
            set(app.RignameDropDown,'items',rigs);
        end

        function loadEventTypes(app)
%             ch = get(app.EventTabGroup,'children');
%             N = length(ch);
%             eventNames = cell(N,1);
%             for i=1:N
%                 eventNames{i} = get(ch(i),'Title');
%             end
            set(app.EventTypeDropDown,'items',app.animal_event_list)
        end

        function loadProjects(app)
            projects = fetchn(sln_lab.Project,'project_name');
            set(app.ProjectDropDown,'items',projects);
            set(app.Filter_ProjectDropDown,'items',['All'; projects]);
        end

        function init_BreedersTab(app)
            %load users
            %app.loadUsers_breeders();

            %load rooms
            %app.Breeders_loadRooms();

            %set date picker to current date
            set(app.EventdateDatePicker_breeders,'value',datetime(date,'format','yyyy-MM-dd'))

            %set status lamp to grey and empty text field
            set(app.StatusLamp_breeders,'color','white');
            set(app.EventInsertStatusEdit_breeders, 'value', '');

            %set search switch
            set(app.SearchTypeButtonGroup_breeders, 'SelectedObject', app.DJIDButton_breeders);
            set(app.Search_DJIDEditField_breeders, 'enable', true);
            set(app.Search_TagIDEditField_breeders, 'enable', false);
            set(app.CageNumSearchEditField_breeders, 'enable', false);

            %load breedig cages tables
            app.loadBreedingCagesTable([]);

            %set selections to empty
            app.selected_breeder_cage = [];

            %loadGenotypeMenu_BreedersTab
            app.loadGenotypeMenu_BreedersTab();

        end

        function load_database_items(app)            
            %load substances
            app.loadSubstanceMenus();

            %load brain areas
            app.loadBrainAreas();

            %load social behavior experiment types
            app.loadSocialExperimentTypes();

            %load test animal types for social behavior experiments
            app.loadTestAnimalTypes();

            %load rigs
            app.loadRigs();

            %genetic backgrounds
            app.load_background_dropdown();

            %load test stimlui for social behavior experiments
            app.loadBehaviorStimuli();

            %load projects
            app.loadProjects();

            %load protocols
            app.loadProtocolsMenu_loggerTab();

            %load rooms
            app.Logger_loadRooms();

            %load event types for log menu
            app.loadEventTypes();
            
            %load locus and allele menus
            app.loadGenotypeTabMenus();

            %set date picker to current date
            set(app.EventdateDatePicker,'value',datetime(date,'format','yyyy-MM-dd'))        

            %set search switch
            set(app.SearchTypeButtonGroup,'SelectedObject', app.DJIDButton);
            set(app.Search_DJIDEditField, 'enable', true);
            set(app.Search_TagIDEditField, 'enable', false);
            set(app.CageNumSearchEditField, 'enable', false);

            %set menus and age boxes
            set(app.Filter_SexDropDown,'value','All');
            set(app.ageLow_edit,'value','');
            set(app.ageHigh_edit,'value','');

            %load genotype_search dropdown
            app.load_genotype_search_dropdown();

            %make initial filter struct
            app.filter_genotype_status = 'All';
            app.filter_ageLow = str2double(app.ageLow_edit.Value);
            app.filter_ageHigh = str2double(app.ageHigh_edit.Value);

            filter_query = [];

            try
                %calendar init
                if isdeployed
                    app.calDir = '/home/matlabuser';
                    app.cal_refresh_response.refresh_token = '1//0f4-lbZ90smKbCgYIARAAGA8SNwF-L9IrI9vFDovfyvZzzi5caMaTLx-1_CRoLFeuLuo2RcEEtIhrkyMdG0YSNKRYoLWOT4kK2l4';
                    app.cal_refresh_response.access_token = 'ya29.a0AfH6SMClV7vcfiNv6ebcsTY4UrDavnikDD9ZpsBlYj6pXt3Ysr0qn-tPU8dWhcRTDXt3unnq6TQEpTl0uc6Qm-LShA5OHvarXggtfnkWnv2Im181Pe5l5nzdjowiwCIsuStZAdSRnqhRQmJeKQqLQfuJvbNtslpZh0A';
                else
                    app.calDir = pwd;
                    %not working for deployed yet
                    fid = fopen([app.calDir filesep 'gcal_access.txt'],'r');
                    app.cal_refresh_response.access_token = fgetl(fid);
                    app.cal_refresh_response.refresh_token = fgetl(fid);

                end
                app.cal_refresh_response.expires_in = 3599;
                app.cal_refresh_response.scope = 'https://www.googleapis.com/auth/calendar';
                app.cal_refresh_response.token_type = 'Bearer';
            catch
                disp('Google calendar integration failed');
            end
        end

        function init_AnimalLoggerTab(app)
            %%TODO: why load all of these every time we switch to this tab?
            %% shouldn't this just be in the app startup?? or in response
            %% to a refresh button?? doing it this way is slow

            %update genotype event entries
            app.updateGenotypeEntryVisibility();

            %load possible genotype results
            %app.loadGenotypeStatus(); %need to come back to this            

            %set status lamp to grey and empty text field
            set(app.StatusLamp_Logger,'color','white');
            set(app.EventInsertStatusEdit, 'value', '');

            set(app.SubmitevententryButton,'enable',true); %enable

            %load animal table
            app.loadAnimalTable([]);
        end

        function loadBreedingCagesTable(app, filter_query)
            % change status loading...
            set(app.BreederPairs_status, 'Text', 'loading...');
            %and make table invisible
            set(app.BreederCagesTable,'visible','off');
            drawnow;

            %get only breeders
            if ~isempty(filter_query)
                allAnimals = fetch(sln_animal.Deceased.living() & filter_query,'*');
            else
                allAnimals = fetch(sln_animal.Deceased.living(),'*');
            end
            animal_ids = [allAnimals.animal_id]';
            isBreeder = sln_animal.Animal.isActiveBreeder(animal_ids);
            animal_ids = animal_ids(isBreeder);

            breeding_pairs_struct = fetch(sln_animal.BreedingPair,'*');

            male_breeders = [breeding_pairs_struct.male_id];
            female_breeders = [breeding_pairs_struct.female_id];

            Nbreeders = length(female_breeders);
            display_ind = false(Nbreeders,1);
            for i=1:Nbreeders
                if ismember(male_breeders(i), animal_ids) && ismember(female_breeders(i), animal_ids)
                    display_ind(i) = true;
                end
            end
            breeding_pairs_struct = breeding_pairs_struct(display_ind);

            Nbreeders = length(breeding_pairs_struct)
            tableSize = Nbreeders;
            %set up uitable
            D = table('Size',[tableSize,13],'VariableTypes', ...
                {'uint32',...   #1 Cage
                'string', ...   #2 Room
                'uint32',...    #3 male DJID
                'string',...    #4 male genotype
                'singlenan',...    #5 male age
                'uint32',...    #6 female DJID
                'string',...    #7 female genotype
                'singlenan',...    #8 female age
                'string',...    #9 date paired
                'uint32',...    #10 number of litters
                'string',...    #11 litter dates
                'string',...    #12 litter sizes weaned
                'string',...    #13 next wean date
                },...
                'VariableNames', ...
                {'Cage_number',...
                'Room_number', ...
                'Male_DJID',...
                'Male_Genotype'...
                'Male_age', ...
                'Female_DJID', ...
                'Female_Genotype'...
                'Female_age', ...
                'Date_paired',...
                'N_litters',...
                'Litter_dates',...
                'Weaned_sizes',...
                'Next_wean_date',...
                });

            %source_ids_struct = rmfield(breeding_pairs_struct,{'male_id','female_id'});
            male_ids_struct = repmat(struct('animal_id',0),Nbreeders,1);
            [male_ids_struct(:).animal_id] = breeding_pairs_struct.male_id;
            female_ids_struct = repmat(struct('animal_id',0),Nbreeders,1);
            [female_ids_struct(:).animal_id] = breeding_pairs_struct.female_id;

            for i=1:Nbreeders
                male_cage = sln_animal.Animal.cageNumber(male_ids_struct(i).animal_id);
                male_cages(i) = male_cage.cage_number;
                female_cage = sln_animal.Animal.cageNumber(female_ids_struct(i).animal_id);
                female_cages(i) = female_cage.cage_number;
                male_room = sln_animal.Animal.roomNumber(male_ids_struct(i).animal_id);
                male_rooms{i} = male_room.room_number;
            end

            if ~all(male_cages == female_cages)
                error('breeding pair male and female not assigned to same cage');
            end

            for i=1:Nbreeders %fill in table
                D.Cage_number(i) = male_cages(i);
                D.Room_number(i) = male_rooms{i};
                D.Male_DJID(i) = breeding_pairs_struct(i).male_id;
                D.Female_DJID(i) = breeding_pairs_struct(i).female_id;

                male_age_struct = sln_animal.Animal.age(breeding_pairs_struct(i).male_id);
                D.Male_age(i) = calweeks(male_age_struct.age);

                D.Male_Genotype(i) = fetch1(sln_animal.GenotypeString & sprintf('animal_id=%d', breeding_pairs_struct(i).male_id), 'genotype');
                D.Female_Genotype(i) = fetch1(sln_animal.GenotypeString & sprintf('animal_id=%d', breeding_pairs_struct(i).female_id), 'genotype');

                female_age_struct = sln_animal.Animal.age(breeding_pairs_struct(i).female_id);
                D.Female_age(i) = calweeks(female_age_struct.age);

                activate_event = sln_animal.ActivateBreedingPair & ...
                    sprintf('source_id=%d',breeding_pairs_struct(i).source_id);
                if activate_event.exists
                    %LIMIT 1 ORDER BY date DESC'
                    D.Date_paired(i) = fetch1(activate_event,'date');
                end

                birth_events = sln_animal.AnimalEvent * sln_animal.Birth & ...
                    sprintf('source_id=%d',breeding_pairs_struct(i).source_id);
                birth_events_dates = fetchn(birth_events,'date','ORDER BY date ASC');
                D.N_litters(i) = birth_events.count;
                date_str = [];
                for b=1:birth_events.count
                    date_str = [date_str birth_events_dates{b}];
                    if b<birth_events.count
                        date_str = [date_str, ', '];
                    end
                end
                D.Litter_dates(i) = date_str;

                weaned_events = sln_animal.AnimalEvent * sln_animal.Weaned & ...
                    sprintf('source_id=%d',breeding_pairs_struct(i).source_id);
                weaned_events_struct = fetch(weaned_events,'*','ORDER BY date ASC');
                D.Weaned_sizes(i) = num2str([weaned_events_struct.number_of_pups]);

                Nweaned = length(weaned_events_struct);
                if birth_events.count == Nweaned + 1 %one more birth than weaning
                    next_wean_date = datetime(birth_events_dates{birth_events.count}) + days(22);
                    next_wean_date_str = datestr(next_wean_date,'yyyy-MM-dd');
                    D.Next_wean_date(i) = next_wean_date_str;
                else
                    D.Next_wean_date(i) = '';
                end
            end

            %set table data
            set(app.BreederCagesTable,'Data',D);
            defaultStyle = uistyle('FontColor','black','FontWeight','normal');
            addStyle(app.BreederCagesTable,defaultStyle);

            %make no cage selected
            app.selected_breeder_cage = [];

            % change status
            set(app.BreederPairs_status, 'Text', [num2str(tableSize) ' cages']);
            %and make table visible
            set(app.BreederCagesTable,'visible','on');
            drawnow;
        end

        function loadAnimalTable(app, filter_query)
            % change title to table loading...
            set(app.AnimalSelectionPanel, 'Title', 'Table loading...');
            %and make table invisible
            set(app.AnimalTable,'visible','off');
            drawnow;

            if isempty(app.filter_ageLow) || isnan(app.filter_ageLow)
                app.filter_ageLow = 0;
            end
            if isempty(app.filter_ageHigh) || isnan(app.filter_ageHigh)
                app.filter_ageHigh = 200;
            end

            if ~isempty(filter_query) %run specified query instead of stored filter
                allAnimals = fetch(sln_animal.Deceased.living() & filter_query,'*');
            else
                % make age restriction
                if ~isempty(get(app.ageLow_edit,'value')) || ~isempty(get(app.ageHigh_edit,'value'))
                    max_dob = char(datetime(datetime('now') - 7*app.filter_ageLow,'format','yyyy-MM-dd'));
                    if app.filter_ageHigh >= 25 %25 weeks considered inf
                        min_dob = '1900-01-01'; %really old mouse!
                    else
                        min_dob = char(datetime(datetime('now') - 7*app.filter_ageHigh,'format','yyyy-MM-dd'));
                    end
                    ageQuery = {['dob >= ' '"' min_dob '"'], ['dob <= ' '"' max_dob '"']};
                else
                    ageQuery = {};
                end

                %use LIKE operator for genotype name
                if isfield(app.filter_struct, 'genotype_name')
                    allele_search_str = app.filter_struct.genotype_name;
                    filter = rmfield(app.filter_struct, 'genotype_name');

                    thisLocus = sln_animal.AlleleLocusMap & sprintf('allele_name="%s"', allele_search_str);
                    if thisLocus.exists
                        locus_name = fetch1(thisLocus,'locus_name');
                    else
                        locus_name = 'unknown';
                    end

                    switch app.filter_genotype_status
                        case 'All'
                            allAnimals = fetch(sln_animal.Deceased.living() * sln_animal.GenotypeString ...
                                & filter ...
                                & sprintf('genotype LIKE "%%%s%%"', allele_search_str) & ...
                                ageQuery,...
                                'animal_id', 'sex', 'dob', 'background_name');
                        case 'het'
                            %fprintf('genotype LIKE "%%%s:%s/WT%%"\n', locus_name, allele_search_str) 
                            allAnimals = fetch(sln_animal.Deceased.living() * sln_animal.GenotypeString ...
                                & filter ...
                                & sprintf('genotype LIKE "%%%s:%s/WT%%"', locus_name, allele_search_str) ...
                                & ageQuery,...
                                'animal_id', 'sex', 'dob', 'background_name');

                        case 'homo'
                            %fprintf('genotype LIKE "%%%s:%s/%s%%"\n', locus_name, allele_search_str, allele_search_str)
                            allAnimals = fetch(sln_animal.Deceased.living() * sln_animal.GenotypeString ...
                                & filter ...
                                & sprintf('genotype LIKE "%%%s:%s/%s%%"', locus_name, allele_search_str, allele_search_str) ...
                                & ageQuery,...
                                'animal_id', 'sex', 'dob', 'background_name');

                        case 'carrier'
                            %fprintf('genotype LIKE "%%%s:%s/%%"\n', locus_name, allele_search_str)
                            allAnimals = fetch(sln_animal.Deceased.living() * sln_animal.GenotypeString ...
                                & filter ...
                                & sprintf('genotype LIKE "%%%s:%s/%%"', locus_name, allele_search_str) ...
                                & ageQuery,...
                                'animal_id', 'sex', 'dob', 'background_name');

                        case 'non-carrier'
                            %fprintf('genotype LIKE "%%%s:WT/WT%%"\n', locus_name)
                            allAnimals = fetch(sln_animal.Deceased.living() * sln_animal.GenotypeString ...
                                & filter ...
                                & sprintf('genotype LIKE "%%%s:WT/WT%%"', locus_name) ...
                                & ageQuery,...
                                'animal_id', 'sex', 'dob', 'background_name');
                        case 'unknown'
                            %fprintf('genotype LIKE "%%%s:%%"\n', locus_name) 
                            allAnimals = fetch(sln_animal.Deceased.living() * sln_animal.GenotypeString ...
                                & filter ...
                                & sprintf('genotype LIKE "%%%s:?%%"', locus_name) ...
                                & ageQuery,...
                                'animal_id', 'sex', 'dob', 'background_name');
                    end
                else
                    filter = app.filter_struct;

                    allAnimals = fetch(sln_animal.Deceased.living() ...
                        & filter & ageQuery,'animal_id', 'sex', 'dob', 'background_name');
                end

            end
            if isempty(allAnimals)
                D = table;
            else
                animal_ids = [allAnimals.animal_id]';
                nAnimals = length(animal_ids);
                tag_ids = sln_animal.Animal.tagNumber(animal_ids);
                tag_ear = sln_animal.Animal.tagEar(animal_ids);
                punch_ear = sln_animal.Animal.earPunch(animal_ids);
                cage_numbers = sln_animal.Animal.cageNumber(animal_ids);
                room_numbers = sln_animal.Animal.roomNumber(animal_ids);
                genotype_str = fetch(sln_animal.GenotypeString & allAnimals, '*');
                project_name = sln_animal.Animal.reservedProject(animal_ids);
                protocol_number = sln_animal.Animal.assignedProtocol(animal_ids);
                hasBehavior = sln_animal.Animal.hasEvent('SocialBehaviorSession',animal_ids);
                hasBrainInjection = sln_animal.Animal.hasEvent('BrainInjection',animal_ids);
                hasEyeInjection = sln_animal.Animal.hasEvent('EyeInjection',animal_ids);
                hasIPinjection = sln_animal.Animal.hasEvent('IPInjection',animal_ids);
                hasRigReservation = sln_animal.Animal.hasEvent('ReservedForSession',animal_ids);
                hasProjectReservation = sln_animal.Animal.hasEvent('ReservedForProject',animal_ids);
                hasProtocol = sln_animal.Animal.hasEvent('AssignProtocol',animal_ids);
                isActiveBreeder = sln_animal.Animal.isActiveBreeder(animal_ids); %search for active breeders to get rid of them here

                age = sln_animal.Animal.age(animal_ids);
                T = struct2table(allAnimals,'AsArray',true);
                if isempty(tag_ids)
                    T.tag_id = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(tag_ids,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                if isempty(tag_ear)
                    T.tag_ear = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(tag_ear,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                if isempty(punch_ear)
                    T.punch = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(punch_ear,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                if isempty(cage_numbers)
                    T.cage_number = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(cage_numbers,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                if isempty(room_numbers)
                    T.room_number = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(room_numbers,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                if isempty(genotype_str)
                    T.genotype_str = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(genotype_str,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                if isempty(project_name)
                    T.project_name = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(project_name,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                if isempty(protocol_number)
                    T.protocol_number = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(protocol_number,'AsArray',true), 'MergeKeys', true, 'type','Left');
                end

                T = outerjoin(T, struct2table(age,'AsArray',true),'MergeKeys',true,'type','Left'); %every mouse has an age
                T.hasBehavior = hasBehavior;
                T.hasBrainInjection = hasBrainInjection;
                T.hasEyeInjection = hasEyeInjection;
                T.hasIPinjection = hasIPinjection;
                T.hasRigReservation = hasRigReservation;
                T.hasProjectReservation = hasProjectReservation;
                T.hasProtocol = hasProtocol;
                T = T(~isActiveBreeder, :); %do not show active breeders in AnimalLogger tab
                N = height(T);
                %if N==1
                %    keyboard;
                %end
                D = table('Size',[N,17],'VariableTypes', ...
                    {'uint32',...   #1 DJ ID
                    'uint32', ...   #2 tag ID
                    'string',...    #3 tag ear
                    'string',...    #4 punch ear
                    'string',...    #5 cage number
                    'string',...    #6 room number
                    'string',...    #6 background
                    'string',...    #8 genotype str
                    'string',...    #9 sex
                    'singlenan',... #10 age
                    'logical',...   #11 IP inj
                    'logical',...   #12 eye inj
                    'logical',...   #13 brain inj
                    'logical', ...  #14 behavior
                    'logical', ...  #15 reserved for rig
                    'string', ...   #16 reserved project
                    'uint32', ...   #17 protocol number
                    },...
                    'VariableNames', ...
                    {'DJ_ID',...
                    'Tag_ID', ...
                    'Tag_ear',...
                    'Punch_ear',...
                    'Cage_number',...
                    'Room_number',...
                    'Background'...
                    'Genotype_str',...
                    'Sex',...
                    'Age',...
                    'IP_inj',...
                    'Eye_inj',...
                    'Brain_inj',...
                    'BehaviorExp',...
                    'ReservedForRig', ...
                    'ReservedProject', ...
                    'Protocol'
                    });
                D.DJ_ID = T.animal_id;
                D.Tag_ID = T.tag_id;
                D.Tag_ear = T.tag_ear;
                D.Punch_ear = T.punch;
                D.Cage_number = T.cage_number;
                D.Room_number = T.room_number;
                D.Background = string(T.background_name);
                D.Genotype_str = T.genotype;
                D.IP_inj = T.hasIPinjection;
                D.Sex = string(T.sex);
                D.Age = T.age;
                D.Eye_inj = T.hasEyeInjection;
                D.Brain_inj = T.hasBrainInjection;
                D.BehaviorExp = T.hasBehavior;
                D.ReservedForRig = T.hasRigReservation;
                D.ReservedProject = string(T.project_name);
                D.Protocol = uint32(T.protocol_number);

                %filter parts that have to be done after table construction
                ind = true(size(D,1),1); %don't filter by default
                switch get(app.IPInjectionButtonGroup,'SelectedObject').Text
                    case 'Yes'
                        ind = ind & D.IP_inj;
                    case 'No'
                        ind = ind & ~D.IP_inj;
                end
                    %                     D = D(ind,:);
                switch get(app.EyeInjectionButtonGroup,'SelectedObject').Text
                    case 'Yes'
                        ind = ind & D.Eye_inj;
                    case 'No'
                        ind = ind & ~D.Eye_inj;
                end
               
                switch get(app.BrainInjectionButtonGroup,'SelectedObject').Text
                    case 'Yes'
                        ind = ind & D.Brain_inj;
                    case 'No'
                        ind = ind & ~D.Brain_inj;
                end

                switch get(app.RigreservationButtonGroup,'SelectedObject').Text
                    case 'Yes'
                        ind = ind & D.ReservedForRig;
                    case 'No'
                        ind = ind & ~D.ReservedForRig;
                end

                switch get(app.BehaviorsessionButtonGroup,'SelectedObject').Text
                    case 'Yes'
                        ind = ind & D.BehaviorExp;
                    case 'No'
                        ind = ind & ~D.BehaviorExp;
                end

                %project filter
                selected_project = get(app.Filter_ProjectDropDown,'value');
                if ~strcmp(selected_project, 'All')
                    ind = ind & strcmp(selected_project, D.ReservedProject);
                end

                %protocol filter
                selected_protocol = get(app.Filter_ProtocolDropDown,'value');
                if ~strcmp(selected_protocol, 'All')
                    selected_protocol_num = str2double(strtrim(extractAfter(selected_protocol, '-')));
                    ind = ind & D.Protocol == selected_protocol_num;
                end

                D = D(ind,:);
            end
            N = height(D);
            if N>0
                set(app.AnimalSelectionPanel, 'Title', ...
                    ['Animal Selection: ' num2str(N) ' total']);
            else
                set(app.AnimalSelectionPanel, 'Title', ...
                    'Animal Selection: none found');
            end
            set(app.AnimalTable,'Data',D);
            defaultStyle = uistyle('FontColor','black','FontWeight','normal');
            addStyle(app.AnimalTable,defaultStyle);

            %make no animal selected
            app.selected_animal_id = [];

            %make visible again
            set(app.AnimalTable,'visible','on');
            drawnow;
        end

        function loadGenotypeMenu_BreedersTab(app)
            allele_list = fetchn(sln_animal.Allele,'allele_name');           
            set(app.GenotypeDropDown_BreedersTab,'items',['All'; allele_list]);
        end

        function loadAnimalSourceMenu(app)
            switch get(app.SourceTypeDropDown, 'value')
                case 'breeding'
                    male_breeders = fetchn(sln_animal.BreedingPair,'male_id');
                    female_breeders = fetchn(sln_animal.BreedingPair,'female_id');

                    active_mask = sln_animal.Animal.isActiveBreeder(male_breeders) & sln_animal.Animal.isActiveBreeder(female_breeders);
                    breedingSourceList = fetchn(sln_animal.BreedingPair,'source_id');

                    male_breeders = male_breeders(active_mask);
                    female_breeders = female_breeders(active_mask);
                    breedingSourceList = breedingSourceList(active_mask);

                    cages = sln_animal.Animal.cageNumber(female_breeders);
                    cages = [cages.cage_number]';

                    %breedingCageList = breedingCageList(active_mask);

                    %put None (0) first
                    cages = [0; cages];
                    sources = [0; breedingSourceList];
                    male_breeders = [0; male_breeders];
                    female_breeders = [0; female_breeders];

                    Ncages = length(cages);
                    menu_str = cell(Ncages,1);

                    for i=1:Ncages
                        if cages(i)==0
                            menu_str{i} = 'None';
                        else
                            menu_str{i} = sprintf('%d: Cage %d, M:%d / F:%d', sources(i), cages(i), male_breeders(i), female_breeders(i));
                        end

                    end
                    set(app.AnimalSourceDropDown,'Items',menu_str);
                
                case 'vendor'
                    vendor_strain_struct = fetch(sln_animal.VendorStrain, '*');
                    N = length(vendor_strain_struct);
                    menu_str = cell(N,1);

                    for i=1:N
                        v = vendor_strain_struct(i);
                        menu_str{i} = sprintf('%d: %s, %s %s', ...
                            v.source_id, v.strain_name, v.vendor_name, v.catalog_number);
                    end
                    set(app.AnimalSourceDropDown,'Items',menu_str);

                case 'other lab'
                    collaborator_strain_struct = fetch(sln_animal.CollaboratorStrain, '*');
                    N = length(collaborator_strain_struct);
                    menu_str = cell(N,1);

                    for i=1:N
                        c = collaborator_strain_struct(i);
                        menu_str{i} = sprintf('%d: %s, %s %s', ...
                            c.source_id, c.strain_name, c.lab_name, c.organization);
                    end
                    
            end

            set(app.AnimalSourceDropDown,'Items',menu_str);

            N_entries = 8;
            for e=1:N_entries
                set(app.(['SourceDropDown_E' num2str(e)]),'Items',menu_str);
            end

        end

        function loadProtocolsMenu_addAnimalTab(app)
            protocolList = fetchn(sln_animal.AnimalProtocol,'protocol_name');
            protocolList_nums = fetchn(sln_animal.AnimalProtocol,'protocol_number');
            for i=1:length(protocolList)
                protocolList{i} = sprintf('%s - %d', protocolList{i}, protocolList_nums(i));
            end
            protocolList = cat(1,'Unknown',protocolList);
            set(app.ProtocolDropDown,'Items',protocolList);

            N_entries = 8;
            for e=1:N_entries
                set(app.(['ProtocolDropDown_E' num2str(e)]),'Items',protocolList);
            end
        end

        function loadProtocolsMenu_loggerTab(app)
            protocolList = fetchn(sln_animal.AnimalProtocol,'protocol_name');
            protocolList_nums = fetchn(sln_animal.AnimalProtocol,'protocol_number');
            for i=1:length(protocolList)
                protocolList{i} = sprintf('%s - %d', protocolList{i}, protocolList_nums(i));
            end
            set(app.AnimalprotocolDropDown,'Items',protocolList);
            set(app.Filter_ProtocolDropDown,'Items',['All'; protocolList]);
        end

        function [calEventAdded, result] = addCalendarEvent(app,key,type)
            if isempty(app.cal_access_token_time) || isempty(app.cal_access_response) || ...
                    datetime('now') - app.cal_access_token_time > seconds(app.cal_access_response.expires_in)
                %token expired
                try
                    app.cal_access_response = webwrite(...
                        'https://oauth2.googleapis.com/token',...
                        'grant_type','refresh_token',...
                        'client_id','667407419363-59ebnl9973ie08ui05fh1b8go1fvpbsn.apps.googleusercontent.com',...
                        'client_secret','dLydGGCoNOaMD1bA8HQFADqL',...
                        'refresh_token',app.cal_refresh_response.refresh_token,...
                        'redirect_uri','urn:ietf:wg:oauth:2.0:oob'...
                        );
                    app.cal_access_token_time = datetime('now');
                catch
                    result = 'Calendar access code failed: maybe refresh_token expired.';
                    calEventAdded = false;
                    return;
                end
            else
                disp('using saved access token');
            end

            try
                switch type
                    case 'reserveForRig'
                        %make reserve forRigEvent
                        genotype = fetch1(sln_animal.GenotypeString & sprintf('animal_id=%d',key.animal_id), 'genotype');
                        body = struct();
                        body.start.date = key.date;
                        body.end.date = key.date;
                        body.summary = sprintf('%s: experiment on rig %s with mouse(DJID): %d, genotype: %s', ...
                            key.user_name, key.rig_name, key.animal_id, genotype);
                        cageNum = sln_animal.Animal.cageNumber(key.animal_id);
                        roomNum = sln_animal.Animal.roomNumber(key.animal_id);
                        body.location = sprintf('Room %s, cage %s', roomNum.room_number, cageNum.cage_number);
                        if ~isempty(key.notes)
                            body.description = key.notes;
                        end

                    case 'weaning'
                        %make weaning event
                        body = struct();
                        body.start.date = key.date;
                        body.end.date = key.date;
                        body.summary = sprintf('Wean animals in cage %s', ...
                            key.cage_number);
                        body.colorId = 11; %red, by request
                end


                %make request
                request = matlab.net.http.RequestMessage(...
                    matlab.net.http.RequestMethod.POST,...
                    matlab.net.http.HeaderField('Authorization',sprintf('Bearer %s',app.cal_access_response.access_token),...
                    'MediaType','application/json'),...
                    body);

                %post it
                status = request.send('https://www.googleapis.com/calendar/v3/calendars/schwartzlabnu@gmail.com/events');
                if strcmp(status.StatusCode,'OK')
                    calEventAdded = true;
                    result = 'Calendar entry insert success';
                else
                    calEventAdded = false;
                    result = 'Calendar entry insert failed';
                end
            catch
                calEventAdded = false;
                result = 'Calendar entry insert failed';
            end

        end

        function AddAnimal_loadRooms(app)
            roomList = fetchn(sln_animal.CageRoom,'room_number');
            if ismember('SB-443',roomList)
                value = 'SB-443';
            else
                value = roomList{1};
            end
            set(app.RoomDropDown,'Items',roomList);
            set(app.RoomDropDown,'Value',value);

            N_entries = 8;
            for e=1:N_entries
                set(app.(['RoomDropDown_E' num2str(e)]),'Items',roomList);
                set(app.(['RoomDropDown_E' num2str(e)]),'Value',value);
            end
        end

        function Logger_loadRooms(app)
            roomList = fetchn(sln_animal.CageRoom,'room_number');
            if ismember('SB-443',roomList)
                value = 'SB-443';
            else
                value = roomList{1};
            end
            set(app.RoomDropDown_logger,'Items',roomList);
            set(app.RoomDropDown_logger,'Value',value);
            set(app.RoomDropDown_Pair,'Items',roomList);
            set(app.RoomDropDown_Pair,'Value',value);
        end

        function Breeders_loadRooms(app)
            roomList = fetchn(sln_animal.CageRoom,'room_number');
            if ismember('SB-443',roomList)
                value = 'SB-443';
            else
                value = roomList{1};
            end
            set(app.RoomDropDown_Pair,'Items',roomList);
            set(app.RoomDropDown_Pair,'Value',value);
            set(app.RoomDropDown_breeders_sepMale,'Items',roomList);
            set(app.RoomDropDown_breeders_sepMale,'Value',value);
            set(app.RoomDropDown_breeders_sepFemale,'Items',roomList);
            set(app.RoomDropDown_breeders_sepFemale,'Value',value);
        end


        function updateAnimalEntryVisibility(app)
            %value = app.NumberofentriesSpinner.Value;
            value = app.NumberofentriesSlider.Value;
            N_entries = 8;
            itemList = {'BackgroundDropDown_E' ...
                'DOBDatePicker_E', ...
                'TagIDEditField_E', ...
                'CageEditField_E', ...
                'SexButtonGroup_E', ...
                'RoomDropDown_E', ...
                'SourceDropDown_E', ...
                'ExtInfo_E', ...
                'ProtocolDropDown_E', ...
                'PunchearButtonGroup_E', ...
                'TagearButtonGroup_E', ...
                'tagged_switch_E', ...
                'DOB_known_switch_E', ...
                'Label_', ...
                };
            for e=1:value
                for i = 1:length(itemList)
                    %keyboard;
                    set(app.([itemList{i} num2str(e)]), 'Visible', true);
                end
                if e==1
                    set(app.SetotherentriesButton, 'Visible', true)
                end
            end
            for e=value+1:N_entries
                for i = 1:length(itemList)
                    set(app.([itemList{i} num2str(e)]), 'Visible', false);
                end
                if e==1
                    set(app.SetotherentriesButton, 'Visible', false)
                end
            end
        end

        function updateGenotypeEntryVisibility(app)
            value = app.GenotypeEntriesSpinner.Value;
            N_entries = 4;
            itemList = {'LocusDropDown_' ...
                'Allele1DropDown_'...
                'Allele2DropDown_'...
                };
            for e=1:value
                for i = 1:length(itemList)
                    set(app.([itemList{i} num2str(e)]), 'Visible', true);
                end
                if e==1
                    set(app.SetotherentriesButton, 'Visible', true)
                end
            end
            for e=value+1:N_entries
                for i = 1:length(itemList)
                    set(app.([itemList{i} num2str(e)]), 'Visible', false);
                end
                if e==1
                    set(app.SetotherentriesButton, 'Visible', false)
                end
            end
        end

        function loadBehaviorStimuli(app)
            stims = fetchn(sl_behavior.VisualStimulusType,'stim_type');
            set(app.AnimalAEditField, 'visible', false);
            set(app.AnimalBEditField, 'visible', false);
            set(app.AnimalCEditField, 'visible', false);

            set(app.WindowAStim,'items',stims);
            set(app.WindowAStim,'value','empty');

            set(app.WindowBStim,'items',stims);
            set(app.WindowBStim,'value','empty');

            set(app.WindowCStim,'items',stims);
            set(app.WindowCStim,'value','empty');
        end

        function init_TODOListTab(app)
            f = sl_mutable.TodoQueries().fetch('*');
            if ~isempty(f)
                app.todo_queries = containers.Map({f(:).query_name},{f(:).query_text});
            else
                app.todo_queries = containers.Map();
            end
            set(app.QueriesListBox,'items',app.todo_queries.keys(),'value',{});
            set(app.QuerytextTextArea,'value','');
            set(app.QuerynameEditField,'value','');
            set(app.QuerynameLabel,'text','');
            set(app.QueryResultTable,'data',table(),'columnname',[]);

            set(app.StatusLamp_TODOquery,'color','white');
            set(app.EditField_TODOquery,'value','');
        end

        function loadGenotypeTabMenus(app)
            set(app.GenotypeSourceDropDown, 'items', fetchn(sln_animal.GenotypeSource,'source_name'));
            N_entries = 4;

            for i=1:N_entries
                locusMenuName = ['LocusDropDown_' num2str(i)];
                allele1MenuName = ['Allele1DropDown_' num2str(i)];
                allele2MenuName = ['Allele2DropDown_' num2str(i)];

                locus_list = fetchn(sln_animal.GeneLocus,'locus_name');
                allele_list = fetchn(sln_animal.Allele,'allele_name');

                set(app.(locusMenuName), 'items', locus_list);
                set(app.(allele1MenuName),'items', allele_list);
                set(app.(allele2MenuName),'items', allele_list);
            end

            %LocusDropDown_1
            %AlleleDropDown_1
        end        
        
        function load_genotype_search_dropdown(app)
            allele_list = fetchn(sln_animal.Allele,'allele_name');           
            set(app.Filter_GenotypeDropDown,'items',['All'; allele_list]);
        end
        
        function load_background_dropdown(app)
            bg_list = fetchn(sln_animal.Background,'background_name');
            set(app.Filter_BgDropDown,'items',['All'; bg_list]);
        end
    end

    methods (Static)

        function ind = sortEvents(events)
            ev_structs = fetch(events,'date','time');
            L = length(ev_structs);
            %evTimes = datetime(L,1);
            for i=1:L
                if isempty(ev_structs(i).time)
                    thisTime = '00:00:00';
                else
                    thisTime = ev_structs(i).time;
                end
                evTimes(i) = datetime([ev_structs(i).date, ' ', thisTime]);
            end

            [~,ind] = sort(evTimes);
        end

        function setRadioButtonToValue(buttonGroup, value)
            ch = get(buttonGroup, 'children');
            N_children = length(ch);
            for i=1:N_children
                if strcmp(get(ch(i),'Text'), value)
                    set(buttonGroup, 'SelectedObject', ch(i));
                end
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.connection = struct;
            app.connection.isConnected = false;

            %             if isdeployed
            %                 app.logFolder = '/mnt/fsmresfiles/AnimalLogs/';
            %             else
            %                 app.logFolder = '/Volumes/SchwartzLab/AnimalLogs/';
            %             end
            %datajoint setup
            %             setenv('DJ_USER', 'sl_user')
            %             if isdeployed
            %                 setenv('DJ_HOST', '127.0.0.1')
            %             else
            %                 setenv('DJ_HOST', 'vfsmdatajoint01.fsm.northwestern.edu')
            %             end
            %             setenv('DJ_PASS', 'r0zsNB0xk6wc5BgA!')
            %             dj.conn();

            %start with only login tab enabled (default)

            %load user names into login dropdown
           % users = fetchn(sln_lab.User,'user_name'); 
            %set(app.UserDropDown,'items',users);
            drawnow;
            app.load_database_items()
        end

        % Selection change function: MainTabGroup
        function MainTabGroupSelectionChanged(app, event)
            selectedTab = app.MainTabGroup.SelectedTab;

            if ~ismember(get(selectedTab,'Title'),app.accessible_tabs)
                app.MainTabGroup.SelectedTab = event.OldValue;
            else
                selectedTabName = get(selectedTab,'Title');

                switch(selectedTabName)
                    case 'AnimalLogger'
                        app.init_AnimalLoggerTab;
                    case 'Breeders'
                        app.init_BreedersTab;
                    case 'Add Animals'
                        app.init_AddAnimalTab;
                    case 'Add Genotype'
                        app.init_AddGenotypeTab;
                    case 'Add Substance'
                        app.init_AddSubstanceTab;
                    case 'Add Brain Area'
                        app.init_AddBrainAreaTab;
                    case 'Add Project'
                        app.init_AddProjectTab;
                    case 'Add Behavior Experiment Type'
                        app.init_AddBehaviorExpTab;
                    case 'TODO List'
                        app.init_TODOListTab;
                    case 'Login'
                        %                         if app.connection.isConnected
                        %                             app.connection.close;
                        %                             app.connection = struct;
                        %                             app.connection.isConnected = false;
                        %                         end
                        set(app.PasswordEditField,'value','');
                        %                         app.accessible_tabs = {'Login'};
                end
            end
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            val = event.Value;
            current_pass_length=length(val); %get the number of characters present in password edit field
            app.prev_password_length=length(app.user_password);

            %if password edit field is empty and is in focus
            if isempty(val)
                app.user_password='';   %set the password property to empty string
                app.prev_password_length=0;  %set the previous password length to zero
                app.PasswordEditField.Value='';  %set the value in edit field to empty string

                %if backspace is clicked by user
            elseif(current_pass_length<app.prev_password_length)
                app.user_password = app.user_password(1:current_pass_length);
                app.PasswordEditField.Value = repelem('*', current_pass_length);
            else
                %remove the previous available '*' from the password value obtained from the app
                add_string=erase(val, repelem('*', length(app.user_password)));

                %append the chacter to user_passowrd property
                app.user_password=append(app.user_password,add_string);

                %mask the password in edit field with the total length of characters stored in user_password
                app.PasswordEditField.Value = repelem('*', length(app.user_password));
            end
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            currentUser = get(app.LoginUserEditField, 'value');
            if isdeployed
                try
                    disp('deployed');
                    if app.connection.isConnected
                        app.connection.close;
                        app.connection = struct;
                        app.connection.isConnected = false;
                    end
                    app.connection = dj.conn('127.0.0.1', currentUser, app.user_password, [], true);
                catch ME
                    disp('deployed');
                    rethrow(ME);
                end

            else
                try
                    disp('not deployed');
                    if app.connection.isConnected
                        app.connection.close;
                        app.connection = struct;
                        app.connection.isConnected = false;
                    end
                    %                     app.connection = dj.conn();
                    app.connection = dj.conn('vfsmdatajoint01.fsm.northwestern.edu', currentUser, app.user_password, [], true);
                catch ME
                    disp('not deployed');
                    rethrow(ME)
                end
            end

            if app.connection.isConnected
                disp('login successful');
                app.login_user = currentUser;
                %enable AnimalLogger tab
                if strcmp(app.login_user, 'Susan') || strcmp(app.login_user, 'Greg')
                    app.accessible_tabs = [app.accessible_tabs, ...
                        'AnimalLogger', ...
                        'Breeders', ...
                        'Add Animals', ...
                        'Add Genotype', ...
                        'Add Substance',...
                        'Add Brain Area',...
                        'Add Project',...
                        'Add Behavior Experiment Type', ...
                        'Log',...
                        'TODO List',...
                        'Delete Animals', ...
                        ];
                else
                    app.accessible_tabs = [app.accessible_tabs, ...
                        'AnimalLogger', ...
                        'Breeders', ...
                        'Add Animals', ...
                        'Add Genotype', ...
                        'Add Substance',...
                        'Add Brain Area',...
                        'Add Project',...
                        'Add Behavior Experiment Type', ...
                        'Log',...
                        'TODO List',...
                        ];
                end

                app.MainTabGroup.SelectedTab = app.AnimalLoggerTab;

                %load users
                app.loadUsers(); %this is here so that it can set the login user to the default

                %initialize the appropriate tabs
                app.init_AnimalLoggerTab
            else
                disp('login failed');
            end
        end

        % Value changed function: DOB_known_switch
        function DOB_known_switchValueChanged(app, event)
            value = app.DOB_known_switch.Value;
            if strcmp(value,'DOB unknown')
                set(app.DOBDatePicker,'Enable',false);
            else
                set(app.DOBDatePicker,'Enable',true);
            end
        end

        % Value changed function: tagged_switch
        function tagged_switchValueChanged(app, event)
            value = app.tagged_switch.Value;
            if ~strcmp(value,'Tagged')
                set(app.TagIDEditField,'Enable',false)
            else
                set(app.TagIDEditField,'Enable',true);
            end
        end

        % Button pushed function: AddsingleanimaltodatabaseButton
        function AddsingleanimaltodatabaseButtonPushed(app, event)
            %make insert key
            key = struct;

            if ~strcmp(get(app.tagged_switch,'value'),'Tagged')
                key_tag.do_tag = false;
            end
            key_tag.tag_id = str2double(get(app.TagIDEditField,'value'));
            key_tag.tag_ear = get(get(app.TagearButtonGroup,'SelectedObject'), 'text');
            key_tag.punch = get(get(app.PunchearButtonGroup,'SelectedObject'), 'text');

            %get background
            key.background_name = get(app.BackgroundDropDown, 'value');

            %TODO
            key.species_name = 'mouse';
            %get(get(app.SpeciesTabGroup, 'SelectedTab'), 'Title');

            %check DOB
            if strcmp(get(app.DOB_known_switch,'value'),'DOB known')
                key.dob = char(get(app.DOBDatePicker,'value'));
            else
                key.dob = [];
            end
            
            %sex
            key.sex = get(get(app.SexButtonGroup,'SelectedObject'), 'text');

            %source and source_id
            source_id_str = get(app.AnimalSourceDropDown,'value');
            key.source_id = str2double(strtok(source_id_str,':'));
            key.external_id = get(app.ExternalInfoEditField, 'value');

            %assign tag
            key_tag.user_name = app.login_user;
            if isempty(key.dob)
                key_tag.date = '1900-01-01'; %arbitrary early date
            else
                key_tag.date = key.dob; %wean date is more exact, but this will do
            end

            %assign cage
            key_cage.cage_number = str2double(get(app.CageEditField,'value'));
            key_cage.room_number = get(app.RoomDropDown, 'value');
            key_cage.user_name = app.login_user;
            key_cage.cause = 'assigned at database insert';
            if isempty(key.dob)
                key_cage.date = '1900-01-01'; %arbitrary early date
            else
                key_cage.date = char(datetime(get(app.DOBDatePicker,'value') + days(22), 'Format', 'yyyy-MM-dd')); %wean date approximately
            end

            %assign to protocol
            protocol_name = get(app.ProtocolDropDown,'value');
            if strcmp(protocol_name,'Unknown')
                key_protocol = [];
            else
                key_protocol.protocol_name = deblank(strtok(protocol_name, '-'));
                key_protocol.user_name = app.login_user;
                key_protocol.date = char(datetime(date,'Format','yyyy-MM-dd')); %current date
            end

            %add the animal
            disp('Adding animal with keys:');
            key
            key_tag
            key_cage
            key_protocol
            C = dj.conn;
            C.startTransaction;
            inserted = false;
            try
                insert(sln_animal.Animal,key);
                last_id = fetch1(sln_animal.Animal,'animal_id','ORDER BY animal_id DESC LIMIT 1')
                key_tag.animal_id = last_id;
                key_cage.animal_id = last_id;
                key_protocol.animal_id = last_id;
                sln_animal.add_event(key_tag,'Tag',C);
                sln_animal.add_event(key_cage,'AssignCage',C);
                if ~isempty(key_protocol)
                    sln_animal.add_event(key_protocol,'AssignProtocol',C);
                end
                sln_animal.updateGenotypeString(last_id);
                out_txt = 'animal insert successful';
                C.commitTransaction;
                inserted = true;
            catch ME
                disp('animal insert failed')
                C.cancelTransaction;
                out_txt = [animal insert failed, ME.message];
                rethrow(ME);
            end            
            
            if inserted
                set(app.StatusLamp_Animal,'color','green');
                pause(2);
                set(app.StatusLamp_Animal,'color','white');
            else
                set(app.StatusLamp_Animal,'color','red');
            end
            set(app.AnimalInsertStatusEdit,'value',out_txt);
        end

        % Button pushed function: AddgenotypetodatabaseButton
        function AddgenotypetodatabaseButtonPushed(app, event)
            key.genotype_name = get(app.GenotypenameEditField, 'value');
            key.source = get(app.SourceEditField, 'value');
            desc = get(app.DescriptionTextArea, 'value');
            desc_flat = strcat(cell2mat(desc'));
            key.description = desc_flat;
            key.catalog_number = get(app.CatalogEditField, 'value');
            try
                insert(sl.Genotype, key);
                disp(['Genotype ' key.genotype_name ' insert successful']);
                set(app.StatusLamp_Genotype,'color','green');
                set(app.GenotypeInsertStatusEdit,'value','Genotype insert successful.')
                pause(2);
                set(app.StatusLamp_Genotype,'color','white');
                app.init_AddGenotypeTab(false);
            catch ME
                disp(['Genotype ' key.genotype_name ' insert failed']);
                set(app.StatusLamp_Genotype,'color','red');
                set(app.GenotypeInsertStatusEdit,'value','Genotype insert failed.')
                %rethrow(ME);
            end
        end

        % Value changed function: RecordBehavior_switch
        function RecordBehavior_switchValueChanged(app, event)
            value = app.RecordBehavior_switch.Value;
            if strcmp(value,'Recorded')
                app.Lamp_isRecorded.Enable = true;
            else
                app.Lamp_isRecorded.Enable = false;
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            selectedButton = app.SearchTypeButtonGroup.SelectedObject;
            q = [];
            switch get(selectedButton,'Text')
                case 'DJ ID'
                    v = get(app.Search_DJIDEditField,'value');
                    if ischar(v)
                        v = str2double(v);
                    end
                    if ~isnan(v)
                        q.animal_id = v;
                    end
                case 'Cage #'
                    v = str2double(get(app.CageNumSearchEditField,'value'));
                    allCage = sln_animal.Animal.cageNumber([],true);
                    ind = find([allCage.cage_number]== v);
                    if isempty(ind)
                        q = struct;
                        q.animal_id = 0;
                    end
                    for i=1:length(ind)
                        q(i).animal_id = allCage(ind(i)).animal_id;
                    end
                case 'Tag #'
                    v = get(app.Search_TagIDEditField,'value');
                    if ischar(v)
                        v = str2double(v);
                    end
                    allTag = sln_animal.Animal.tagNumber([],true);
                    ind = find([allTag.tag_id]==v);
                    if isempty(ind)
                        q = struct;
                        q.animal_id = 0;
                    else
                        q.animal_id = allTag(ind).animal_id;
                    end
            end
            if ~isempty(q)
                app.loadAnimalTable(q)
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            q = [];
            app.loadAnimalTable(q)
        end

        % Cell selection callback: AnimalTable
        function AnimalTableCellSelection(app, event)
            indices = event.Indices;
            selectedRow = indices(1,1);

            D = get(app.AnimalTable,'Data');
            Nrows = size(D,1);

            %select animal
            app.selected_animal_id = D{selectedRow,1};

            %make styles
            curStyle = uistyle('FontColor','red','FontWeight','bold');
            oldStyle = uistyle('FontColor','black','FontWeight','normal');

            %add style to this row
            addStyle(app.AnimalTable,curStyle,'row',selectedRow);

            %put other rows back to normal
            otherRows = setdiff(1:Nrows,selectedRow);
            for i=1:Nrows-1
                addStyle(app.AnimalTable,oldStyle,'row',otherRows(i));
            end
        end

        % Value changed function: Filter_SexDropDown
        function Filter_SexDropDownValueChanged(app, event)
            value = app.Filter_SexDropDown.Value;
            if strcmp(value,'All')
                app.filter_struct = rmfield(app.filter_struct, 'sex');
            else
                app.filter_struct.sex = value;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: Filter_GenotypeDropDown
        function Filter_GenotypeDropDownValueChanged(app, event)
            value = app.Filter_GenotypeDropDown.Value;
            if strcmp(value,'All')
                app.filter_struct = rmfield(app.filter_struct, 'genotype_name');
            else
                app.filter_struct.genotype_name = value;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: Filter_GenotypeStatusDropDown
        function Filter_GenotypeStatusDropDownValueChanged(app, event)
            value = app.Filter_GenotypeStatusDropDown.Value;
            app.filter_genotype_status = value;
            app.loadAnimalTable([]);
        end

        % Callback function
        function ID_type_switchValueChanged(app, event)
            value = app.ID_type_switch.Value;
            if strcmp(value,'DJ ID')
                set(app.Search_DJIDEditField, 'Enable', true);
                set(app.Search_TagIDEditField, 'Enable', false);
            else
                set(app.Search_DJIDEditField, 'Enable', false);
                set(app.Search_TagIDEditField, 'Enable', true);
            end
        end

        % Selection change function: EventTabGroup
        function EventTabGroupSelectionChanged(app, event)
            selectedTab = app.EventTabGroup.SelectedTab;
            selectedTabName = get(selectedTab,'Title');
            if strcmp(selectedTabName, 'Assign cage / room') ...
                    || strcmp(selectedTabName, 'Gave birth') ...
                    || strcmp(selectedTabName, 'Set as breeder') ...
                    || strcmp(selectedTabName, 'Assign to animal protocol') ...
                    || strcmp(selectedTabName, 'Reserve for project') ...
                    || strcmp(selectedTabName, 'Reserve for rig')
                set(app.EventTimeH,'Enable',false);
                set(app.EventTimeM,'Enable',false);
                set(app.EventTimeAMPM,'Enable',false);
            else
                set(app.EventTimeH,'Enable',true);
                set(app.EventTimeM,'Enable',true);
                set(app.EventTimeAMPM,'Enable',true);
            end
            if strcmp(selectedTabName, 'Social behavior session')
                set(app.EventTimeH,'Value','4');
                set(app.EventTimeM,'Value','00');
                set(app.EventTimeAMPM,'Value','PM');
            else
                set(app.EventTimeH,'Value','9');
                set(app.EventTimeM,'Value','00');
                set(app.EventTimeAMPM,'Value','AM');
            end
            if strcmp(selectedTabName, 'Genotyped')
                %update genotype event entries
                app.updateGenotypeEntryVisibility();

                %load genotype source menus
                %load allele menus
                %load locus menus
                app.loadGenotypeTabMenus();

            end

            set(app.EventdateDatePicker,'value',datetime(date,'format','yyyy-MM-dd'))
        end

        % Button pushed function: SubmitevententryButton
        function SubmitevententryButtonPushed(app, event)
            selectedTabName = get(get(app.EventTabGroup, 'SelectedTab'), 'Title');            
            if isempty(app.selected_animal_id) && ~strcmp(selectedTabName, 'Pair breeders')
                disp('No animal selected');
                set(app.StatusLamp_Logger,'color','red');
                set(app.EventInsertStatusEdit,'value','No animal selected');
                return;
            end

            set(app.SubmitevententryButton,'enable',false); %disable while working 
            drawnow; %not updating fast - make it a toggle button?

            key.user_name = get(app.PersonDropDown, 'value');
            key.date = datestr(get(app.EventdateDatePicker, 'value'),'yyyy-mm-dd');
            key.animal_id = app.selected_animal_id;
            notes = get(app.EventNotesTextArea, 'value');
            key.notes = strcat(cell2mat(notes'));
            hrs = str2double(get(app.EventTimeH, 'value'));
            if strcmp(get(app.EventTimeAMPM,'value'), 'PM')
                hrs = rem(hrs, 12) + 12;
            end
            mins = get(app.EventTimeM, 'value');
            key.time = [num2str(hrs,'%02d') ':' num2str(mins,'%02d') ':00'];

            switch selectedTabName
                case "Genotyped"
                    key.source_name = get(app.GenotypeSourceDropDown,'value');
                    key
                    C = dj.conn;
                    C.startTransaction;
                    try
                        [inserted, out_txt] = sln_animal.add_event(key,'GenotypeResult',C)
                    catch ME
                        C.cancelTransaction;
                        rethrow(ME)
                    end

                    N_entries = app.GenotypeEntriesSpinner.Value;
                    ploidy_limit = 2;
                    try %add genotype entries first in a transaction;
                        for i=1:N_entries
                            keys(i).locus_name = get(app.(['LocusDropDown_' num2str(i)]), 'value');
                            keys(i).allele_name = get(app.(['AlleleDropDown_' num2str(i)]), 'value');

                            locusMatchCount = sum(strcmp({keys.locus_name}, keys(i).locus_name));
                            if locusMatchCount > ploidy_limit
                                error(['Error: exceeded ploity limit for locus' keys(i).locus_name]);
                            else
                                keys(i).animal_id = key.animal_id;
                                keys(i).allele_id = locusMatchCount;
                                this_event_id = max(fetchn(sln_animal.GenotypeResult, 'event_id'));
                                keys(i).event_id = this_event_id;
                            end
                            keys(i)
                        end
                        insert(sln_animal.Genotype,keys);
                        C.commitTransaction;
                    catch ME
                        C.cancelTransaction;
                        rethrow(ME);
                    end

                case "Deceased"
                    key.cause = get(app.DeceasedReasonDropDown, 'value');
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'Deceased');
                case "Assign cage / room"
                    key.cage_number = str2double(get(app.TocageEditField, 'value'));
                    key.room_number = get(app.RoomDropDown_logger, 'value');
                    key.cause = get(app.MoveReasonDropDown, 'value');
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'AssignCage');
                case "Assign to animal protocol"
                    protocol_name = get(app.AnimalprotocolDropDown,'value');
                    key.protocol_name = deblank(strtok(protocol_name, '-'));
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'AssignProtocol');
                case "Tag"
                    key = rmfield(key,'time'); %no time for this one
                    key.tag_id = uint32(str2double(get(app.TagEditField, 'value')));
                    key.tag_ear = get(app.TagearDropDown, 'value');
                    key.punch = get(app.EarpunchDropDown, 'value');
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'Tag');
                case "IP injection"
                    substance_name = get(app.SubstanceDropDown_IP, 'value');
                    thisSubstance = sln_animal.InjectionSubstance & sprintf('substance_name="%s"',substance_name);
                    key.substance_id = thisSubstance.fetch1('substance_id');
                    key.concentration = str2double(get(app.IP_inj_ConcentrationEditField, 'value'));
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'IPInjection');
                case "Feed"
                    substance_name = get(app.SubstanceDropDown_Feed, 'value');
                    thisSubstance = sln_animal.InjectionSubstance & sprintf('substance_name="%s"',substance_name);
                    key.substance_id = thisSubstance.fetch1('substance_id');
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'Feed');
                case "Eye injection"
                    substance_name = get(app.SubstanceDropDown_Eye, 'value');
                    thisSubstance = sln_animal.InjectionSubstance & sprintf('substance_name="%s"',substance_name);
                    key.substance_id = thisSubstance.fetch1('substance_id');
                    key.dilution = str2double(get(app.Dilution_EyeEditField, 'value'));
                    key.side = get(app.WhichEyeDropDown, 'value');

                    %add the eyes now if they are not in the DB
                    these_eyes = sln_animal.Eye & sprintf('animal_id=%d', key.animal_id);
                    if these_eyes.count==0
                        eye1.animal_id = key.animal_id;
                        eye1.side = 'Left';
                        eye2.animal_id = key.animal_id;
                        eye2.side = 'Right';
                        insert(sln_animal.Eye, eye1);
                        insert(sln_animal.Eye, eye2);
                    end

                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'EyeInjection');
                case "Brain injection"
                    substance_name = get(app.SubstanceDropDown_Brain, 'value');
                    thisSubstance = sln_animal.InjectionSubstance & sprintf('substance_name="%s"',substance_name);
                    key.substance_id = thisSubstance.fetch1('substance_id');
                    key.dilution = str2double(get(app.Dilution_BrainEditField, 'value'));
                    key.target = get(app.BrainTargetDropDown, 'value');
                    key.hemisphere = get(app.HemisphereDropDown, 'value');
                    key.head_rotation = str2double(get(app.AngleEditField, 'value'));
                    AP = str2double(get(app.APEditField, 'value'));
                    ML = str2double(get(app.MLEditField, 'value'));
                    DV = str2double(get(app.DVEditField, 'value'));
                    key.coordinates = [AP, ML, DV];
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'BrainInjection');
                case "Pair breeders"
                    key = rmfield(key,'time'); %no time for this one
                    key.male_id = str2double(get(app.MaleDJIDPairEditField,'value'));
                    key.female_id = str2double(get(app.FemaleDJIDPairEditField,'value'));
                    key.animal_id = key.female_id; %AnimalEvent needs an animal_id so we will set it to the female
                    male = sln_animal.Animal & sprintf('animal_id=%d', key.male_id);
                    if ~male.exists
                        error('Male with DJID = %d not found', key.male_id);
                    elseif ~strcmp(fetch1(male,'sex'),'Male')
                        error('Animal with DJID = %d is not male', key.male_id);
                    end
                    female = sln_animal.Animal & sprintf('animal_id=%d', key.female_id);
                    if ~female.exists
                        error('Female with DJID = %d not found', key.female_id);
                    elseif ~strcmp(fetch1(female,'sex'),'Female')
                        error('Animal with DJID = %d is not female', key.female_id);
                    end
                    key.cage_number = str2double(get(app.NewcagenumberPairEditField,'value'));
                    key.room_number = get(app.RoomDropDown_Pair,'value');
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'PairBreeders');
                    %need to activate the breeding cage in a separate
                    %transaction
                    key_breeding_pair.male_id = key.male_id;
                    key_breeding_pair.female_id = key.female_id;
                    thisPair = sln_animal.BreedingPair & key_breeding_pair;
                    source_id = fetch1(thisPair,'source_id');
                    key_activate = rmfield(key,{'animal_id','male_id','female_id','cage_number','room_number'});
                    key_activate.source_id = source_id;
                    try
                        insert(sln_animal.ActivateBreedingPair, key_activate);
                        disp('activation of breeding cage successful')
                    catch ME
                        disp('activation of breeding cage failed')
                        disp(ME.message);
                        keyboard;
                    end
                case "Social behavior session"
                    key.purpose = get(app.ExperimenttypeDropDown,'value');
                    key.animal_type_name = get(app.TestanimaltypeDropDown,'value');
                    if strcmp(get(app.RecordBehavior_switch, 'value'), 'Recorded')
                        key.recorded = 'T';
                    else
                        key.recorded = 'F';
                    end
                    key.stimArms = {'A', 'B', 'C'};
                    key.stimTypes{1} = get(app.WindowAStim,'value');
                    key.stimTypes{2} = get(app.WindowBStim,'value');
                    key.stimTypes{3} = get(app.WindowCStim,'value');
                    if get(app.AnimalAEditField, 'visible')
                        key.stimIDs(1) = str2double(get(app.AnimalAEditField,'value'));
                    else
                        key.stimIDs(1) = 0;
                    end
                    if get(app.AnimalBEditField, 'visible')
                        key.stimIDs(2) = str2double(get(app.AnimalBEditField,'value'));
                    else
                        key.stimIDs(2) = 0;
                    end
                    if get(app.AnimalCEditField, 'visible')
                        key.stimIDs(3) = str2double(get(app.AnimalCEditField,'value'));
                    else
                        key.stimIDs(3) = 0;
                    end
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'SocialBehaviorSession');
                case "Reserve for project"
                    key.project_name = get(app.ProjectDropDown, 'value');
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'ReservedForProject');
                case "Reserve for rig"
                    whichRig = get(app.RignameDropDown, 'value');
                    key = rmfield(key,'time'); %no time for this one
                    if strcmp(whichRig, 'A and B')
                        keyA = key;
                        keyA.rig_name = 'A';
                        keyB = key;
                        keyB.rig_name = 'B';
                        keyA
                        keyB
                        [insertedA, out_txt] = sln_animal.add_event(keyA,'ReservedForSession');
                        [calEventAddedA, result] = app.addCalendarEvent(keyA, 'reserveForRig');
                        [insertedB, out_txt] = sln_animal.add_event(keyA,'ReservedForSession');
                        [calEventAddedB, result] = app.addCalendarEvent(keyB, 'reserveForRig');
                        out_txt = [out_txt '; Cal: ' result];
                        inserted = insertedA & calEventAddedA & insertedB & calEventAddedB ;
                    else
                        key
                        key.rig_name = whichRig;
                        [inserted, out_txt] = sln_animal.add_event(key,'ReservedForSession');
                        [calEventAdded, result] = app.addCalendarEvent(key, 'reserveForRig');
                        out_txt = [out_txt '; Cal: ' result];
                        inserted = inserted & calEventAdded;
                    end
            end
            if inserted
                set(app.StatusLamp_Logger,'color','green');
                %need to reload current table state
                app.loadAnimalTable([]);
                pause(2);
                set(app.StatusLamp_Logger,'color','white');
            else
                set(app.StatusLamp_Logger,'color','red');
            end
            set(app.EventInsertStatusEdit,'value',out_txt);
            
            set(app.SubmitevententryButton,'enable',true); %enable
            drawnow;
        end

        % Value changed function: Filter_ProjectDropDown
        function Filter_ProjectDropDownValueChanged(app, event)
            value = app.Filter_ProjectDropDown.Value;
            app.filter_project = value;
            app.loadAnimalTable([]);
        end

        % Close request function: AnimalInterfaceV2UIFigure
        function AnimalInterfaceV2UIFigureCloseRequest(app, event)
            if app.connection.isConnected && isdeployed
                app.connection.close;
            end
            delete(app)
        end

        % Value changed function: ageLow_edit
        function ageLow_editValueChanged(app, event)
            app.filter_ageLow = str2double(app.ageLow_edit.Value);
            app.loadAnimalTable([]);
        end

        % Value changed function: ageHigh_edit
        function ageHigh_editValueChanged(app, event)
            app.filter_ageHigh = str2double(app.ageHigh_edit.Value);
            app.loadAnimalTable([]);
        end

        % Value changed function: GenotypesindatabaseListBox
        function GenotypesindatabaseListBoxValueChanged(app, event)
            value = app.GenotypesindatabaseListBox.Value;
            thisGenotype = fetch(sl.Genotype & sprintf('genotype_name="%s"',value), '*');
            set(app.GenotypenameEditField, 'value', thisGenotype.genotype_name);
            set(app.DescriptionTextArea, 'value', thisGenotype.description);
            set(app.SourceEditField, 'value', thisGenotype.source);
            set(app.CatalogEditField, 'value', thisGenotype.catalog_number);
        end

        % Button pushed function: AddsubstancetodatabaseButton
        function AddsubstancetodatabaseButtonPushed(app, event)
            key.substance_name = get(app.SubstancenameEditField, 'value');
            key.source = get(app.SourceEditField_substance, 'value');
            desc = get(app.DescriptionTextArea_substance, 'value');
            desc_flat = strcat(cell2mat(desc'));
            key.description = desc_flat;
            location = get(app.StorageLocationTextArea_substance, 'value');
            location_flat = strcat(cell2mat(location'));
            key.storage_location = location_flat;
            key.catalog_number = get(app.CatalogEditField_substance, 'value');
            try
                insert(sln_animal.InjectionSubstance, key);
                disp(['Substance ' key.substance_name ' insert successful']);
                set(app.StatusLamp_Substance,'color','green');
                set(app.SubstanceInsertStatusEdit,'value','Substance insert successful.')
                pause(2);
                set(app.StatusLamp_Substance,'color','white');
                app.init_AddSubstanceTab(false);
            catch
                disp(['Substance ' key.substance_name ' insert failed']);
                set(app.StatusLamp_Substance,'color','red');
                set(app.SubstanceInsertStatusEdit,'value','Substance insert failed.')
                app.init_AddSubstanceTab(false);
                %rethrow(ME);
            end
        end

        % Value changed function: SubstancesindatabaseListBox
        function SubstancesindatabaseListBoxValueChanged(app, event)
            value = app.SubstancesindatabaseListBox.Value;
            thisSubstance = fetch(sln_animal.InjectionSubstance & sprintf('substance_name="%s"',value), '*');
            set(app.SubstancenameEditField, 'value', thisSubstance.substance_name);
            set(app.DescriptionTextArea_substance, 'value', thisSubstance.description);
            set(app.SourceEditField_substance, 'value', thisSubstance.source);
            set(app.CatalogEditField_substance, 'value', thisSubstance.catalog_number);
            set(app.StorageLocationTextArea_substance, 'value', thisSubstance.storage_location);
        end

        % Value changed function: BrainareasindatabaseListBox
        function BrainareasindatabaseListBoxValueChanged(app, event)
            value = app.BrainareasindatabaseListBox.Value;
            thisArea = fetch(sln_animal.BrainArea & sprintf('target="%s"',value), '*');
            set(app.AbbreviationEditField, 'value', thisArea.target);
            set(app.BrainLongNameTextArea, 'value', thisArea.long_name);
        end

        % Button pushed function: AddbrainareatodatabaseButton
        function AddbrainareatodatabaseButtonPushed(app, event)
            key.target = get(app.AbbreviationEditField, 'value');
            long_text = get(app.BrainLongNameTextArea, 'value');
            long_text_flat = strcat(cell2mat(long_text'));
            key.long_name = long_text_flat;
            try
                insert(sln_animal.BrainArea, key);
                disp(['Brain area ' key.target ' insert successful']);
                set(app.StatusLamp_BrainArea,'color','green');
                set(app.BrainAreaInsertStatusEdit,'value','Brain area insert successful.')
                pause(2);
                set(app.StatusLamp_BrainArea,'color','white');
                app.init_AddBrainAreaTab(false);
            catch ME
                disp(['Brain area ' key.target ' insert failed']);
                set(app.StatusLamp_BrainArea,'color','red');
                set(app.BrainAreaInsertStatusEdit,'value','Brain area insert failed.')
                %rethrow(ME);
            end

        end

        % Button pushed function: AlleventsforselectedanimalButton
        function AlleventsforselectedanimalButtonPushed(app, event)
            if isempty(app.selected_animal_id)
                disp('cannot show log: no animal selected');
                return;
            end
            N = length(app.animal_event_list);
            ev_cellArray = {};
            ev_type = {};
            ev_date = [];
            z = 1;
            for i=1:N
                curEvent = app.animal_event_list{i};
                q = sln_animal.AnimalEvent * feval(sprintf('sln_animal.%s', curEvent)) & sprintf('animal_id=%d',app.selected_animal_id);
                ev_struct = fetch(q,'*');
                for j=1:length(ev_struct)
                    ev_cellArray{z} = ev_struct(j);
                    ev_type{z} = curEvent;
                    if isfield(ev_struct(j),'time')
                        ev_date = [ev_date datetime([ev_struct(j).date ' ' ev_struct(j).time])];
                    else
                        ev_date = [ev_date datetime([ev_struct(j).date ' 00:00:00'])];
                    end
                    z=z+1;
                end
            end
            [~, order] = sort(ev_date);
            ev_type = ev_type(order);
            ev_cellArray = ev_cellArray(order);
            L = length(ev_type);
            logText = cell(L,1);
            for i=1:L
                thisType = ev_type{i};
                thisID = ev_cellArray{i}.event_id;
                ev = eval(sprintf('sln_animal.AnimalEvent*sln_animal.%s',thisType)) & sprintf('event_id=%d',thisID);
                e = eval(sprintf('sln_animal.%s',thisType)) & proj(ev);
                logText{i} = sln_animal.printEvents(e);
            end
            thisAnimal = fetch(sln_animal.Animal & sprintf('animal_id=%d', app.selected_animal_id),'*');
            animal_str = sprintf('Animal %d: %s born %s, source=%s',...
                thisAnimal.animal_id,thisAnimal.sex, thisAnimal.dob, thisAnimal.source_id);
            logText = cat(1,{animal_str; ''},logText);
            set(app.LogTextArea,'value', logText);
            app.MainTabGroup.SelectedTab = app.LogTab;
        end

        % Button pushed function: EventsforallanimalsButton
        function EventsforallanimalsButtonPushed(app, event)
            selectedEventType = get(app.EventTypeDropDown,'value');
            switch selectedEventType
                case 'Genotyped'
                    eventType = 'Genotyped';
                case 'Deceased'
                    eventType = 'Deceased';
                case 'Gave birth'
                    eventType = 'GaveBirth';
                case 'Assign cage'
                    eventType = 'AssignCage';
                case 'IP injection'
                    eventType = 'IPinjection';
                case 'Eye injection'
                    eventType = 'EyeInjection';
                case 'Feed'
                    eventType = 'Feed';
                case 'Brain injection'
                    eventType = 'BrainInjection';
                case 'Handled'
                    eventType = 'Handling';
                case 'Social behavior session'
                    eventType = 'SocialBehaviorSession';
                case 'Reserve for project'
                    eventType = 'ReservedForProject';
                case 'Reserve for rig'
                    eventType = 'ReservedForSession';
            end
            D = get(app.AnimalTable,'data');
            inTableIDs = num2cell(D.DJ_ID);
            events = sl.AnimalEvent.all & struct('animal_id', inTableIDs) & sprintf('event_type="%s"',eventType);
            eventOrder = app.sortEvents(events);
            eventStruct = fetch(events,'*');
            L = length(eventStruct);
            logText = cell(L,1);
            for i=1:L
                thisID = eventStruct(eventOrder(i)).event_id;
                ev = feval(sprintf('sl.AnimalEvent%s',eventType)) & sprintf('event_id=%d',thisID);
                logText{i} = ev.printEvents;
            end
            set(app.LogTextArea,'value', logText);
            app.MainTabGroup.SelectedTab = app.LogTab;
        end

        % Button pushed function: ReauthorizeButton
        function ReauthorizeButtonPushed(app, event)
            auth_code = get(app.CalendarrefreshtokenEditField, 'value');
            try
                app.refresh_response = webwrite(...
                    'https://oauth2.googleapis.com/token',...
                    'grant_type','authorization_code',...
                    'client_id','667407419363-59ebnl9973ie08ui05fh1b8go1fvpbsn.apps.googleusercontent.com',...
                    'client_secret','dLydGGCoNOaMD1bA8HQFADqL',...
                    'code',auth_code,...
                    'redirect_uri','urn:ietf:wg:oauth:2.0:oob'...
                    );
                fid = fopen([app.calDir filesep 'gcal_access.txt'], 'w');
                fprintf(fid, '%s\n', refresh_response.access_token);
                fprintf(fid, '%s\n', refresh_response.refresh_token);
                fclose(fid);
                dips('Calendar reauthorization success');
            catch
                disp('Calendar reauthorization failed');
            end
        end

        % Button pushed function: SubmitevententryButton_breeders
        function SubmitevententryButton_breedersPushed(app, event)
            %             if isempty(app.selected_animal_id)
            %                 disp('No animal selected');
            %                 set(app.StatusLamp_Logger,'color','red');
            %                 set(app.EventInsertStatusEdit,'value','No animal selected');
            %                 return;
            %             end
            selectedTabName = get(get(app.EventTabGroup_breeders, 'SelectedTab'), 'Title');
            key.user_name = get(app.PersonDropDown_breeders, 'value');
            key.date = datestr(get(app.EventdateDatePicker_breeders, 'value'),'yyyy-mm-dd');
            notes = get(app.EventNotesTextArea_breeders, 'value');
            key.notes = strcat(cell2mat(notes'));
            %key.animal_id = app.selected_animal_id; %need to set 2 IDs
            switch selectedTabName
                case "Pair breeders"
                    [selected_ids, selected_sex] = app.getUnpairedBreederTableSelection;
                    validSelection = false;
                    if length(selected_ids) == 2
                        if strcmp(selected_sex(1),'Male')
                            if strcmp(selected_sex(2),'Female')
                                key.male_id = selected_ids(1);
                                key.female_id = selected_ids(2);
                                validSelection = true;
                            end
                        elseif strcmp(selected_sex(1),'Female')
                            if strcmp(selected_sex(2),'Male')
                                key.male_id = selected_ids(2);
                                key.female_id = selected_ids(1);
                                validSelection = true;
                            end
                        end
                    end
                    if ~validSelection
                        inserted = false;
                        out_txt = 'need to select a male and a female in unpaired breeders table';
                    else
                        key.cage_number = get(app.NewbreedercageEditField, 'value');
                        if isempty(key.cage_number)
                            inserted = false;
                            out_txt = 'need to enter a cage number for breeders';
                        else
                            key.room_number = get(app.RoomDropDown_Pair, 'value');
                            key
                            [inserted, out_txt] = add_animalEvent(key,'PairBreeders');
                        end
                    end
                case "Log birth"
                    key.cage_number = app.selected_breeder_cage;
                    key.number_of_pups = str2double(get(app.NumberofpupsEditField, 'value'));

                    D = get(app.selectedFemaleTable,'Data');
                    if ~isempty(D)
                        key.animal_id = D.DJ_ID(1);
                        key
                        [inserted, out_txt] = add_animalEvent(key,'GaveBirth');
                        %add calendar event for weaning
                        weanDate = datetime(key.date) + days(21);
                        weanKey.date = datestr(weanDate,'yyyy-mm-dd');
                        weanKey.cage_number = key.cage_number;
                        [calEventAdded, result] = app.addCalendarEvent(weanKey, 'weaning');
                        out_txt = [out_txt '; Cal: ' result];
                        inserted = inserted & calEventAdded;
                    else
                        inserted = false;
                        out_txt = 'need to select a breeding cage';
                    end
                case "Log weaning"
                    if ~isempty(app.selected_breeder_cage)
                        key.cage_number = app.selected_breeder_cage;
                        key.number_of_pups = str2double(get(app.NumberofpupsEditField_weaned, 'value'));
                        key
                        [inserted, out_txt] = add_animalEvent(key,'Weaned');
                    else
                        inserted = false;
                        out_txt = 'need to select a breeding cage';
                    end
                case "Separate breeding pair"
                    if ~isempty(app.selected_breeder_cage)
                        key.cage_number = app.selected_breeder_cage;
                        key.new_cage_male = get(app.NewcageformaleEditField, 'value');
                        key.new_room_male = get(app.RoomDropDown_breeders_sepMale, 'value');
                        key.new_cage_female = get(app.NewcageforfemaleEditField, 'value');
                        key.new_room_female = get(app.RoomDropDown_breeders_sepFemale, 'value');

                        D = get(app.selectedMaleTable,'Data');
                        key.male_id = D.DJ_ID(1);
                        D = get(app.selectedFemaleTable,'Data');
                        key.female_id = D.DJ_ID(1);

                        key
                        [inserted, out_txt] = add_animalEvent(key,'SeparateBreeders');
                    else
                        inserted = false;
                        out_txt = 'need to select a breeding cage';
                    end
                case "Retire breeder"
                    selected_ids = app.getUnpairedBreederTableSelection;
                    if length(selected_ids) == 1
                        key.animal_id = selected_ids;
                        key
                        [inserted, out_txt] = add_animalEvent(key,'RetireAsBreeder');
                    else
                        inserted = false;
                        out_txt = 'need to select a single unpaired breeder';
                    end
                case "Sacrifice breeding pair"
                    if ~isempty(app.selected_breeder_cage)
                        key_sep = key;

                        key_sep.cage_number = app.selected_breeder_cage;
                        D = get(app.selectedMaleTable,'Data');
                        male_id = D.DJ_ID(1);
                        key_sep.male_id = male_id;
                        D = get(app.selectedFemaleTable,'Data');
                        female_id = D.DJ_ID(1);
                        room = sl.Animal.roomNumber(male_id).room_number;
                        key_sep.female_id = female_id;

                        key_sep.new_cage_male = strcat('M', key_sep.cage_number);
                        key_sep.new_cage_female = strcat('F', key_sep.cage_number);
                        key_sep.new_room_male = room;
                        key_sep.new_room_female = room;

                        key_sep
                        [inserted, out_txt] = add_animalEvent(key_sep,'SeparateBreeders');
                        %TODO: but why are we doing this? these entries are
                        %fundamentally different from all the other entries
                        %in the table (will appear as a move to the same
                        %cage)

                        key_retire = key;
                        key_retire.animal_id = male_id;
                        key_retire
                        [inserted, out_txt] = add_animalEvent(key_retire,'RetireAsBreeder');
                        key_retire.animal_id = female_id;
                        key_retire
                        [inserted, out_txt] = add_animalEvent(key_retire,'RetireAsBreeder');

                        key_sac = key;
                        key_sac.cause = 'sacrificed not needed';
                        key_sac.notes = 'retired breeder';
                        key_sac.animal_id = male_id;
                        key_sac
                        [inserted, out_txt] = add_animalEvent(key_sac,'Deceased');
                        key_sac.animal_id = female_id;
                        key_sac
                        [inserted, out_txt] = add_animalEvent(key_sac,'Deceased');

                    else
                        inserted = false;
                        out_txt = 'need to select a breeding cage';
                    end
            end
            if inserted
                set(app.StatusLamp_breeders,'color','green');
                %need to reload current table state
                app.loadUnpairedBreedersTable([]);
                app.loadBreedingCagesTable([]);
                pause(2);
                set(app.StatusLamp_breeders,'color','white');
            else
                set(app.StatusLamp_breeders,'color','red');
            end
            set(app.EventInsertStatusEdit_breeders,'value',out_txt);
        end

        % Cell selection callback: BreederCagesTable
        function BreederCagesTableCellSelection(app, event)
            indices = event.Indices;
            selectedRow = indices(1,1);

            D = get(app.BreederCagesTable,'Data');
            Nrows = size(D,1);

            %select animal
            app.selected_breeder_cage = D{selectedRow,1};

            %make styles
            curStyle = uistyle('FontColor','red','FontWeight','bold');
            oldStyle = uistyle('FontColor','black','FontWeight','normal');

            %add style to this row
            addStyle(app.BreederCagesTable,curStyle,'row',selectedRow);

            %put other rows back to normal
            otherRows = setdiff(1:Nrows,selectedRow);
            for i=1:Nrows-1
                addStyle(app.BreederCagesTable,oldStyle,'row',otherRows(i));
            end
        end

        % Button pushed function: ExporttoExcelButton_breeders
        function ExporttoExcelButton_breedersPushed(app, event)
            %get breeder table
            D = get(app.BreederCagesTable,'Data');

            breederTable = D;

            curDate = string(datetime("today"), 'yyyy-MM-dd');
            defname = sprintf('%s-breederTable.xls', curDate);
            [fname, fpath] = uiputfile('*','Select table file location',defname);
            writetable(breederTable,[fpath fname]);
        end

        % Button pushed function: ExporttoExcelButton
        function ExporttoExcelButtonPushed(app, event)
            %get current table
            D = get(app.AnimalTable,'Data');
            curDate = string(datetime("today"), 'yyyy-MM-dd');
            defname = sprintf('%s_%s_animalTable.xls', curDate, app.login_user);
            [fname, fpath] = uiputfile('*','Select table file location',defname);
            writetable(D,[fpath, fname]);
        end

        % Button pushed function: ExporttotextfileButton
        function ExporttotextfileButtonPushed(app, event)
            logText = get(app.LogTextArea, 'value');
            Nlines = length(logText);

            curTime = string(datetime("now"), 'yyyy-MM-dd_hh-mm');
            defname = sprintf('eventLog_%s_%s.txt', curTime, app.login_user);
            [fname, fpath] = uiputfile('*','Select log file location',defname);
            fid = fopen([fpath fname],'w');

            for i=1:Nlines
                fprintf(fid, [logText{i} '\n']);
            end

            fclose(fid);

        end

        % Callback function
        function NumberofentriesSpinnerValueChanged(app, event)
            app.updateAnimalEntryVisibility();
        end

        % Button pushed function: SetotherentriesButton
        function SetotherentriesButtonPushed(app, event)
            bg_E1 = get(app.BackgroundDropDown_E1,'Value');
            DOB_E1 = get(app.DOBDatePicker_E1,'Value');
            tagID_E1_str = get(app.TagIDEditField_E1,'Value');
            cage_num_E1 = get(app.CageEditField_E1, 'Value');
            room_num_E1 = get(app.RoomDropDown_E1, 'Value');
            source_E1 = get(app.SourceDropDown_E1, 'Value');
            info_E1 = get(app.ExtInfo_E1, 'Value');
            protocol_E1 = get(app.ProtocolDropDown_E1, 'Value');
            sex_E1 = get(get(app.SexButtonGroup_E1,'SelectedObject'), 'text');
            punch_ear_E1 = get(get(app.PunchearButtonGroup_E1,'SelectedObject'), 'text');
            tag_ear_E1 = get(get(app.TagearButtonGroup_E1,'SelectedObject'), 'text');
            tagged_E1 = get(app.tagged_switch_E1,'Value');
            DOB_known_E1 = get(app.DOB_known_switch_E1,'Value');

            tagID_E1 = str2double(tagID_E1_str);

            %N_visible_entries = get(app.NumberofentriesSpinner, 'Value');
            N_visible_entries = get(app.NumberofentriesSlider, 'Value');
            for e=2:N_visible_entries
                set(app.(['BackgroundDropDown_E' num2str(e)]),'Value',bg_E1);
                set(app.(['DOBDatePicker_E' num2str(e)]),'Value',DOB_E1);
                set(app.(['CageEditField_E' num2str(e)]),'Value',cage_num_E1);
                set(app.(['RoomDropDown_E' num2str(e)]),'Value',room_num_E1);
                set(app.(['SourceDropDown_E' num2str(e)]),'Value',source_E1);
                set(app.(['ExtInfo_E' num2str(e)]),'Value',info_E1);
                set(app.(['ProtocolDropDown_E' num2str(e)]),'Value',protocol_E1);
                set(app.(['tagged_switch_E' num2str(e)]),'Value',tagged_E1);
                set(app.(['DOB_known_switch_E' num2str(e)]),'Value',DOB_known_E1);
                set(app.(['TagIDEditField_E' num2str(e)]),'Value',num2str(tagID_E1+e-1));
                app.setRadioButtonToValue(app.(['SexButtonGroup_E' num2str(e)]), sex_E1);
                app.setRadioButtonToValue(app.(['PunchearButtonGroup_E' num2str(e)]), punch_ear_E1);
                app.setRadioButtonToValue(app.(['TagearButtonGroup_E' num2str(e)]), tag_ear_E1);
            end
        end

        % Button pushed function: AddmultipleanimalstodatabaseButton
        function AddmultipleanimalstodatabaseButtonPushed(app, event)
            %N_visible_entries = get(app.NumberofentriesSpinner, 'Value');
            N_visible_entries = get(app.NumberofentriesSlider, 'Value');

            for e=1:N_visible_entries
                %make insert key
                key = struct;
                key_tag = struct;

                %check isTagged
                if ~strcmp(get(app.(['tagged_switch_E' num2str(e)]),'value'),'Tagged')
                    key_tag.do_tag = false;
                end
                t = get(app.(['TagIDEditField_E' num2str(e)]),'value');
                if ~isempty(t)
                    key_tag.tag_id = uint32(str2double(t));
                else
                    key_tag.tag_id = NaN;
                end
                key_tag.tag_ear = get(get(app.(['TagearButtonGroup_E', num2str(e)]),'SelectedObject'), 'text');
                %ear punch
                key_tag.punch = get(get(app.(['PunchearButtonGroup_E' num2str(e)]),'SelectedObject'), 'text');

                %get genotype
                key.genotype_name = get(app.(['GenotypeDropDown_E' num2str(e)]), 'value');

                %TODO
                key.species = get(get(app.SpeciesTabGroup, 'SelectedTab'), 'Title');

                %check DOB
                if strcmp(get(app.(['DOB_known_switch_E' num2str(e)]),'value'),'DOB known')
                    key.dob = string(get(app.(['DOBDatePicker_E' num2str(e)]),'value'));
                else
                    key.dob = [];
                end

                %sex
                key.sex = get(get(app.(['SexButtonGroup_E' num2str(e)]),'SelectedObject'), 'text');

                %source and source ID
                key.source = get(app.(['SourceDropDown_E' num2str(e)]),'value');
                key.source_id = get(app.(['SourceIDEditField_E' num2str(e)]),'value');

                %assign tag
                if isempty(key.dob)
                    key_tag.date = '1900-01-01'; %arbitrary early date
                else
                    key_tag.date = key.dob; %wean date is more exact, but this will do
                end
                key_tag.user_name = app.login_user;

                %assign cage
                key_cage.cage_number = get(app.(['CageEditField_E' num2str(e)]),'value');
                key_cage.room_number = get(app.(['RoomDropDown_E' num2str(e)]), 'value');
                key_cage.user_name = app.login_user;
                if isempty(key.dob)
                    key_cage.date = '1900-01-01'; %arbitrary early date
                else
                    key_cage.date = key.dob; %wean date is more exact, but this will do
                end

                %assign to protocol
                protocol_name = get(app.(['ProtocolDropDown_E' num2str(e)]),'value');
                key_protocol.protocol_name = deblank(strtok(protocol_name, '-'));
                key_protocol.user_name = app.login_user;
                key_protocol.date = string(datetime(date,'format','yyyy-MM-dd')); %current date

                key_tagVec(e) = key_tag;
                key_protocolVec(e) = key_protocol;
                key_cageVec(e) = key_cage;
                keyVec(e) = key;

            end
            disp(['Adding ' num2str(N_visible_entries) ' animals']);
            %add the animals
            [inserted, out_txt] = add_animals(keyVec, key_tagVec, key_cageVec, key_protocolVec);
            sln_animal.updateGenotypeString([keyVec.animal_id]);

            if inserted
                set(app.StatusLamp_Animal,'color','green');
                pause(2);
                set(app.StatusLamp_Animal,'color','white');
            else
                set(app.StatusLamp_Animal,'color','red');
            end
            set(app.AnimalInsertStatusEdit,'value',out_txt);
        end

        % Button pushed function: AddprojecttodatabaseButton
        function AddprojecttodatabaseButtonPushed(app, event)
            key.project_name = get(app.ProjectNameEditField, 'value');
            desc_text = get(app.ProjectDescriptionTextArea, 'value');
            desc_text_flat = strcat(cell2mat(desc_text'));
            key.description = desc_text_flat;
            try
                insert(sln_lab.Project, key);
                disp(['Project ' key.project_name ' insert successful']);
                set(app.StatusLamp_Project,'color','green');
                set(app.ProjectInsertStatusEdit,'value','Project insert successful.')
                pause(2);
                set(app.StatusLamp_Project,'color','white');
                app.init_AddProjectTab(false);
            catch ME
                disp(['Project ' key.project_name ' insert failed']);
                set(app.StatusLamp_Project,'color','red');
                set(app.StatusLamp_Project,'value','Project insert failed.')
                %rethrow(ME);
            end
        end

        % Value changed function: ProjectsindatabaseListBox
        function ProjectsindatabaseListBoxValueChanged(app, event)
            value = app.ProjectsindatabaseListBox.Value;
            thisProject = fetch(sln_lab.Project & sprintf('project_name="%s"',value), '*');
            set(app.ProjectNameEditField, 'value', thisProject.project_name);
            set(app.ProjectDescriptionTextArea, 'value', thisProject.description);
        end

        % Value changed function: BehaviorexperimenttypesindatabaseListBox
        function BehaviorexperimenttypesindatabaseListBoxValueChanged(app, event)
            value = app.BehaviorexperimenttypesindatabaseListBox.Value;
            thisExp = fetch(sl_behavior.SocialBehaviorExperimentType & sprintf('purpose="%s"',value), '*');
            set(app.BehaviorExpNameEditField, 'value', thisExp.purpose);
            set(app.BehaviorExpDescriptionTextArea, 'value', thisExp.description);
        end

        % Button pushed function: AddexperimenttypetodatabaseButton
        function AddexperimenttypetodatabaseButtonPushed(app, event)
            key.purpose = get(app.BehaviorExpNameEditField, 'value');
            desc_text = get(app.BehaviorExpDescriptionTextArea, 'value');
            desc_text_flat = strcat(cell2mat(desc_text'));
            key.description = desc_text_flat;
            try
                insert(sl_behavior.SocialBehaviorExperimentType, key);
                disp(['Experiment ' key.purpose ' insert successful']);
                set(app.StatusLamp_BehaviorExp,'color','green');
                set(app.BehaviorExpInsertStatusEdit,'value','Experiment insert successful.')
                pause(2);
                set(app.StatusLamp_BehaviorExp,'color','white');
                app.init_AddProjectTab(false);
            catch ME
                disp(['Experiment ' key.purpose ' insert failed']);
                set(app.StatusLamp_BehaviorExp,'color','red');
                set(app.StatusLamp_BehaviorExp,'value','Experiment insert failed.')
                %rethrow(ME);
            end
        end

        % Value changing function: NumberofentriesSlider
        function NumberofentriesSliderValueChanging(app, event)
            changingValue = event.Value;
            set(app.NumberofentriesSlider, 'Value', round(changingValue));
        end

        % Value changed function: NumberofentriesSlider
        function NumberofentriesSliderValueChanged(app, event)
            value = app.NumberofentriesSlider.Value;
            set(app.NumberofentriesSlider, 'Value', round(value));
            app.updateAnimalEntryVisibility();
        end

        % Button pushed function: SetsourceButton
        function SetsourceButtonPushed(app, event)
            selectedSource = get(app.AnimalSourceDropDown, 'value');
            set(app.SourceDropDown_E1, 'Value', selectedSource);
            set(app.ExtInfo_E1, 'Value', get(app.ExternalInfoEditField,'value'));
        end

        % Callback function
        function DeleteAnimal_buttonButtonPushed(app, event)
%             DJID = get(app.DJIDEditField_deleteAnimal,'value');
%             thisAnimal = sl.Animal & sprintf('animal_id=%d',DJID);
%             if thisAnimal.exists
%                 disp(['Deleting animal ' num2str(DJID)]);
%                 del(thisAnimal);
%                 set(app.StatusLamp_deleteAnimal,'color','green');
%                 pause(2);
%                 set(app.StatusLamp_deleteAnimal,'color','white');
%                 out_txt = ['Animal ' num2str(DJID) ' deleted'];
%             else
%                 disp(['DJID: ' num2str(DJID) ' not found']);
%                 set(app.StatusLamp_deleteAnimal,'color','red');
%                 out_txt = ['Animal ' num2str(DJID) ' not found'];
%             end
%             set(app.EventInsertStatusEdit_deleteAnimal,'value',out_txt);
% 
        end

        % Value changed function: WindowAStim
        function WindowAStimValueChanged(app, event)
            value = app.WindowAStim.Value;
            needs_id = strcmp(fetch1(sl_behavior.BehaviorVisualStimulusType & sprintf('stim_type="%s"', value), 'needs_id'), 'T');
            if needs_id
                set(app.AnimalAEditField,'visible',true);
            else
                set(app.AnimalAEditField,'visible',false);
            end
        end

        % Value changed function: WindowBStim
        function WindowBStimValueChanged(app, event)
            value = app.WindowBStim.Value;
            needs_id = strcmp(fetch1(sl_behavior.BehaviorVisualStimulusType & sprintf('stim_type="%s"', value), 'needs_id'), 'T');
            if needs_id
                set(app.AnimalBEditField,'visible',true);
            else
                set(app.AnimalBEditField,'visible',false);
            end
        end

        % Value changed function: WindowCStim
        function WindowCStimValueChanged(app, event)
            value = app.WindowCStim.Value;
            needs_id = strcmp(fetch1(sl_behavior.BehaviorVisualStimulusType & sprintf('stim_type="%s"', value), 'needs_id'), 'T');
            if needs_id
                set(app.AnimalCEditField,'visible',true);
            else
                set(app.AnimalCEditField,'visible',false);
            end
        end

        % Selection changed function: SearchTypeButtonGroup_breeders
        function SearchTypeButtonGroup_breedersSelectionChanged(app, event)
            selectedButton = app.SearchTypeButtonGroup_breeders.SelectedObject;
            switch get(selectedButton,'Text')
                case 'DJ ID'
                    set(app.Search_DJIDEditField_breeders, 'enable', true);
                    set(app.Search_TagIDEditField_breeders, 'enable', false);
                    set(app.CageNumSearchEditField_breeders, 'enable', false);
                case 'Cage #'
                    set(app.Search_DJIDEditField_breeders, 'enable', false);
                    set(app.Search_TagIDEditField_breeders, 'enable', false);
                    set(app.CageNumSearchEditField_breeders, 'enable', true);
                case 'Tag #'
                    set(app.Search_DJIDEditField_breeders, 'enable', false);
                    set(app.Search_TagIDEditField_breeders, 'enable', true);
                    set(app.CageNumSearchEditField_breeders, 'enable', false);
            end
        end

        % Selection changed function: SearchTypeButtonGroup
        function SearchTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SearchTypeButtonGroup.SelectedObject;
            switch get(selectedButton,'Text')
                case 'DJ ID'
                    set(app.Search_DJIDEditField, 'enable', true);
                    set(app.Search_TagIDEditField, 'enable', false);
                    set(app.CageNumSearchEditField, 'enable', false);
                case 'Cage #'
                    set(app.Search_DJIDEditField, 'enable', false);
                    set(app.Search_TagIDEditField, 'enable', false);
                    set(app.CageNumSearchEditField, 'enable', true);
                case 'Tag #'
                    set(app.Search_DJIDEditField, 'enable', false);
                    set(app.Search_TagIDEditField, 'enable', true);
                    set(app.CageNumSearchEditField, 'enable', false);
            end
        end

        % Button pushed function: SearchButton_breeders
        function SearchButton_breedersPushed(app, event)
%             selectedButton = app.SearchTypeButtonGroup_breeders.SelectedObject;
%             q = [];
%             q_breeders = [];
%             allCage = sln_animal.Animal.cageNumber([],true);
%             switch get(selectedButton,'Text')
%                 case 'DJ ID'
%                     v = get(app.Search_DJIDEditField_breeders,'value');
%                     if ischar(v)
%                         v = str2double(v);
%                     end
%                     if ~isnan(v)
%                         q.animal_id = v;
%                         %for breeding pair we need cage nunmber instead
%                         cage = sln_animal.Animal.cageNumber(v);
%                         if~isempty(cage)
%                             ind = find(strcmp({allCage.cage_number}, cage.cage_number));
%                             for i=1:length(ind)
%                                 q_breeders(i).animal_id = allCage(ind(i)).animal_id;
%                             end
%                         end
%                     end
%                 case 'Cage #'
%                     v = get(app.CageNumSearchEditField_breeders,'value');
%                     allCage = sln_animal.Animal.cageNumber([],true);
%                     ind = find(strcmp({allCage.cage_number}, v));
%                     if isempty(ind)
%                         q = struct;
%                         q.animal_id = 0;
%                     end
%                     for i=1:length(ind)
%                         q(i).animal_id = allCage(ind(i)).animal_id;
%                     end
%                     q_breeders = q;
%                 case 'Tag #'
%                     v = get(app.Search_TagIDEditField_breeders,'value');
%                     if ischar(v)
%                         v = str2double(v);
%                     end
%                     allTag = sln_animal.Animal.tagNumber([],true);
%                     ind = find([allTag.tag_id]==v);
%                     if isempty(ind)
%                         q = struct;
%                         q.animal_id = 0;
%                     else
%                         q.animal_id = allTag(ind).animal_id;
%                         %for breeding pair we need cage nunmber instead
%                         cage = sln_animal.Animal.cageNumber(q.animal_id);
%                         if~isempty(cage)
%                             ind = find(strcmp({allCage.cage_number}, cage.cage_number));
%                             for i=1:length(ind)
%                                 q_breeders(i).animal_id = allCage(ind(i)).animal_id;
%                             end
%                         end
%                     end
%             end
%             if ~isempty(q)
%                 app.loadBreedingCagesTable(q_breeders);
%                 app.loadUnpairedBreedersTable(q);
%             end
        end

        % Button pushed function: ResetButton_breeders
        function ResetButton_breedersPushed(app, event)
            q = [];
            app.loadBreedingCagesTable(q);
            app.loadUnpairedBreedersTable(q);
        end

        % Value changed function: QueriesListBox
        function QueriesListBoxValueChanged(app, event)
            value = app.QueriesListBox.Value;
            set(app.QuerynameLabel,'text',value);
            set(app.QuerynameEditField,'value',value);

            text = split(app.todo_queries(value),'\n');
            set(app.QuerytextTextArea,'value',text);
            s = eval(cell2mat(join(text)));
            if ~isempty(s)
                table = struct2table(s);
            else
                f = fieldnames(s);
                table = cell2table(cell(0,numel(f)),'VariableNames',f);
            end
            set(app.QueryResultTable,...
                'data',table,...
                'columnname',table.Properties.VariableNames);
            % table??
        end

        % Button pushed function: SubmitnewqueryButton
        function SubmitnewqueryButtonPushed(app, event)
            key = struct(...
                'query_name', get(app.QuerynameEditField,'value'),...
                'query_text',cell2mat(join(get(app.QuerytextTextArea,'value'),'\n'))...
                );

            err = false;
            try
                eval(strrep(key.query_text,'\n','')); %if this errors, show bad status
            catch ME
                txt = 'Error evaluating query';
                err = true;
                disp(getReport(ME,'extended'));
                disp('Query was:');
                disp(strrep(key.query_text,'\n',''));
            end

            if ~err
                try
                    sl_mutable.TodoQueries().insert(key)
                catch
                    err = true;
                    txt = 'Error saving TODO query to database';
                end
            end

            if ~err
                app.todo_queries(key.query_name) = key.query_text;
                set(app.QueriesListBox,'items',app.todo_queries.keys());
                set(app.QueriesListBox,'value',key.query_name);
                set(app.StatusLamp_TODOquery,'color','green')
                set(app.EditField_TODOquery,'value','success')
                pause(2)
                set(app.StatusLamp_TODOquery,'color','white')
                set(app.EditField_TODOquery,'value','')
            else
                set(app.StatusLamp_TODOquery,'color','red')
                set(app.EditField_TODOquery,'value',txt)
            end

        end

        % Value changed function: GenotypeDropDown_BreedersTab
        function GenotypeDropDown_BreedersTabValueChanged(app, event)
%             value = app.GenotypeDropDown_BreedersTab.Value;
%             q_breeders = [];
%             q = [];
%             if ~strcmp(value, 'All')
%                 allCage = sln_animal.Animal.cageNumber([],true);
%                 q = fetch(sl.Animal & sprintf('genotype_name = "%s"', value));
%                 these_ids = fetchn(sl.Animal & sprintf('genotype_name = "%s"', value),'animal_id');
%                 %for breeding pair we need cage nunmber instead
%                 all_breeding_cages = fetchn(sl.BreedingCage, 'cage_number');
%                 active_mask = sl.BreedingCage.isActive(all_breeding_cages);
%                 all_breeding_cages = all_breeding_cages(active_mask);
%                 cage = sl.Animal.cageNumber(these_ids);
%                 ind = find(matches({allCage.cage_number}, {cage.cage_number}));
%                 z=1;
%                 for i=1:length(ind)
%                     if sum(strcmp(allCage(ind(i)).cage_number, all_breeding_cages))
%                         q_breeders(z).animal_id = allCage(ind(i)).animal_id;
%                         z=z+1;
%                     end
%                 end
%                 if isempty(q_breeders)
%                     q_breeders.animal_id = 0;
%                 end
%             end
%             app.loadBreedingCagesTable(q_breeders);
%             app.loadUnpairedBreedersTable(q);
        end

        % Value changed function: GenotypeEntriesSpinner
        function GenotypeEntriesSpinnerValueChanged(app, event)
            app.updateGenotypeEntryVisibility();
        end

        % Value changed function: SourceTypeDropDown
        function SourceTypeDropDownValueChanged(app, event)
            app.loadAnimalSourceMenu();
        end

        % Value changed function: Filter_ProtocolDropDown
        function Filter_ProtocolDropDownValueChanged(app, event)
            value = app.Filter_ProtocolDropDown.Value;
            app.filter_protocol= value;
            app.loadAnimalTable([]);
        end

        % Value changed function: Filter_BgDropDown
        function Filter_BgDropDownValueChanged(app, event)
            value = app.Filter_BgDropDown.Value;
            if strcmp(value,'All')
                app.filter_struct = rmfield(app.filter_struct, 'background_name');
            else
                app.filter_struct.background_name = value;
            end
            app.loadAnimalTable([]);
        end

        % Selection changed function: IPInjectionButtonGroup
        function IPInjectionButtonGroupSelectionChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Selection changed function: EyeInjectionButtonGroup
        function EyeInjectionButtonGroupSelectionChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Selection changed function: BrainInjectionButtonGroup
        function BrainInjectionButtonGroupSelectionChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Selection changed function: RigreservationButtonGroup
        function RigreservationButtonGroupSelectionChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Selection changed function: BehaviorsessionButtonGroup
        function BehaviorsessionButtonGroupSelectionChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Value changed function: LocusDropDown_1
        function LocusDropDown_1ValueChanged(app, event)
            value = app.LocusDropDown_1.Value;
            alleles_at_locus = fetchn(sln_animal.AlleleLocusMap & sprintf('locus_name="%s"', value), 'allele_name');
            items = [alleles_at_locus; {'WT'; 'Ambiguous'}];  
            set(app.Allele1DropDown_1,'items',items);
            set(app.Allele2DropDown_1,'items',items);
        end

        % Value changed function: Allele1DropDown_1
        function Allele1DropDown_1ValueChanged(app, event)
            value = app.Allele1DropDown_1.Value;
            switch value
                case {'WT', 'Ambiguous'}
                    %do nothing
                otherwise
                    locus_for_allele = fetchn(sln_animal.AlleleLocusMap & sprintf('allele_name="%s"', value), 'locus_name');
                    if ~ismember(locus_for_allele, 'unknown')
                        items = [locus_for_allele; 'unknown'];
                    else
                        items = locus_for_allele;
                    end
                    set(app.LocusDropDown_1,'items',items);
            end
        end

        % Value changed function: Allele2DropDown_1
        function Allele2DropDown_1ValueChanged(app, event)
            value = app.Allele2DropDown_1.Value;
            switch value
                case {'WT', 'Ambiguous'}
                    %do nothing
                otherwise
                    locus_for_allele = fetchn(sln_animal.AlleleLocusMap & sprintf('allele_name="%s"', value), 'locus_name');
                    if ~ismember(locus_for_allele, 'unknown')
                        items = [locus_for_allele; 'unknown'];
                    else
                        items = locus_for_allele;
                    end
                    set(app.LocusDropDown_1,'items',items);
            end
        end

        % Value changed function: LocusDropDown_2
        function LocusDropDown_2ValueChanged(app, event)
            value = app.LocusDropDown_2.Value;
            alleles_at_locus = fetchn(sln_animal.AlleleLocusMap & sprintf('locus_name="%s"', value), 'allele_name');
            items = [alleles_at_locus; {'WT'; 'Ambiguous'}];  
            set(app.Allele1DropDown_2,'items',items);
            set(app.Allele2DropDown_2,'items',items);
        end

        % Value changed function: LocusDropDown_3
        function LocusDropDown_3ValueChanged(app, event)
            value = app.LocusDropDown_3.Value;
            alleles_at_locus = fetchn(sln_animal.AlleleLocusMap & sprintf('locus_name="%s"', value), 'allele_name');
            items = [alleles_at_locus; {'WT'; 'Ambiguous'}];  
            set(app.Allele1DropDown_3,'items',items);
            set(app.Allele2DropDown_3,'items',items);
        end

        % Value changed function: LocusDropDown_4
        function LocusDropDown_4ValueChanged(app, event)
            value = app.LocusDropDown_4.Value;
            alleles_at_locus = fetchn(sln_animal.AlleleLocusMap & sprintf('locus_name="%s"', value), 'allele_name');
            items = [alleles_at_locus; {'WT'; 'Ambiguous'}];  
            set(app.Allele1DropDown_4,'items',items);
            set(app.Allele2DropDown_4,'items',items);
        end

        % Value changed function: Allele1DropDown_2
        function Allele1DropDown_2ValueChanged(app, event)
            value = app.Allele1DropDown_2.Value;
            switch value
                case {'WT', 'Ambiguous'}
                    %do nothing
                otherwise
                    locus_for_allele = fetchn(sln_animal.AlleleLocusMap & sprintf('allele_name="%s"', value), 'locus_name');
                    if ~ismember(locus_for_allele, 'unknown')
                        items = [locus_for_allele; 'unknown'];
                    else
                        items = locus_for_allele;
                    end
                    set(app.LocusDropDown_2,'items',items);
            end
        end

        % Value changed function: Allele2DropDown_2
        function Allele2DropDown_2ValueChanged(app, event)
            value = app.Allele2DropDown_2.Value;
            switch value
                case {'WT', 'Ambiguous'}
                    %do nothing
                otherwise
                    locus_for_allele = fetchn(sln_animal.AlleleLocusMap & sprintf('allele_name="%s"', value), 'locus_name');
                    if ~ismember(locus_for_allele, 'unknown')
                        items = [locus_for_allele; 'unknown'];
                    else
                        items = locus_for_allele;
                    end
                    set(app.LocusDropDown_2,'items',items);
            end
        end

        % Value changed function: Allele1DropDown_3
        function Allele1DropDown_3ValueChanged(app, event)
            value = app.Allele1DropDown_3.Value;
            switch value
                case {'WT', 'Ambiguous'}
                    %do nothing
                otherwise
                    locus_for_allele = fetchn(sln_animal.AlleleLocusMap & sprintf('allele_name="%s"', value), 'locus_name');
                    if ~ismember(locus_for_allele, 'unknown')
                        items = [locus_for_allele; 'unknown'];
                    else
                        items = locus_for_allele;
                    end
                    set(app.LocusDropDown_3,'items',items);
            end
        end

        % Value changed function: Allele2DropDown_3
        function Allele2DropDown_3ValueChanged(app, event)
            value = app.Allele2DropDown_3.Value;
            switch value
                case {'WT', 'Ambiguous'}
                    %do nothing
                otherwise
                    locus_for_allele = fetchn(sln_animal.AlleleLocusMap & sprintf('allele_name="%s"', value), 'locus_name');
                    if ~ismember(locus_for_allele, 'unknown')
                        items = [locus_for_allele; 'unknown'];
                    else
                        items = locus_for_allele;
                    end
                    set(app.LocusDropDown_3,'items',items);
            end
        end

        % Value changed function: Allele1DropDown_4
        function Allele1DropDown_4ValueChanged(app, event)
            value = app.Allele1DropDown_4.Value;
            switch value
                case {'WT', 'Ambiguous'}
                    %do nothing
                otherwise
                    locus_for_allele = fetchn(sln_animal.AlleleLocusMap & sprintf('allele_name="%s"', value), 'locus_name');
                    if ~ismember(locus_for_allele, 'unknown')
                        items = [locus_for_allele; 'unknown'];
                    else
                        items = locus_for_allele;
                    end
                    set(app.LocusDropDown_4,'items',items);
            end
        end

        % Value changed function: Allele2DropDown_4
        function Allele2DropDown_4ValueChanged(app, event)
            value = app.Allele2DropDown_4.Value;
            switch value
                case {'WT', 'Ambiguous'}
                    %do nothing
                otherwise
                    locus_for_allele = fetchn(sln_animal.AlleleLocusMap & sprintf('allele_name="%s"', value), 'locus_name');
                    if ~ismember(locus_for_allele, 'unknown')
                        items = [locus_for_allele; 'unknown'];
                    else
                        items = locus_for_allele;
                    end
                    set(app.LocusDropDown_4,'items',items);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnimalInterfaceV2UIFigure = uifigure('Visible', 'off');
            app.AnimalInterfaceV2UIFigure.Name = 'AnimalInterfaceV2';
            app.AnimalInterfaceV2UIFigure.CloseRequestFcn = createCallbackFcn(app, @AnimalInterfaceV2UIFigureCloseRequest, true);
            app.MainTabGroup = uitabgroup(app.AnimalInterfaceV2UIFigure);
            app.MainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @MainTabGroupSelectionChanged, true);
            app.LoginTab = uitab(app.MainTabGroup);
            app.LoginTab.Title = 'Login';
            app.PasswordEditFieldLabel = uilabel(app.LoginTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.LoginTab, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.LoginButton = uibutton(app.LoginTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.UserEditFieldLabel = uilabel(app.LoginTab);
            app.UserEditFieldLabel.Text = 'User';
            app.LoginUserEditField = uieditfield(app.LoginTab, 'text');
            app.AnimalLoggerTab = uitab(app.MainTabGroup);
            app.AnimalLoggerTab.Title = 'AnimalLogger';
            app.AnimalTable = uitable(app.AnimalLoggerTab);
            app.AnimalTable.ColumnName = {'DJ ID'; 'Tag ID'; 'Tag'; 'Punch'; 'Cage'; 'Room'; 'Bg'; 'Genotype'; 'Sex'; 'Age'; 'IP inj.'; 'Eye inj.'; 'Brain inj.'; 'Behavior'; 'Res. for rig'; 'Project'; 'Protocol'};
            app.AnimalTable.RowName = {};
            app.AnimalTable.ColumnSortable = [true true true true true false true true true true true true true true true true true];
            app.AnimalTable.CellSelectionCallback = createCallbackFcn(app, @AnimalTableCellSelection, true);
            app.EventTabGroup = uitabgroup(app.AnimalLoggerTab);
            app.EventTabGroup.SelectionChangedFcn = createCallbackFcn(app, @EventTabGroupSelectionChanged, true);
            app.GenotypedTab = uitab(app.EventTabGroup);
            app.GenotypedTab.Title = 'Genotyped';
            app.SourceDropDown_2Label = uilabel(app.GenotypedTab);
            app.SourceDropDown_2Label.Text = 'Source';
            app.GenotypeSourceDropDown = uidropdown(app.GenotypedTab);
            app.SourceDropDown_2Label_2 = uilabel(app.GenotypedTab);
            app.SourceDropDown_2Label_2.Text = 'Locus';
            app.LocusDropDown_1 = uidropdown(app.GenotypedTab);
            app.LocusDropDown_1.ValueChangedFcn = createCallbackFcn(app, @LocusDropDown_1ValueChanged, true);
            app.SourceDropDown_2Label_3 = uilabel(app.GenotypedTab);
            app.SourceDropDown_2Label_3.Text = 'Allele1';
            app.Allele1DropDown_1 = uidropdown(app.GenotypedTab);
            app.Allele1DropDown_1.ValueChangedFcn = createCallbackFcn(app, @Allele1DropDown_1ValueChanged, true);
            app.EntriesSpinnerLabel = uilabel(app.GenotypedTab);
            app.EntriesSpinnerLabel.Text = 'Entries';
            app.GenotypeEntriesSpinner = uispinner(app.GenotypedTab);
            app.GenotypeEntriesSpinner.Limits = [1 4];
            app.GenotypeEntriesSpinner.ValueChangedFcn = createCallbackFcn(app, @GenotypeEntriesSpinnerValueChanged, true);
            app.GenotypeEntriesSpinner.Value = 2;
            app.LocusDropDown_2Label = uilabel(app.GenotypedTab);
            app.LocusDropDown_2Label.Text = 'Locus';
            app.LocusDropDown_2 = uidropdown(app.GenotypedTab);
            app.LocusDropDown_2.ValueChangedFcn = createCallbackFcn(app, @LocusDropDown_2ValueChanged, true);
            app.Allele1DropDown_4Label = uilabel(app.GenotypedTab);
            app.Allele1DropDown_4Label.Text = 'Allele1';
            app.Allele1DropDown_2 = uidropdown(app.GenotypedTab);
            app.Allele1DropDown_2.ValueChangedFcn = createCallbackFcn(app, @Allele1DropDown_2ValueChanged, true);
            app.Allele2DropDownLabel = uilabel(app.GenotypedTab);
            app.Allele2DropDownLabel.Text = 'Allele2';
            app.Allele2DropDown_1 = uidropdown(app.GenotypedTab);
            app.Allele2DropDown_1.ValueChangedFcn = createCallbackFcn(app, @Allele2DropDown_1ValueChanged, true);
            app.Allele2DropDown_4Label = uilabel(app.GenotypedTab);
            app.Allele2DropDown_4Label.Text = 'Allele2';
            app.Allele2DropDown_2 = uidropdown(app.GenotypedTab);
            app.Allele2DropDown_2.ValueChangedFcn = createCallbackFcn(app, @Allele2DropDown_2ValueChanged, true);
            app.LocusDropDown_2Label_4 = uilabel(app.GenotypedTab);
            app.LocusDropDown_2Label_4.Text = 'Locus';
            app.LocusDropDown_3 = uidropdown(app.GenotypedTab);
            app.LocusDropDown_3.ValueChangedFcn = createCallbackFcn(app, @LocusDropDown_3ValueChanged, true);
            app.Allele1DropDown_2Label = uilabel(app.GenotypedTab);
            app.Allele1DropDown_2Label.Text = 'Allele1';
            app.Allele1DropDown_3 = uidropdown(app.GenotypedTab);
            app.Allele1DropDown_3.ValueChangedFcn = createCallbackFcn(app, @Allele1DropDown_3ValueChanged, true);
            app.LocusDropDown_2Label_5 = uilabel(app.GenotypedTab);
            app.LocusDropDown_2Label_5.Text = 'Locus';
            app.LocusDropDown_4 = uidropdown(app.GenotypedTab);
            app.LocusDropDown_4.ValueChangedFcn = createCallbackFcn(app, @LocusDropDown_4ValueChanged, true);
            app.Allele1DropDown_3Label = uilabel(app.GenotypedTab);
            app.Allele1DropDown_3Label.Text = 'Allele1';
            app.Allele1DropDown_4 = uidropdown(app.GenotypedTab);
            app.Allele1DropDown_4.ValueChangedFcn = createCallbackFcn(app, @Allele1DropDown_4ValueChanged, true);
            app.Allele2DropDown_2Label = uilabel(app.GenotypedTab);
            app.Allele2DropDown_2Label.Text = 'Allele2';
            app.Allele2DropDown_3 = uidropdown(app.GenotypedTab);
            app.Allele2DropDown_3.ValueChangedFcn = createCallbackFcn(app, @Allele2DropDown_3ValueChanged, true);
            app.Allele2DropDown_3Label = uilabel(app.GenotypedTab);
            app.Allele2DropDown_3Label.Text = 'Allele2';
            app.Allele2DropDown_4 = uidropdown(app.GenotypedTab);
            app.Allele2DropDown_4.ValueChangedFcn = createCallbackFcn(app, @Allele2DropDown_4ValueChanged, true);
            app.DeceasedTab = uitab(app.EventTabGroup);
            app.DeceasedTab.Title = 'Deceased';
            app.ReasonDropDownLabel = uilabel(app.DeceasedTab);
            app.ReasonDropDownLabel.Text = 'Reason';
            app.DeceasedReasonDropDown = uidropdown(app.DeceasedTab);
            app.DeceasedReasonDropDown.Items = {'sacrificed for experiment', 'sacrificed not needed', 'other', 'unknown'};
            app.DeceasedReasonDropDown.Value = 'sacrificed for experiment';
            app.AssigncageroomTab = uitab(app.EventTabGroup);
            app.AssigncageroomTab.Title = 'Assign cage / room';
            app.ReasonDropDown_2Label = uilabel(app.AssigncageroomTab);
            app.ReasonDropDown_2Label.Text = 'Reason';
            app.MoveReasonDropDown = uidropdown(app.AssigncageroomTab);
            app.MoveReasonDropDown.Items = {'weaning', 'experiment', 'set as breeder', 'crowding', 'cage moved rooms', 'other', 'unknown'};
            app.MoveReasonDropDown.Value = 'weaning';
            app.TocageEditFieldLabel = uilabel(app.AssigncageroomTab);
            app.TocageEditFieldLabel.Text = 'To cage #';
            app.TocageEditField = uieditfield(app.AssigncageroomTab, 'text');
            app.RoomDropDown_2Label = uilabel(app.AssigncageroomTab);
            app.RoomDropDown_2Label.Text = 'Room';
            app.RoomDropDown_logger = uidropdown(app.AssigncageroomTab);
            app.AssigntoanimalprotocolTab = uitab(app.EventTabGroup);
            app.AssigntoanimalprotocolTab.Title = 'Assign to animal protocol';
            app.AnimalprotocolDropDownLabel = uilabel(app.AssigntoanimalprotocolTab);
            app.AnimalprotocolDropDownLabel.Text = 'Animal protocol';
            app.AnimalprotocolDropDown = uidropdown(app.AssigntoanimalprotocolTab);
            app.TagTab = uitab(app.EventTabGroup);
            app.TagTab.Title = 'Tag';
            app.TagEditFieldLabel = uilabel(app.TagTab);
            app.TagEditFieldLabel.Text = 'Tag #';
            app.TagEditField = uieditfield(app.TagTab, 'text');
            app.EarpunchDropDownLabel = uilabel(app.TagTab);
            app.EarpunchDropDownLabel.Text = 'Ear punch';
            app.EarpunchDropDown = uidropdown(app.TagTab);
            app.EarpunchDropDown.Items = {'None', 'L', 'R', 'Both'};
            app.EarpunchDropDown.Value = 'None';
            app.TagearDropDownLabel = uilabel(app.TagTab);
            app.TagearDropDownLabel.Text = 'Tag ear';
            app.TagearDropDown = uidropdown(app.TagTab);
            app.TagearDropDown.Items = {'None', 'L', 'R', 'Unknown'};
            app.TagearDropDown.Value = 'None';
            app.IPinjectionTab = uitab(app.EventTabGroup);
            app.IPinjectionTab.Title = 'IP injection';
            app.SubstanceDropDownLabel = uilabel(app.IPinjectionTab);
            app.SubstanceDropDownLabel.Text = 'Substance';
            app.SubstanceDropDown_IP = uidropdown(app.IPinjectionTab);
            app.ConcentrationmgKgEditFieldLabel_2 = uilabel(app.IPinjectionTab);
            app.ConcentrationmgKgEditFieldLabel_2.Text = 'Concentration (mg / Kg)';
            app.IP_inj_ConcentrationEditField = uieditfield(app.IPinjectionTab, 'text');
            app.EyeinjectionTab = uitab(app.EventTabGroup);
            app.EyeinjectionTab.Title = 'Eye injection';
            app.SubstanceDropDownLabel_2 = uilabel(app.EyeinjectionTab);
            app.SubstanceDropDownLabel_2.Text = 'Substance';
            app.SubstanceDropDown_Eye = uidropdown(app.EyeinjectionTab);
            app.EyeDropDownLabel = uilabel(app.EyeinjectionTab);
            app.EyeDropDownLabel.Text = 'Eye';
            app.WhichEyeDropDown = uidropdown(app.EyeinjectionTab);
            app.WhichEyeDropDown.Items = {'Left', 'Right'};
            app.WhichEyeDropDown.Value = 'Left';
            app.Dilution1XEditFieldLabel_2 = uilabel(app.EyeinjectionTab);
            app.Dilution1XEditFieldLabel_2.Text = 'Dilution (1:X)';
            app.Dilution_EyeEditField = uieditfield(app.EyeinjectionTab, 'text');
            app.BraininjectionTab = uitab(app.EventTabGroup);
            app.BraininjectionTab.Title = 'Brain injection';
            app.SubstanceDropDown_3Label = uilabel(app.BraininjectionTab);
            app.SubstanceDropDown_3Label.Text = 'Substance';
            app.SubstanceDropDown_Brain = uidropdown(app.BraininjectionTab);
            app.CoordinatesLabel = uilabel(app.BraininjectionTab);
            app.CoordinatesLabel.Text = 'Coordinates:';
            app.TargetDropDownLabel = uilabel(app.BraininjectionTab);
            app.TargetDropDownLabel.Text = 'Target';
            app.BrainTargetDropDown = uidropdown(app.BraininjectionTab);
            app.BrainTargetDropDown.Items = {'MeA', 'LHA', 'OPN', 'vLGN', 'dLGN', 'SC'};
            app.BrainTargetDropDown.Value = 'MeA';
            app.HemisphereDropDownLabel = uilabel(app.BraininjectionTab);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.BraininjectionTab);
            app.HemisphereDropDown.Items = {'Left', 'Right'};
            app.HemisphereDropDown.Value = 'Left';
            app.Dilution1XEditFieldLabel_3 = uilabel(app.BraininjectionTab);
            app.Dilution1XEditFieldLabel_3.Text = 'Dilution (1:X)';
            app.Dilution_BrainEditField = uieditfield(app.BraininjectionTab, 'text');
            app.APEditField_2Label = uilabel(app.BraininjectionTab);
            app.APEditField_2Label.Text = 'AP';
            app.APEditField = uieditfield(app.BraininjectionTab, 'text');
            app.MLEditField_2Label = uilabel(app.BraininjectionTab);
            app.MLEditField_2Label.Text = 'ML';
            app.MLEditField = uieditfield(app.BraininjectionTab, 'text');
            app.DVEditField_2Label = uilabel(app.BraininjectionTab);
            app.DVEditField_2Label.Text = 'DV';
            app.DVEditField = uieditfield(app.BraininjectionTab, 'text');
            app.AngleEditField_2Label = uilabel(app.BraininjectionTab);
            app.AngleEditField_2Label.Text = 'Angle';
            app.AngleEditField = uieditfield(app.BraininjectionTab, 'text');
            app.FeedTab = uitab(app.EventTabGroup);
            app.FeedTab.Title = 'Feed';
            app.SubstanceDropDownLabel_3 = uilabel(app.FeedTab);
            app.SubstanceDropDownLabel_3.Text = 'Substance';
            app.SubstanceDropDown_Feed = uidropdown(app.FeedTab);
            app.SocialbehaviorsessionTab = uitab(app.EventTabGroup);
            app.SocialbehaviorsessionTab.Title = 'Social behavior session';
            app.RecordBehavior_switch = uiswitch(app.SocialbehaviorsessionTab, 'rocker');
            app.RecordBehavior_switch.Items = {'Not recorded', 'Recorded'};
            app.RecordBehavior_switch.ValueChangedFcn = createCallbackFcn(app, @RecordBehavior_switchValueChanged, true);
            app.RecordBehavior_switch.Value = 'Recorded';
            app.StimulusLabel = uilabel(app.SocialbehaviorsessionTab);
            app.StimulusLabel.Text = 'Stimulus';
            app.Lamp_isRecorded = uilamp(app.SocialbehaviorsessionTab);
            app.ExperimenttypeDropDownLabel = uilabel(app.SocialbehaviorsessionTab);
            app.ExperimenttypeDropDownLabel.Text = 'Experiment type';
            app.ExperimenttypeDropDown = uidropdown(app.SocialbehaviorsessionTab);
            app.AnimalAEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.AnimalBEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.AnimalCEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.TestanimaltypeDropDownLabel = uilabel(app.SocialbehaviorsessionTab);
            app.TestanimaltypeDropDownLabel.Text = 'Test animal type';
            app.TestanimaltypeDropDown = uidropdown(app.SocialbehaviorsessionTab);
            app.DJIDLabel = uilabel(app.SocialbehaviorsessionTab);
            app.DJIDLabel.Text = 'DJID';
            app.WindowLabel = uilabel(app.SocialbehaviorsessionTab);
            app.WindowLabel.Text = 'Window';
            app.ALabel = uilabel(app.SocialbehaviorsessionTab);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.SocialbehaviorsessionTab);
            app.BLabel.Text = 'B';
            app.CLabel = uilabel(app.SocialbehaviorsessionTab);
            app.CLabel.Text = 'C';
            app.WindowAStim = uidropdown(app.SocialbehaviorsessionTab);
            app.WindowAStim.ValueChangedFcn = createCallbackFcn(app, @WindowAStimValueChanged, true);
            app.WindowBStim = uidropdown(app.SocialbehaviorsessionTab);
            app.WindowBStim.ValueChangedFcn = createCallbackFcn(app, @WindowBStimValueChanged, true);
            app.WindowCStim = uidropdown(app.SocialbehaviorsessionTab);
            app.WindowCStim.ValueChangedFcn = createCallbackFcn(app, @WindowCStimValueChanged, true);
            app.ReserveforprojectTab = uitab(app.EventTabGroup);
            app.ReserveforprojectTab.Title = 'Reserve for project';
            app.ProjectDropDownLabel = uilabel(app.ReserveforprojectTab);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.ReserveforprojectTab);
            app.ReserveforrigTab = uitab(app.EventTabGroup);
            app.ReserveforrigTab.Title = 'Reserve for rig';
            app.RignameDropDownLabel = uilabel(app.ReserveforrigTab);
            app.RignameDropDownLabel.Text = 'Rig name';
            app.RignameDropDown = uidropdown(app.ReserveforrigTab);
            app.CalendarrefreshtokenEditFieldLabel = uilabel(app.ReserveforrigTab);
            app.CalendarrefreshtokenEditFieldLabel.Text = 'Calendar refresh token';
            app.CalendarrefreshtokenEditField = uieditfield(app.ReserveforrigTab, 'text');
            app.ReauthorizeButton = uibutton(app.ReserveforrigTab, 'push');
            app.ReauthorizeButton.ButtonPushedFcn = createCallbackFcn(app, @ReauthorizeButtonPushed, true);
            app.ReauthorizeButton.Text = 'Reauthorize';
            app.PairbreedersTab_2 = uitab(app.EventTabGroup);
            app.PairbreedersTab_2.Title = 'Pair breeders';
            app.MaleDJIDEditFieldLabel = uilabel(app.PairbreedersTab_2);
            app.MaleDJIDEditFieldLabel.Text = 'Male DJ ID';
            app.MaleDJIDPairEditField = uieditfield(app.PairbreedersTab_2, 'text');
            app.FemaleDJIDEditFieldLabel = uilabel(app.PairbreedersTab_2);
            app.FemaleDJIDEditFieldLabel.Text = 'Female DJ ID';
            app.FemaleDJIDPairEditField = uieditfield(app.PairbreedersTab_2, 'text');
            app.NewcagenumberEditFieldLabel = uilabel(app.PairbreedersTab_2);
            app.NewcagenumberEditFieldLabel.Text = 'New cage number';
            app.NewcagenumberPairEditField = uieditfield(app.PairbreedersTab_2, 'text');
            app.RoomDropDown_2Label_5 = uilabel(app.PairbreedersTab_2);
            app.RoomDropDown_2Label_5.Text = 'Room';
            app.RoomDropDown_Pair = uidropdown(app.PairbreedersTab_2);
            app.AnimalSelectionPanel = uipanel(app.AnimalLoggerTab);
            app.AnimalSelectionPanel.Title = 'Animal Selection';
            app.SearchButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.FilterPanel = uipanel(app.AnimalSelectionPanel);
            app.FilterPanel.Title = 'Filter';
            app.AlleleLabel = uilabel(app.FilterPanel);
            app.AlleleLabel.Text = 'Allele';
            app.Filter_GenotypeDropDown = uidropdown(app.FilterPanel);
            app.Filter_GenotypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_GenotypeDropDownValueChanged, true);
            app.StatusDropDownLabel = uilabel(app.FilterPanel);
            app.StatusDropDownLabel.Text = 'Status';
            app.Filter_GenotypeStatusDropDown = uidropdown(app.FilterPanel);
            app.Filter_GenotypeStatusDropDown.Items = {'All', 'carrier', 'non-carrier', 'unknown', 'het', 'homo'};
            app.Filter_GenotypeStatusDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_GenotypeStatusDropDownValueChanged, true);
            app.Filter_GenotypeStatusDropDown.Value = 'All';
            app.SexDropDownLabel = uilabel(app.FilterPanel);
            app.SexDropDownLabel.Text = 'Sex';
            app.Filter_SexDropDown = uidropdown(app.FilterPanel);
            app.Filter_SexDropDown.Items = {'All', 'Male', 'Female'};
            app.Filter_SexDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_SexDropDownValueChanged, true);
            app.Filter_SexDropDown.Value = 'All';
            app.ProjectLabel = uilabel(app.FilterPanel);
            app.ProjectLabel.Text = 'Project';
            app.Filter_ProjectDropDown = uidropdown(app.FilterPanel);
            app.Filter_ProjectDropDown.Items = {'All', 'Male', 'Female'};
            app.Filter_ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_ProjectDropDownValueChanged, true);
            app.Filter_ProjectDropDown.Value = 'All';
            app.AgerangeweeksPanel = uipanel(app.FilterPanel);
            app.AgerangeweeksPanel.Title = 'Age range (weeks)';
            app.toLabel = uilabel(app.AgerangeweeksPanel);
            app.toLabel.Text = 'to';
            app.ageLow_edit = uieditfield(app.AgerangeweeksPanel, 'text');
            app.ageLow_edit.ValueChangedFcn = createCallbackFcn(app, @ageLow_editValueChanged, true);
            app.ageHigh_edit = uieditfield(app.AgerangeweeksPanel, 'text');
            app.ageHigh_edit.ValueChangedFcn = createCallbackFcn(app, @ageHigh_editValueChanged, true);
            app.EyeInjectionButtonGroup = uibuttongroup(app.FilterPanel);
            app.EyeInjectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EyeInjectionButtonGroupSelectionChanged, true);
            app.EyeInjectionButtonGroup.Title = 'Eye Injection';
            app.AnyButton = uiradiobutton(app.EyeInjectionButtonGroup);
            app.AnyButton.Text = 'Any';
            app.AnyButton.Value = true;
            app.YesButton = uiradiobutton(app.EyeInjectionButtonGroup);
            app.YesButton.Text = 'Yes';
            app.NoButton = uiradiobutton(app.EyeInjectionButtonGroup);
            app.NoButton.Text = 'No';
            app.BrainInjectionButtonGroup = uibuttongroup(app.FilterPanel);
            app.BrainInjectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BrainInjectionButtonGroupSelectionChanged, true);
            app.BrainInjectionButtonGroup.Title = 'Brain Injection';
            app.AnyButton_2 = uiradiobutton(app.BrainInjectionButtonGroup);
            app.AnyButton_2.Text = 'Any';
            app.AnyButton_2.Value = true;
            app.YesButton_2 = uiradiobutton(app.BrainInjectionButtonGroup);
            app.YesButton_2.Text = 'Yes';
            app.NoButton_2 = uiradiobutton(app.BrainInjectionButtonGroup);
            app.NoButton_2.Text = 'No';
            app.RigreservationButtonGroup = uibuttongroup(app.FilterPanel);
            app.RigreservationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RigreservationButtonGroupSelectionChanged, true);
            app.RigreservationButtonGroup.Title = 'Rig reservation';
            app.AnyButton_3 = uiradiobutton(app.RigreservationButtonGroup);
            app.AnyButton_3.Text = 'Any';
            app.AnyButton_3.Value = true;
            app.YesButton_3 = uiradiobutton(app.RigreservationButtonGroup);
            app.YesButton_3.Text = 'Yes';
            app.NoButton_3 = uiradiobutton(app.RigreservationButtonGroup);
            app.NoButton_3.Text = 'No';
            app.BehaviorsessionButtonGroup = uibuttongroup(app.FilterPanel);
            app.BehaviorsessionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BehaviorsessionButtonGroupSelectionChanged, true);
            app.BehaviorsessionButtonGroup.Title = 'Behavior session';
            app.AnyButton_4 = uiradiobutton(app.BehaviorsessionButtonGroup);
            app.AnyButton_4.Text = 'Any';
            app.AnyButton_4.Value = true;
            app.YesButton_4 = uiradiobutton(app.BehaviorsessionButtonGroup);
            app.YesButton_4.Text = 'Yes';
            app.NoButton_4 = uiradiobutton(app.BehaviorsessionButtonGroup);
            app.NoButton_4.Text = 'No';
            app.IPInjectionButtonGroup = uibuttongroup(app.FilterPanel);
            app.IPInjectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IPInjectionButtonGroupSelectionChanged, true);
            app.IPInjectionButtonGroup.Title = 'IP Injection';
            app.AnyButton_5 = uiradiobutton(app.IPInjectionButtonGroup);
            app.AnyButton_5.Text = 'Any';
            app.AnyButton_5.Value = true;
            app.YesButton_5 = uiradiobutton(app.IPInjectionButtonGroup);
            app.YesButton_5.Text = 'Yes';
            app.NoButton_5 = uiradiobutton(app.IPInjectionButtonGroup);
            app.NoButton_5.Text = 'No';
            app.BgLabel = uilabel(app.FilterPanel);
            app.BgLabel.Text = 'Bg';
            app.Filter_BgDropDown = uidropdown(app.FilterPanel);
            app.Filter_BgDropDown.Items = {'All', 'Male', 'Female'};
            app.Filter_BgDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_BgDropDownValueChanged, true);
            app.Filter_BgDropDown.Value = 'All';
            app.ProjectLabel_2 = uilabel(app.FilterPanel);
            app.ProjectLabel_2.Text = 'Protocol';
            app.Filter_ProtocolDropDown = uidropdown(app.FilterPanel);
            app.Filter_ProtocolDropDown.Items = {'All', 'Male', 'Female'};
            app.Filter_ProtocolDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_ProtocolDropDownValueChanged, true);
            app.Filter_ProtocolDropDown.Value = 'All';
            app.ResetButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Search_DJIDEditField = uieditfield(app.AnimalSelectionPanel, 'text');
            app.Search_TagIDEditField = uieditfield(app.AnimalSelectionPanel, 'text');
            app.CageNumSearchEditField = uieditfield(app.AnimalSelectionPanel, 'text');
            app.SearchTypeButtonGroup = uibuttongroup(app.AnimalSelectionPanel);
            app.SearchTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SearchTypeButtonGroupSelectionChanged, true);
            app.DJIDButton = uiradiobutton(app.SearchTypeButtonGroup);
            app.DJIDButton.Text = 'DJ ID';
            app.DJIDButton.Value = true;
            app.CageButton = uiradiobutton(app.SearchTypeButtonGroup);
            app.CageButton.Text = 'Cage #';
            app.TagButton = uiradiobutton(app.SearchTypeButtonGroup);
            app.TagButton.Text = 'Tag #';
            app.PersonDropDownLabel = uilabel(app.AnimalLoggerTab);
            app.PersonDropDownLabel.Text = 'Person';
            app.PersonDropDown = uidropdown(app.AnimalLoggerTab);
            app.PersonDropDown.Items = {'David', 'Zach', 'Devon', 'Greg', 'Kayla', 'Sam', 'Sophia', 'Susan'};
            app.PersonDropDown.Value = 'David';
            app.EventdateDatePickerLabel = uilabel(app.AnimalLoggerTab);
            app.EventdateDatePickerLabel.Text = 'Event date';
            app.EventdateDatePicker = uidatepicker(app.AnimalLoggerTab);
            app.EventdateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SubmitevententryButton = uibutton(app.AnimalLoggerTab, 'push');
            app.SubmitevententryButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitevententryButtonPushed, true);
            app.SubmitevententryButton.Text = 'Submit event entry';
            app.NotesTextArea_2Label = uilabel(app.AnimalLoggerTab);
            app.NotesTextArea_2Label.Text = 'Notes';
            app.EventNotesTextArea = uitextarea(app.AnimalLoggerTab);
            app.EventTimeM = uidropdown(app.AnimalLoggerTab);
            app.EventTimeM.Items = {'00', '15', '30', '45'};
            app.EventTimeM.Value = '00';
            app.EventTimeAMPM = uidropdown(app.AnimalLoggerTab);
            app.EventTimeAMPM.Items = {'AM', 'PM'};
            app.EventTimeAMPM.Value = 'AM';
            app.TimeLabel_2 = uilabel(app.AnimalLoggerTab);
            app.TimeLabel_2.Text = 'Time';
            app.AlleventsforselectedanimalButton = uibutton(app.AnimalLoggerTab, 'push');
            app.AlleventsforselectedanimalButton.ButtonPushedFcn = createCallbackFcn(app, @AlleventsforselectedanimalButtonPushed, true);
            app.AlleventsforselectedanimalButton.Text = 'All events for selected animal';
            app.EventTimeH = uidropdown(app.AnimalLoggerTab);
            app.EventTimeH.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.EventTimeH.Value = '9';
            app.ViewlogsLabel = uilabel(app.AnimalLoggerTab);
            app.ViewlogsLabel.Text = 'View logs';
            app.EventsforallanimalsButton = uibutton(app.AnimalLoggerTab, 'push');
            app.EventsforallanimalsButton.ButtonPushedFcn = createCallbackFcn(app, @EventsforallanimalsButtonPushed, true);
            app.EventsforallanimalsButton.Text = 'Events for all animals';
            app.TypeDropDownLabel = uilabel(app.AnimalLoggerTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.EventTypeDropDown = uidropdown(app.AnimalLoggerTab);
            app.StatusLampLabel = uilabel(app.AnimalLoggerTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp_Logger = uilamp(app.AnimalLoggerTab);
            app.EventInsertStatusEdit = uieditfield(app.AnimalLoggerTab, 'text');
            app.EventInsertStatusEdit.Editable = 'off';
            app.ExporttoExcelButton = uibutton(app.AnimalLoggerTab, 'push');
            app.ExporttoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoExcelButtonPushed, true);
            app.ExporttoExcelButton.Text = 'Export to Excel';
            app.BreedersTab = uitab(app.MainTabGroup);
            app.BreedersTab.Title = 'Breeders';
            app.BreederCagesTable = uitable(app.BreedersTab);
            app.BreederCagesTable.ColumnName = {'Cage'; 'Room'; 'Male: DJID'; 'Genotype'; 'Age (wks)'; 'Female: DJID'; 'Genotype'; 'Age (wks)'; 'Date paired'; '# of litters'; 'Litter dates'; 'Weaned sizes'; 'Next wean date'};
            app.BreederCagesTable.RowName = {};
            app.BreederCagesTable.ColumnSortable = [true true true true true true true true true true false false true];
            app.BreederCagesTable.CellSelectionCallback = createCallbackFcn(app, @BreederCagesTableCellSelection, true);
            app.ExporttoExcelButton_breeders = uibutton(app.BreedersTab, 'push');
            app.ExporttoExcelButton_breeders.ButtonPushedFcn = createCallbackFcn(app, @ExporttoExcelButton_breedersPushed, true);
            app.ExporttoExcelButton_breeders.Text = 'Export to Excel';
            app.SubmitevententryButton_breeders = uibutton(app.BreedersTab, 'push');
            app.SubmitevententryButton_breeders.ButtonPushedFcn = createCallbackFcn(app, @SubmitevententryButton_breedersPushed, true);
            app.SubmitevententryButton_breeders.Text = 'Submit event entry';
            app.StatusLampLabel_6 = uilabel(app.BreedersTab);
            app.StatusLampLabel_6.Text = 'Status';
            app.StatusLamp_breeders = uilamp(app.BreedersTab);
            app.EventInsertStatusEdit_breeders = uieditfield(app.BreedersTab, 'text');
            app.EventInsertStatusEdit_breeders.Editable = 'off';
            app.PersonDropDown_2Label = uilabel(app.BreedersTab);
            app.PersonDropDown_2Label.Text = 'Person';
            app.PersonDropDown_breeders = uidropdown(app.BreedersTab);
            app.PersonDropDown_breeders.Items = {'David', 'Zach', 'Devon', 'Greg', 'Kayla', 'Sam', 'Sophia', 'Susan'};
            app.PersonDropDown_breeders.Value = 'David';
            app.EventdateDatePicker_2Label = uilabel(app.BreedersTab);
            app.EventdateDatePicker_2Label.Text = 'Event date';
            app.EventdateDatePicker_breeders = uidatepicker(app.BreedersTab);
            app.EventdateDatePicker_breeders.DisplayFormat = 'uuuu-MM-dd';
            app.NotesTextArea_2Label_2 = uilabel(app.BreedersTab);
            app.NotesTextArea_2Label_2.Text = 'Notes';
            app.EventNotesTextArea_breeders = uitextarea(app.BreedersTab);
            app.EventTabGroup_breeders = uitabgroup(app.BreedersTab);
            app.SeparatebreedingpairTab = uitab(app.EventTabGroup_breeders);
            app.SeparatebreedingpairTab.Title = 'Separate breeding pair';
            app.NewcageformaleEditFieldLabel = uilabel(app.SeparatebreedingpairTab);
            app.NewcageformaleEditFieldLabel.Text = 'New cage for male';
            app.NewcageformaleEditField = uieditfield(app.SeparatebreedingpairTab, 'text');
            app.NewcageforfemaleEditFieldLabel = uilabel(app.SeparatebreedingpairTab);
            app.NewcageforfemaleEditFieldLabel.Text = 'New cage for female';
            app.NewcageforfemaleEditField = uieditfield(app.SeparatebreedingpairTab, 'text');
            app.RoomDropDown_2Label_3 = uilabel(app.SeparatebreedingpairTab);
            app.RoomDropDown_2Label_3.Text = 'Room';
            app.RoomDropDown_breeders_sepMale = uidropdown(app.SeparatebreedingpairTab);
            app.RoomDropDown_2Label_4 = uilabel(app.SeparatebreedingpairTab);
            app.RoomDropDown_2Label_4.Text = 'Room';
            app.RoomDropDown_breeders_sepFemale = uidropdown(app.SeparatebreedingpairTab);
            app.SacrificebreedingpairTab = uitab(app.EventTabGroup_breeders);
            app.SacrificebreedingpairTab.Title = 'Sacrifice breeding pair';
            app.LogbirthTab = uitab(app.EventTabGroup_breeders);
            app.LogbirthTab.Title = 'Log birth';
            app.LogweaningTab = uitab(app.EventTabGroup_breeders);
            app.LogweaningTab.Title = 'Log weaning';
            app.NumberofpupsLabel = uilabel(app.LogweaningTab);
            app.NumberofpupsLabel.Text = '🍼Number of pups';
            app.NumberofpupsEditField_weaned = uieditfield(app.LogweaningTab, 'text');
            app.SearchButton_breeders = uibutton(app.BreedersTab, 'push');
            app.SearchButton_breeders.ButtonPushedFcn = createCallbackFcn(app, @SearchButton_breedersPushed, true);
            app.SearchButton_breeders.Text = 'Search';
            app.ResetButton_breeders = uibutton(app.BreedersTab, 'push');
            app.ResetButton_breeders.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_breedersPushed, true);
            app.ResetButton_breeders.Text = 'Reset';
            app.Search_DJIDEditField_breeders = uieditfield(app.BreedersTab, 'text');
            app.Search_TagIDEditField_breeders = uieditfield(app.BreedersTab, 'text');
            app.CageNumSearchEditField_breeders = uieditfield(app.BreedersTab, 'text');
            app.SearchTypeButtonGroup_breeders = uibuttongroup(app.BreedersTab);
            app.SearchTypeButtonGroup_breeders.SelectionChangedFcn = createCallbackFcn(app, @SearchTypeButtonGroup_breedersSelectionChanged, true);
            app.DJIDButton_breeders = uiradiobutton(app.SearchTypeButtonGroup_breeders);
            app.DJIDButton_breeders.Text = 'DJ ID';
            app.DJIDButton_breeders.Value = true;
            app.CageButton_breeders = uiradiobutton(app.SearchTypeButtonGroup_breeders);
            app.CageButton_breeders.Text = 'Cage #';
            app.TagButton_breeders = uiradiobutton(app.SearchTypeButtonGroup_breeders);
            app.TagButton_breeders.Text = 'Tag #';
            app.BreederPairs_status = uilabel(app.BreedersTab);
            app.BreederPairs_status.Text = 'loading...';
            app.BreedingpairsLabel = uilabel(app.BreedersTab);
            app.BreedingpairsLabel.Text = 'Breeding pairs:';
            app.GenotypeDropDown_2Label = uilabel(app.BreedersTab);
            app.GenotypeDropDown_2Label.Text = 'Genotype';
            app.GenotypeDropDown_BreedersTab = uidropdown(app.BreedersTab);
            app.GenotypeDropDown_BreedersTab.ValueChangedFcn = createCallbackFcn(app, @GenotypeDropDown_BreedersTabValueChanged, true);
            app.AddAnimalsTab = uitab(app.MainTabGroup);
            app.AddAnimalsTab.Title = 'Add Animals';
            app.SpeciesTabGroup = uitabgroup(app.AddAnimalsTab);
            app.MouseTab = uitab(app.SpeciesTabGroup);
            app.MouseTab.Title = 'Mouse';
            app.tagged_switch = uiswitch(app.MouseTab, 'slider');
            app.tagged_switch.Items = {'Untagged', 'Tagged'};
            app.tagged_switch.ValueChangedFcn = createCallbackFcn(app, @tagged_switchValueChanged, true);
            app.tagged_switch.Value = 'Untagged';
            app.SexButtonGroup = uibuttongroup(app.MouseTab);
            app.SexButtonGroup.Title = 'Sex';
            app.UnknownButton = uiradiobutton(app.SexButtonGroup);
            app.UnknownButton.Text = 'Unknown';
            app.UnknownButton.Value = true;
            app.MaleButton = uiradiobutton(app.SexButtonGroup);
            app.MaleButton.Text = 'Male';
            app.FemaleButton = uiradiobutton(app.SexButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.PunchearButtonGroup = uibuttongroup(app.MouseTab);
            app.PunchearButtonGroup.Title = 'Punch ear';
            app.NoneButton = uiradiobutton(app.PunchearButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.LButton = uiradiobutton(app.PunchearButtonGroup);
            app.LButton.Text = 'L';
            app.RButton = uiradiobutton(app.PunchearButtonGroup);
            app.RButton.Text = 'R';
            app.BothButton = uiradiobutton(app.PunchearButtonGroup);
            app.BothButton.Text = 'Both';
            app.DOB_known_switch = uiswitch(app.MouseTab, 'slider');
            app.DOB_known_switch.Items = {'DOB unknown', 'DOB known'};
            app.DOB_known_switch.ValueChangedFcn = createCallbackFcn(app, @DOB_known_switchValueChanged, true);
            app.DOB_known_switch.Value = 'DOB known';
            app.BackgroundDropDownLabel = uilabel(app.MouseTab);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.MouseTab);
            app.DOBDatePickerLabel = uilabel(app.MouseTab);
            app.DOBDatePickerLabel.Text = 'DOB';
            app.DOBDatePicker = uidatepicker(app.MouseTab);
            app.DOBDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.TagearButtonGroup = uibuttongroup(app.MouseTab);
            app.TagearButtonGroup.Title = 'Tag ear';
            app.NoneButton_2 = uiradiobutton(app.TagearButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.LButton_2 = uiradiobutton(app.TagearButtonGroup);
            app.LButton_2.Text = 'L';
            app.RButton_2 = uiradiobutton(app.TagearButtonGroup);
            app.RButton_2.Text = 'R';
            app.UnknownButton_2 = uiradiobutton(app.TagearButtonGroup);
            app.UnknownButton_2.Text = 'Unknown';
            app.RoomDropDownLabel = uilabel(app.MouseTab);
            app.RoomDropDownLabel.Text = 'Room';
            app.RoomDropDown = uidropdown(app.MouseTab);
            app.TagIDEditFieldLabel = uilabel(app.MouseTab);
            app.TagIDEditFieldLabel.Text = 'Tag ID';
            app.TagIDEditField = uieditfield(app.MouseTab, 'text');
            app.SourcetypeLabel = uilabel(app.MouseTab);
            app.SourcetypeLabel.Text = 'Source type';
            app.SourceTypeDropDown = uidropdown(app.MouseTab);
            app.SourceTypeDropDown.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceTypeDropDownValueChanged, true);
            app.SourceTypeDropDown.Value = 'breeding';
            app.CageEditFieldLabel = uilabel(app.MouseTab);
            app.CageEditFieldLabel.Text = 'Cage #';
            app.CageEditField = uieditfield(app.MouseTab, 'text');
            app.ProtocolDropDownLabel = uilabel(app.MouseTab);
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.ProtocolDropDown = uidropdown(app.MouseTab);
            app.SingleentryLabel = uilabel(app.MouseTab);
            app.SingleentryLabel.Text = 'Single entry';
            app.MultipleentryLabel = uilabel(app.MouseTab);
            app.MultipleentryLabel.Text = 'Multiple entry';
            app.BackgroundDropDown_E1 = uidropdown(app.MouseTab);
            app.DOBDatePicker_E1 = uidatepicker(app.MouseTab);
            app.DOBDatePicker_E1.DisplayFormat = 'uuuu-MM-dd';
            app.BackgroundLabel = uilabel(app.MouseTab);
            app.BackgroundLabel.Text = 'Background';
            app.DOBLabel = uilabel(app.MouseTab);
            app.DOBLabel.Text = 'DOB';
            app.EntryLabel = uilabel(app.MouseTab);
            app.EntryLabel.Text = 'Entry';
            app.Label_1 = uilabel(app.MouseTab);
            app.Label_1.Text = '1';
            app.TagIDEditField_E1 = uieditfield(app.MouseTab, 'text');
            app.TagIDLabel = uilabel(app.MouseTab);
            app.TagIDLabel.Text = 'Tag ID';
            app.CageEditField_E1 = uieditfield(app.MouseTab, 'text');
            app.CageLabel = uilabel(app.MouseTab);
            app.CageLabel.Text = 'Cage #';
            app.SexButtonGroup_E1 = uibuttongroup(app.MouseTab);
            app.SexButtonGroup_E1.Title = 'Sex';
            app.UnknownButton_3 = uiradiobutton(app.SexButtonGroup_E1);
            app.UnknownButton_3.Text = 'Unknown';
            app.UnknownButton_3.Value = true;
            app.MaleButton_2 = uiradiobutton(app.SexButtonGroup_E1);
            app.MaleButton_2.Text = 'Male';
            app.FemaleButton_2 = uiradiobutton(app.SexButtonGroup_E1);
            app.FemaleButton_2.Text = 'Female';
            app.RoomDropDown_E1 = uidropdown(app.MouseTab);
            app.RoomLabel = uilabel(app.MouseTab);
            app.RoomLabel.Text = 'Room';
            app.SourceDropDown_E1 = uidropdown(app.MouseTab);
            app.SourceDropDown_E1.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E1.Value = 'breeding';
            app.SourceLabel = uilabel(app.MouseTab);
            app.SourceLabel.Text = 'Source';
            app.ProtocolDropDown_E1 = uidropdown(app.MouseTab);
            app.ProtocolLabel = uilabel(app.MouseTab);
            app.ProtocolLabel.Text = 'Protocol';
            app.PunchearButtonGroup_E1 = uibuttongroup(app.MouseTab);
            app.PunchearButtonGroup_E1.Title = 'Punch ear';
            app.NoneButton_3 = uiradiobutton(app.PunchearButtonGroup_E1);
            app.NoneButton_3.Text = 'None';
            app.NoneButton_3.Value = true;
            app.LButton_3 = uiradiobutton(app.PunchearButtonGroup_E1);
            app.LButton_3.Text = 'L';
            app.RButton_3 = uiradiobutton(app.PunchearButtonGroup_E1);
            app.RButton_3.Text = 'R';
            app.BothButton_2 = uiradiobutton(app.PunchearButtonGroup_E1);
            app.BothButton_2.Text = 'Both';
            app.TagearButtonGroup_E1 = uibuttongroup(app.MouseTab);
            app.TagearButtonGroup_E1.Title = 'Tag ear';
            app.NoneButton_4 = uiradiobutton(app.TagearButtonGroup_E1);
            app.NoneButton_4.Text = 'None';
            app.NoneButton_4.Value = true;
            app.LButton_4 = uiradiobutton(app.TagearButtonGroup_E1);
            app.LButton_4.Text = 'L';
            app.RButton_4 = uiradiobutton(app.TagearButtonGroup_E1);
            app.RButton_4.Text = 'R';
            app.UnknownButton_4 = uiradiobutton(app.TagearButtonGroup_E1);
            app.UnknownButton_4.Text = 'Unknown';
            app.tagged_switch_E1 = uiswitch(app.MouseTab, 'slider');
            app.tagged_switch_E1.Items = {' ', 'Tagged'};
            app.tagged_switch_E1.Value = ' ';
            app.DOB_known_switch_E1 = uiswitch(app.MouseTab, 'slider');
            app.DOB_known_switch_E1.Items = {'', 'DOB known'};
            app.DOB_known_switch_E1.Value = 'DOB known';
            app.SetotherentriesButton = uibutton(app.MouseTab, 'push');
            app.SetotherentriesButton.ButtonPushedFcn = createCallbackFcn(app, @SetotherentriesButtonPushed, true);
            app.SetotherentriesButton.Text = 'Set other entries';
            app.BackgroundDropDown_E2 = uidropdown(app.MouseTab);
            app.DOBDatePicker_E2 = uidatepicker(app.MouseTab);
            app.DOBDatePicker_E2.DisplayFormat = 'uuuu-MM-dd';
            app.Label_2 = uilabel(app.MouseTab);
            app.Label_2.Text = '2';
            app.TagIDEditField_E2 = uieditfield(app.MouseTab, 'text');
            app.CageEditField_E2 = uieditfield(app.MouseTab, 'text');
            app.SexButtonGroup_E2 = uibuttongroup(app.MouseTab);
            app.SexButtonGroup_E2.Title = 'Sex';
            app.UnknownButton_5 = uiradiobutton(app.SexButtonGroup_E2);
            app.UnknownButton_5.Text = 'Unknown';
            app.UnknownButton_5.Value = true;
            app.MaleButton_3 = uiradiobutton(app.SexButtonGroup_E2);
            app.MaleButton_3.Text = 'Male';
            app.FemaleButton_3 = uiradiobutton(app.SexButtonGroup_E2);
            app.FemaleButton_3.Text = 'Female';
            app.RoomDropDown_E2 = uidropdown(app.MouseTab);
            app.ProtocolDropDown_E2 = uidropdown(app.MouseTab);
            app.PunchearButtonGroup_E2 = uibuttongroup(app.MouseTab);
            app.PunchearButtonGroup_E2.Title = 'Punch ear';
            app.NoneButton_5 = uiradiobutton(app.PunchearButtonGroup_E2);
            app.NoneButton_5.Text = 'None';
            app.NoneButton_5.Value = true;
            app.LButton_5 = uiradiobutton(app.PunchearButtonGroup_E2);
            app.LButton_5.Text = 'L';
            app.RButton_5 = uiradiobutton(app.PunchearButtonGroup_E2);
            app.RButton_5.Text = 'R';
            app.BothButton_3 = uiradiobutton(app.PunchearButtonGroup_E2);
            app.BothButton_3.Text = 'Both';
            app.TagearButtonGroup_E2 = uibuttongroup(app.MouseTab);
            app.TagearButtonGroup_E2.Title = 'Tag ear';
            app.NoneButton_6 = uiradiobutton(app.TagearButtonGroup_E2);
            app.NoneButton_6.Text = 'None';
            app.NoneButton_6.Value = true;
            app.LButton_6 = uiradiobutton(app.TagearButtonGroup_E2);
            app.LButton_6.Text = 'L';
            app.RButton_6 = uiradiobutton(app.TagearButtonGroup_E2);
            app.RButton_6.Text = 'R';
            app.UnknownButton_6 = uiradiobutton(app.TagearButtonGroup_E2);
            app.UnknownButton_6.Text = 'Unknown';
            app.tagged_switch_E2 = uiswitch(app.MouseTab, 'slider');
            app.tagged_switch_E2.Items = {' ', 'Tagged'};
            app.tagged_switch_E2.Value = ' ';
            app.DOB_known_switch_E2 = uiswitch(app.MouseTab, 'slider');
            app.DOB_known_switch_E2.Items = {'', 'DOB known'};
            app.DOB_known_switch_E2.Value = 'DOB known';
            app.BackgroundDropDown_E3 = uidropdown(app.MouseTab);
            app.DOBDatePicker_E3 = uidatepicker(app.MouseTab);
            app.DOBDatePicker_E3.DisplayFormat = 'uuuu-MM-dd';
            app.Label_3 = uilabel(app.MouseTab);
            app.Label_3.Text = '3';
            app.TagIDEditField_E3 = uieditfield(app.MouseTab, 'text');
            app.CageEditField_E3 = uieditfield(app.MouseTab, 'text');
            app.SexButtonGroup_E3 = uibuttongroup(app.MouseTab);
            app.SexButtonGroup_E3.Title = 'Sex';
            app.UnknownButton_7 = uiradiobutton(app.SexButtonGroup_E3);
            app.UnknownButton_7.Text = 'Unknown';
            app.UnknownButton_7.Value = true;
            app.MaleButton_4 = uiradiobutton(app.SexButtonGroup_E3);
            app.MaleButton_4.Text = 'Male';
            app.FemaleButton_4 = uiradiobutton(app.SexButtonGroup_E3);
            app.FemaleButton_4.Text = 'Female';
            app.RoomDropDown_E3 = uidropdown(app.MouseTab);
            app.ProtocolDropDown_E3 = uidropdown(app.MouseTab);
            app.PunchearButtonGroup_E3 = uibuttongroup(app.MouseTab);
            app.PunchearButtonGroup_E3.Title = 'Punch ear';
            app.NoneButton_7 = uiradiobutton(app.PunchearButtonGroup_E3);
            app.NoneButton_7.Text = 'None';
            app.NoneButton_7.Value = true;
            app.LButton_7 = uiradiobutton(app.PunchearButtonGroup_E3);
            app.LButton_7.Text = 'L';
            app.RButton_7 = uiradiobutton(app.PunchearButtonGroup_E3);
            app.RButton_7.Text = 'R';
            app.BothButton_4 = uiradiobutton(app.PunchearButtonGroup_E3);
            app.BothButton_4.Text = 'Both';
            app.TagearButtonGroup_E3 = uibuttongroup(app.MouseTab);
            app.TagearButtonGroup_E3.Title = 'Tag ear';
            app.NoneButton_8 = uiradiobutton(app.TagearButtonGroup_E3);
            app.NoneButton_8.Text = 'None';
            app.NoneButton_8.Value = true;
            app.LButton_8 = uiradiobutton(app.TagearButtonGroup_E3);
            app.LButton_8.Text = 'L';
            app.RButton_8 = uiradiobutton(app.TagearButtonGroup_E3);
            app.RButton_8.Text = 'R';
            app.UnknownButton_8 = uiradiobutton(app.TagearButtonGroup_E3);
            app.UnknownButton_8.Text = 'Unknown';
            app.tagged_switch_E3 = uiswitch(app.MouseTab, 'slider');
            app.tagged_switch_E3.Items = {' ', 'Tagged'};
            app.tagged_switch_E3.Value = ' ';
            app.DOB_known_switch_E3 = uiswitch(app.MouseTab, 'slider');
            app.DOB_known_switch_E3.Items = {'', 'DOB known'};
            app.DOB_known_switch_E3.Value = 'DOB known';
            app.BackgroundDropDown_E4 = uidropdown(app.MouseTab);
            app.DOBDatePicker_E4 = uidatepicker(app.MouseTab);
            app.DOBDatePicker_E4.DisplayFormat = 'uuuu-MM-dd';
            app.Label_4 = uilabel(app.MouseTab);
            app.Label_4.Text = '4';
            app.TagIDEditField_E4 = uieditfield(app.MouseTab, 'text');
            app.CageEditField_E4 = uieditfield(app.MouseTab, 'text');
            app.SexButtonGroup_E4 = uibuttongroup(app.MouseTab);
            app.SexButtonGroup_E4.Title = 'Sex';
            app.UnknownButton_9 = uiradiobutton(app.SexButtonGroup_E4);
            app.UnknownButton_9.Text = 'Unknown';
            app.UnknownButton_9.Value = true;
            app.MaleButton_5 = uiradiobutton(app.SexButtonGroup_E4);
            app.MaleButton_5.Text = 'Male';
            app.FemaleButton_5 = uiradiobutton(app.SexButtonGroup_E4);
            app.FemaleButton_5.Text = 'Female';
            app.RoomDropDown_E4 = uidropdown(app.MouseTab);
            app.ProtocolDropDown_E4 = uidropdown(app.MouseTab);
            app.PunchearButtonGroup_E4 = uibuttongroup(app.MouseTab);
            app.PunchearButtonGroup_E4.Title = 'Punch ear';
            app.NoneButton_9 = uiradiobutton(app.PunchearButtonGroup_E4);
            app.NoneButton_9.Text = 'None';
            app.NoneButton_9.Value = true;
            app.LButton_9 = uiradiobutton(app.PunchearButtonGroup_E4);
            app.LButton_9.Text = 'L';
            app.RButton_9 = uiradiobutton(app.PunchearButtonGroup_E4);
            app.RButton_9.Text = 'R';
            app.BothButton_5 = uiradiobutton(app.PunchearButtonGroup_E4);
            app.BothButton_5.Text = 'Both';
            app.TagearButtonGroup_E4 = uibuttongroup(app.MouseTab);
            app.TagearButtonGroup_E4.Title = 'Tag ear';
            app.NoneButton_10 = uiradiobutton(app.TagearButtonGroup_E4);
            app.NoneButton_10.Text = 'None';
            app.NoneButton_10.Value = true;
            app.LButton_10 = uiradiobutton(app.TagearButtonGroup_E4);
            app.LButton_10.Text = 'L';
            app.RButton_10 = uiradiobutton(app.TagearButtonGroup_E4);
            app.RButton_10.Text = 'R';
            app.UnknownButton_10 = uiradiobutton(app.TagearButtonGroup_E4);
            app.UnknownButton_10.Text = 'Unknown';
            app.tagged_switch_E4 = uiswitch(app.MouseTab, 'slider');
            app.tagged_switch_E4.Items = {' ', 'Tagged'};
            app.tagged_switch_E4.Value = ' ';
            app.DOB_known_switch_E4 = uiswitch(app.MouseTab, 'slider');
            app.DOB_known_switch_E4.Items = {'', 'DOB known'};
            app.DOB_known_switch_E4.Value = 'DOB known';
            app.BackgroundDropDown_E5 = uidropdown(app.MouseTab);
            app.DOBDatePicker_E5 = uidatepicker(app.MouseTab);
            app.DOBDatePicker_E5.DisplayFormat = 'uuuu-MM-dd';
            app.Label_5 = uilabel(app.MouseTab);
            app.Label_5.Text = '5';
            app.TagIDEditField_E5 = uieditfield(app.MouseTab, 'text');
            app.CageEditField_E5 = uieditfield(app.MouseTab, 'text');
            app.SexButtonGroup_E5 = uibuttongroup(app.MouseTab);
            app.SexButtonGroup_E5.Title = 'Sex';
            app.UnknownButton_11 = uiradiobutton(app.SexButtonGroup_E5);
            app.UnknownButton_11.Text = 'Unknown';
            app.UnknownButton_11.Value = true;
            app.MaleButton_6 = uiradiobutton(app.SexButtonGroup_E5);
            app.MaleButton_6.Text = 'Male';
            app.FemaleButton_6 = uiradiobutton(app.SexButtonGroup_E5);
            app.FemaleButton_6.Text = 'Female';
            app.RoomDropDown_E5 = uidropdown(app.MouseTab);
            app.ProtocolDropDown_E5 = uidropdown(app.MouseTab);
            app.PunchearButtonGroup_E5 = uibuttongroup(app.MouseTab);
            app.PunchearButtonGroup_E5.Title = 'Punch ear';
            app.NoneButton_11 = uiradiobutton(app.PunchearButtonGroup_E5);
            app.NoneButton_11.Text = 'None';
            app.NoneButton_11.Value = true;
            app.LButton_11 = uiradiobutton(app.PunchearButtonGroup_E5);
            app.LButton_11.Text = 'L';
            app.RButton_11 = uiradiobutton(app.PunchearButtonGroup_E5);
            app.RButton_11.Text = 'R';
            app.BothButton_6 = uiradiobutton(app.PunchearButtonGroup_E5);
            app.BothButton_6.Text = 'Both';
            app.TagearButtonGroup_E5 = uibuttongroup(app.MouseTab);
            app.TagearButtonGroup_E5.Title = 'Tag ear';
            app.NoneButton_12 = uiradiobutton(app.TagearButtonGroup_E5);
            app.NoneButton_12.Text = 'None';
            app.NoneButton_12.Value = true;
            app.LButton_12 = uiradiobutton(app.TagearButtonGroup_E5);
            app.LButton_12.Text = 'L';
            app.RButton_12 = uiradiobutton(app.TagearButtonGroup_E5);
            app.RButton_12.Text = 'R';
            app.UnknownButton_12 = uiradiobutton(app.TagearButtonGroup_E5);
            app.UnknownButton_12.Text = 'Unknown';
            app.tagged_switch_E5 = uiswitch(app.MouseTab, 'slider');
            app.tagged_switch_E5.Items = {' ', 'Tagged'};
            app.tagged_switch_E5.Value = ' ';
            app.DOB_known_switch_E5 = uiswitch(app.MouseTab, 'slider');
            app.DOB_known_switch_E5.Items = {'', 'DOB known'};
            app.DOB_known_switch_E5.Value = 'DOB known';
            app.BackgroundDropDown_E6 = uidropdown(app.MouseTab);
            app.DOBDatePicker_E6 = uidatepicker(app.MouseTab);
            app.DOBDatePicker_E6.DisplayFormat = 'uuuu-MM-dd';
            app.Label_6 = uilabel(app.MouseTab);
            app.Label_6.Text = '6';
            app.TagIDEditField_E6 = uieditfield(app.MouseTab, 'text');
            app.CageEditField_E6 = uieditfield(app.MouseTab, 'text');
            app.SexButtonGroup_E6 = uibuttongroup(app.MouseTab);
            app.SexButtonGroup_E6.Title = 'Sex';
            app.UnknownButton_13 = uiradiobutton(app.SexButtonGroup_E6);
            app.UnknownButton_13.Text = 'Unknown';
            app.UnknownButton_13.Value = true;
            app.MaleButton_7 = uiradiobutton(app.SexButtonGroup_E6);
            app.MaleButton_7.Text = 'Male';
            app.FemaleButton_7 = uiradiobutton(app.SexButtonGroup_E6);
            app.FemaleButton_7.Text = 'Female';
            app.RoomDropDown_E6 = uidropdown(app.MouseTab);
            app.ProtocolDropDown_E6 = uidropdown(app.MouseTab);
            app.PunchearButtonGroup_E6 = uibuttongroup(app.MouseTab);
            app.PunchearButtonGroup_E6.Title = 'Punch ear';
            app.NoneButton_13 = uiradiobutton(app.PunchearButtonGroup_E6);
            app.NoneButton_13.Text = 'None';
            app.NoneButton_13.Value = true;
            app.LButton_13 = uiradiobutton(app.PunchearButtonGroup_E6);
            app.LButton_13.Text = 'L';
            app.RButton_13 = uiradiobutton(app.PunchearButtonGroup_E6);
            app.RButton_13.Text = 'R';
            app.BothButton_7 = uiradiobutton(app.PunchearButtonGroup_E6);
            app.BothButton_7.Text = 'Both';
            app.TagearButtonGroup_E6 = uibuttongroup(app.MouseTab);
            app.TagearButtonGroup_E6.Title = 'Tag ear';
            app.NoneButton_14 = uiradiobutton(app.TagearButtonGroup_E6);
            app.NoneButton_14.Text = 'None';
            app.NoneButton_14.Value = true;
            app.LButton_14 = uiradiobutton(app.TagearButtonGroup_E6);
            app.LButton_14.Text = 'L';
            app.RButton_14 = uiradiobutton(app.TagearButtonGroup_E6);
            app.RButton_14.Text = 'R';
            app.UnknownButton_14 = uiradiobutton(app.TagearButtonGroup_E6);
            app.UnknownButton_14.Text = 'Unknown';
            app.tagged_switch_E6 = uiswitch(app.MouseTab, 'slider');
            app.tagged_switch_E6.Items = {' ', 'Tagged'};
            app.tagged_switch_E6.Value = ' ';
            app.DOB_known_switch_E6 = uiswitch(app.MouseTab, 'slider');
            app.DOB_known_switch_E6.Items = {'', 'DOB known'};
            app.DOB_known_switch_E6.Value = 'DOB known';
            app.BackgroundDropDown_E7 = uidropdown(app.MouseTab);
            app.DOBDatePicker_E7 = uidatepicker(app.MouseTab);
            app.DOBDatePicker_E7.DisplayFormat = 'uuuu-MM-dd';
            app.TagIDEditField_E7 = uieditfield(app.MouseTab, 'text');
            app.CageEditField_E7 = uieditfield(app.MouseTab, 'text');
            app.SexButtonGroup_E7 = uibuttongroup(app.MouseTab);
            app.SexButtonGroup_E7.Title = 'Sex';
            app.UnknownButton_15 = uiradiobutton(app.SexButtonGroup_E7);
            app.UnknownButton_15.Text = 'Unknown';
            app.UnknownButton_15.Value = true;
            app.MaleButton_8 = uiradiobutton(app.SexButtonGroup_E7);
            app.MaleButton_8.Text = 'Male';
            app.FemaleButton_8 = uiradiobutton(app.SexButtonGroup_E7);
            app.FemaleButton_8.Text = 'Female';
            app.RoomDropDown_E7 = uidropdown(app.MouseTab);
            app.ProtocolDropDown_E7 = uidropdown(app.MouseTab);
            app.PunchearButtonGroup_E7 = uibuttongroup(app.MouseTab);
            app.PunchearButtonGroup_E7.Title = 'Punch ear';
            app.NoneButton_15 = uiradiobutton(app.PunchearButtonGroup_E7);
            app.NoneButton_15.Text = 'None';
            app.NoneButton_15.Value = true;
            app.LButton_15 = uiradiobutton(app.PunchearButtonGroup_E7);
            app.LButton_15.Text = 'L';
            app.RButton_15 = uiradiobutton(app.PunchearButtonGroup_E7);
            app.RButton_15.Text = 'R';
            app.BothButton_8 = uiradiobutton(app.PunchearButtonGroup_E7);
            app.BothButton_8.Text = 'Both';
            app.TagearButtonGroup_E7 = uibuttongroup(app.MouseTab);
            app.TagearButtonGroup_E7.Title = 'Tag ear';
            app.NoneButton_16 = uiradiobutton(app.TagearButtonGroup_E7);
            app.NoneButton_16.Text = 'None';
            app.NoneButton_16.Value = true;
            app.LButton_16 = uiradiobutton(app.TagearButtonGroup_E7);
            app.LButton_16.Text = 'L';
            app.RButton_16 = uiradiobutton(app.TagearButtonGroup_E7);
            app.RButton_16.Text = 'R';
            app.UnknownButton_16 = uiradiobutton(app.TagearButtonGroup_E7);
            app.UnknownButton_16.Text = 'Unknown';
            app.tagged_switch_E7 = uiswitch(app.MouseTab, 'slider');
            app.tagged_switch_E7.Items = {' ', 'Tagged'};
            app.tagged_switch_E7.Value = ' ';
            app.DOB_known_switch_E7 = uiswitch(app.MouseTab, 'slider');
            app.DOB_known_switch_E7.Items = {'', 'DOB known'};
            app.DOB_known_switch_E7.Value = 'DOB known';
            app.BackgroundDropDown_E8 = uidropdown(app.MouseTab);
            app.DOBDatePicker_E8 = uidatepicker(app.MouseTab);
            app.DOBDatePicker_E8.DisplayFormat = 'uuuu-MM-dd';
            app.TagIDEditField_E8 = uieditfield(app.MouseTab, 'text');
            app.CageEditField_E8 = uieditfield(app.MouseTab, 'text');
            app.SexButtonGroup_E8 = uibuttongroup(app.MouseTab);
            app.SexButtonGroup_E8.Title = 'Sex';
            app.UnknownButton_17 = uiradiobutton(app.SexButtonGroup_E8);
            app.UnknownButton_17.Text = 'Unknown';
            app.UnknownButton_17.Value = true;
            app.MaleButton_9 = uiradiobutton(app.SexButtonGroup_E8);
            app.MaleButton_9.Text = 'Male';
            app.FemaleButton_9 = uiradiobutton(app.SexButtonGroup_E8);
            app.FemaleButton_9.Text = 'Female';
            app.RoomDropDown_E8 = uidropdown(app.MouseTab);
            app.ProtocolDropDown_E8 = uidropdown(app.MouseTab);
            app.PunchearButtonGroup_E8 = uibuttongroup(app.MouseTab);
            app.PunchearButtonGroup_E8.Title = 'Punch ear';
            app.NoneButton_17 = uiradiobutton(app.PunchearButtonGroup_E8);
            app.NoneButton_17.Text = 'None';
            app.NoneButton_17.Value = true;
            app.LButton_17 = uiradiobutton(app.PunchearButtonGroup_E8);
            app.LButton_17.Text = 'L';
            app.RButton_17 = uiradiobutton(app.PunchearButtonGroup_E8);
            app.RButton_17.Text = 'R';
            app.BothButton_9 = uiradiobutton(app.PunchearButtonGroup_E8);
            app.BothButton_9.Text = 'Both';
            app.TagearButtonGroup_E8 = uibuttongroup(app.MouseTab);
            app.TagearButtonGroup_E8.Title = 'Tag ear';
            app.NoneButton_18 = uiradiobutton(app.TagearButtonGroup_E8);
            app.NoneButton_18.Text = 'None';
            app.NoneButton_18.Value = true;
            app.LButton_18 = uiradiobutton(app.TagearButtonGroup_E8);
            app.LButton_18.Text = 'L';
            app.RButton_18 = uiradiobutton(app.TagearButtonGroup_E8);
            app.RButton_18.Text = 'R';
            app.UnknownButton_18 = uiradiobutton(app.TagearButtonGroup_E8);
            app.UnknownButton_18.Text = 'Unknown';
            app.tagged_switch_E8 = uiswitch(app.MouseTab, 'slider');
            app.tagged_switch_E8.Items = {' ', 'Tagged'};
            app.tagged_switch_E8.Value = ' ';
            app.DOB_known_switch_E8 = uiswitch(app.MouseTab, 'slider');
            app.DOB_known_switch_E8.Items = {'', 'DOB known'};
            app.DOB_known_switch_E8.Value = 'DOB known';
            app.Label_7 = uilabel(app.MouseTab);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.MouseTab);
            app.Label_8.Text = '8';
            app.NumberofentriesSliderLabel = uilabel(app.MouseTab);
            app.NumberofentriesSliderLabel.Text = 'Number of entries';
            app.NumberofentriesSlider = uislider(app.MouseTab);
            app.NumberofentriesSlider.Limits = [0 8];
            app.NumberofentriesSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofentriesSliderValueChanged, true);
            app.NumberofentriesSlider.ValueChangingFcn = createCallbackFcn(app, @NumberofentriesSliderValueChanging, true);
            app.NumberofentriesSlider.MinorTicks = [];
            app.SourceinfoLabel = uilabel(app.MouseTab);
            app.SourceinfoLabel.Text = 'External info.';
            app.ExternalInfoEditField = uieditfield(app.MouseTab, 'text');
            app.AnimalSourceDropDown = uidropdown(app.MouseTab);
            app.ExtinfoLabel = uilabel(app.MouseTab);
            app.ExtinfoLabel.Text = 'Ext. info.';
            app.ExtInfo_E1 = uieditfield(app.MouseTab, 'text');
            app.SourceDropDown_E2 = uidropdown(app.MouseTab);
            app.SourceDropDown_E2.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E2.Value = 'breeding';
            app.ExtInfo_E2 = uieditfield(app.MouseTab, 'text');
            app.SourceDropDown_E3 = uidropdown(app.MouseTab);
            app.SourceDropDown_E3.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E3.Value = 'breeding';
            app.ExtInfo_E3 = uieditfield(app.MouseTab, 'text');
            app.SourceDropDown_E4 = uidropdown(app.MouseTab);
            app.SourceDropDown_E4.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E4.Value = 'breeding';
            app.ExtInfo_E4 = uieditfield(app.MouseTab, 'text');
            app.SourceDropDown_E5 = uidropdown(app.MouseTab);
            app.SourceDropDown_E5.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E5.Value = 'breeding';
            app.ExtInfo_E5 = uieditfield(app.MouseTab, 'text');
            app.SourceDropDown_E6 = uidropdown(app.MouseTab);
            app.SourceDropDown_E6.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E6.Value = 'breeding';
            app.ExtInfo_E6 = uieditfield(app.MouseTab, 'text');
            app.SourceDropDown_E7 = uidropdown(app.MouseTab);
            app.SourceDropDown_E7.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E7.Value = 'breeding';
            app.ExtInfo_E7 = uieditfield(app.MouseTab, 'text');
            app.SourceDropDown_E8 = uidropdown(app.MouseTab);
            app.SourceDropDown_E8.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E8.Value = 'breeding';
            app.ExtInfo_E8 = uieditfield(app.MouseTab, 'text');
            app.SetsourceButton = uibutton(app.MouseTab, 'push');
            app.SetsourceButton.ButtonPushedFcn = createCallbackFcn(app, @SetsourceButtonPushed, true);
            app.SetsourceButton.Text = 'Set source';
            app.ChameleonTab = uitab(app.SpeciesTabGroup);
            app.ChameleonTab.Title = 'Chameleon';
            app.PerhapssomedayLabel = uilabel(app.ChameleonTab);
            app.PerhapssomedayLabel.Text = 'Perhaps someday';
            app.AddsingleanimaltodatabaseButton = uibutton(app.AddAnimalsTab, 'push');
            app.AddsingleanimaltodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddsingleanimaltodatabaseButtonPushed, true);
            app.AddsingleanimaltodatabaseButton.Text = 'Add single animal to database';
            app.StatusLampLabel_2 = uilabel(app.AddAnimalsTab);
            app.StatusLampLabel_2.Text = 'Status';
            app.StatusLamp_Animal = uilamp(app.AddAnimalsTab);
            app.AnimalInsertStatusEdit = uieditfield(app.AddAnimalsTab, 'text');
            app.AnimalInsertStatusEdit.Editable = 'off';
            app.AddmultipleanimalstodatabaseButton = uibutton(app.AddAnimalsTab, 'push');
            app.AddmultipleanimalstodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddmultipleanimalstodatabaseButtonPushed, true);
            app.AddmultipleanimalstodatabaseButton.Text = 'Add multiple animals to database';
            app.AddGenotypeTab = uitab(app.MainTabGroup);
            app.AddGenotypeTab.Title = 'Add Genotype';
            app.GenotypenameEditFieldLabel = uilabel(app.AddGenotypeTab);
            app.GenotypenameEditFieldLabel.Text = 'Genotype name';
            app.GenotypenameEditField = uieditfield(app.AddGenotypeTab, 'text');
            app.DescriptionTextAreaLabel = uilabel(app.AddGenotypeTab);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.AddGenotypeTab);
            app.SourceEditFieldLabel = uilabel(app.AddGenotypeTab);
            app.SourceEditFieldLabel.Text = 'Source';
            app.SourceEditField = uieditfield(app.AddGenotypeTab, 'text');
            app.CatalogEditFieldLabel = uilabel(app.AddGenotypeTab);
            app.CatalogEditFieldLabel.Text = 'Catalog #';
            app.CatalogEditField = uieditfield(app.AddGenotypeTab, 'text');
            app.AddgenotypetodatabaseButton = uibutton(app.AddGenotypeTab, 'push');
            app.AddgenotypetodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddgenotypetodatabaseButtonPushed, true);
            app.AddgenotypetodatabaseButton.Text = 'Add genotype to database';
            app.GenotypesindatabaseListBoxLabel = uilabel(app.AddGenotypeTab);
            app.GenotypesindatabaseListBoxLabel.Text = 'Genotypes in database';
            app.GenotypesindatabaseListBox = uilistbox(app.AddGenotypeTab);
            app.GenotypesindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @GenotypesindatabaseListBoxValueChanged, true);
            app.StatusLampLabel_3 = uilabel(app.AddGenotypeTab);
            app.StatusLampLabel_3.Text = 'Status';
            app.StatusLamp_Genotype = uilamp(app.AddGenotypeTab);
            app.GenotypeInsertStatusEdit = uieditfield(app.AddGenotypeTab, 'text');
            app.GenotypeInsertStatusEdit.Editable = 'off';
            app.AddSubstanceTab = uitab(app.MainTabGroup);
            app.AddSubstanceTab.Title = 'Add Substance';
            app.SubstancenameEditFieldLabel = uilabel(app.AddSubstanceTab);
            app.SubstancenameEditFieldLabel.Text = 'Substance name';
            app.SubstancenameEditField = uieditfield(app.AddSubstanceTab, 'text');
            app.DescriptionTextArea_2Label = uilabel(app.AddSubstanceTab);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.DescriptionTextArea_substance = uitextarea(app.AddSubstanceTab);
            app.SourceEditField_2Label = uilabel(app.AddSubstanceTab);
            app.SourceEditField_2Label.Text = 'Source';
            app.SourceEditField_substance = uieditfield(app.AddSubstanceTab, 'text');
            app.CatalogEditField_2Label = uilabel(app.AddSubstanceTab);
            app.CatalogEditField_2Label.Text = 'Catalog #';
            app.CatalogEditField_substance = uieditfield(app.AddSubstanceTab, 'text');
            app.StoragelocationLabel = uilabel(app.AddSubstanceTab);
            app.StoragelocationLabel.Text = 'Storage location';
            app.StorageLocationTextArea_substance = uitextarea(app.AddSubstanceTab);
            app.AddsubstancetodatabaseButton = uibutton(app.AddSubstanceTab, 'push');
            app.AddsubstancetodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddsubstancetodatabaseButtonPushed, true);
            app.AddsubstancetodatabaseButton.Text = 'Add substance to database';
            app.SubstancesindatabaseListBoxLabel = uilabel(app.AddSubstanceTab);
            app.SubstancesindatabaseListBoxLabel.Text = 'Substances in database';
            app.SubstancesindatabaseListBox = uilistbox(app.AddSubstanceTab);
            app.SubstancesindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @SubstancesindatabaseListBoxValueChanged, true);
            app.StatusLampLabel_4 = uilabel(app.AddSubstanceTab);
            app.StatusLampLabel_4.Text = 'Status';
            app.StatusLamp_Substance = uilamp(app.AddSubstanceTab);
            app.SubstanceInsertStatusEdit = uieditfield(app.AddSubstanceTab, 'text');
            app.SubstanceInsertStatusEdit.Editable = 'off';
            app.AddBrainAreaTab = uitab(app.MainTabGroup);
            app.AddBrainAreaTab.Title = 'Add Brain Area';
            app.AbbreviationEditFieldLabel = uilabel(app.AddBrainAreaTab);
            app.AbbreviationEditFieldLabel.Text = 'Abbreviation';
            app.AbbreviationEditField = uieditfield(app.AddBrainAreaTab, 'text');
            app.LongnameLabel = uilabel(app.AddBrainAreaTab);
            app.LongnameLabel.Text = 'Long name';
            app.BrainLongNameTextArea = uitextarea(app.AddBrainAreaTab);
            app.BrainareasindatabaseListBoxLabel = uilabel(app.AddBrainAreaTab);
            app.BrainareasindatabaseListBoxLabel.Text = 'Brain areas in database';
            app.BrainareasindatabaseListBox = uilistbox(app.AddBrainAreaTab);
            app.BrainareasindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @BrainareasindatabaseListBoxValueChanged, true);
            app.AddbrainareatodatabaseButton = uibutton(app.AddBrainAreaTab, 'push');
            app.AddbrainareatodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddbrainareatodatabaseButtonPushed, true);
            app.AddbrainareatodatabaseButton.Text = 'Add brain area to database';
            app.StatusLampLabel_5 = uilabel(app.AddBrainAreaTab);
            app.StatusLampLabel_5.Text = 'Status';
            app.StatusLamp_BrainArea = uilamp(app.AddBrainAreaTab);
            app.BrainAreaInsertStatusEdit = uieditfield(app.AddBrainAreaTab, 'text');
            app.BrainAreaInsertStatusEdit.Editable = 'off';
            app.AddProjectTab = uitab(app.MainTabGroup);
            app.AddProjectTab.Title = 'Add Project';
            app.AddprojecttodatabaseButton = uibutton(app.AddProjectTab, 'push');
            app.AddprojecttodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddprojecttodatabaseButtonPushed, true);
            app.AddprojecttodatabaseButton.Text = 'Add project to database';
            app.ProjectInsertStatusEdit = uieditfield(app.AddProjectTab, 'text');
            app.ProjectInsertStatusEdit.Editable = 'off';
            app.NameEditFieldLabel = uilabel(app.AddProjectTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.ProjectNameEditField = uieditfield(app.AddProjectTab, 'text');
            app.DescriptionLabel = uilabel(app.AddProjectTab);
            app.DescriptionLabel.Text = 'Description';
            app.ProjectDescriptionTextArea = uitextarea(app.AddProjectTab);
            app.ProjectsindatabaseListBoxLabel = uilabel(app.AddProjectTab);
            app.ProjectsindatabaseListBoxLabel.Text = 'Projects in database';
            app.ProjectsindatabaseListBox = uilistbox(app.AddProjectTab);
            app.ProjectsindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @ProjectsindatabaseListBoxValueChanged, true);
            app.StatusLampLabel_7 = uilabel(app.AddProjectTab);
            app.StatusLampLabel_7.Text = 'Status';
            app.StatusLamp_Project = uilamp(app.AddProjectTab);
            app.AddBehaviorExperimentTypeTab = uitab(app.MainTabGroup);
            app.AddBehaviorExperimentTypeTab.Title = 'Add Behavior Experiment Type';
            app.AddexperimenttypetodatabaseButton = uibutton(app.AddBehaviorExperimentTypeTab, 'push');
            app.AddexperimenttypetodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddexperimenttypetodatabaseButtonPushed, true);
            app.AddexperimenttypetodatabaseButton.Text = 'Add experiment type to database';
            app.BehaviorExpInsertStatusEdit = uieditfield(app.AddBehaviorExperimentTypeTab, 'text');
            app.BehaviorExpInsertStatusEdit.Editable = 'off';
            app.NameEditFieldLabel_2 = uilabel(app.AddBehaviorExperimentTypeTab);
            app.NameEditFieldLabel_2.Text = 'Name';
            app.BehaviorExpNameEditField = uieditfield(app.AddBehaviorExperimentTypeTab, 'text');
            app.DescriptionLabel_2 = uilabel(app.AddBehaviorExperimentTypeTab);
            app.DescriptionLabel_2.Text = 'Description';
            app.BehaviorExpDescriptionTextArea = uitextarea(app.AddBehaviorExperimentTypeTab);
            app.BehaviorexperimenttypesindatabaseListBoxLabel = uilabel(app.AddBehaviorExperimentTypeTab);
            app.BehaviorexperimenttypesindatabaseListBoxLabel.Text = 'Behavior experiment types in database';
            app.BehaviorexperimenttypesindatabaseListBox = uilistbox(app.AddBehaviorExperimentTypeTab);
            app.BehaviorexperimenttypesindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @BehaviorexperimenttypesindatabaseListBoxValueChanged, true);
            app.StatusLampLabel_8 = uilabel(app.AddBehaviorExperimentTypeTab);
            app.StatusLampLabel_8.Text = 'Status';
            app.StatusLamp_BehaviorExp = uilamp(app.AddBehaviorExperimentTypeTab);
            app.LogTab = uitab(app.MainTabGroup);
            app.LogTab.Title = 'Log';
            app.LogTextArea = uitextarea(app.LogTab);
            app.LogTextArea.Editable = 'off';
            app.ExporttotextfileButton = uibutton(app.LogTab, 'push');
            app.ExporttotextfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttotextfileButtonPushed, true);
            app.ExporttotextfileButton.Text = 'Export to text file';
            app.TODOListTab = uitab(app.MainTabGroup);
            app.TODOListTab.Title = 'TODO List';
            app.QueriesListBoxLabel = uilabel(app.TODOListTab);
            app.QueriesListBoxLabel.Text = 'Queries';
            app.QueriesListBox = uilistbox(app.TODOListTab);
            app.QueriesListBox.ValueChangedFcn = createCallbackFcn(app, @QueriesListBoxValueChanged, true);
            app.QuerytextTextAreaLabel = uilabel(app.TODOListTab);
            app.QuerytextTextAreaLabel.Text = 'Query text';
            app.QuerytextTextArea = uitextarea(app.TODOListTab);
            app.NameEditFieldLabel_3 = uilabel(app.TODOListTab);
            app.NameEditFieldLabel_3.Text = 'Name';
            app.QuerynameEditField = uieditfield(app.TODOListTab, 'text');
            app.SubmitnewqueryButton = uibutton(app.TODOListTab, 'push');
            app.SubmitnewqueryButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitnewqueryButtonPushed, true);
            app.SubmitnewqueryButton.Text = 'Submit new query';
            app.QueryResultTable = uitable(app.TODOListTab);
            app.QueryResultTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.QueryResultTable.RowName = {};
            app.QuerynameLabel = uilabel(app.TODOListTab);
            app.QuerynameLabel.Text = 'Query name';
            app.StatusLampLabel_10 = uilabel(app.TODOListTab);
            app.StatusLampLabel_10.Text = 'Status';
            app.StatusLamp_TODOquery = uilamp(app.TODOListTab);
            app.EditField_TODOquery = uieditfield(app.TODOListTab, 'text');
            app.EditField_TODOquery.Editable = 'off';
            % Show the figure after all components are created
            app.AnimalInterfaceV2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnimalInterfaceV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnimalInterfaceV2UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnimalInterfaceV2UIFigure)
        end
    end
end