classdef Calcium_Imaging_Browser_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalciumImagingBrowserUIFigure  matlab.ui.Figure
        Lamp                           matlab.ui.control.Lamp
        Spinner                        matlab.ui.control.Spinner
        SpinnerLabel                   matlab.ui.control.Label
        EpochSlider                    matlab.ui.control.Slider
        EpochSliderLabel               matlab.ui.control.Label
        PlotbyepochSwitch              matlab.ui.control.Switch
        PlotbyepochSwitchLabel         matlab.ui.control.Label
        PlotsPanel                     matlab.ui.container.Panel
        GoButton                       matlab.ui.control.Button
        preceedingepochsSpinner        matlab.ui.control.Spinner
        preceedingepochsSpinnerLabel   matlab.ui.control.Label
        PlotCheckBox                   matlab.ui.control.CheckBox
        MultipleparamsCheckBox         matlab.ui.control.CheckBox
        VariableparamsListBox          matlab.ui.control.ListBox
        VariableparamsListBoxLabel     matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        AlignmentListBox               matlab.ui.control.ListBox
        AlignmentListBoxLabel          matlab.ui.control.Label
        CellsListBox                   matlab.ui.control.ListBox
        CellsListBoxLabel              matlab.ui.control.Label
        ExperimentdayDatePicker        matlab.ui.control.DatePicker
        ExperimentdayLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        public_file_name = struct();
        public_source_id_list = [];
        public_alignment_list = [];
        public_variable_params = [];
        public_spike_data = struct();
        public_cal_data = struct();
        public_epoch_params = [];
    end
    
    methods (Access = private)
        
        function loadAlignment(app, file_name, source_id)
            if nargin < 3
                file_name = app.public_file_name.file_name;
                source_id = app.public_source_id_list(1);
            end
            alignment_querry_string = sprintf('sln_funcimage.Alignment & ''file_name = "%s" and source_id = %d''', app.public_file_name.file_name, source_id);
            alignment_querry = fetch(eval(alignment_querry_string), '*');
            alignments = [{alignment_querry.dataset_name}; {alignment_querry.image_fname}];
            app.public_alignment_list = cell2struct(alignments, {'dataset_name', 'image_fname'});
            app.AlignmentListBox.Items = join(alignments', " | ");
            
        end
      
        
        function loadVariableParams(app, alignment)
            if nargin < 2
                alignment = app.public_alignment_list(1);
                disp('here')
            end
            epoch_block_querry = sln_symphony.ExperimentEpochBlock & (sln_symphony.DatasetEpoch & (sln_funcimage.Alignment & alignment & app.public_file_name));
            protocol_name = sqlProtName2ProtName(fetch1(epoch_block_querry, 'protocol_name'));
            variable_param = '*';
            switch protocol_name
                case 'FlashedBar'
                    variable_param = 'bar_angle';
                case 'SpotsMultiSize'
                    variable_param = 'cur_spot_size';
                case 'MultiPulse'
                    variable_param = 'pulse_1_curr';
            end
            app.VariableparamsListBoxLabel.Text = ['Variable params: ', variable_param];

            epoch_params = fetchn(aka.EpochParams(protocol_name) & epoch_block_querry, variable_param);
            app.VariableparamsListBox.Items = string(unique(epoch_params));
            app.public_variable_params = unique(epoch_params);
            app.public_epoch_params = epoch_params;
            
        end
        
        function loadData(app, alignment)
            app.Lamp.Color = "#FF0000"; drawnow();
            
            
            if nargin < 2
                alignment = app.public_alignment_list(1);

            end
            
            spike_querry = sln_symphony.ExperimentEpochChannel & (sln_symphony.DatasetEpoch & (sln_funcimage.Alignment & alignment) & 'channel_name LIKE "Amp_"' & app.public_file_name);
            cal_querry = sln_funcimage.ROITraces & alignment;
            sample_rate = fetch1(sln_symphony.ExperimentChannel & (sln_symphony.DatasetEpoch & (sln_funcimage.Alignment & alignment) & 'channel_name LIKE "Amp_"' & app.public_file_name), 'sample_rate');
            raw_data = cell2mat(fetchn(spike_querry, 'raw_data'));
            n_spike_time_points = size(raw_data, 2);
            spike_time_axis = [0:n_spike_time_points - 1] ./ sample_rate * 1e3;
            app.public_spike_data.time_axis = spike_time_axis;
            app.public_spike_data.raw_data = raw_data;

            [time_axis, ~, ~, trace_matrix] = sln_funcimage.meanForROI(cal_querry, 1, true);
            app.public_cal_data.time_axis = time_axis + abs(min(time_axis));
            app.public_cal_data.trace_matrix = trace_matrix;

            app.plotAllDataNow();
            app.Lamp.Color = "#00FF00";
            
        end
        
        function results = plotAllDataNow(app)
            t = tiledlayout(app.PlotsPanel,2,1);
            ax1 = nexttile(t);
            plot(ax1,app.public_spike_data.time_axis, app.public_spike_data.raw_data', 'LineWidth', 1);
            ax2 = nexttile(t);
            plot(ax2, app.public_cal_data.time_axis, app.public_cal_data.trace_matrix', 'LineWidth', 1);
            fontsize(ax1, 15, 'points');
            xlabel(ax1, 'Time (ms)');
            ylabel(ax1, 'mV');
            fontsize(ax2, 15, 'points');
            xlabel(ax2, 'Time (ms)');
            ylabel(ax2, '\DeltaF/F_0');
        end
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            t = tiledlayout(app.PlotsPanel,2,1);
            ax1 = nexttile(t);
            plot(ax1,[1:10]);
            ax2 = nexttile(t);
            plot(ax2,[1:30], ':r')
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            experiment_day = datestr(app.ExperimentdayDatePicker.Value, 'mmddyy');
            alignment_querry_1 = sprintf('sln_funcimage.Alignment & ''file_name like "%s_"''', experiment_day);
            querry_1 = eval(alignment_querry_1);
            if querry_1.count == 0
                errordlg(sprintf("Selected day %s doesn't have alignment", experiment_day), '404', 'non-modal');
            else
                alignment_fetch_1 = fetch(querry_1);
                file_name_fetch_1 = alignment_fetch_1(1).file_name;
                source_fetch_1 = unique([alignment_fetch_1.source_id]);
                n = length(source_fetch_1);
                A = '%d';
                source_id_string = join(repmat({A},1,n),', ');
                source_id_string = source_id_string{1};
                cell_name_querry_string = ['sln_cell.CellName & ''file_name = "%s" and source_id in(', source_id_string,')'''];
                cell_name_querry_string = sprintf(cell_name_querry_string, file_name_fetch_1, source_fetch_1);
                cell_name_querry = eval(cell_name_querry_string);
                cell_names_fetch = fetch(cell_name_querry, '*');
                app.CellsListBox.Items = {cell_names_fetch.cell_name};
                app.public_file_name.file_name = file_name_fetch_1;
                app.public_source_id_list = source_fetch_1;
                app.loadAlignment()
                app.loadVariableParams()
                app.loadData()
            end


        end

        % Double-clicked callback: CellsListBox
        function CellsListBoxDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            loadAlignment(app, app.public_file_name, app.public_source_id_list(item));
            loadVariableParams(app);
            loadData(app);
            plotAllDataNow(app);
            
        end

        % Double-clicked callback: AlignmentListBox
        function AlignmentListBoxDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            loadVariableParams(app, app.public_alignment_list(item));
            loadData(app, app.public_alignment_list(item));
            
        end

        % Double-clicked callback: VariableparamsListBox
        function VariableparamsListBoxDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            curr = app.public_variable_params(item);
            idx = find(app.public_epoch_params == curr);
            spike_data = vertcat(app.public_spike_data.raw_data(idx, :));
            cal_data = vertcat(app.public_cal_data.trace_matrix(idx, :));
            t = tiledlayout(app.PlotsPanel,2,1);
            ax1 = nexttile(t, 1); 
            
            for i = 1:length(idx)
                hold(ax1, "on");
                plot(ax1,app.public_spike_data.time_axis, spike_data(i, :), 'LineWidth', 2, 'DisplayName', string(i));
               
            end
            legend(ax1); fontsize(ax1, 15, 'points');
            xlabel(ax1, 'Time (ms)');
            ylabel(ax1, 'mV');
            ax2 = nexttile(t, 2); 
            hold on;
            for i = 1:length(idx)
                hold(ax2, "on");
                plot(ax2, app.public_cal_data.time_axis, cal_data(i,:), 'LineWidth', 2, 'DisplayName', string(i));
                
            end
            legend(ax2); fontsize(ax2, 15, 'points');
            xlabel(ax2, 'Time (ms)');
            ylabel(ax2, '\DeltaF/F_0');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CalciumImagingBrowserUIFigure = uifigure('Visible', 'off');
            app.CalciumImagingBrowserUIFigure.Name = 'Calcium Imaging Browser';
            app.CalciumImagingBrowserUIFigure.Icon = fullfile(pathToMLAPP, 'pngimg.com - cow_PNG50631.png');
            app.ExperimentdayLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.ExperimentdayLabel.Text = 'Experiment day';
            app.ExperimentdayDatePicker = uidatepicker(app.CalciumImagingBrowserUIFigure);
            app.ExperimentdayDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.ExperimentdayDatePicker.Value = datetime([2024 7 5]);
            app.CellsListBoxLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.CellsListBoxLabel.Text = 'Cells';
            app.CellsListBox = uilistbox(app.CalciumImagingBrowserUIFigure);
            app.CellsListBox.DoubleClickedFcn = createCallbackFcn(app, @CellsListBoxDoubleClicked, true);
            app.AlignmentListBoxLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.AlignmentListBoxLabel.Text = 'Alignment';
            app.AlignmentListBox = uilistbox(app.CalciumImagingBrowserUIFigure);
            app.AlignmentListBox.DoubleClickedFcn = createCallbackFcn(app, @AlignmentListBoxDoubleClicked, true);
            app.PlotButton = uibutton(app.CalciumImagingBrowserUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.VariableparamsListBoxLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.VariableparamsListBoxLabel.Text = 'Variable params';
            app.VariableparamsListBox = uilistbox(app.CalciumImagingBrowserUIFigure);
            app.VariableparamsListBox.Multiselect = 'on';
            app.VariableparamsListBox.DoubleClickedFcn = createCallbackFcn(app, @VariableparamsListBoxDoubleClicked, true);
            app.VariableparamsListBox.Value = {'Item 1'};
            app.MultipleparamsCheckBox = uicheckbox(app.CalciumImagingBrowserUIFigure);
            app.MultipleparamsCheckBox.Text = 'Multiple params';
            app.PlotCheckBox = uicheckbox(app.CalciumImagingBrowserUIFigure);
            app.PlotCheckBox.Text = 'Plot';
            app.preceedingepochsSpinnerLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.preceedingepochsSpinnerLabel.Text = 'preceeding epochs';
            app.preceedingepochsSpinner = uispinner(app.CalciumImagingBrowserUIFigure);
            app.GoButton = uibutton(app.CalciumImagingBrowserUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.PlotsPanel = uipanel(app.CalciumImagingBrowserUIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.PlotbyepochSwitchLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.PlotbyepochSwitchLabel.Text = 'Plot by epoch';
            app.PlotbyepochSwitch = uiswitch(app.CalciumImagingBrowserUIFigure, 'slider');
            app.EpochSliderLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.EpochSliderLabel.Text = 'Epoch';
            app.EpochSlider = uislider(app.CalciumImagingBrowserUIFigure);
            app.SpinnerLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.CalciumImagingBrowserUIFigure);
            app.Lamp = uilamp(app.CalciumImagingBrowserUIFigure);
            % Show the figure after all components are created
            app.CalciumImagingBrowserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calcium_Imaging_Browser_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalciumImagingBrowserUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalciumImagingBrowserUIFigure)
        end
    end
end