classdef Curator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CuratorUIFigure               matlab.ui.Figure
        EpochnumberSlider             matlab.ui.control.Slider
        EpochnumberSliderLabel        matlab.ui.control.Label
        LoadcellButton                matlab.ui.control.Button
        WorkingcellEditField          matlab.ui.control.EditField
        WorkingcellEditFieldLabel     matlab.ui.control.Label
        FilterconstructionLabel       matlab.ui.control.Label
        ParametersforselectedepochLabel  matlab.ui.control.Label
        SelectedValTable              matlab.ui.control.Table
        ParmeterswithmultiplevaluesLabel  matlab.ui.control.Label
        MultiValTable                 matlab.ui.control.Table
        FilterTable                   matlab.ui.control.Table
        FilterpatternstringEditField  matlab.ui.control.EditField
        FilterpatternstringEditFieldLabel  matlab.ui.control.Label
        DatasetsDropDown              matlab.ui.control.DropDown
        DatasetsDropDownLabel         matlab.ui.control.Label
        DeletedatasetButton           matlab.ui.control.Button
        RenamedatasetButton           matlab.ui.control.Button
        SavedatasetButton             matlab.ui.control.Button
        DatachannelDropDown           matlab.ui.control.DropDown
        DatachannelDropDownLabel      matlab.ui.control.Label
        XaxisDropDown                 matlab.ui.control.DropDown
        XaxisDropDownLabel            matlab.ui.control.Label
        RawAxes                       matlab.ui.control.UIAxes
        DiaryAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        loaded_cell % query for currently loaded cell
        all_epoch_ids % all epoch_ids for loaded cell
        epoch_ids % epoch_ids matching current query
        loaded_epoch %query for currently loaded epoch
        epoch_selection_index
        diary_x % x points in diary plot
        diary_y % y points in diary plot
        operators = {' ', '==','>','<','>=','<=','~='};
        params_to_remove = {...
            'file_name', ...
            'source_id', ...
            'cell_id', ...
            'spot_sizes', ...
            'epoch_group_id', ...
            'epoch_block_id', ...
            'number_of_cycles', ...
            'epoch_block_start_time', ...
            'epoch_block_end_time', ...
            'epoch_group_start_time', ...
            'epoch_group_end_time', ...
            'epoch_start_time' ...
            };
        param_list % list of all of searchable parameters in the loaded epochs
        valsMap % map of all unique parameter values for each parameter
    end
    
    methods (Access = private)
        
        function init(app)
%             Nrows = 7;
%             D = {1,'paramA','==',' '};
%             for i=2:Nrows
%                 D = [D; {i,'paramA','==',' '}];
%             end
%             app.FilterTable.Data = D;
%             app.FilterTable.ColumnEditable = [false, true, true, true];
%             app.FilterTable.ColumnFormat = {'numeric',{'paramA','paramB'}, app.operators,'char'};
        end
        
        function loadCell(app, cellname)
            s.file_name = cellname(1:7);
            s.rig_name = cellname(7);
            s.cell_number = str2double(cellname(9:end));
            q = sln_symphony.Experiment * sln_symphony.ExperimentCell & s;
            if ~q.exists
                errordlg(sprintf('%s not found', cellname));
            elseif q.count > 1
                errordlg(sprintf('%s: more than 1 matching cell', cellname));
            else
                cla(app.RawAxes);
                cla(app.DiaryAxes);
                drawnow;
                app.CuratorUIFigure.Name = 'loading...';
                app.loaded_cell = q;
                app.WorkingcellEditField.Value = cellname;
                app.all_epoch_ids = sort(fetchn(aka.Epoch & app.loaded_cell, 'epoch_id'), 'ascend');
                app.epoch_ids = app.all_epoch_ids;
                app.loaded_epoch = aka.Epoch & app.loaded_cell & sprintf('epoch_id = %d', app.epoch_ids(1));
                app.EpochnumberSlider.Limits = [app.epoch_ids(1), app.epoch_ids(end)];
                app.EpochnumberSlider.Value = app.epoch_ids(1);
                app.EpochnumberSlider.MajorTicks = round(linspace(app.epoch_ids(1), app.epoch_ids(end), 10));

                app.epoch_selection_index = 1;
                app.getParamsForEpochs();
                app.init_filter_table();
                app.updateDiaryPlot();
                app.plotCurrentEpoch();
                app.CuratorUIFigure.Name = 'Curator';
            end
        end
        
        function plotCurrentEpoch(app)
            cla(app.RawAxes);
            title(app.RawAxes, 'plotting...');
            drawnow;
            app.CuratorUIFigure.Name = 'plotting...';
            [timeAxis, data] = sln_symphony.getEpochRawData(app.loaded_epoch,app.DatachannelDropDown.Value);    
            if isempty(data)
                return;
            end
            epoch_number = fetch1(app.loaded_epoch, 'epoch_id');
            recording_mode = fetch1(sln_symphony.ExperimentElectrode & app.loaded_epoch, 'recording_mode');
            if strcmp(recording_mode,'Current clamp') %% THIS IS STILL BACKWARDS!
                units = 'pA';
            else
                units = 'mV';
            end
            
            protocol_name = sqlProtName2ProtName(fetch1(sln_symphony.ExperimentEpochBlock & app.loaded_epoch, 'protocol_name'));
            block_params = fetch(aka.BlockParams(protocol_name) & app.loaded_epoch, '*');

            plot(app.RawAxes,timeAxis,data,'k');
            hold(app.RawAxes,'on');
            if isfield(block_params, 'pre_time')
                if ~isnan(block_params.pre_time)
                    line(app.RawAxes, [0 0], ylim(app.RawAxes),'Color','r');                    
                end
            end
            if isfield(block_params, 'stim_time')
                if ~isnan(block_params.stim_time)
                    line(app.RawAxes, [block_params.stim_time block_params.stim_time]./1E3, ylim(app.RawAxes),...
                        'Color','r','LineStyle','--');                    
                end
            elseif isfield(block_params, 'stim_1_time')
                if ~isnan(block_params.stim_1_time)
                    line(app.RawAxes, [block_params.stim_1_time block_params.stim_1_time]./1E3, ylim(app.RawAxes),...
                        'Color','r','LineStyle','--');                    
                end
            end

            ylabel(app.RawAxes, units);
            title(app.RawAxes, sprintf('Epoch %d', epoch_number));                        
            hold(app.RawAxes,'off');

            app.updateDiaryPlot();

            hold(app.DiaryAxes,'on');
            scatter(app.DiaryAxes, app.diary_x(app.epoch_selection_index), app.diary_y(app.epoch_selection_index), ...
                'filled','o','Color','r');
            hold(app.DiaryAxes,'off');
            app.CuratorUIFigure.Name = 'Curator';
        end
        
        function incrementEpoch(app)            
           epoch_number = fetch1(app.loaded_epoch, 'epoch_id');
           ind = find(app.epoch_ids == epoch_number);
           if ind < length(app.epoch_ids)
              app.loaded_epoch = aka.Epoch & app.loaded_cell & sprintf('epoch_id = %d', app.epoch_ids(ind+1));
              app.EpochnumberSlider.Value = app.epoch_ids(ind+1);
              app.epoch_selection_index = ind+1;
              app.plotCurrentEpoch();
           end
        end

        function decrementEpoch(app)
           epoch_number = fetch1(app.loaded_epoch, 'epoch_id');
           ind = find(app.epoch_ids == epoch_number);
           if ind > 1
              app.loaded_epoch = aka.Epoch & app.loaded_cell & sprintf('epoch_id = %d', app.epoch_ids(ind-1));
              app.EpochnumberSlider.Value = app.epoch_ids(ind-1);
              app.epoch_selection_index = ind-1;
              app.plotCurrentEpoch();
           end
        end

        
        function updateDiaryPlot(app)
            s = cell2struct(num2cell(app.epoch_ids),'epoch_id', 2);
            ep_struct = fetch(sln_symphony.ExperimentEpochBlock * ...
                sln_symphony.ExperimentEpochGroup * ...
                aka.Epoch * ...
                sln_symphony.ExperimentElectrode ...
                & app.loaded_cell & s, ...
                'epoch_id', 'protocol_name', 'epoch_start_time', 'epoch_group_label','recording_mode','amp_mode');

            N_epochs = length(ep_struct);
            prot_name_ind = zeros(N_epochs, 1);
            epoch_group_label = {ep_struct.epoch_group_label};
            epoch_recording_mode = {ep_struct.recording_mode};
            epoch_amp_mode = {ep_struct.amp_mode};
            epoch_time = ([ep_struct.epoch_start_time] - ep_struct(1).epoch_start_time) ./ 1E3; %to seconds  

            prot_name_list = unique({ep_struct.protocol_name});
            N_protocols = length(prot_name_list);
            for i=1:N_protocols
                prot_name_list{i} = sqlProtName2ProtName(prot_name_list{i});
            end
            
            condition_names = {};
            condition_ind = zeros(N_epochs, 1);
            z=1;
            for i=1:N_epochs
                prot_name = sqlProtName2ProtName(ep_struct(i).protocol_name);
                prot_name_ind(i) = find(strcmp(prot_name, prot_name_list));
                
                cur_name = sprintf('%s %s %s %s',...
                    prot_name,epoch_amp_mode{i},epoch_recording_mode{i},epoch_group_label{i});
                ind = find(strcmp(cur_name,condition_names));
                if isempty(ind)
                    condition_names{z} = cur_name;
                    tickA{z} = prot_name;
                    tickB{z} = epoch_amp_mode{i};
                    tickC{z} = epoch_recording_mode{i};
                    tickD{z} = epoch_group_label{i};
                    condition_ind(i) = z;
                    z=z+1;
                else
                    condition_ind(i) = ind;
                end
            end

            YTickA = 1:length(condition_names);
            YTickB = YTickA + .2;
            YTickC = YTickA + .4;
            YTick = [YTickA, YTickB, YTickC];
            tick = [tickA tickB tickC];

            if length(unique(tickD)) > 1
                YTickD = YTickA + .6;
                YTick = [YTickA, YTickB, YTickC, YTickD];
                tick = [tickA tickB tickC tickD];
            end

            [YTick, ind] = sort(YTick, 'ascend');
            tick = tick(ind);
    
            app.DiaryAxes.YTick = YTick;
            app.DiaryAxes.YTickLabel = tick;
            app.diary_y = condition_ind;
            if strcmp(app.XaxisDropDown.Value,'Time')
                app.diary_x = epoch_time;
                scatter(app.DiaryAxes,epoch_time,condition_ind, 'ko');
                xlabel(app.DiaryAxes,'Time (s)')
            else
                app.diary_x = app.epoch_ids;
                scatter(app.DiaryAxes,app.epoch_ids,condition_ind, 'ko');
                xlabel(app.DiaryAxes,'Epoch number')
            end
            ylim(app.DiaryAxes,[0 length(condition_names)+1]);
        end
        
        function getParamsForEpochs(app)
            if isempty(app.epoch_ids)
                return;
            end
            s = cell2struct(num2cell(app.epoch_ids),'epoch_id', 2);
            q = sln_symphony.ExperimentEpochBlock * ...
                aka.Epoch ...
                & app.loaded_cell & s;
            for i=1:length(s)
                prot_name = sqlProtName2ProtName(fetch1(q & s(i),'protocol_name'));                
                q_with_params = aka.BlockParams(prot_name) * aka.EpochParams(prot_name) ...
                    * sln_symphony.ExperimentElectrode * sln_symphony.ExperimentEpochGroup ...
                    * q & s(i);
                if i==1
                    param_list = q_with_params.header.notBlobs;
                else
                    param_list = union(param_list, q_with_params.header.notBlobs);
                end                 
                param_list = setdiff(param_list,app.params_to_remove);
                app.param_list = [' ', 'Time', 'Epoch number', param_list];
            end
            valsMap = containers.Map;
            all_prot_names = unique(fetchn(q & s,'protocol_name'));
            for i=1:length(all_prot_names)
                all_prot_names{i} = sqlProtName2ProtName(all_prot_names{i});
                q_with_params = aka.BlockParams(all_prot_names{i}) * aka.EpochParams(all_prot_names{i}) ...
                    * sln_symphony.ExperimentElectrode * sln_symphony.ExperimentEpochGroup ...
                    * q & s;
                for j=1:length(param_list)
                    try
                        vals = unique(fetchn(q_with_params, param_list{j}),'stable');
                        if valsMap.isKey(param_list{j})
                            valsMap(param_list{j}) = union(valsMap(param_list{j}), vals);
                        else
                            valsMap(param_list{j}) = vals;
                        end
                        v = valsMap(param_list{j});
                        valsMap(param_list{j}) = v(isfinite(v));
                    catch 
                        %skipping params not in this protocol
                    end
                end
            end
           app.valsMap = valsMap;

           app.fillMultiParamsTable();
        end
        
        function init_filter_table(app)
            Nrows = 7;
            
            D = {1,app.param_list{1},app.operators{1},' '};
            for i=2:Nrows
                D = [D; {i,app.param_list{1},app.operators{1},' '};];
            end
            app.FilterTable.Data = D;
            app.FilterTable.ColumnEditable = [false, true, true, true];
            app.FilterTable.ColumnFormat = {'numeric',app.param_list, app.operators,'char'};
        end
        
        function fillMultiParamsTable(app)
            allKeys = app.valsMap.keys;
            z=1;
            for i=1:app.valsMap.Count
                val = app.valsMap(allKeys{i});
                if isnumeric(val) && length(val)>1
                    app.MultiValTable.Data{z,1} = allKeys{i};
                    [r, c] = size(val);
                    if r>c
                        app.MultiValTable.Data{z,2}= num2str(val');
                    else
                        app.MultiValTable.Data{z,2}= num2str(val);
                    end
                    z=z+1;
                elseif iscell(val) && length(val)>1
                    app.MultiValTable.Data{z,1} = allKeys{i};
                    app.MultiValTable.Data{z,2}= char(join(val, ', '));
                    z=z+1;
                end                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, init_cellname)
            app.init()
            if ~isempty(init_cellname)
                app.loadCell(init_cellname);
            end
        end

        % Button pushed function: LoadcellButton
        function LoadcellButtonPushed(app, event)
            app.loadCell(app.WorkingcellEditField.Value);
        end

        % Value changed function: EpochnumberSlider
        function EpochnumberSliderValueChanged(app, event)
            value = app.EpochnumberSlider.Value;
            d = app.epoch_ids - value;
            [~, ind] = min(abs(d));
            app.epoch_selection_index = ind(1);
            app.loaded_epoch = aka.Epoch & app.loaded_cell & sprintf('epoch_id = %d', app.epoch_ids(ind(1))); 
            app.plotCurrentEpoch;
        end

        % Key press function: CuratorUIFigure
        function CuratorUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'rightarrow')
                app.incrementEpoch();
            elseif strcmp(key,'leftarrow')
                app.decrementEpoch();
            end
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            app.updateDiaryPlot();
        end

        % Button down function: DiaryAxes
        function DiaryAxesButtonDown(app, event)
            if strcmp(event.EventName,'Hit')
                xval = event.IntersectionPoint(1);
                d = abs(app.diary_x - xval);
                [~, ind] = min(d);
                app.EpochnumberSlider.Value = app.epoch_ids(ind(1));
                app.epoch_selection_index = ind(1);
                app.loaded_epoch = aka.Epoch & app.loaded_cell & sprintf('epoch_id = %d', app.epoch_ids(ind(1))); 
                app.updateDiaryPlot();
                app.plotCurrentEpoch();
            end
        end

        % Value changed function: DatachannelDropDown
        function DatachannelDropDownValueChanged(app, event)
            app.plotCurrentEpoch();
        end

        % Cell edit callback: FilterTable
        function FilterTableCellEdit(app, event)
            row = event.Indices(1);
            col = event.Indices(2);
            if col==2 %param column
                param = event.NewData;
                if app.valsMap.isKey(param)
                    if isnumeric(app.valsMap(param))
                        [r,c] = size(app.valsMap(param));
                        if r>c
                            app.FilterTable.Data{row,4} = num2str(app.valsMap(param)');
                        else
                            app.FilterTable.Data{row,4} = num2str(app.valsMap(param));
                        end
                    elseif iscell(app.valsMap(param))
                        app.FilterTable.Data{row,4} = char(join(app.valsMap(param), ', '));
                    else
                        app.FilterTable.Data{row,4} = char(app.valsMap(param));
                    end
                else
                    app.FilterTable.Data{row,4} = '';
                end

            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CuratorUIFigure = uifigure('Visible', 'off');
            app.CuratorUIFigure.Name = 'Curator';
            app.CuratorUIFigure.KeyPressFcn = createCallbackFcn(app, @CuratorUIFigureKeyPress, true);
            app.DiaryAxes = uiaxes(app.CuratorUIFigure);
            app.DiaryAxes.YDir = 'reverse';
            app.DiaryAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.DiaryAxes.ButtonDownFcn = createCallbackFcn(app, @DiaryAxesButtonDown, true);
            app.RawAxes = uiaxes(app.CuratorUIFigure);
            xlabel(app.RawAxes, 'Epoch time (s)')
            app.XaxisDropDownLabel = uilabel(app.CuratorUIFigure);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.CuratorUIFigure);
            app.XaxisDropDown.Items = {'Time', 'Epoch number'};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 'Time';
            app.DatachannelDropDownLabel = uilabel(app.CuratorUIFigure);
            app.DatachannelDropDownLabel.Text = 'Data channel';
            app.DatachannelDropDown = uidropdown(app.CuratorUIFigure);
            app.DatachannelDropDown.Items = {'Amp1', 'Amp2'};
            app.DatachannelDropDown.ValueChangedFcn = createCallbackFcn(app, @DatachannelDropDownValueChanged, true);
            app.DatachannelDropDown.Value = 'Amp1';
            app.SavedatasetButton = uibutton(app.CuratorUIFigure, 'push');
            app.SavedatasetButton.Text = 'Save dataset';
            app.RenamedatasetButton = uibutton(app.CuratorUIFigure, 'push');
            app.RenamedatasetButton.Text = 'Rename dataset';
            app.DeletedatasetButton = uibutton(app.CuratorUIFigure, 'push');
            app.DeletedatasetButton.Text = 'Delete dataset';
            app.DatasetsDropDownLabel = uilabel(app.CuratorUIFigure);
            app.DatasetsDropDownLabel.Text = 'Data sets';
            app.DatasetsDropDown = uidropdown(app.CuratorUIFigure);
            app.FilterpatternstringEditFieldLabel = uilabel(app.CuratorUIFigure);
            app.FilterpatternstringEditFieldLabel.Text = 'Filter pattern string';
            app.FilterpatternstringEditField = uieditfield(app.CuratorUIFigure, 'text');
            app.FilterTable = uitable(app.CuratorUIFigure);
            app.FilterTable.ColumnName = {'#'; 'Param'; 'Operator'; 'Value'};
            app.FilterTable.RowName = {};
            app.FilterTable.ColumnEditable = true;
            app.FilterTable.CellEditCallback = createCallbackFcn(app, @FilterTableCellEdit, true);
            app.MultiValTable = uitable(app.CuratorUIFigure);
            app.MultiValTable.ColumnName = {'Param'; 'Values'};
            app.MultiValTable.RowName = {};
            app.ParmeterswithmultiplevaluesLabel = uilabel(app.CuratorUIFigure);
            app.ParmeterswithmultiplevaluesLabel.Text = 'Parmeters with multiple values';
            app.SelectedValTable = uitable(app.CuratorUIFigure);
            app.SelectedValTable.ColumnName = {'Param'; 'Value'};
            app.SelectedValTable.RowName = {};
            app.ParametersforselectedepochLabel = uilabel(app.CuratorUIFigure);
            app.ParametersforselectedepochLabel.Text = 'Parameters for selected epoch';
            app.FilterconstructionLabel = uilabel(app.CuratorUIFigure);
            app.FilterconstructionLabel.Text = 'Filter construction';
            app.WorkingcellEditFieldLabel = uilabel(app.CuratorUIFigure);
            app.WorkingcellEditFieldLabel.Text = 'Working cell';
            app.WorkingcellEditField = uieditfield(app.CuratorUIFigure, 'text');
            app.LoadcellButton = uibutton(app.CuratorUIFigure, 'push');
            app.LoadcellButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcellButtonPushed, true);
            app.LoadcellButton.Text = 'Load cell';
            app.EpochnumberSliderLabel = uilabel(app.CuratorUIFigure);
            app.EpochnumberSliderLabel.Text = 'Epoch number';
            app.EpochnumberSlider = uislider(app.CuratorUIFigure);
            app.EpochnumberSlider.ValueChangedFcn = createCallbackFcn(app, @EpochnumberSliderValueChanged, true);
            app.EpochnumberSlider.MinorTicks = [];
            % Show the figure after all components are created
            app.CuratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Curator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CuratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CuratorUIFigure)
        end
    end
end