classdef DataGrouper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataGrouperUIFigure            matlab.ui.Figure
        SavepngButton                  matlab.ui.control.Button
        ToHDF5Button                   matlab.ui.control.Button
        TocommandlineButton            matlab.ui.control.Button
        SavefigButton                  matlab.ui.control.Button
        ResultsindatabasePanel         matlab.ui.container.Panel
        ResultsInDBListBox             matlab.ui.control.ListBox
        PlotsPanel                     matlab.ui.container.Panel
        StimCond_label_4               matlab.ui.control.Label
        StimCond_label_3               matlab.ui.control.Label
        StimCond_label_2               matlab.ui.control.Label
        StimCond_label_1               matlab.ui.control.Label
        StimCond_DD_4                  matlab.ui.control.DropDown
        StimCond_DD_3                  matlab.ui.control.DropDown
        StimCond_DD_2                  matlab.ui.control.DropDown
        StimCond_DD_1                  matlab.ui.control.DropDown
        PlotAreaPanel                  matlab.ui.container.Panel
        PlotAxes                       matlab.ui.control.UIAxes
        PlotterDropDown                matlab.ui.control.DropDown
        PlotterDropDownLabel           matlab.ui.control.Label
        ExportTypeSwitch               matlab.ui.control.RockerSwitch
        ExportSelectionButton          matlab.ui.control.Button
        AddUserParamButton             matlab.ui.control.Button
        RemoveUserParamButton          matlab.ui.control.Button
        AddconditionwithuserparamButton  matlab.ui.control.Button
        TypeEditField_2                matlab.ui.control.EditField
        TypeEditField_2Label           matlab.ui.control.Label
        DescriptionTextArea_2          matlab.ui.control.TextArea
        DescriptionTextAreaLabel_2     matlab.ui.control.Label
        UserParamsListBox              matlab.ui.control.ListBox
        UserparametersDropDown         matlab.ui.control.DropDown
        UserparametersDropDownLabel    matlab.ui.control.Label
        ParamsTablesDropDown           matlab.ui.control.DropDown
        ProtocolparametersLabel        matlab.ui.control.Label
        IncluderawtracesCheckBox       matlab.ui.control.CheckBox
        ExportFullDataGroupButton      matlab.ui.control.Button
        BrowserPanel                   matlab.ui.container.Panel
        OnlinelabelLabel               matlab.ui.control.Label
        OnlinetypeEditField            matlab.ui.control.EditField
        LookforsavedresultsCheckBox    matlab.ui.control.CheckBox
        AnalysisRawDataSwitch          matlab.ui.control.Switch
        AnalysisDataSwitch             matlab.ui.control.Switch
        AnalysisStateSwitch            matlab.ui.control.Switch
        ReplaceResultsButtonGroup      matlab.ui.container.ButtonGroup
        AllButton                      matlab.ui.control.RadioButton
        NoneButton                     matlab.ui.control.RadioButton
        CellTypeDropDown               matlab.ui.control.DropDown
        CellClassDropDown              matlab.ui.control.DropDown
        AssigncelltypeButton           matlab.ui.control.Button
        UserParamValueEdit             matlab.ui.control.EditField
        NewuserparameterPanel          matlab.ui.container.Panel
        UserParamDescriptionEditField  matlab.ui.control.EditField
        DescriptionEditFieldLabel      matlab.ui.control.Label
        UserParamTableDropDown         matlab.ui.control.DropDown
        UserParamOptionsTextArea       matlab.ui.control.TextArea
        CreateUserParamButton          matlab.ui.control.Button
        UserParamNameEdit              matlab.ui.control.EditField
        NameLabel                      matlab.ui.control.Label
        UserParamTypeDropDown          matlab.ui.control.DropDown
        TypeLabel                      matlab.ui.control.Label
        UserParamValDropDown           matlab.ui.control.DropDown
        AnalysisNameEdit               matlab.ui.control.EditField
        UserParamNameDropDown          matlab.ui.control.DropDown
        ChooseanalysisButton           matlab.ui.control.Button
        AdduserparameterButton         matlab.ui.control.Button
        RunanalysisButton              matlab.ui.control.Button
        DataElementsListBox            matlab.ui.control.ListBox
        NEditField                     matlab.ui.control.NumericEditField
        NLabel                         matlab.ui.control.Label
        ViewlevelDropDown              matlab.ui.control.DropDown
        ViewlevelDropDownLabel         matlab.ui.control.Label
        DescriptionTextArea            matlab.ui.control.TextArea
        DescriptionTextAreaLabel       matlab.ui.control.Label
        ResultsPanel                   matlab.ui.container.Panel
        LoadqueryButton                matlab.ui.control.Button
        experimentsEditField           matlab.ui.control.EditField
        experimentsEditFieldLabel      matlab.ui.control.Label
        epochsEditField                matlab.ui.control.EditField
        epochsLabel                    matlab.ui.control.Label
        SavequeryButton                matlab.ui.control.Button
        datasetsEditField              matlab.ui.control.EditField
        datasetsEditField_3Label       matlab.ui.control.Label
        eyesEditField                  matlab.ui.control.EditField
        eyesEditFieldLabel             matlab.ui.control.Label
        cellsEditField                 matlab.ui.control.EditField
        cellsEditField_2Label          matlab.ui.control.Label
        animalsEditField               matlab.ui.control.EditField
        animalsEditFieldLabel          matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        TypeEditField                  matlab.ui.control.EditField
        TypeEditFieldLabel             matlab.ui.control.Label
        LogicaloperatorsPanel          matlab.ui.container.Panel
        clearButton                    matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        ANDButton                      matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        ANYOFButton                    matlab.ui.control.Button
        NOTButton                      matlab.ui.control.Button
        DeletelastelementButton        matlab.ui.control.Button
        AddconditionwithattributeButton  matlab.ui.control.Button
        QueryconstructionPanel         matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        LogicButton_20                 matlab.ui.control.StateButton
        LogicButton_18                 matlab.ui.control.StateButton
        LogicButton_16                 matlab.ui.control.StateButton
        LogicButton_14                 matlab.ui.control.StateButton
        LogicButton_12                 matlab.ui.control.StateButton
        LogicButton_10                 matlab.ui.control.StateButton
        LogicButton_8                  matlab.ui.control.StateButton
        LogicButton_6                  matlab.ui.control.StateButton
        LogicButton_4                  matlab.ui.control.StateButton
        LogicButton_2                  matlab.ui.control.StateButton
        OpDrop_21                      matlab.ui.control.DropDown
        OpDrop_19                      matlab.ui.control.DropDown
        OpDrop_17                      matlab.ui.control.DropDown
        OpDrop_15                      matlab.ui.control.DropDown
        OpDrop_13                      matlab.ui.control.DropDown
        OpDrop_11                      matlab.ui.control.DropDown
        OpDrop_9                       matlab.ui.control.DropDown
        OpDrop_7                       matlab.ui.control.DropDown
        OpDrop_5                       matlab.ui.control.DropDown
        OpDrop_3                       matlab.ui.control.DropDown
        OpDrop_1                       matlab.ui.control.DropDown
        EditField_Q21                  matlab.ui.control.EditField
        EditField_Q19                  matlab.ui.control.EditField
        EditField_Q17                  matlab.ui.control.EditField
        EditField_Q15                  matlab.ui.control.EditField
        EditField_Q13                  matlab.ui.control.EditField
        EditField_Q11                  matlab.ui.control.EditField
        EditField_Q9                   matlab.ui.control.EditField
        EditField_Q7                   matlab.ui.control.EditField
        EditField_Q5                   matlab.ui.control.EditField
        EditField_Q3                   matlab.ui.control.EditField
        EditField_Q1                   matlab.ui.control.EditField
        AttributesListBox              matlab.ui.control.ListBox
        AttributesListBoxLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        searchTable % joined table for running the query
        connection % DJ connnection
        queryParts % cell array of items in the query
        rowInd = 1; % current row for entering stuff into the GUI panel on the right
        queryEntryObjects % list of handles to the ui elements in column 3
        queryState % full state of the query
        queryResult % result of last run query
        lookup_attributes = {... % attributes for which we need to use a table lookup and the corresponding tables and props
            'rig_name', 'sln_lab.Rig', 'rig_name'; ...
            'experimenter', 'sln_lab.User', 'user_name'; ...
            }
        remove_attributes = { ... %attributes we don't want to search
            'epoch_block_id', ...
            'epoch_group_id', ...
            'retina_id', ...
            'source_id', ...
            'source_id_retina', ...
            };

        %queries for currently queried data elements (set in "run")
        experiments
        animals
        eyes
        cells
        datasets
        epochs
        selectedUserParamType %menu based on menu selection, needed for adding parameter
        data_selection_ind = [];% logical indices of selected data elements
        data_order = []; %sorted order of data elements
        genotype_strings % table of all genotype strings
        selected_result_entry
    end

    methods (Access = private)

        function updateSearchTable(app)
            app.searchTable = sln_symphony.Experiment ...
                * proj(sln_symphony.ExperimentRetina,'*','source_id->source_id_retina') ...
                * sln_symphony.Dataset ...
                * aka.Cell ...
                * sln_symphony.ExperimentEpochBlock ...
                * sln_symphony.ExperimentChannel ...
                * sln_symphony.ExperimentElectrode ...
                * sln_animal.Eye ...
                * sln_cell.Cell ...
                * sln_cell.AssignType.current() ...
                * sln_symphony.DatasetEpoch;

            % proj(sln_animal.Animal,'source_id->animal_source_id') ...
            %                * sln_animal.GenotypeString ...
            %                 * sln_symphony.SpikeTrain ...     
            %                * sln_symphony.ExperimentLEDSettings ...
            %    * sln_symphony.ExperimentProjectorSettings ...

            %join protocol parameters
            param_table_name = get(app.ParamsTablesDropDown,'value');
            if ~strcmp(param_table_name,'None')
                epoch_table = eval(sprintf('sln_symphony.ExperimentProtocol%sEpochParameters',param_table_name));
                block_table = eval(sprintf('sln_symphony.ExperimentProtocol%sBlockParameters',param_table_name));
                app.searchTable = app.searchTable * epoch_table * block_table;
            end

            %join user parameters
            user_param_table_name = get(app.UserParamsListBox,'items');
            N = length(user_param_table_name);
            for i=1:N
                app.searchTable = app.searchTable * eval(sprintf('sln_symphony.UserParam%s',user_param_table_name{i}));
            end

            attr = sort(app.searchTable.header.notBlobs);
            attr = setdiff(attr,app.remove_attributes);

            set(app.AttributesListBox, 'items', attr);
            %keyboard;
        end

        function entryNuller(app, event)
            value = event.Value;
            [~, rowInd] = strtok(event.Source.Tag,'_');
            rowInd = str2double(rowInd(2:end));
            h = app.queryEntryObjects(rowInd);
            if strcmp(value, 'is NULL')
                set(h,'visible',false);
            else
                set(h,'visible',true);
            end

        end

        function unselectOtherLogicButtons(app,event)
            [~, rowInd] = strtok(event.Source.Tag,'_');
            rowInd = str2double(rowInd(2:end));
            deactivateList = setdiff(2:2:20, rowInd);
            for i=1:length(deactivateList)
                set(app.(['LogicButton_' num2str(deactivateList(i))]), 'value', false);
            end
        end

        function name = getSelectedLogicButtonName(app)
            name = '';
            ind = [];
            for i=2:2:20
                if get(app.(['LogicButton_' num2str(i)]), 'value')
                    ind = [ind i];
                end
            end

            if length(ind) == 1
                name = ['LogicButton_' num2str(ind)];
            end
        end

        function setLogicButton(app, event)
            txt = get(event.Source, 'text');
            selectedButtonName = app.getSelectedLogicButtonName;
            if ~isempty(selectedButtonName)
                curTxt = get(app.(selectedButtonName),'text');
                set(app.(selectedButtonName),'text', [curTxt, ' ', txt]);
            end
        end

        function storeQueryState(app)
            colInd = 1:2:21;
            attributeColumn = struct('name','', 'type', '');
            operatorColumn = struct('items', {}, 'value', '');
            queryEntryColumn = struct('type','','visible', 1 ,'items', {}, 'value', '');
            logicButtonColumn = struct('visible', 0, 'text', '');

            allAttributeNames = {app.searchTable.header.attributes.name};

            for i=colInd
                if get(app.(['EditField_Q' num2str(i)]), 'visible')
                    attributeColumn(i).name = get(app.(['EditField_Q' num2str(i)]), 'value');
                    ind = find(strcmp(allAttributeNames,attributeColumn(i).name));
                    attributeColumn(i).type = app.searchTable.header.attributes(ind).type;
                    operatorColumn(i).items = get(app.(['OpDrop_' num2str(i)]), 'items');
                    operatorColumn(i).value = get(app.(['OpDrop_' num2str(i)]), 'value');
                    uiElement = app.queryEntryObjects(i);
                    queryEntryColumn(i).visible = double(get(uiElement, 'visible'));
                    queryEntryColumn(i).type = get(uiElement, 'type');
                    queryEntryColumn(i).value = get(uiElement, 'value');
                    if strcmp(queryEntryColumn(i).type, 'uidropdown')
                        queryEntryColumn(i).items = get(uiElement, 'items');
                    end
                end
            end

            colInd = 2:2:20;
            for i=colInd
                if get(app.(['LogicButton_' num2str(i)]), 'visible')
                    logicButtonColumn(i).visible = 1;
                    logicButtonColumn(i).text = get(app.(['LogicButton_' num2str(i)]), 'text');
                end
            end

            queryState.rowInd = app.rowInd;
            queryState.attributeColumn = attributeColumn;
            queryState.operatorColumn = operatorColumn;
            queryState.queryEntryColumn = queryEntryColumn;
            queryState.logicButtonColumn = logicButtonColumn;
            queryState.searchTable = app.searchTable;
            queryState.selectedParamTable = get(app.ParamsTablesDropDown,'value');

            app.queryState = queryState;
        end

        function loadQueryState(app,queryState)
            app.rowInd = queryState.rowInd;
            app.searchTable = queryState.searchTable;
            set(app.ParamsTablesDropDown,'value', queryState.selectedParamTable);

            colInd = 1:2:length(queryState.attributeColumn);
            for i=colInd
                if ~isempty(queryState.attributeColumn(i).name)
                    set(app.(['EditField_Q' num2str(i)]), 'visible', true)
                    set(app.(['EditField_Q' num2str(i)]), 'value', queryState.attributeColumn(i).name);
                    set(app.(['OpDrop_' num2str(i)]), 'visible', true);
                    set(app.(['OpDrop_' num2str(i)]), 'items', queryState.operatorColumn(i).items);
                    set(app.(['OpDrop_' num2str(i)]), 'value', queryState.operatorColumn(i).value);
                    switch queryState.queryEntryColumn(i).type
                        case 'uieditfield'
                            app.queryEntryObjects(i) = uieditfield(app.GridLayout,'text');
                        case 'uinumericeditfield'
                            app.queryEntryObjects(i) = uieditfield(app.GridLayout,'numeric');
                        case 'uidropdown'
                            app.queryEntryObjects(i) = uidropdown(app.GridLayout);
                            set(app.queryEntryObjects(i),'items',queryState.queryEntryColumn(i).items);
                        case 'uidatepicker'
                            app.queryEntryObjects(i) = uidatepicker(app.GridLayout,'DisplayFormat','yyyy-MM-dd');
                    end
                    layoutObj = get(app.queryEntryObjects(i), 'Layout');
                    layoutObj.Column = 3;
                    layoutObj.Row = i;
                    set(app.queryEntryObjects(i), 'Layout', layoutObj)
                    set(app.queryEntryObjects(i), 'value', queryState.queryEntryColumn(i).value)
                    set(app.queryEntryObjects(i), 'visible', queryState.queryEntryColumn(i).visible);
                else
                    set(app.(['EditField_Q' num2str(i)]), 'visible', false)
                    set(app.(['OpDrop_' num2str(i)]), 'visible', false);
                end
            end

            colInd = 2:2:length(queryState.logicButtonColumn);
            for i=colInd
                if queryState.logicButtonColumn(i).visible
                    set(app.(['LogicButton_' num2str(i)]), 'visible', true);
                    set(app.(['LogicButton_' num2str(i)]), 'text', queryState.logicButtonColumn(i).text);
                else
                    set(app.(['LogicButton_' num2str(i)]), 'visible', false);
                end
            end

        end

        function updateDataElmentsList(app)
            switch get(app.ViewlevelDropDown,'value')
                case 'Experiment'
                    elementList = fetchn(app.experiments,'file_name');
                case 'Animal'
                    animal_list = cellstr(num2str(fetchn(app.animals,'animal_id')));
                    N = length(animal_list);
                    elementList = cell(N,1);
                    for i=1:N 
                        cur_genotype = fetch1(app.genotype_strings & sprintf('animal_id=%s',animal_list{i}), 'genotype_string');
                        elementList{i} = sprintf('%s: %s', animal_list{i}, cur_genotype);
                        %elementList{i} = sprintf('%s', animal_list{i});
                    end                    
                case 'Eye' %TODO;
                    eye_struct = fetch(app.eyes,'*');
                    N = length(eye_struct);
                    elementList = cell(N,1);
                    for i=1:N 
                        cur_genotype = fetch1(app.genotype_strings & sprintf('animal_id=%d',eye_struct(i).animal_id), 'genotype_string');
                        elementList{i} = sprintf('%d:%s, %s', eye_struct(i).animal_id, cur_genotype, eye_struct(i).side);
                        %elementList{i} = sprintf('%d: %s', eye_struct(i).animal_id, eye_struct(i).side);
                    end   
                case 'Cell pair' %TODO, cell pair and eye levels
                    %elementList = fetchn(aka.Cell & proj(app.queryResult),'cell_name');
                case 'Cell'
                    cell_num_List = cellstr(num2str(fetchn(app.cells,'cell_number')));
                    fname_list = fetchn(app.cells,'file_name');
                    cells_struct = fetch(app.cells);
                   
                    N = length(cell_num_List);
                    cell_list = cell(N,1);
                    for i=1:N                            
                        cur_type_event =  sln_cell.AssignType.current * sln_cell.Cell * sln_symphony.ExperimentCell & cells_struct(i);
                        if cur_type_event.exists
                            cur_type = fetch1(cur_type_event,'cell_type');
                        else
                            cur_type = '';
                        end
                        cell_list{i} = sprintf('%sc%s:%s',fname_list{i},cell_num_List{i},cur_type);
                    end
                    elementList = cell_list;
                case 'Dataset'
                    ds_query = sln_symphony.Dataset * sln_symphony.ExperimentCell & app.datasets;
                    ds_struct = fetch(ds_query, 'dataset_name', 'cell_number', 'file_name');%slow fetch!
                    ds_name_List = {ds_struct.dataset_name};
                    cell_num_List = [ds_struct.cell_number];
                    fname_list = {ds_struct.file_name};
                    N = length(ds_name_List);
                    ds_list = cell(N,1);
                    for i=1:N
                        ds_list{i} = sprintf('%sc%d: %s',fname_list{i},cell_num_List(i), ds_name_List{i});
                    end
                    elementList = ds_list;
                case 'Epoch'
                    ds_name_List = fetchn(app.queryResult,'dataset_name');
                    cell_num_List = cellstr(num2str(fetchn(app.queryResult,'cell_number')));
                    fname_list = fetchn(app.queryResult,'file_name');
                    ep_num_list = fetchn(app.queryResult,'epoch_id');
                    N = length(ds_name_List);
                    ep_list = cell(N,1);
                    for i=1:N
                        ep_list{i} = sprintf('%sc%s: %s: e%d',fname_list{i},cell_num_List{i}, ds_name_List{i}, ep_num_list(i));
                    end
                    elementList = ep_list;
            end

            N = length(elementList);
            [elementList_sorted,app.data_order] = sort(elementList);
            set(app.DataElementsListBox,'items',elementList_sorted);
            set(app.NEditField,'value',N);
            
            %set selection to false for all
            app.data_selection_ind = false(N,1);

            app.UserParamNameDropDownValueChanged([]);
            app.DataElementsListBoxValueChanged([]);
        end

        function initParamsDropDown(app)
            schema = sln_symphony.getSchema;
            table_names = schema.classNames;
            table_names = table_names(contains(table_names,'sln_symphony.ExperimentProtocol') & contains(table_names,'EpochParameters'));
            N = length(table_names);
            items = cell(N,1);
            for i=1:N
                temp = extractBetween(table_names{i},'ExperimentProtocol','EpochParameters');
                items{i} = temp{1};
            end
            set(app.ParamsTablesDropDown,'items',['None'; items]);
        end

        function loadUserParamsTables(app)
            schema = sln_symphony.getSchema;
            table_names = schema.classNames;
            table_names = table_names(contains(table_names,'sln_symphony.UserParam'));
            N = length(table_names);
            items = cell(N,1);
            for i=1:N
                items{i} = extractAfter(table_names{i},'UserParam');
            end
            set(app.UserparametersDropDown,'items',['None'; items]);
        end

        function loadUserParamsTablesByLevel(app)
            %only put the ones for the correct view level
            schema = sln_symphony.getSchema;
            table_names = schema.classNames;
            table_names = table_names(contains(table_names,['sln_symphony.UserParam' get(app.ViewlevelDropDown,'value')]));
            N = length(table_names);
            items = cell(N,1);
            for i=1:N
                items{i} = extractAfter(table_names{i},'UserParam');
            end
            set(app.UserParamNameDropDown,'items',items);
        end

        function result_struct_selected = fetchDataSelection(app)
            switch get(app.ViewlevelDropDown,'value')
                case 'Experiment'
                    result_struct = fetch(app.experiments);
                case 'Animal'
                    result_struct = fetch(app.animals);
                case 'Eye'
                    result_struct = fetch(app.eyes);
                case 'Cell pair' %todo
                case 'Cell'
                    result_struct = fetch(app.cells);
                case 'Dataset'
                    result_struct = fetch(app.datasets);
                case 'Epoch'
                    result_struct = fetch(app.epochs);
            end

            result_struct = result_struct(app.data_order);
            result_struct_selected = result_struct(app.data_selection_ind);
        end

        function loadCellClassMenu(app)
            set(app.CellClassDropDown, 'items', unique(fetchn(sln_cell.CellType,'cell_class')));          
            app.loadCellTypeMenu();
        end

        function loadCellTypeMenu(app)
            selectedClass = get(app.CellClassDropDown,'value');
            items = sort(unique(fetchn(sln_cell.CellType & sprintf('cell_class="%s"',selectedClass),'cell_type')));
            set(app.CellTypeDropDown, 'items', items);                  
        end
        
%         function loadPlottersByLevel(app)
%             %level = get(app.ViewlevelDropDown,'value');
%             plotters_base_folder = getenv('User_plotters_folder');
%             if ~isempty(plotters_base_folder)
%                 item_list = app.m_files_in_folder(plotters_base_folder);
%                 item_list = ['None', item_list];
%             else
%                 item_list = {'None'};
%             end
%             set(app.PlotterDropDown,'items',item_list);
%         end
        
        function items = m_files_in_folder(app, folder_name)
            D = dir(folder_name);
            items = {};
            z=1;
            for i=1:length(D)
                curName = D(i).name;
                if endsWith(curName,'.m')
                    items{z} = extractBefore(curName,'.m');
                    z=z+1;
                end
            end
        end

        function load_results_for_cell_selection(app)
            cell_selected = app.fetchDataSelection;
            if isempty(cell_selected)
                return;
            end
            results_schema = sln_results.getSchema;
            result_classes = results_schema.classNames;
            result_types = {};

            %Cell level
            ind = find(contains(result_classes, '.Cell'));            
            for i=1:length(ind)
                q = eval(result_classes{ind(i)}) & cell_selected;
                if q.exists
                    n_sets = q.count;
                    if n_sets>1
                        result_types = [result_types, sprintf('%s (%d)', extractAfter(result_classes{ind(i)}, '.'), n_sets)];
                    else
                        result_types = [result_types, extractAfter(result_classes{ind(i)}, '.')];
                    end
                end
            end

            %Dataset level 
            ind = find(contains(result_classes, '.Dataset'));            
            for i=1:length(ind)
                q = eval(result_classes{ind(i)}) & cell_selected;
                if q.exists
                    n_sets = q.count;
                    if n_sets>1
                        result_types = [result_types, sprintf('%s (%d)', extractAfter(result_classes{ind(i)}, '.'), n_sets)];
                    else
                        result_types = [result_types, extractAfter(result_classes{ind(i)}, '.')];
                    end
                end
            end
            set(app.ResultsInDBListBox,'items',result_types);
        end
        
        function load_results_for_data_selection(app)
            struct_selected = app.fetchDataSelection;
            if isempty(struct_selected)
                return;
            end
            view_level = get(app.ViewlevelDropDown,'value');
            results_schema = sln_results.getSchema;
            result_classes = results_schema.classNames;
            ind = find(contains(result_classes, ['.' view_level]));
            result_types = {};
            for i=1:length(ind)
                q = eval(result_classes{ind(i)}) & struct_selected;
                if q.exists
                    result_types = [result_types, extractAfter(result_classes{ind(i)}, '.')];
                end
            end
            %result_types
            set(app.ResultsInDBListBox,'items',result_types);
        end

        function run_plotter(app, R)
            N_menus = 4;
            if isempty(R)
                app.clear_plots();
                set(app.PlotAxes,'visible',false);
                for i=1:N_menus
                    label_name = sprintf('StimCond_label_%d', i);
                    menu_name = sprintf('StimCond_DD_%d', i);
                    set(app.(label_name), 'visible', false);
                    set(app.(menu_name), 'visible', false);
                end
                return;
            end

            plotFunc = get(app.PlotterDropDown,'value');
            if strcmp(plotFunc,'None')
                app.clear_plots();
                set(app.PlotAxes,'visible',false);
                for i=1:N_menus
                    label_name = sprintf('StimCond_label_%d', i);
                    menu_name = sprintf('StimCond_DD_%d', i);
                    set(app.(label_name), 'visible', false);
                    set(app.(menu_name), 'visible', false);
                end
            elseif contains(plotFunc,'_MultiCondition') %populate stim condition menus           
                try
                    stim_conditions = R.stim_condition_list;
                catch 
                    disp('No stim condition list found');
                    return;
                end
                N_menus = length(stim_conditions);
                for i=1:N_menus
                    label_name = sprintf('StimCond_label_%d', i);
                    menu_name = sprintf('StimCond_DD_%d', i);

                    stim_var_name = sprintf('%ss',stim_conditions{i});
                    stim_values = R.(stim_var_name);
                    if length(stim_values)>1
                        items = ['plot all'; cellstr(num2str(stim_values))];
                    else
                        items = cellstr(num2str(stim_values));
                    end
                                        
                    set(app.(label_name), 'text', stim_conditions{i});
                    set(app.(menu_name), 'items', items);

                    set(app.(label_name), 'visible', true);
                    set(app.(menu_name), 'visible', true);
                end
                app.run_multi_condition_plotter(R);                
            else
                for i=1:N_menus
                    label_name = sprintf('StimCond_label_%d', i);
                    menu_name = sprintf('StimCond_DD_%d', i);
                    set(app.(label_name), 'visible', false);
                    set(app.(menu_name), 'visible', false);
                end

                try
                    app.clear_plots();
                    eval(sprintf('%s(R,app.PlotAxes);', plotFunc));
                    set(app.PlotAxes,'visible',true);
                catch ME
                    disp('plot failure');
                    disp(ME.message);
                end
            end
        end
        
        function run_multi_condition_plotter(app, R)
            plotFunc = get(app.PlotterDropDown,'value');
            stim_conditions = R.stim_condition_list;
            N_menus = length(stim_conditions);
            condition_struct = struct;

            for i=1:N_menus
                label_name = sprintf('StimCond_label_%d', i);
                menu_name = sprintf('StimCond_DD_%d', i);

                condition_struct.(stim_conditions{i}) = strtrim(get(app.(menu_name), 'value'));
            end
            %condition_struct
            try
                app.clear_plots();
                eval(sprintf('%s(R,condition_struct,app.PlotAxes);', plotFunc));
                set(app.PlotAxes,'visible',true);
            catch ME
                disp('plot failure');
                disp(ME.message);
            end
        end
        
        function clear_plots(app)
            delete(get(app.PlotAreaPanel,'children'));
            app.PlotAxes = uiaxes(app.PlotAreaPanel);
            app.PlotAxes.XTick = [];
            app.PlotAxes.YTick = [];
            app.PlotAxes.FontUnits = 'points';
            app.PlotAxes.FontSize = 12;
            app.PlotAxes.Visible = 'off';
            app.PlotAxes.Units = 'normalized';
            app.PlotAxes.Position = [.05 .05 .9 .9];
        end
        
        function populate_plotter_menu(app, R)
            items = {'None'};
            if ~isempty(R)
                fields_in_R = fieldnames(R);
                plotters_base_folder = getenv('User_plotters_folder');
                plotter_list = app.m_files_in_folder(plotters_base_folder);

                for i=1:length(plotter_list)
                    required_fields = eval(sprintf('%s();',plotter_list{i}));
                    if all(arrayfun(@(pat)(any(strcmp(fields_in_R,pat))),required_fields))
                        items = [items, plotter_list{i}];
                    end
                end
            end
            set(app.PlotterDropDown,'items',items);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %load parameter table list
            app.initParamsDropDown();

            %make initial joined table object
            app.updateSearchTable();

            %set UserParam elements to invisible to start
            set(app.UserParamOptionsTextArea,'visible',false);
            set(app.UserParamTableDropDown,'visible',false);

            %lod user params tables dropdown
            app.loadUserParamsTables();
            app.loadUserParamsTablesByLevel();

            set(app.UserParamValueEdit,'visible',false);
            set(app.UserParamValDropDown,'visible',false);

            set(app.AssigncelltypeButton,'visible',false);
            set(app.CellClassDropDown,'visible',false);
            set(app.CellTypeDropDown,'visible',false);

            app.loadCellClassMenu();

            app.genotype_strings = proj(...
                aggr(sln_animal.Animal, ... %final result will have 1 entry per animal
                proj(...
                aggr((sln_animal.Animal * sln_animal.GeneLocus) & sln_animal.Genotype,... %intermediate result has 1 entry per animal/locus
                sln_animal.Genotype,... % start with 1 entry per animal/allele
                'group_concat(allele_name separator "/")->temp1'... %merge alleles at same locus with a "/"
                ),'IF(temp1 LIKE "%/%",concat(locus_name, "(",temp1,")"),concat(locus_name, "(", temp1, "/?)"))->temp2'... %add the locus and a trailing "/?" if only one known allele
                ),'group_concat(temp2 separator ",")->genotype_string',... %join the loci with a comma
                'strain_name'),...
                'IF(genotype_string is null, IF(strain_name="WT", "WT", "?"), genotype_string) -> genotype_string'); %for WT animals, fill in with "WT"; for ungenotyped animals, fill in with "?";
        end

        % Callback function
        function RemoveButtonPushed(app, event)
            selectedTables = get(app.TablestoJoinListBox,'value');
            currentTables = get(app.TablestoJoinListBox,'items');
            if strcmp(selectedTables, 'Dataset')
                disp('Dataset cannot be removed');
            else
                set(app.TablestoJoinListBox, 'items', setdiff(currentTables, selectedTables));
            end
        end

        % Callback function
        function AddButtonPushed(app, event)
            selectedTable = get(app.ParamsTablesDropDown, 'value');
            currentTables = get(app.TablestoJoinListBox,'items');
            if ~ismember(selectedTable, currentTables)
                set(app.TablestoJoinListBox, 'items', [currentTables, selectedTable]);
                app.updateSearchTable();
            end
        end

        % Close request function: DataGrouperUIFigure
        function DataGrouperUIFigureCloseRequest(app, event)
            if ~isempty(app.connection) && app.connection.isConnected
                app.connection.close();
            end
            delete(app)
        end

        % Value changed function: AttributesListBox
        function AttributesListBoxValueChanged(app, event)
            value = app.AttributesListBox.Value;
            allAttributeNames = {app.searchTable.header.attributes.name};
            ind = find(strcmp(allAttributeNames,value));
            set(app.DescriptionTextArea,'value',app.searchTable.header.attributes(ind).comment);
            set(app.TypeEditField,'value',app.searchTable.header.attributes(ind).type);
        end

        % Button pushed function: AddconditionwithattributeButton
        function AddconditionwithattributeButtonPushed(app, event)
            value = app.AttributesListBox.Value;
            allAttributeNames = {app.searchTable.header.attributes.name};
            ind = find(strcmp(allAttributeNames,value));
            attributeStruct = app.searchTable.header.attributes(ind);

            lookup_ind = find(strcmp(value,app.lookup_attributes(:,1)));

            %test special case of starting with logical value
            if ~get(app.EditField_Q1,'visible') && ~get(app.EditField_Q3,'visible')
                firstLogicButtonText = strtrim(get(app.LogicButton_2, 'text'));
                if strcmp(firstLogicButtonText, 'NOT') || startsWith(firstLogicButtonText, 'ANY OF')
                    app.rowInd = app.rowInd + 2;
                end
            end

            set(app.(['EditField_Q' num2str(app.rowInd)]), 'value', attributeStruct.name);
            set(app.(['EditField_Q' num2str(app.rowInd)]), 'visible', true);
            if app.rowInd>1
                set(app.(['LogicButton_' num2str(app.rowInd-1)]), 'visible', true);
                if app.rowInd<20
                    set(app.(['LogicButton_' num2str(app.rowInd+1)]), 'visible', true);
                    set(app.(['LogicButton_' num2str(app.rowInd+1)]), 'text', '');
                end
                if isempty(get(app.(['LogicButton_' num2str(app.rowInd-1)]), 'text'))
                    set(app.(['LogicButton_' num2str(app.rowInd-1)]), 'text', 'AND');
                end
            end

            opField = ['OpDrop_' num2str(app.rowInd)];

            if ~isempty(lookup_ind) %special attribute that requires a lookup
                opList = {'=','!=','is NULL'};
                entryType = 'dropdown';
                entryList = unique(fetchn(eval(app.lookup_attributes{lookup_ind,2}), app.lookup_attributes{lookup_ind,3}));
            elseif startsWith(attributeStruct.type, 'enum')
                opList = {'=','!=','is NULL'};
                entryType = 'dropdown';
                entryList = attributeStruct.type(5:end); %remove 'enum'
                entryList = strrep(entryList, '(', '{');
                entryList = strrep(entryList, ')', '}');
                entryList = eval(entryList);
            elseif strcmp(attributeStruct.type, 'date')
                opList = {'=', '>', '<', '>=', '<=', '!=', 'is NULL'};
                entryType = 'datePicker';
            elseif attributeStruct.isNumeric
                opList = {'=', '>', '<', '>=', '<=', '!=', 'is NULL'};
                entryType = 'numEdit';
            elseif attributeStruct.isString
                opList = {'is', 'is like', 'is not', 'is NULL'};
                entryType = 'strEdit';
            end

            set(app.(opField), 'items', opList);
            set(app.(opField), 'visible', true);

            switch entryType
                case 'dropdown'
                    app.queryEntryObjects(app.rowInd) = uidropdown(app.GridLayout);
                    set(app.queryEntryObjects(app.rowInd),'items',sort(entryList));
                case 'numEdit'
                    app.queryEntryObjects(app.rowInd) = uieditfield(app.GridLayout,'numeric');
                case 'strEdit'
                    app.queryEntryObjects(app.rowInd) = uieditfield(app.GridLayout,'text');
                case 'datePicker'
                    app.queryEntryObjects(app.rowInd) = uidatepicker(app.GridLayout,'DisplayFormat','yyyy-MM-dd');
            end
            layoutObj = get(app.queryEntryObjects(app.rowInd), 'Layout');
            layoutObj.Column = 3;
            layoutObj.Row = app.rowInd;
            set(app.queryEntryObjects(app.rowInd), 'Layout', layoutObj)

            app.rowInd = app.rowInd + 2;

        end

        % Callback function
        function TablestoJoinListBoxValueChanged(app, event)
            app.updateSearchTable();
        end

        % Value changed function: OpDrop_1
        function OpDrop_1ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_3
        function OpDrop_3ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_5
        function OpDrop_5ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_7
        function OpDrop_7ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_9
        function OpDrop_9ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_11
        function OpDrop_11ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_13
        function OpDrop_13ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_15
        function OpDrop_15ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_17
        function OpDrop_17ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_19
        function OpDrop_19ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_21
        function OpDrop_21ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Button pushed function: DeletelastelementButton
        function DeletelastelementButtonPushed(app, event)
            if app.rowInd > 2
                app.rowInd = app.rowInd - 2;
                if app.rowInd > 2
                    set(app.(['LogicButton_' num2str(app.rowInd+1)]), 'visible', false);
                end
                set(app.(['EditField_Q' num2str(app.rowInd)]), 'visible', false);
                set(app.(['OpDrop_' num2str(app.rowInd)]), 'visible', false);
                try
                    set(app.queryEntryObjects(app.rowInd),'visible',false);
                end
            end
        end

        % Value changed function: LogicButton_2
        function LogicButton_2ValueChanged(app, event)
            value = app.LogicButton_2.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_4
        function LogicButton_4ValueChanged(app, event)
            value = app.LogicButton_4.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_6
        function LogicButton_6ValueChanged(app, event)
            value = app.LogicButton_6.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_8
        function LogicButton_8ValueChanged(app, event)
            value = app.LogicButton_8.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_10
        function LogicButton_10ValueChanged(app, event)
            value = app.LogicButton_10.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_12
        function LogicButton_12ValueChanged(app, event)
            value = app.LogicButton_12.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_14
        function LogicButton_14ValueChanged(app, event)
            value = app.LogicButton_14.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_16
        function LogicButton_16ValueChanged(app, event)
            value = app.LogicButton_16.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_18
        function LogicButton_18ValueChanged(app, event)
            value = app.LogicButton_18.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_20
        function LogicButton_20ValueChanged(app, event)
            value = app.LogicButton_20.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            selectedButtonName = app.getSelectedLogicButtonName;
            if ~isempty(selectedButtonName)
                set(app.(selectedButtonName), 'text', '');
            end
        end

        % Button pushed function: NOTButton
        function NOTButtonPushed(app, event)
            app.setLogicButton(event);
        end

        % Button pushed function: ANYOFButton
        function ANYOFButtonPushed(app, event)
            app.setLogicButton(event);
        end

        % Button pushed function: ANDButton
        function ANDButtonPushed(app, event)
            app.setLogicButton(event);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.setLogicButton(event);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.setLogicButton(event);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.setLogicButton(event);
        end

        % Button pushed function: SavequeryButton
        function SavequeryButtonPushed(app, event)
            app.storeQueryState();
            queryState = app.queryState;
            [fname, fpath] = uiputfile('.mat','Save query as...',getenv('queryFolder'));
            if fname
                save([fpath fname], 'queryState', '-mat');
            end
        end

        % Button pushed function: LoadqueryButton
        function LoadqueryButtonPushed(app, event)
            [fname, fpath] = uigetfile('.mat','Load query file',getenv('queryFolder'));
            if fname
                load([fpath fname], 'queryState');
                app.loadQueryState(queryState);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            set(app.DataGrouperUIFigure, 'name', 'working...');
            app.storeQueryState();
            app.queryResult = parseQueryStructV2(app.queryState, app.searchTable);
            if ischar(app.queryResult) && isempty(app.queryResult)
                set(app.datasetsEditField, 'value', 'err');
            else
                experiments = sln_symphony.Experiment & proj(app.queryResult);
                app.experiments = experiments;
                set(app.experimentsEditField, 'value', num2str(experiments.count));
                animals = proj(sln_animal.Animal,'source_id->animal_source_id') & proj(app.queryResult);
                app.animals = animals;
                set(app.animalsEditField, 'value', num2str(animals.count));
                eyes = sln_animal.Eye & app.queryResult;
                app.eyes = eyes;
                set(app.eyesEditField, 'value', num2str(eyes.count));
                cells = sln_symphony.ExperimentCell & proj(app.queryResult);
                app.cells = cells;
                set(app.cellsEditField, 'value', num2str(cells.count));
                datasets = aka.Dataset & proj(app.queryResult);
                app.datasets = datasets;
                set(app.datasetsEditField, 'value', num2str(datasets.count));
                app.epochs = sln_symphony.DatasetEpoch & proj(app.queryResult);
                ep_count = app.queryResult.count;
                set(app.epochsEditField, 'value', num2str(ep_count));

                app.updateDataElmentsList();
                set(app.DataGrouperUIFigure, 'name', 'DataGrouper');
                % keyboard;
            end
        end

        % Value changed function: ViewlevelDropDown
        function ViewlevelDropDownValueChanged(app, event)
            set(app.DataGrouperUIFigure, 'name', 'working...');
            value = get(app.ViewlevelDropDown,'value');
            app.loadUserParamsTablesByLevel();
            app.updateDataElmentsList();
            if strcmp(value,'Cell')
                set(app.AssigncelltypeButton,'visible',true);
                set(app.CellClassDropDown,'visible',true);
                set(app.CellTypeDropDown,'visible',true);
                set(app.OnlinetypeEditField,'visible',true);
                set(app.OnlinelabelLabel,'visible',true);               
            else
                set(app.OnlinelabelLabel,'visible',false);
                set(app.OnlinetypeEditField,'visible',false);
                set(app.AssigncelltypeButton,'visible',false);
                set(app.CellClassDropDown,'visible',false);
                set(app.CellTypeDropDown,'visible',false);
            end
            set(app.DataGrouperUIFigure, 'name', 'DataGrouper');
        end

        % Button pushed function: AdduserparameterButton
        function AdduserparameterButtonPushed(app, event)
            set(app.DataGrouperUIFigure, 'name', 'working...');
            switch app.selectedUserParamType
                case 'enum'
                    param_val = get(app.UserParamValDropDown,'value');
                case 'int'
                    param_val = int32(str2double(get(app.UserParamValueEdit,'value')));
                case 'float'
                    param_val = str2double(get(app.UserParamValueEdit,'value'));
                case 'string'
                    param_val = get(app.UserParamValueEdit,'value');
                case 'table' %TODO

            end
            
            param_name = changeCase(extractAfter(get(app.UserParamNameDropDown,'value'), get(app.ViewlevelDropDown,'value')),'snake');
            table_name = sprintf('sln_symphony.UserParam%s%s',get(app.UserParamNameDropDown,'value'));

            result_struct = app.fetchDataSelection();
            N = length(result_struct);
            if N==0 
                disp('You must make a selection to add a user parameter');
                return;
            end

            for i=1:N
                result_struct(i).(param_name) = param_val;
            end
            try
                insert(eval(table_name),result_struct,'REPLACE');
            catch ME
                disp('parameter insert failed')
                disp(ME.message);
            end

            %add NULL for items without a value
            switch get(app.ViewlevelDropDown,'value')
                case 'Experiment'
                    unlabeled_items = fetch(app.experiments-eval(table_name));
                case 'Animal'
                    unlabeled_items = fetch(app.animals-eval(table_name));
                case 'Cell'
                    unlabeled_items = fetch(app.cells-eval(table_name));
                case 'Dataset'
                    unlabeled_items = fetch(app.datasets-eval(table_name));
                case 'Epoch'
                    unlabeled_items = fetch(app.epochs-eval(table_name));
            end


            try                
                fprintf('parameter NULL insert success for %d items\n', length(unlabeled_items))
                insert(eval(table_name),unlabeled_items,'REPLACE');
            catch ME
                disp('parameter NULL insert failed')
                disp(ME.message);
            end
            set(app.DataGrouperUIFigure, 'name', 'DataGrouper');
        end

        % Value changed function: ParamsTablesDropDown
        function ParamsTablesDropDownValueChanged(app, event)
            app.updateSearchTable();
        end

        % Value changed function: UserParamTypeDropDown
        function UserParamTypeDropDownValueChanged(app, event)
            value = app.UserParamTypeDropDown.Value;
            switch value
                case 'list item'
                    set(app.UserParamTableDropDown,'visible',false);
                    set(app.UserParamOptionsTextArea,'visible',true);
                case 'table element'
                    set(app.UserParamOptionsTextArea,'visible',false);
                    set(app.UserParamTableDropDown,'visible',true);
                otherwise
                    set(app.UserParamOptionsTextArea,'visible',false);
                    set(app.UserParamTableDropDown,'visible',false);
            end
        end

        % Button pushed function: CreateUserParamButton
        function CreateUserParamButtonPushed(app, event)
            set(app.DataGrouperUIFigure, 'name', 'working...');
            table_header_str = cell(6,1);
            table_header_str{1} = '%{';
            table_name = ['UserParam' get(app.ViewlevelDropDown,'value') changeCase(get(app.UserParamNameEdit,'value'),'upperCamel')];
            table_header_str{2} = ['# ' table_name];
            switch get(app.ViewlevelDropDown,'value')
                case 'Experiment'
                    table_header_str{3} = '-> sln_symphony.Experiment';
                case 'Animal'
                    table_header_str{3} = '-> sln_animal.Animal';
                case 'Cell'
                    table_header_str{3} = '-> sln_symphony.ExperimentCell';
                case 'Dataset'
                    table_header_str{3} = '-> sln_symphony.Dataset';
                case 'Epoch'
                    table_header_str{3} = '-> sln_symphony.DatasetEpoch';
            end
            table_header_str{4} = '---';
            var_name = changeCase(get(app.UserParamNameEdit,'value'),'snake');
            description = get(app.UserParamDescriptionEditField,'value');
            switch get(app.UserParamTypeDropDown,'value')
                case 'string'
                    table_header_str{5} = sprintf('%s = NULL : varchar(64) # %s', var_name, description);
                case 'bool'
                    table_header_str{5} = sprintf('%s = NULL : enum(''T'',''F'') # %s', var_name, description);
                case 'integer'
                    table_header_str{5} = sprintf('%s = NULL : int # %s', var_name, description);
                case 'float'
                    table_header_str{5} = sprintf('%s = NULL : float # %s', var_name, description);
                case 'list item'
                    list_items = get(app.UserParamOptionsTextArea,'value');
                    N = length(list_items);
                    enum_str = ['enum('];
                    for i=1:N
                        enum_str = [enum_str, sprintf('''%s''', list_items{i})];
                        if i<N
                            enum_str = [enum_str, ', '];
                        end
                    end
                    enum_str = [enum_str, ')'];
                    table_header_str{5} = sprintf('%s = NULL : %s # %s', var_name, enum_str, description);
                case 'table element'
                    external_table_name = get(app.UserParamTableDropDown,'value');
                    table_header_str{5} = sprintf('[nullable] -> %s # %s', external_table_name, description);
            end
            table_header_str{6} = '%}';
            table_def_str{1} = sprintf('classdef %s < dj.Manual', table_name);
            table_def_str{2} = 'end';
            fname = fullfile(getenv('DJ_ROOT'),'schemas','+sln_symphony',sprintf('%s.m',table_name));
            fid = fopen(fname,'w');
            for i=1:length(table_header_str)
                fprintf(fid,'%s\n',table_header_str{i});
            end
            for i=1:length(table_def_str)
                fprintf(fid,'%s\n',table_def_str{i});
            end
            fclose(fid);
            %initialize the table - can't do it within app apparently
            rehash('path');
            eval(sprintf('sln_symphony.%s',table_name));
            app.loadUserParamsTables();
            app.loadUserParamsTablesByLevel();
            set(app.DataGrouperUIFigure, 'name', 'DataGrouper');
        end

        % Value changed function: UserParamNameDropDown
        function UserParamNameDropDownValueChanged(app, event)
            value = app.UserParamNameDropDown.Value;
            if ~isempty(value)
                table_name = sprintf('sln_symphony.UserParam%s',value);
                table = eval(table_name);
                param_name = table.header.dependentFields{1};
                attr = table.header.attributes;
                ind = find(strcmp({attr.name},param_name));
                param_type = attr(ind).type;

                if startsWith(param_type,'enum')
                    app.selectedUserParamType = 'enum';
                    str = extractAfter(param_type,'enum(');
                    item_list = strtrim(strsplit(str,','));
                    item_list{end} = item_list{end}(1:end-1); %remove trailing )
                    for i=1:length(item_list)
                        item_list{i} = strrep(item_list{i},'''','');
                    end
                    set(app.UserParamValueEdit,'visible',false);
                    set(app.UserParamValDropDown,'visible',true);
                    set(app.UserParamValDropDown,'items',item_list);

                elseif startsWith(param_type,'varchar')
                    app.selectedUserParamType = 'string';
                    set(app.UserParamValueEdit,'visible',true);
                    set(app.UserParamValDropDown,'visible',false);

                elseif startsWith(param_type,'int')
                    app.selectedUserParamType = 'int';
                    set(app.UserParamValueEdit,'visible',true);
                    set(app.UserParamValDropDown,'visible',false);

                elseif startsWith(param_type,'float')
                    app.selectedUserParamType = 'float';
                    set(app.UserParamValueEdit,'visible',true);
                    set(app.UserParamValDropDown,'visible',false);

                else %table
                    app.selectedUserParamType = 'table';
                    %TODO

                end
            end
        end

        % Button pushed function: AddUserParamButton
        function AddUserParamButtonPushed(app, event)
            cur_items = get(app.UserParamsListBox,'items');
            selected_item = get(app.UserparametersDropDown,'value');
            if ~ismember(selected_item,cur_items) && ~strcmp(selected_item,'None')
                set(app.UserParamsListBox,'items',[selected_item, cur_items]);
            end
            app.updateSearchTable();
        end

        % Button pushed function: RemoveUserParamButton
        function RemoveUserParamButtonPushed(app, event)
            selected_item = get(app.UserParamsListBox,'value');
            if ~isempty(selected_item)
                cur_items = get(app.UserParamsListBox,'items');
                set(app.UserParamsListBox,'items',setdiff(cur_items, selected_item));
            end
            app.updateSearchTable();
        end

        % Button pushed function: ExportFullDataGroupButton
        function ExportFullDataGroupButtonPushed(app, event)
            set(app.DataGrouperUIFigure, 'name', 'exporting...');
            tic;
            if get(app.IncluderawtracesCheckBox,'value')
                data_group = app.queryResult * sln_symphony.ExperimentEpochChannel;
            else
                data_group = app.queryResult;
            end
            data_group_struct = fetch(data_group,'*');

            if strcmp(get(app.ExportTypeSwitch,'value'),'File')
                [fname, fpath] = uiputfile('*.mat','Save data group',getenv('dataGroupsFolder'));
                if fname
                    save([fpath, fname],'data_group','data_group_struct');
                    set(app.DataGrouperUIFigure, 'name', 'DataGrouper');
                end
            else
                assignin('base','data_group',data_group);
                assignin('base','data_group_struct',data_group_struct);
            end

            sprintf('Elasped time: %f\n', toc);
            set(app.DataGrouperUIFigure, 'name', 'DataGrouper');
            %end
        end

        % Value changed function: DataElementsListBox
        function DataElementsListBoxValueChanged(app, event)
            values = get(app.DataElementsListBox,'value');
            N = length(values);
            items = get(app.DataElementsListBox,'items');
            Nitems = length(items);
            app.data_selection_ind = false(Nitems,1);
            for i=1:N
                app.data_selection_ind(find(strcmp(items,values{i}))) = true;
            end
            data_level = get(app.ViewlevelDropDown,'value');
            if strcmp(data_level,'Cell')
                results_struct = app.fetchDataSelection();
                online_label = fetch1(sln_symphony.ExperimentCell & results_struct, 'online_type');
                set(app.OnlinetypeEditField,'value',online_label);
            end
            if get(app.LookforsavedresultsCheckBox,'value')
                if strcmp(data_level,'Cell')
                    app.load_results_for_cell_selection();
                else
                    app.load_results_for_data_selection();
                end
                app.ResultsInDBListBoxValueChanged([]);
            end
            if strcmp(data_level,'Epoch') %enable for epochs even if no stored results
                fetched_data = app.fetchDataSelection();
                app.populate_plotter_menu(fetched_data);
                app.run_plotter(fetched_data);
            end
        end

        % Value changed function: CellClassDropDown
        function CellClassDropDownValueChanged(app, event)
            value = app.CellClassDropDown.Value;
            app.loadCellTypeMenu();
        end

        % Button pushed function: AssigncelltypeButton
        function AssigncelltypeButtonPushed(app, event)
            C = dj.conn;
            key.cell_class = get(app.CellClassDropDown,'value');
            key.cell_type = get(app.CellTypeDropDown,'value');
            key.user_name = C.user;
            result_struct = app.fetchDataSelection();
            C.startTransaction;
            try
                for i=1:length(result_struct)
                    cell_unid = fetch1(sln_cell.Cell & result_struct(i),'cell_unid');
                    cur_key = key;
                    cur_key.cell_unid = cell_unid;
                    sln_cell.add_event(cur_key,'AssignType',C)
                end
                disp('assignment successful');
                C.commitTransaction;
            catch ME
                disp('assignemnt failed')
                disp(ME.message);
                C.cancelTransaction;
            end
            pause(0.5);
            app.updateDataElmentsList();
        end

        % Button pushed function: ExportSelectionButton
        function ExportSelectionButtonPushed(app, event)
            set(app.DataGrouperUIFigure, 'name', 'exporting...');
            tic;
            results_struct = app.fetchDataSelection();
            if get(app.IncluderawtracesCheckBox,'value')
                data_group = app.queryResult * sln_symphony.ExperimentEpochChannel & results_struct;
            else
                data_group = app.queryResult & results_struct;
            end
            data_group_struct = fetch(data_group,'*');

            if strcmp(get(app.ExportTypeSwitch,'value'),'File')
                [fname, fpath] = uiputfile('*.mat','Save data group',getenv('dataGroupsFolder'));
                if fname
                    save([fpath, fname],'data_group','data_group_struct');
                    set(app.DataGrouperUIFigure, 'name', 'DataGrouper');
                end
            else
                assignin('base','data_group',data_group);
                assignin('base','data_group_struct',data_group_struct);
            end

            sprintf('Elasped time: %f\n', toc);
            set(app.DataGrouperUIFigure, 'name', 'DataGrouper');

        end

        % Button pushed function: RunanalysisButton
        function RunanalysisButtonPushed(app, event)
            analysis_name = get(app.AnalysisNameEdit, 'value');
            func_path = which(analysis_name);
            if isempty(func_path)
                error('Function %s not found on path', analysis_name);
            end
            analysis_state = get(app.AnalysisStateSwitch,'value');
            data_state = get(app.AnalysisDataSwitch, 'value');
            get_raw = get(app.AnalysisRawDataSwitch, 'value');
                        
            if strcmp(get_raw,'Include raw data')
                q = app.queryResult * sln_symphony.ExperimentEpochChannel;
            else
                q = app.queryResult;
            end

            if strcmp(data_state,'Selection')
                results_struct = app.fetchDataSelection();
                data_group = q & results_struct;
            else
                data_group = q; 
            end

            data_level = get(app.ViewlevelDropDown,'value');

            if strcmp(analysis_state,'Command line')
                R = sln_results.get(analysis_name,data_group,data_level,true);
                assignin('base','results', R);
            else
                table_name = sprintf('%s%s',data_level,strrep(analysis_name,'_',''));
                replace_choice = get(get(app.ReplaceResultsButtonGroup,'SelectedObject'),'text');

                switch replace_choice
                    case 'None'
                       R = sln_results.get(analysis_name,data_group,data_level,true);                        
                    case 'All'
                        eval(sprintf('R = %s(data_group);', analysis_name));
                        eval(sprintf('sln_results.insert(R,"%s",true);', data_level));
                    case 'This user' %TODO
                end
                
            end
        end

        % Button pushed function: ChooseanalysisButton
        function ChooseanalysisButtonPushed(app, event)
            start_folder = getenv('User_analysis_folder');
            fname = uigetfile('*.m', 'Choose analysis file', start_folder);
            if ~isempty(fname)
                set(app.AnalysisNameEdit,'value',extractBefore(fname,'.m'));
            end
        end

        % Value changed function: LookforsavedresultsCheckBox
        function LookforsavedresultsCheckBoxValueChanged(app, event)
            value = app.LookforsavedresultsCheckBox.Value;
            data_level = get(app.ViewlevelDropDown,'value');
            if value
                if strcmp(data_level,'Cell')
                    app.load_results_for_cell_selection();
                else
                    app.load_results_for_data_selection();
                end
            else
                set(app.ResultsInDBListBox,'items',{});
            end
            app.ResultsInDBListBoxValueChanged([]);
        end

        % Value changed function: ResultsInDBListBox
        function ResultsInDBListBoxValueChanged(app, event)
            if contains(app.ResultsInDBListBox.Value, '(')
                selected_result_type = extractBefore(app.ResultsInDBListBox.Value, ' ');
            else
                selected_result_type = app.ResultsInDBListBox.Value;
            end
            if isempty(selected_result_type)
                app.selected_result_entry = [];
                app.populate_plotter_menu([]);
                app.run_plotter([]);
                return
            end
            struct_selected = app.fetchDataSelection;
            app.selected_result_entry = eval(sprintf('sln_results.%s',selected_result_type)) & struct_selected;  
            fetched_results = fetch(app.selected_result_entry,'*');
            app.populate_plotter_menu(fetched_results);
            app.run_plotter(fetched_results);
        end

        % Button pushed function: TocommandlineButton
        function TocommandlineButtonPushed(app, event)
            if isempty(app.selected_result_entry)
                disp('No stored result saved');
                return;
            end
            assignin('base','result',app.selected_result_entry);
            assignin('base','result_table',sln_results.toMatlabTable(app.selected_result_entry));            
        end

        % Button pushed function: ToHDF5Button
        function ToHDF5ButtonPushed(app, event)
            if isempty(app.selected_result_entry)
                disp('No stored result saved');
                return;
            end
            [fname, fpath] = uiputfile('.h5','Save result file as...',getenv('h5_folder'));
            if fname
                sln_results.toH5([fpath fname], app.selected_result_entry);
            end            
        end

        % Value changed function: PlotterDropDown
        function PlotterDropDownValueChanged(app, event)
            app.ResultsInDBListBoxValueChanged([])
        end

        % Button pushed function: SavefigButton
        function SavefigButtonPushed(app, event)
            [fname, fpath] = uiputfile('.fig','Save fig as...',getenv('MatlabFigFolder'));
            if fname
                tempFig = figure;
                h = copyobj(app.PlotAreaPanel,tempFig);
                set(h,'Units', 'normalized','position',[.05 .05 .9 .9]);
                savefig(tempFig,[fpath fname]);
                close(tempFig);
            end
        end

        % Button pushed function: SavepngButton
        function SavepngButtonPushed(app, event)
            [fname, fpath] = uiputfile('.png','Save png as...',getenv('MatlabPNGFolder'));
            if fname
                tempFig = figure;
                h = copyobj(app.PlotAreaPanel,tempFig);
                set(h,'Units', 'normalized','position',[.05 .05 .9 .9]);
                saveas(tempFig,[fpath fname],'png');
                close(tempFig);
            end
        end

        % Value changed function: StimCond_DD_1, StimCond_DD_2, 
        % ...and 2 other components
        function StimCond_ValueChanged(app, event)
            R = fetch(app.selected_result_entry,'*');
            app.populate_plotter_menu(R);
            app.run_multi_condition_plotter(R);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataGrouperUIFigure = uifigure('Visible', 'off');
            app.DataGrouperUIFigure.Name = 'DataGrouper';
            app.DataGrouperUIFigure.CloseRequestFcn = createCallbackFcn(app, @DataGrouperUIFigureCloseRequest, true);
            app.AttributesListBoxLabel = uilabel(app.DataGrouperUIFigure);
            app.AttributesListBoxLabel.Text = 'Attributes';
            app.AttributesListBox = uilistbox(app.DataGrouperUIFigure);
            app.AttributesListBox.ValueChangedFcn = createCallbackFcn(app, @AttributesListBoxValueChanged, true);
            app.QueryconstructionPanel = uipanel(app.DataGrouperUIFigure);
            app.QueryconstructionPanel.Title = 'Query construction';
            app.GridLayout = uigridlayout(app.QueryconstructionPanel);
            app.EditField_Q1 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q1.Editable = 'off';
            app.EditField_Q1.Visible = 'off';
            app.EditField_Q3 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q3.Editable = 'off';
            app.EditField_Q3.Visible = 'off';
            app.EditField_Q5 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q5.Editable = 'off';
            app.EditField_Q5.Visible = 'off';
            app.EditField_Q7 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q7.Editable = 'off';
            app.EditField_Q7.Visible = 'off';
            app.EditField_Q9 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q9.Editable = 'off';
            app.EditField_Q9.Visible = 'off';
            app.EditField_Q11 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q11.Editable = 'off';
            app.EditField_Q11.Visible = 'off';
            app.EditField_Q13 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q13.Editable = 'off';
            app.EditField_Q13.Visible = 'off';
            app.EditField_Q15 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q15.Editable = 'off';
            app.EditField_Q15.Visible = 'off';
            app.EditField_Q17 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q17.Editable = 'off';
            app.EditField_Q17.Visible = 'off';
            app.EditField_Q19 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q19.Editable = 'off';
            app.EditField_Q19.Visible = 'off';
            app.EditField_Q21 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q21.Editable = 'off';
            app.EditField_Q21.Visible = 'off';
            app.OpDrop_1 = uidropdown(app.GridLayout);
            app.OpDrop_1.ValueChangedFcn = createCallbackFcn(app, @OpDrop_1ValueChanged, true);
            app.OpDrop_1.Tag = 'OpDrop_1';
            app.OpDrop_1.Visible = 'off';
            app.OpDrop_3 = uidropdown(app.GridLayout);
            app.OpDrop_3.ValueChangedFcn = createCallbackFcn(app, @OpDrop_3ValueChanged, true);
            app.OpDrop_3.Tag = 'OpDrop_3';
            app.OpDrop_3.Visible = 'off';
            app.OpDrop_5 = uidropdown(app.GridLayout);
            app.OpDrop_5.ValueChangedFcn = createCallbackFcn(app, @OpDrop_5ValueChanged, true);
            app.OpDrop_5.Tag = 'OpDrop_5';
            app.OpDrop_5.Visible = 'off';
            app.OpDrop_7 = uidropdown(app.GridLayout);
            app.OpDrop_7.ValueChangedFcn = createCallbackFcn(app, @OpDrop_7ValueChanged, true);
            app.OpDrop_7.Tag = 'OpDrop_7';
            app.OpDrop_7.Visible = 'off';
            app.OpDrop_9 = uidropdown(app.GridLayout);
            app.OpDrop_9.ValueChangedFcn = createCallbackFcn(app, @OpDrop_9ValueChanged, true);
            app.OpDrop_9.Tag = 'OpDrop_9';
            app.OpDrop_9.Visible = 'off';
            app.OpDrop_11 = uidropdown(app.GridLayout);
            app.OpDrop_11.ValueChangedFcn = createCallbackFcn(app, @OpDrop_11ValueChanged, true);
            app.OpDrop_11.Tag = 'OpDrop_11';
            app.OpDrop_11.Visible = 'off';
            app.OpDrop_13 = uidropdown(app.GridLayout);
            app.OpDrop_13.ValueChangedFcn = createCallbackFcn(app, @OpDrop_13ValueChanged, true);
            app.OpDrop_13.Tag = 'OpDrop_13';
            app.OpDrop_13.Visible = 'off';
            app.OpDrop_15 = uidropdown(app.GridLayout);
            app.OpDrop_15.ValueChangedFcn = createCallbackFcn(app, @OpDrop_15ValueChanged, true);
            app.OpDrop_15.Tag = 'OpDrop_15';
            app.OpDrop_15.Visible = 'off';
            app.OpDrop_17 = uidropdown(app.GridLayout);
            app.OpDrop_17.ValueChangedFcn = createCallbackFcn(app, @OpDrop_17ValueChanged, true);
            app.OpDrop_17.Tag = 'OpDrop_17';
            app.OpDrop_17.Visible = 'off';
            app.OpDrop_19 = uidropdown(app.GridLayout);
            app.OpDrop_19.ValueChangedFcn = createCallbackFcn(app, @OpDrop_19ValueChanged, true);
            app.OpDrop_19.Tag = 'OpDrop_19';
            app.OpDrop_19.Visible = 'off';
            app.OpDrop_21 = uidropdown(app.GridLayout);
            app.OpDrop_21.ValueChangedFcn = createCallbackFcn(app, @OpDrop_21ValueChanged, true);
            app.OpDrop_21.Tag = 'OpDrop_21';
            app.OpDrop_21.Visible = 'off';
            app.LogicButton_2 = uibutton(app.GridLayout, 'state');
            app.LogicButton_2.ValueChangedFcn = createCallbackFcn(app, @LogicButton_2ValueChanged, true);
            app.LogicButton_2.Tag = 'LogicButton_2';
            app.LogicButton_2.Text = '';
            app.LogicButton_4 = uibutton(app.GridLayout, 'state');
            app.LogicButton_4.ValueChangedFcn = createCallbackFcn(app, @LogicButton_4ValueChanged, true);
            app.LogicButton_4.Tag = 'LogicButton_4';
            app.LogicButton_4.Visible = 'off';
            app.LogicButton_4.Text = 'AND';
            app.LogicButton_6 = uibutton(app.GridLayout, 'state');
            app.LogicButton_6.ValueChangedFcn = createCallbackFcn(app, @LogicButton_6ValueChanged, true);
            app.LogicButton_6.Tag = 'LogicButton_6';
            app.LogicButton_6.Visible = 'off';
            app.LogicButton_6.Text = 'AND';
            app.LogicButton_8 = uibutton(app.GridLayout, 'state');
            app.LogicButton_8.ValueChangedFcn = createCallbackFcn(app, @LogicButton_8ValueChanged, true);
            app.LogicButton_8.Tag = 'LogicButton_8';
            app.LogicButton_8.Visible = 'off';
            app.LogicButton_8.Text = 'AND';
            app.LogicButton_10 = uibutton(app.GridLayout, 'state');
            app.LogicButton_10.ValueChangedFcn = createCallbackFcn(app, @LogicButton_10ValueChanged, true);
            app.LogicButton_10.Tag = 'LogicButton_10';
            app.LogicButton_10.Visible = 'off';
            app.LogicButton_10.Text = 'AND';
            app.LogicButton_12 = uibutton(app.GridLayout, 'state');
            app.LogicButton_12.ValueChangedFcn = createCallbackFcn(app, @LogicButton_12ValueChanged, true);
            app.LogicButton_12.Tag = 'LogicButton_12';
            app.LogicButton_12.Visible = 'off';
            app.LogicButton_12.Text = 'AND';
            app.LogicButton_14 = uibutton(app.GridLayout, 'state');
            app.LogicButton_14.ValueChangedFcn = createCallbackFcn(app, @LogicButton_14ValueChanged, true);
            app.LogicButton_14.Tag = 'LogicButton_14';
            app.LogicButton_14.Visible = 'off';
            app.LogicButton_14.Text = 'AND';
            app.LogicButton_16 = uibutton(app.GridLayout, 'state');
            app.LogicButton_16.ValueChangedFcn = createCallbackFcn(app, @LogicButton_16ValueChanged, true);
            app.LogicButton_16.Tag = 'LogicButton_16';
            app.LogicButton_16.Visible = 'off';
            app.LogicButton_16.Text = 'AND';
            app.LogicButton_18 = uibutton(app.GridLayout, 'state');
            app.LogicButton_18.ValueChangedFcn = createCallbackFcn(app, @LogicButton_18ValueChanged, true);
            app.LogicButton_18.Tag = 'LogicButton_18';
            app.LogicButton_18.Visible = 'off';
            app.LogicButton_18.Text = 'AND';
            app.LogicButton_20 = uibutton(app.GridLayout, 'state');
            app.LogicButton_20.ValueChangedFcn = createCallbackFcn(app, @LogicButton_20ValueChanged, true);
            app.LogicButton_20.Tag = 'LogicButton_20';
            app.LogicButton_20.Visible = 'off';
            app.LogicButton_20.Text = 'AND';
            app.AddconditionwithattributeButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.AddconditionwithattributeButton.ButtonPushedFcn = createCallbackFcn(app, @AddconditionwithattributeButtonPushed, true);
            app.AddconditionwithattributeButton.Text = 'Add condition with attribute';
            app.DeletelastelementButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.DeletelastelementButton.ButtonPushedFcn = createCallbackFcn(app, @DeletelastelementButtonPushed, true);
            app.DeletelastelementButton.Text = 'Delete last element';
            app.LogicaloperatorsPanel = uipanel(app.DataGrouperUIFigure);
            app.LogicaloperatorsPanel.Title = 'Logical operators';
            app.NOTButton = uibutton(app.LogicaloperatorsPanel, 'push');
            app.NOTButton.ButtonPushedFcn = createCallbackFcn(app, @NOTButtonPushed, true);
            app.NOTButton.Text = 'NOT';
            app.ANYOFButton = uibutton(app.LogicaloperatorsPanel, 'push');
            app.ANYOFButton.ButtonPushedFcn = createCallbackFcn(app, @ANYOFButtonPushed, true);
            app.ANYOFButton.Text = 'ANY OF (';
            app.Button_2 = uibutton(app.LogicaloperatorsPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = ')';
            app.Button_3 = uibutton(app.LogicaloperatorsPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '(';
            app.ANDButton = uibutton(app.LogicaloperatorsPanel, 'push');
            app.ANDButton.ButtonPushedFcn = createCallbackFcn(app, @ANDButtonPushed, true);
            app.ANDButton.Text = 'AND';
            app.Button_4 = uibutton(app.LogicaloperatorsPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = ',';
            app.clearButton = uibutton(app.LogicaloperatorsPanel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.TypeEditFieldLabel = uilabel(app.DataGrouperUIFigure);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.DataGrouperUIFigure, 'text');
            app.TypeEditField.Editable = 'off';
            app.RunButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResultsPanel = uipanel(app.DataGrouperUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.animalsEditFieldLabel = uilabel(app.ResultsPanel);
            app.animalsEditFieldLabel.Text = 'animals';
            app.animalsEditField = uieditfield(app.ResultsPanel, 'text');
            app.animalsEditField.Editable = 'off';
            app.cellsEditField_2Label = uilabel(app.ResultsPanel);
            app.cellsEditField_2Label.Text = 'cells';
            app.cellsEditField = uieditfield(app.ResultsPanel, 'text');
            app.cellsEditField.Editable = 'off';
            app.eyesEditFieldLabel = uilabel(app.ResultsPanel);
            app.eyesEditFieldLabel.Text = 'eyes';
            app.eyesEditField = uieditfield(app.ResultsPanel, 'text');
            app.eyesEditField.Editable = 'off';
            app.datasetsEditField_3Label = uilabel(app.ResultsPanel);
            app.datasetsEditField_3Label.Text = 'datasets';
            app.datasetsEditField = uieditfield(app.ResultsPanel, 'text');
            app.datasetsEditField.Editable = 'off';
            app.SavequeryButton = uibutton(app.ResultsPanel, 'push');
            app.SavequeryButton.ButtonPushedFcn = createCallbackFcn(app, @SavequeryButtonPushed, true);
            app.SavequeryButton.Text = 'Save query';
            app.epochsLabel = uilabel(app.ResultsPanel);
            app.epochsLabel.Text = 'epochs';
            app.epochsEditField = uieditfield(app.ResultsPanel, 'text');
            app.epochsEditField.Editable = 'off';
            app.experimentsEditFieldLabel = uilabel(app.ResultsPanel);
            app.experimentsEditFieldLabel.Text = 'experiments';
            app.experimentsEditField = uieditfield(app.ResultsPanel, 'text');
            app.experimentsEditField.Editable = 'off';
            app.LoadqueryButton = uibutton(app.ResultsPanel, 'push');
            app.LoadqueryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadqueryButtonPushed, true);
            app.LoadqueryButton.Text = 'Load query';
            app.DescriptionTextAreaLabel = uilabel(app.DataGrouperUIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.DataGrouperUIFigure);
            app.DescriptionTextArea.Editable = 'off';
            app.BrowserPanel = uipanel(app.DataGrouperUIFigure);
            app.BrowserPanel.Title = 'Browser';
            app.ViewlevelDropDownLabel = uilabel(app.BrowserPanel);
            app.ViewlevelDropDownLabel.Text = 'View level';
            app.ViewlevelDropDown = uidropdown(app.BrowserPanel);
            app.ViewlevelDropDown.Items = {'Experiment', 'Animal', 'Eye', 'Cell', 'Dataset', 'Epoch'};
            app.ViewlevelDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewlevelDropDownValueChanged, true);
            app.ViewlevelDropDown.Value = 'Experiment';
            app.NLabel = uilabel(app.BrowserPanel);
            app.NLabel.Text = 'N =';
            app.NEditField = uieditfield(app.BrowserPanel, 'numeric');
            app.NEditField.Editable = 'off';
            app.DataElementsListBox = uilistbox(app.BrowserPanel);
            app.DataElementsListBox.Multiselect = 'on';
            app.DataElementsListBox.ValueChangedFcn = createCallbackFcn(app, @DataElementsListBoxValueChanged, true);
            app.DataElementsListBox.Value = {'Item 1'};
            app.RunanalysisButton = uibutton(app.BrowserPanel, 'push');
            app.RunanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButtonPushed, true);
            app.RunanalysisButton.Text = 'Run analysis';
            app.AdduserparameterButton = uibutton(app.BrowserPanel, 'push');
            app.AdduserparameterButton.ButtonPushedFcn = createCallbackFcn(app, @AdduserparameterButtonPushed, true);
            app.AdduserparameterButton.Text = 'Add user parameter';
            app.ChooseanalysisButton = uibutton(app.BrowserPanel, 'push');
            app.ChooseanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseanalysisButtonPushed, true);
            app.ChooseanalysisButton.Text = 'Choose analysis';
            app.UserParamNameDropDown = uidropdown(app.BrowserPanel);
            app.UserParamNameDropDown.ValueChangedFcn = createCallbackFcn(app, @UserParamNameDropDownValueChanged, true);
            app.AnalysisNameEdit = uieditfield(app.BrowserPanel, 'text');
            app.UserParamValDropDown = uidropdown(app.BrowserPanel);
            app.NewuserparameterPanel = uipanel(app.BrowserPanel);
            app.NewuserparameterPanel.Title = 'New user parameter';
            app.TypeLabel = uilabel(app.NewuserparameterPanel);
            app.TypeLabel.Text = 'Type';
            app.UserParamTypeDropDown = uidropdown(app.NewuserparameterPanel);
            app.UserParamTypeDropDown.Items = {'string', 'bool', 'integer', 'float', 'list item', 'table element'};
            app.UserParamTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @UserParamTypeDropDownValueChanged, true);
            app.UserParamTypeDropDown.Value = 'string';
            app.NameLabel = uilabel(app.NewuserparameterPanel);
            app.NameLabel.Text = 'Name';
            app.UserParamNameEdit = uieditfield(app.NewuserparameterPanel, 'text');
            app.CreateUserParamButton = uibutton(app.NewuserparameterPanel, 'push');
            app.CreateUserParamButton.ButtonPushedFcn = createCallbackFcn(app, @CreateUserParamButtonPushed, true);
            app.CreateUserParamButton.Text = 'Create';
            app.UserParamOptionsTextArea = uitextarea(app.NewuserparameterPanel);
            app.UserParamTableDropDown = uidropdown(app.NewuserparameterPanel);
            app.DescriptionEditFieldLabel = uilabel(app.NewuserparameterPanel);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.UserParamDescriptionEditField = uieditfield(app.NewuserparameterPanel, 'text');
            app.UserParamValueEdit = uieditfield(app.BrowserPanel, 'text');
            app.AssigncelltypeButton = uibutton(app.BrowserPanel, 'push');
            app.AssigncelltypeButton.ButtonPushedFcn = createCallbackFcn(app, @AssigncelltypeButtonPushed, true);
            app.AssigncelltypeButton.Text = 'Assign cell type';
            app.CellClassDropDown = uidropdown(app.BrowserPanel);
            app.CellClassDropDown.Items = {'RGC', 'amacrine', 'bipolar', 'horizontal', 'glial', 'photoreceptor', 'pericyte', 'RPE', 'unknown', 'other'};
            app.CellClassDropDown.ValueChangedFcn = createCallbackFcn(app, @CellClassDropDownValueChanged, true);
            app.CellClassDropDown.Value = 'RGC';
            app.CellTypeDropDown = uidropdown(app.BrowserPanel);
            app.ReplaceResultsButtonGroup = uibuttongroup(app.BrowserPanel);
            app.ReplaceResultsButtonGroup.Title = 'Replace';
            app.NoneButton = uiradiobutton(app.ReplaceResultsButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.AllButton = uiradiobutton(app.ReplaceResultsButtonGroup);
            app.AllButton.Text = 'All';
            app.AnalysisStateSwitch = uiswitch(app.BrowserPanel, 'slider');
            app.AnalysisStateSwitch.Items = {'Command line', 'Database'};
            app.AnalysisStateSwitch.Value = 'Command line';
            app.AnalysisDataSwitch = uiswitch(app.BrowserPanel, 'slider');
            app.AnalysisDataSwitch.Items = {'Selection', 'All at this level'};
            app.AnalysisDataSwitch.Value = 'Selection';
            app.AnalysisRawDataSwitch = uiswitch(app.BrowserPanel, 'slider');
            app.AnalysisRawDataSwitch.Items = {'No raw data', 'Include raw data'};
            app.AnalysisRawDataSwitch.Value = 'No raw data';
            app.LookforsavedresultsCheckBox = uicheckbox(app.BrowserPanel);
            app.LookforsavedresultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LookforsavedresultsCheckBoxValueChanged, true);
            app.LookforsavedresultsCheckBox.Text = 'Look for saved results';
            app.OnlinetypeEditField = uieditfield(app.BrowserPanel, 'text');
            app.OnlinetypeEditField.Editable = 'off';
            app.OnlinetypeEditField.Visible = 'off';
            app.OnlinelabelLabel = uilabel(app.BrowserPanel);
            app.OnlinelabelLabel.Text = 'Online label';
            app.ExportFullDataGroupButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.ExportFullDataGroupButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFullDataGroupButtonPushed, true);
            app.ExportFullDataGroupButton.Text = 'Export Full DataGroup';
            app.IncluderawtracesCheckBox = uicheckbox(app.DataGrouperUIFigure);
            app.IncluderawtracesCheckBox.Text = 'Include raw traces';
            app.ProtocolparametersLabel = uilabel(app.DataGrouperUIFigure);
            app.ProtocolparametersLabel.Text = 'Protocol parameters';
            app.ParamsTablesDropDown = uidropdown(app.DataGrouperUIFigure);
            app.ParamsTablesDropDown.Items = {};
            app.ParamsTablesDropDown.ValueChangedFcn = createCallbackFcn(app, @ParamsTablesDropDownValueChanged, true);
            app.ParamsTablesDropDown.Value = {};
            app.UserparametersDropDownLabel = uilabel(app.DataGrouperUIFigure);
            app.UserparametersDropDownLabel.Text = 'User parameters';
            app.UserparametersDropDown = uidropdown(app.DataGrouperUIFigure);
            app.UserParamsListBox = uilistbox(app.DataGrouperUIFigure);
            app.UserParamsListBox.Items = {};
            app.UserParamsListBox.Value = {};
            app.DescriptionTextAreaLabel_2 = uilabel(app.DataGrouperUIFigure);
            app.DescriptionTextAreaLabel_2.Text = 'Description';
            app.DescriptionTextArea_2 = uitextarea(app.DataGrouperUIFigure);
            app.DescriptionTextArea_2.Editable = 'off';
            app.TypeEditField_2Label = uilabel(app.DataGrouperUIFigure);
            app.TypeEditField_2Label.Text = 'Type';
            app.TypeEditField_2 = uieditfield(app.DataGrouperUIFigure, 'text');
            app.TypeEditField_2.Editable = 'off';
            app.AddconditionwithuserparamButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.AddconditionwithuserparamButton.Text = 'Add condition with user param';
            app.RemoveUserParamButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.RemoveUserParamButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveUserParamButtonPushed, true);
            app.RemoveUserParamButton.Text = 'Remove';
            app.AddUserParamButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.AddUserParamButton.ButtonPushedFcn = createCallbackFcn(app, @AddUserParamButtonPushed, true);
            app.AddUserParamButton.Text = 'Add';
            app.ExportSelectionButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.ExportSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSelectionButtonPushed, true);
            app.ExportSelectionButton.Text = 'Export Selection';
            app.ExportTypeSwitch = uiswitch(app.DataGrouperUIFigure, 'rocker');
            app.ExportTypeSwitch.Items = {'File', 'Command line'};
            app.ExportTypeSwitch.Value = 'File';
            app.PlotsPanel = uipanel(app.DataGrouperUIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.PlotterDropDownLabel = uilabel(app.PlotsPanel);
            app.PlotterDropDownLabel.Text = 'Plotter';
            app.PlotterDropDown = uidropdown(app.PlotsPanel);
            app.PlotterDropDown.Items = {};
            app.PlotterDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotterDropDownValueChanged, true);
            app.PlotterDropDown.Value = {};
            app.PlotAreaPanel = uipanel(app.PlotsPanel);
            app.PlotAxes = uiaxes(app.PlotAreaPanel);
            app.PlotAxes.XTick = [];
            app.PlotAxes.YTick = [];
            app.PlotAxes.Visible = 'off';
            app.StimCond_DD_1 = uidropdown(app.PlotsPanel);
            app.StimCond_DD_1.ValueChangedFcn = createCallbackFcn(app, @StimCond_ValueChanged, true);
            app.StimCond_DD_1.Visible = 'off';
            app.StimCond_DD_2 = uidropdown(app.PlotsPanel);
            app.StimCond_DD_2.ValueChangedFcn = createCallbackFcn(app, @StimCond_ValueChanged, true);
            app.StimCond_DD_2.Visible = 'off';
            app.StimCond_DD_3 = uidropdown(app.PlotsPanel);
            app.StimCond_DD_3.ValueChangedFcn = createCallbackFcn(app, @StimCond_ValueChanged, true);
            app.StimCond_DD_3.Visible = 'off';
            app.StimCond_DD_4 = uidropdown(app.PlotsPanel);
            app.StimCond_DD_4.ValueChangedFcn = createCallbackFcn(app, @StimCond_ValueChanged, true);
            app.StimCond_DD_4.Visible = 'off';
            app.StimCond_label_1 = uilabel(app.PlotsPanel);
            app.StimCond_label_1.Visible = 'off';
            app.StimCond_label_2 = uilabel(app.PlotsPanel);
            app.StimCond_label_2.Visible = 'off';
            app.StimCond_label_3 = uilabel(app.PlotsPanel);
            app.StimCond_label_3.Visible = 'off';
            app.StimCond_label_4 = uilabel(app.PlotsPanel);
            app.StimCond_label_4.Visible = 'off';
            app.ResultsindatabasePanel = uipanel(app.DataGrouperUIFigure);
            app.ResultsindatabasePanel.Title = 'Results in database';
            app.ResultsInDBListBox = uilistbox(app.ResultsindatabasePanel);
            app.ResultsInDBListBox.Items = {};
            app.ResultsInDBListBox.ValueChangedFcn = createCallbackFcn(app, @ResultsInDBListBoxValueChanged, true);
            app.ResultsInDBListBox.Value = {};
            app.SavefigButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.SavefigButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigButtonPushed, true);
            app.SavefigButton.Text = 'Save .fig';
            app.TocommandlineButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.TocommandlineButton.ButtonPushedFcn = createCallbackFcn(app, @TocommandlineButtonPushed, true);
            app.TocommandlineButton.Text = 'To command line';
            app.ToHDF5Button = uibutton(app.DataGrouperUIFigure, 'push');
            app.ToHDF5Button.ButtonPushedFcn = createCallbackFcn(app, @ToHDF5ButtonPushed, true);
            app.ToHDF5Button.Text = 'To HDF5';
            app.SavepngButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.SavepngButton.ButtonPushedFcn = createCallbackFcn(app, @SavepngButtonPushed, true);
            app.SavepngButton.Text = 'Save .png';
            % Show the figure after all components are created
            app.DataGrouperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataGrouper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataGrouperUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataGrouperUIFigure)
        end
    end
end