classdef Diary_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiaryPlotterUIFigure    matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        RightUpperEdit          matlab.ui.control.NumericEditField
        RightLowerEdit          matlab.ui.control.NumericEditField
        LeftUpperEdit           matlab.ui.control.NumericEditField
        LeftLowerEdit           matlab.ui.control.NumericEditField
        RightaxisDropDown       matlab.ui.control.DropDown
        RightaxisDropDownLabel  matlab.ui.control.Label
        LeftaxisDropDown        matlab.ui.control.DropDown
        LeftaxisDropDownLabel   matlab.ui.control.Label
        LoadButton              matlab.ui.control.Button
        EpochsEditField         matlab.ui.control.EditField
        EpochsEditFieldLabel    matlab.ui.control.Label
        CellnameEditField       matlab.ui.control.EditField
        CellnameEditFieldLabel  matlab.ui.control.Label
        DiaryAxis_T             matlab.ui.control.UIAxes
        DiaryAxis_B             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        results_struct % all epoch results
        epoch_times % s, since epoch 1
        good_ind % logical vector for indexing epochs
    end
    
    methods (Access = private)
        
        function init_dropdowns(app)
            fnames = fieldnames(app.results_struct);
            fnames = setdiff(fnames,{'file_name', ...
                'source_id', ...
                'epoch_group_id', ...
                'epoch_block_id', ...
                'epoch_id', ...
                'channel_name', ...
                'analysis_entry_time', ...
                'isolated_spike_shape', ...
                'epoch_start_time', ...
                'epoch_duration' ...
                });
            app.LeftaxisDropDown.Items = fnames;
            app.RightaxisDropDown.Items = ['None'; fnames];
            app.LeftaxisDropDown.Value = fnames{1};
            app.RightaxisDropDown.Value = "None";
        end
        
        function update_plot(app)
            yyaxis(app.DiaryAxis_B,'left'); 
            cla(app.DiaryAxis_B);
            yyaxis(app.DiaryAxis_B,'right'); 
            cla(app.DiaryAxis_B);
            yyaxis(app.DiaryAxis_T,'left'); 
            cla(app.DiaryAxis_T);
            yyaxis(app.DiaryAxis_T,'right'); 
            cla(app.DiaryAxis_T);
                      
            yvals_left = [app.results_struct.(app.LeftaxisDropDown.Value)];
            yvals_left = yvals_left(app.good_ind);
            if strcmp(app.RightaxisDropDown.Value,'None')
                yvals_right = [];
            else
                yvals_right= [app.results_struct.(app.RightaxisDropDown.Value)];
                yvals_right = yvals_right(app.good_ind);
            end
            yyaxis(app.DiaryAxis_B,'left');    
            scatter(app.DiaryAxis_B,app.epoch_times(app.good_ind),yvals_left,'bo','filled');
            ylabel(app.DiaryAxis_B,app.LeftaxisDropDown.Value,'Interpreter','none');            
            app.DiaryAxis_T.XTickMode = 'manual';
            epoch_ticks = [app.results_struct(app.good_ind).epoch_id];
            epoch_times = [app.epoch_times(app.good_ind)];
            app.DiaryAxis_T.XTick = epoch_times(1:10:end);  
            app.DiaryAxis_T.XTickLabel = epoch_ticks(1:10:end);
            app.DiaryAxis_T.YTick = [];

            left_lims = app.DiaryAxis_B.YLim;
            app.LeftLowerEdit.Value = left_lims(1);
            app.LeftUpperEdit.Value = left_lims(2);
            if ~isempty(yvals_right)
                yyaxis(app.DiaryAxis_B,'right');
                scatter(app.DiaryAxis_B,app.epoch_times(app.good_ind),yvals_right,'ro','filled');
                ylabel(app.DiaryAxis_B,app.RightaxisDropDown.Value,'Interpreter','none');
                app.DiaryAxis_B.YTickMode = 'auto';
                right_lims = app.DiaryAxis_B.YLim
                app.RightLowerEdit.Value = right_lims(1);
                app.RightUpperEdit.Value = right_lims(2);
            else
                yyaxis(app.DiaryAxis_B,'right');
                ylabel(app.DiaryAxis_B,'');
                app.DiaryAxis_B.YTick = [];                
            end
            app.DiaryAxis_B.XLim = [min(app.epoch_times(app.good_ind))-1, max(app.epoch_times(app.good_ind))+1];
            app.DiaryAxis_T.XLim = [min(app.epoch_times(app.good_ind))-1, max(app.epoch_times(app.good_ind))+1];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t = tiledlayout(app.Panel,1,1);
            app.DiaryAxis_B.Parent = t;
            app.DiaryAxis_T.Parent = t;  
            app.DiaryAxis_B.Box = "off";
            app.DiaryAxis_T.Box = "off";
            app.DiaryAxis_T.Color = 'none';
            app.DiaryAxis_B.XTickMode = 'auto';
            app.DiaryAxis_T.XTickMode = 'manual';
            app.DiaryAxis_B.YTickMode = 'auto';
            app.DiaryAxis_T.YTickMode = 'manual';
            app.DiaryAxis_T.YTick = [];            
            app.DiaryAxis_B.XLimitMethod = "tight";
            app.DiaryAxis_T.XLimitMethod = "tight";            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            thisCell = sln_cell.CellName & ...
                sprintf('cell_name="%s"',app.CellnameEditField.Value);
            if ~thisCell.exists
                fig = uifigure;
                uialert(fig,'Cell not found','Cell not found');
                return;
            end
            epochResults = sln_results.CCEpochStats & thisCell;
            if ~epochResults.exists
                fig = uifigure;
                uialert(fig,'No epoch results found for this cell','No epoch results found');
                return;
            end
            first_epoch_id = min(fetchn(aka.Epoch & thisCell,'epoch_id'));
            first_epoch_start_time = fetch1(aka.Epoch & thisCell & ...
                sprintf('epoch_id=%d',first_epoch_id), 'epoch_start_time');
            app.results_struct = fetch(epochResults,'*');
            selected_epochs = [];
            try
                selected_epochs = eval(app.EpochsEditField.Value);
            catch
            end
            if ~isempty(selected_epochs)
                app.good_ind = ismember([app.results_struct.epoch_id],selected_epochs);
            else
                app.good_ind = true(1,length(app.results_struct));
            end
            epoch_times = fetchn(aka.Epoch & epochResults, 'epoch_start_time');
            app.epoch_times = (epoch_times - first_epoch_start_time) / 1E3; %s
            app.init_dropdowns;
            app.update_plot;
        end

        % Value changed function: LeftaxisDropDown
        function LeftaxisDropDownValueChanged(app, event)
            app.update_plot;
        end

        % Value changed function: RightaxisDropDown
        function RightaxisDropDownValueChanged(app, event)
            app.update_plot;            
        end

        % Value changed function: LeftLowerEdit
        function LeftLowerEditValueChanged(app, event)
            value = app.LeftLowerEdit.Value;
            yyaxis(app.DiaryAxis_B,'left');
            lims = app.DiaryAxis_B.YLim;
            if value<lims(2)
                app.DiaryAxis_B.YLim = [value, lims(2)];
            end
        end

        % Value changed function: LeftUpperEdit
        function LeftUpperEditValueChanged(app, event)
            value = app.LeftUpperEdit.Value;
            yyaxis(app.DiaryAxis_B,'left');
            lims = app.DiaryAxis_B.YLim;
            if value>lims(1)
                app.DiaryAxis_B.YLim = [lims(1), value];
            end
        end

        % Value changed function: RightLowerEdit
        function RightLowerEditValueChanged(app, event)
            value = app.RightLowerEdit.Value;
            yyaxis(app.DiaryAxis_B,'right');
            lims = app.DiaryAxis_B.YLim;
            if value<lims(2)
                app.DiaryAxis_B.YLim = [value, lims(2)];
            end
        end

        % Value changed function: RightUpperEdit
        function RightUpperEditValueChanged(app, event)
            value = app.RightUpperEdit.Value;
            yyaxis(app.DiaryAxis_B,'right');
            lims = app.DiaryAxis_B.YLim;
            if value>lims(1)
                app.DiaryAxis_B.YLim = [lims(1), value];
            end
        end

        % Value changed function: EpochsEditField
        function EpochsEditFieldValueChanged(app, event)
            value = app.EpochsEditField.Value;
            selected_epochs = [];
            try
                selected_epochs = eval(value);
            catch
            end
            if ~isempty(selected_epochs)
                app.good_ind = ismember([app.results_struct.epoch_id],selected_epochs);
            else
                app.good_ind = true(1,length(app.results_struct));
            end
            app.update_plot;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiaryPlotterUIFigure = uifigure('Visible', 'off');
            app.DiaryPlotterUIFigure.Name = 'Diary Plotter';
            app.DiaryAxis_B = uiaxes(app.DiaryPlotterUIFigure);
            xlabel(app.DiaryAxis_B, 'Time from first epoch (s)')
            zlabel(app.DiaryAxis_B, 'Z')
            app.DiaryAxis_B.XTick = [];
            app.DiaryAxis_B.YTick = [];
            app.DiaryAxis_T = uiaxes(app.DiaryPlotterUIFigure);
            xlabel(app.DiaryAxis_T, 'Epoch number')
            zlabel(app.DiaryAxis_T, 'Z')
            app.DiaryAxis_T.XAxisLocation = 'top';
            app.DiaryAxis_T.XTick = [];
            app.DiaryAxis_T.YTick = [];
            app.CellnameEditFieldLabel = uilabel(app.DiaryPlotterUIFigure);
            app.CellnameEditFieldLabel.Text = 'Cellname';
            app.CellnameEditField = uieditfield(app.DiaryPlotterUIFigure, 'text');
            app.EpochsEditFieldLabel = uilabel(app.DiaryPlotterUIFigure);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.DiaryPlotterUIFigure, 'text');
            app.EpochsEditField.ValueChangedFcn = createCallbackFcn(app, @EpochsEditFieldValueChanged, true);
            app.LoadButton = uibutton(app.DiaryPlotterUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LeftaxisDropDownLabel = uilabel(app.DiaryPlotterUIFigure);
            app.LeftaxisDropDownLabel.Text = 'Left axis';
            app.LeftaxisDropDown = uidropdown(app.DiaryPlotterUIFigure);
            app.LeftaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @LeftaxisDropDownValueChanged, true);
            app.RightaxisDropDownLabel = uilabel(app.DiaryPlotterUIFigure);
            app.RightaxisDropDownLabel.Text = 'Right axis';
            app.RightaxisDropDown = uidropdown(app.DiaryPlotterUIFigure);
            app.RightaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @RightaxisDropDownValueChanged, true);
            app.LeftLowerEdit = uieditfield(app.DiaryPlotterUIFigure, 'numeric');
            app.LeftLowerEdit.ValueChangedFcn = createCallbackFcn(app, @LeftLowerEditValueChanged, true);
            app.LeftUpperEdit = uieditfield(app.DiaryPlotterUIFigure, 'numeric');
            app.LeftUpperEdit.ValueChangedFcn = createCallbackFcn(app, @LeftUpperEditValueChanged, true);
            app.RightLowerEdit = uieditfield(app.DiaryPlotterUIFigure, 'numeric');
            app.RightLowerEdit.ValueChangedFcn = createCallbackFcn(app, @RightLowerEditValueChanged, true);
            app.RightUpperEdit = uieditfield(app.DiaryPlotterUIFigure, 'numeric');
            app.RightUpperEdit.ValueChangedFcn = createCallbackFcn(app, @RightUpperEditValueChanged, true);
            app.Panel = uipanel(app.DiaryPlotterUIFigure);
            % Show the figure after all components are created
            app.DiaryPlotterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Diary_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiaryPlotterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiaryPlotterUIFigure)
        end
    end
end