classdef QueryBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QueryBrowserUIFigure   matlab.ui.Figure
        ResulttoSwitch         matlab.ui.control.RockerSwitch
        ResulttoSwitchLabel    matlab.ui.control.Label
        FilterbyPanel          matlab.ui.container.Panel
        UserFilterDropDown     matlab.ui.control.DropDown
        UserDropDownLabel      matlab.ui.control.Label
        ProjectFilterDropDown  matlab.ui.control.DropDown
        ProjectDropDownLabel   matlab.ui.control.Label
        ProjectsDropDown       matlab.ui.control.DropDown
        SetprojectButton       matlab.ui.control.Button
        RenameButton           matlab.ui.control.Button
        DeleteButton           matlab.ui.control.Button
        ResultTableDropDown    matlab.ui.control.DropDown
        FetchresultButton      matlab.ui.control.Button
        FetchButton            matlab.ui.control.Button
        QueryTable             matlab.ui.control.Table
    end

    
    properties (Access = private)
        selectedRow = []; %just the index
        query_table %Full table in memory. Might be filtered for display
    end
    
    methods (Access = private)
        
        function init(app)
            q_struct = fetch(sln_lab.Query,'project_name');
            q_table = struct2table(q_struct,'AsArray',true);
            n_queries = height(q_table);
            %add blanks for the counts of stuff
            blank_counts = array2table(zeros(n_queries,5));
            app.query_table = [q_table, blank_counts];

            %set projects menu;
            active_projects = fetchn(sln_lab.ProjectActive,'project_name');
            app.ProjectsDropDown.Items = active_projects;

            %set filter menus
            all_projects = fetchn(sln_lab.Project,'project_name');
            app.ProjectFilterDropDown.Items = [' '; all_projects];
            app.UserFilterDropDown.Items = [' '; fetchn(sln_lab.User, 'user_name')];
            
            %set results dropdown
            s = sln_results.getSchema;
            table_names = s.tableNames.keys;
            table_names = table_names(startsWith(table_names,'sln_results.Dataset'));
            app.ResultTableDropDown.Items = extractAfter(table_names,'sln_results.Dataset');

            %show the table
            app.showTable();
        end
        
        function showTable(app)
            T = app.query_table;
            filt_user = app.UserFilterDropDown.Value;
            filt_project = app.ProjectFilterDropDown.Value;

            if ~strcmp(filt_user, ' ')
                T = T(strcmp(T.user_name,filt_user),:);                
            end
            if ~strcmp(filt_project, ' ')
                T = T(strcmp(T.project_name,filt_project),:);
            end

            app.QueryTable.Data = T;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            app.QueryBrowserUIFigure.Name = 'Fetching...'; drawnow;

            temp = app.QueryTable.Data{app.selectedRow,1};
            query_name = temp{1};
            q = sln_lab.Query & sprintf('query_name = "%s"', query_name);            
            result = q.runAndFetch();
            assignin('base','datasets',result);
            assignin('base','datasets_table',struct2table(result,'AsArray',true));
            temp = aka.Cell * ...
                sln_symphony.Experiment * ...
                proj(sln_symphony.ExperimentRetina,'source_id->retina_id','*') & ...
                result;
            exps = proj(sln_symphony.Experiment) & temp; 
            animals = proj(sln_animal.Animal) & temp;
            eyes = sln_animal.Eye & temp;
            cells = aka.Cell & result;
            datasets = aka.Dataset & result;
            app.QueryTable.Data{app.selectedRow,4} = count(exps);
            app.QueryTable.Data{app.selectedRow,5} = count(animals);
            app.QueryTable.Data{app.selectedRow,6} = count(eyes);
            app.QueryTable.Data{app.selectedRow,7} = count(cells);
            app.QueryTable.Data{app.selectedRow,8} = count(datasets);
                       
            app.QueryBrowserUIFigure.Name = 'QueryBrowser'; drawnow;
        end

        % Selection changed function: QueryTable
        function QueryTableSelectionChanged(app, event)
            selection = app.QueryTable.Selection;
            app.selectedRow = selection(1);
            removeStyle(app.QueryTable);
            s = uistyle('FontColor','red','FontWeight','bold');
            addStyle(app.QueryTable,s,"row",app.selectedRow);
            %default_color = 
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            key.query_name = app.QueryTable.Data{app.selectedRow,1}{1};
            key.user_name = app.QueryTable.Data{app.selectedRow,2}{1};
            choice = questdlg(sprintf('Delete query %s?', key.query_name), ...
                  'Confirmation', ...
                  'OK', 'Cancel', 'OK');
            if strcmp(choice,'OK')
                delQuick(sln_lab.Query & key);
                app.init();
            end            
        end

        % Button pushed function: RenameButton
        function RenameButtonPushed(app, event)
            key.query_name = app.QueryTable.Data{app.selectedRow,1}{1};
            key.user_name = app.QueryTable.Data{app.selectedRow,2}{1};
            this_query = sln_lab.Query & key;
            new_name = inputdlg('New query name:', ...
                  sprintf('Rename query %s?', key.query_name), ...
                  [1 45] ...
                  );
            if ~isempty(new_name)
                key.sql_query = fetch1(this_query,'sql_query');
                C = dj.conn;
                delQuick(this_query);
                key.project_name = app.QueryTable.Data{app.selectedRow,3}{1};
                key.query_name = new_name{1};
                key.user_name = C.user;
                insert(sln_lab.Query, key);
                app.init();
            end   
        end

        % Button pushed function: SetprojectButton
        function SetprojectButtonPushed(app, event)
            key.query_name = app.QueryTable.Data{app.selectedRow,1}{1};
            key.user_name = app.QueryTable.Data{app.selectedRow,2}{1};
            this_query = sln_lab.Query & key;
            key = fetch(this_query,'*');
            key.project_name = app.ProjectsDropDown.Value;
            delQuick(this_query);
            insert(sln_lab.Query, key);
            app.init();
        end

        % Value changed function: UserFilterDropDown
        function UserFilterDropDownValueChanged(app, event)
            app.showTable;
        end

        % Value changed function: ProjectFilterDropDown
        function ProjectFilterDropDownValueChanged(app, event)
            app.showTable;
        end

        % Button pushed function: FetchresultButton
        function FetchresultButtonPushed(app, event)
            app.QueryBrowserUIFigure.Name = 'Fetching...'; drawnow;

            temp = app.QueryTable.Data{app.selectedRow,1};
            query_name = temp{1};
            q = sln_lab.Query & sprintf('query_name = "%s"', query_name);            
            result = q.runAndFetchAnalysisResult(['Dataset' app.ResultTableDropDown.Value]);
            exps = proj(sln_symphony.Experiment) & result; 
            animals = proj(sln_animal.Animal) & result;
            eyes = sln_animal.Eye & result;
            cells = proj(aka.Cell) & result;
            datasets = proj(aka.Dataset) & result;
            app.QueryTable.Data{app.selectedRow,4} = count(exps);
            app.QueryTable.Data{app.selectedRow,5} = count(animals);
            app.QueryTable.Data{app.selectedRow,6} = count(eyes);
            app.QueryTable.Data{app.selectedRow,7} = count(cells);
            app.QueryTable.Data{app.selectedRow,8} = count(datasets);

            if strcmp(app.ResulttoSwitch.Value,'Command line')
                assignin('base','data',result);
                assignin('base','data_table',struct2table(result,'AsArray',true));
            else
                [fname, floc] = uiputfile('*.mat','Save result file',...
                    sprintf('%s_%s', query_name, app.ResultTableDropDown.Value));
                data = result;
                data_table = struct2table(result,'AsArray',true);
                save(fullfile(floc,fname),"data_table","data");
            end
                       
            app.QueryBrowserUIFigure.Name = 'QueryBrowser'; drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QueryBrowserUIFigure = uifigure('Visible', 'off');
            app.QueryBrowserUIFigure.Name = 'QueryBrowser';
            app.QueryTable = uitable(app.QueryBrowserUIFigure);
            app.QueryTable.ColumnName = {'Query name'; 'User'; 'Project'; '# exps'; '# animals'; '#eyes'; '# cells'; '# datasets'};
            app.QueryTable.RowName = {};
            app.QueryTable.ColumnSortable = [true true true false false false false];
            app.QueryTable.SelectionChangedFcn = createCallbackFcn(app, @QueryTableSelectionChanged, true);
            app.FetchButton = uibutton(app.QueryBrowserUIFigure, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.FetchresultButton = uibutton(app.QueryBrowserUIFigure, 'push');
            app.FetchresultButton.ButtonPushedFcn = createCallbackFcn(app, @FetchresultButtonPushed, true);
            app.FetchresultButton.Text = 'Fetch result';
            app.ResultTableDropDown = uidropdown(app.QueryBrowserUIFigure);
            app.DeleteButton = uibutton(app.QueryBrowserUIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.RenameButton = uibutton(app.QueryBrowserUIFigure, 'push');
            app.RenameButton.ButtonPushedFcn = createCallbackFcn(app, @RenameButtonPushed, true);
            app.RenameButton.Text = 'Rename';
            app.SetprojectButton = uibutton(app.QueryBrowserUIFigure, 'push');
            app.SetprojectButton.ButtonPushedFcn = createCallbackFcn(app, @SetprojectButtonPushed, true);
            app.SetprojectButton.Text = 'Set project';
            app.ProjectsDropDown = uidropdown(app.QueryBrowserUIFigure);
            app.FilterbyPanel = uipanel(app.QueryBrowserUIFigure);
            app.FilterbyPanel.Title = 'Filter by';
            app.ProjectDropDownLabel = uilabel(app.FilterbyPanel);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectFilterDropDown = uidropdown(app.FilterbyPanel);
            app.ProjectFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectFilterDropDownValueChanged, true);
            app.UserDropDownLabel = uilabel(app.FilterbyPanel);
            app.UserDropDownLabel.Text = 'User';
            app.UserFilterDropDown = uidropdown(app.FilterbyPanel);
            app.UserFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @UserFilterDropDownValueChanged, true);
            app.ResulttoSwitchLabel = uilabel(app.QueryBrowserUIFigure);
            app.ResulttoSwitchLabel.Text = 'Result to';
            app.ResulttoSwitch = uiswitch(app.QueryBrowserUIFigure, 'rocker');
            app.ResulttoSwitch.Items = {'Command line', 'File'};
            app.ResulttoSwitch.Value = 'Command line';
            % Show the figure after all components are created
            app.QueryBrowserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QueryBrowser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QueryBrowserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QueryBrowserUIFigure)
        end
    end
end