classdef SpikeDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RigSwitch                       matlab.ui.control.Switch
        RigSwitchLabel                  matlab.ui.control.Label
        PushtodatabaseButton            matlab.ui.control.Button
        LoadsettingsfromfileButton      matlab.ui.control.Button
        SavesettingstofileButton        matlab.ui.control.Button
        PrinCompY                       matlab.ui.control.NumericEditField
        PrinCompX                       matlab.ui.control.NumericEditField
        AddfeatureLabel                 matlab.ui.control.Label
        fromanonymousfunctionButton     matlab.ui.control.Button
        frommfileButton                 matlab.ui.control.Button
        RunonSwitch                     matlab.ui.control.Switch
        RunonSwitchLabel                matlab.ui.control.Label
        ResetinclusionlistButton        matlab.ui.control.Button
        ResetexclusionlistButton        matlab.ui.control.Button
        YaxisDropDown                   matlab.ui.control.DropDown
        YaxisDropDownLabel              matlab.ui.control.Label
        XaxisDropDown                   matlab.ui.control.DropDown
        XaxisDropDownLabel              matlab.ui.control.Label
        DrawinclusionzoneButton         matlab.ui.control.Button
        KeepincludedpointsSwitch_2      matlab.ui.control.Switch
        KeepincludedpointsSwitch_2Label  matlab.ui.control.Label
        KeepincludedpointsSwitch        matlab.ui.control.Switch
        KeepincludedpointsSwitchLabel   matlab.ui.control.Label
        KeepexcludedpointsSwitch_2      matlab.ui.control.Switch
        KeepexcludedpointsSwitch_2Label  matlab.ui.control.Label
        KeepexcludedpointsSwitch        matlab.ui.control.Switch
        KeepexcludedpointsSwitchLabel   matlab.ui.control.Label
        DrawexclusionzoneButton         matlab.ui.control.Button
        ClusteringsettingsLabel         matlab.ui.control.Label
        PCAsettingsLabel                matlab.ui.control.Label
        ThresholdsettingsLabel          matlab.ui.control.Label
        FiltersettingsLabel             matlab.ui.control.Label
        ThresholdonSwitch               matlab.ui.control.Switch
        ThresholdonSwitchLabel          matlab.ui.control.Label
        MinimumpointswithinepsilonEditField  matlab.ui.control.NumericEditField
        MinimumpointswithinepsilonEditFieldLabel  matlab.ui.control.Label
        EpsilonEditField                matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel           matlab.ui.control.Label
        NumberofPCstouseEditField       matlab.ui.control.NumericEditField
        NumberofPCstouseEditFieldLabel  matlab.ui.control.Label
        MaximumalignmentoffsetmsEditField  matlab.ui.control.NumericEditField
        MaximumalignmentoffsetmsEditFieldLabel  matlab.ui.control.Label
        EventdurationmsEditField        matlab.ui.control.NumericEditField
        EventdurationmsEditFieldLabel   matlab.ui.control.Label
        RefractoryperiodmsEditField     matlab.ui.control.NumericEditField
        RefractoryperiodmsEditFieldLabel  matlab.ui.control.Label
        CurrentfilterspikeFiltermatLabel  matlab.ui.control.Label
        SelectnewfilterButton           matlab.ui.control.Button
        MergeepochsButton               matlab.ui.control.Button
        SplitepochsButton               matlab.ui.control.Button
        EpochsetListBox                 matlab.ui.control.ListBox
        EpochsetListBoxLabel            matlab.ui.control.Label
        DisplaySwitch                   matlab.ui.control.Switch
        DisplaySwitchLabel              matlab.ui.control.Label
        EventthresholdpAEditField       matlab.ui.control.NumericEditField
        EventthresholdpAEditFieldLabel  matlab.ui.control.Label
        CellListBox                     matlab.ui.control.ListBox
        CellListBoxLabel                matlab.ui.control.Label
        ExperimentDateDatePicker        matlab.ui.control.DatePicker
        ExperimentDateDatePickerLabel   matlab.ui.control.Label
        waveform_axes                   matlab.ui.control.UIAxes
        feature_axes                    matlab.ui.control.UIAxes
        epoch_axes                      matlab.ui.control.UIAxes
        dataset_axes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        experiment
        cell_data % Description
        selectedEpoch % Description
        epoch_data % Description
        filters % Description
        filter_path % Description
        epoch_trace % Description
        epoch_indicator % Description
        dataset_markers % Description
        threshold_trace % Description
        event_indices
        event_offsets % Description
        event_counts % Description
        event_waveforms % Description
        waveform_traces % Description
        feature_names
        prin_comps
        features % Description
        cluster_index % Description
        event_markers
        spike_indices % Description
        is_spike

    end

    methods (Access = private)

        function updateExperiment(app)

            %% fetch info on the cells in this experiment
            app.experiment = sprintf('%s%s',datestr(app.ExperimentDateDatePicker.Value, 'mmddyy'), app.RigSwitch.Value);
            g = aggr(dj.U('file_name','source_id'), sln_symphony.DatasetEpoch & sprintf('file_name="%s"', app.experiment), 'count(*)->n_epochs', 'count(distinct dataset_name)->n_datasets');
            h = (dj.U('source_type') * sln_symphony.ExperimentCell().proj('"Cell" -> source_type')) | (dj.U('source_type') * sln_symphony.ExperimentCellPair().proj('"Pair" -> source_type'));
            app.cell_data = fetch(g * h, '*');

            %% TODO: resets gfx

            %% update selected cell
            rd = (sln_symphony.ExperimentEpoch & app.cell_data(1) & sln_symphony.DatasetEpoch & (sln_symphony.ExperimentElectrode & 'recording_mode="Current clamp"' & 'amp_mode="Cell attached"')) * sln_symphony.ExperimentEpochChannel * sln_symphony.ExperimentChannel;
            
            app.epoch_data = fetch(rd,'*');
            app.spike_indices = cell(size(app.epoch_data));
            app.selectedEpoch = 1;
            
            %% update the cell and epoch set listviews
            app.CellListBox.Items = arrayfun(@(x) sprintf('Source %d (%s)',x.source_id,x.source_type), app.cell_data,'uni',0);
            app.CellListBox.Value = app.CellListBox.Items{1};
            %             app.EpochsetListBox.Items = {'1'};
            %             app.EpochsetListBox.Value = '1';

            %% update the dataset view
            app.drawDatasets();
           
            app.filterData();
            app.applyThreshold();
        end

        function drawDatasets(app)
            %% fetch info on datasets for this cell
            cd = app.cell_data(strcmp(app.CellListBox.Items, app.CellListBox.Value));
            ed = (sln_symphony.DatasetEpoch & cd) * sln_symphony.ExperimentElectrode & 'recording_mode="Current clamp"' & 'amp_mode="Cell attached"';
            ed = aggr(dj.U('dataset_name'), ed * sln_symphony.ExperimentEpoch, 'min(epoch_start_time)->start_time', 'max(epoch_start_time + epoch_duration)->end_time');
            f = fetch(ed, '*');

            %% update plot
            %             cla(app.dataset_axes);
            delete(app.dataset_markers);
            app.dataset_markers = line(app.dataset_axes, [f(:).start_time; f(:).end_time]/3600000, [1:length(f);1:length(f)],'color','k','linewidth',20);
            yticks(app.dataset_axes, 1:length(f));
            yticklabels(app.dataset_axes, {f(:).dataset_name});
            ylim(app.dataset_axes,[0.5, length(f)+0.5]);
        end

        function drawEpoch(app)
            %% update epoch axes
            if strcmp(app.DisplaySwitch.Value,'Raw')
                d = app.epoch_data(app.selectedEpoch).raw_data;
            else
                d = app.epoch_data(app.selectedEpoch).filt_data;
            end

            set(app.epoch_trace, 'xdata', 1:length(d), 'ydata', d); %if view raw...
            xlim(app.epoch_axes, [1, length(d)]); %TODO: time in seconds...

            %% update dataset axes
            set(app.epoch_indicator, 'value', (app.epoch_data(app.selectedEpoch).epoch_start_time + app.epoch_data(app.selectedEpoch).epoch_duration/2) / 3600000, 'visible', true);

            %% update waveform axes
            ei = cumsum(app.event_counts);
            ei = ei(app.selectedEpoch) - app.event_counts(app.selectedEpoch)+1 : ei(app.selectedEpoch);

            % updating just the color doesn't work, since the plotting
            % order will obscur the highlighted traces
            %  set(app.waveform_traces,'color','#cccccc');
            %  set(app.waveform_traces(ei),'color','k');
            
            % updating the visibility is fast, but what if we want to see
            % all the traces?
            set(app.waveform_traces,'visible',false);
            set(app.waveform_traces(ei),'visible',true);

            % updating the plot order works in combination with the color
            % update, but is very slow
            %  set(app.waveform_axes,'children',app.waveform_traces([ei, 1:ei(1)-1, ei(end)+1:end]));
            %  uistack(app.waveform_traces(ei), 'top');
            % uistack might be supported as of 2023a, but is it faster?
            
            % set(app.spike_markers, 'xdata', app.spike_indices{app.selectedEpoch},'ydata', 1.1*max(d)*ones(size(app.spike_indices{app.selectedEpoch})))
            % c = app.is_spike(app.event_counts(app.selectedEpoch)+1:app.event_counts(app.SelectedEpoch));

                
            ad = zeros(1, numel(app.is_spike));
            ad(ei) = 1;
            set(app.event_markers,'alphadata',ad);

        end

        function filterData(app)
            %
            %             t = filtfilt(d,f(fi).raw_data);
            %             t = t / mad(t,1);
            for i = 1:length(app.epoch_data)
                thisEpoch = app.epoch_data(i);
                tmp = filtfilt(app.filters(thisEpoch.sample_rate),thisEpoch.raw_data) ;
                app.epoch_data(i).filt_data = tmp / mad(tmp,1);

                %also convert raw data to mad's
                app.epoch_data(i).raw_data = app.epoch_data(i).raw_data - median(app.epoch_data(i).raw_data);
                app.epoch_data(i).raw_data = app.epoch_data(i).raw_data / mad(app.epoch_data(i).raw_data, 1);
            end

        end

        function detectAndAlignEvents(app)
            c = cell(size(app.epoch_data));
            app.event_indices = cell(size(app.epoch_data));
            app.spike_indices = cell(size(app.epoch_data));
            if strcmp(app.ThresholdonSwitch,'Raw')
                t = 'raw_data';
            else
                t = 'filt_data';
            end
            rf = app.RefractoryperiodmsEditField.Value/1000;
            w100k = app.EventdurationmsEditField.Value*100;

            for i =1:length(app.epoch_data)
                [~,app.event_indices{i}] = findpeaks(sign(app.EventthresholdpAEditField.Value) * app.epoch_data(i).(t),  'minpeakheight', abs(app.EventthresholdpAEditField.Value), 'minpeakdistance', rf * app.epoch_data(i).sample_rate);
                if isempty(app.event_indices{i})
                    continue
                end

                w = ceil((app.EventdurationmsEditField.Value/1000*app.epoch_data(i).sample_rate)/2);

                ii = app.event_indices{i} + ((-w+1):w)';
                ii(ii<1) = 1;
                ii(ii>length(app.epoch_data(i).(t))) = length(app.epoch_data(i).(t));

                c{i} = reshape(resample(app.epoch_data(i).(t)(ii), 100000, app.epoch_data(i).sample_rate), w100k,[])'; %resample locally to 100kHz
            end
            app.event_waveforms =cell2mat(c)';

            cc = corr(app.event_waveforms);
            [~,i] = max(sum(cc,1)); % the index of the most 'central' spike

            max_o = app.MaximumalignmentoffsetmsEditField.Value * 100;
            r = cell2mat(arrayfun(@(x) xcorr(app.event_waveforms(:,i), app.event_waveforms(:,x), max_o), 1:size(app.event_waveforms,2), 'uni', 0));

            [~,i] = max(r,[],1); %for each event, the best offset to align to the central one

            app.event_offsets = i - max_o + 1;
            app.event_counts = cellfun(@length,app.event_indices);
            eo = mat2cell(app.event_offsets, 1, app.event_counts)';
            app.event_indices = cellfun(@(x,y,z) x + round(y/100000*z), app.event_indices, eo, {app.epoch_data(:).sample_rate}', 'uni', 0);
            app.is_spike = false(sum(app.event_counts),1);
            app.event_waveforms = cell2mat(arrayfun(@(x) circshift(app.event_waveforms(:,x),app.event_offsets(x)), 1:size(app.event_waveforms,2), 'uni', 0));


            set(app.event_markers, 'xdata', horzcat(app.event_indices{:}), 'ydata', -6*app.EventthresholdpAEditField.Value*ones(size(app.is_spike)));
        end

        function drawWaveforms(app)
            %plot all the waveforms in gray...
            delete(app.waveform_traces);
            app.waveform_traces = plot(app.waveform_axes, app.event_waveforms, 'color', '#cccccc');
        end
        
        function applyThreshold(app)
            %% align spike waveforms
            app.detectAndAlignEvents();
            app.drawWaveforms();
            app.doClustering();
            %% update the epoch view
            app.drawEpoch();
        end
        
        function doClustering(app)
            app.prin_comps = pca(app.event_waveforms);
            
            %% peak-to-pea current and time
            [a,i] = min(app.event_waveforms,[],1);
            [b,j] = max(app.event_waveforms,[],1);
            ppi = b-a;
            ppt = j-i;           
            
            %% other features
            med = median(app.event_waveforms,1); %median of waveform
            tmp = app.event_waveforms > (med - .5*(med-a));
            fwhm = arrayfun(@(x) i(x) + find(tmp(i(x):end,x),1,'first') - find(tmp(1:i(x),x),1,'last'), 1:numel(med),'uni',0);
            fwhm(cellfun(@isempty,fwhm)) = deal({0});
            fwhm = cell2mat(fwhm); %full width at half max
            
            %wall time of epoch
            wt = cell2mat(arrayfun(@(x,y,z) x+y*cell2mat(z), [app.epoch_data(:).epoch_start_time]/1000, 1./[app.epoch_data(:).sample_rate], app.event_indices', 'UniformOutput', false));
            
            d = abs(diff(app.event_waveforms,1,1));
            [maxd,i] = max(d,[],1); %max slope
            tmp = d<.2*maxd; 
            th = arrayfun(@(x) find(tmp(1:i(x),x),1,'last'), 1:numel(maxd),'uni',0); %time when slope passes 20% of max
            th(cellfun(@isempty,th)) = deal({0});
            th = cell2mat(th);
            
            
            %% TODO: features from feature list
            
            app.features = vertcat(ppi,ppt,med,fwhm,wt,maxd,th)';
            ft = zscore(app.features,[],1);
            app.cluster_index = dbscan(horzcat(ft,app.prin_comps(:,1:app.NumberofPCstouseEditField.Value)),app.EpsilonEditField.Value,app.MinimumpointswithinepsilonEditField.Value);
            
            app.drawClusters();
        end
        
        function drawClusters(app)
            [x,y] = app.getFeats();
            scatter(app.feature_axes,x,y,20,app.cluster_index);
            colormap(app.feature_axes, 'colorcube');
            
        end
        
        function [x,y] = getFeats(app)
            xind = find(strcmp(app.feature_names, app.XaxisDropDown.Value)) - 1;
            yind = find(strcmp(app.feature_names, app.YaxisDropDown.Value)) - 1;
            
            if xind == 0 
                x = app.prin_comps(:,app.PrinCompX.Value);
            else
                x = app.features(:,xind);
            end
            
            if yind == 0 
                y = app.prin_comps(:,app.PrinCompY.Value);
            else
                y = app.features(:,yind);
            end
        end
    
        function updateSpikeIndices(app)
            % (1) plot on the epoch axes
            app.drawEpoch();

            % (2) change the waveforms to a different color
            set(app.waveform_traces(~app.is_spike),'color', '#cccccc');
            set(app.waveform_traces(app.is_spike),'color', 'g');


            cd = zeros(numel(app.is_spike),3);
            cd(app.is_spike,2) = 1;
            cd(~app.is_spike,:) = .8;
            set(app.event_markers,'cdata',cd);
            
            % 'xdata', app.spike_indices{app.selectedEpoch},'ydata', 1.1*max(d)*ones(size(app.spike_indices{app.selectedEpoch})));

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            %% load spike detector(s)
            app.filter_path = sprintf('%s%sutilities%sspikeFilter.mat',getenv('ANALYSIS_CODE_FOLDER'),filesep,filesep);
            app.filters = containers.Map('KeyType','double','ValueType','any');

            % allows 1 filter per sample rate
            tmp = load(app.filter_path);
            f = fieldnames(tmp);
            for fi = 1:numel(f)
                app.filters(tmp.(f{fi}).SampleRate) = tmp.(f{fi});
            end
            
            %% initialize plots
            hold(app.epoch_axes, 'on');
            app.epoch_trace = plot(app.epoch_axes, 0, nan, 'k');
            app.epoch_indicator = xline(app.dataset_axes, 0,'visible',false,'color','m','linewidth',10);
            app.threshold_trace = yline(app.epoch_axes,app.EventthresholdpAEditField.Value, 'm--', 'visible', true);

            app.event_markers = scatter(app.epoch_axes, nan, nan, 30,'marker','v','markerfacecolor','flat','markerfacealpha','flat', 'markeredgecolor','flat','markeredgealpha','flat');            
            set(app.event_markers,'cdata',[.8 .8 .8]);
            %% features...
%             app.features = vertcat(ppi,ppt,med,fwhm,wt,maxd,th,coeff(:,1:app.NumberofPCstouseEditField.Value)')';
            app.feature_names = {'Principal component', 'Peak-to-peak current', 'Peak-to-peak time', 'Median current', 'Full width at half maximum', 'Event time', 'Maximum slope', 'Threshold time'};
            app.XaxisDropDown.Items = app.feature_names;
            app.YaxisDropDown.Items = app.feature_names;
            app.XaxisDropDown.Value = 'Full width at half maximum';
            app.YaxisDropDown.Value = 'Maximum slope';
            
        end

        % Value changed function: ExperimentDateDatePicker
        function ExperimentDateDatePickerValueChanged(app, event)
            app.updateExperiment();
        end

        % Key release function: UIFigure
        function keybinds(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    if app.selectedEpoch < length(app.epoch_data)
                        app.selectedEpoch = app.selectedEpoch + 1;
                        app.drawEpoch();
                    end
                case 'leftarrow'
                    if app.selectedEpoch > 1
                        app.selectedEpoch = app.selectedEpoch - 1;
                        app.drawEpoch();
                    end
            end
        end

        % Value changed function: DisplaySwitch
        function DisplaySwitchValueChanged(app, event)
            set(app.threshold_trace, 'visible', strcmp(app.DisplaySwitch.Value, app.ThresholdonSwitch.Value));
            app.drawEpoch();
        end

        % Value changed function: EventthresholdpAEditField
        function EventthresholdpAEditFieldValueChanged(app, event)
            set(app.threshold_trace, 'value', app.EventthresholdpAEditField.Value);
            app.applyThreshold();
        end

        % Value changed function: ThresholdonSwitch
        function ThresholdonSwitchValueChanged(app, event)
            set(app.threshold_trace, 'visible', strcmp(app.DisplaySwitch.Value, app.ThresholdonSwitch.Value));
            app.applyThreshold();
        end

        % Button down function: dataset_axes
        function dataset_axesButtonDown(app, event)
            if event.Button == 1
                t = event.IntersectionPoint(1);
                [~,app.selectedEpoch] = min(abs(([app.epoch_data(:).epoch_start_time] + [app.epoch_data(:).epoch_duration])/3600000 - t));
                app.drawEpoch();
            end
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            value = app.XaxisDropDown.Value;
            if strcmp(value,'Principal component')
                app.PrinCompX.Visible = true;  
            else
                app.PrinCompX.Visible = false;   
            end
            app.drawClusters();
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownValueChanged(app, event)
            value = app.YaxisDropDown.Value;
            if strcmp(value,'Principal component')
                app.PrinCompY.Visible = true;    
            else
                app.PrinCompY.Visible = false;   
            end
            app.drawClusters();
        end

        % Value changed function: PrinCompX
        function PrinCompXValueChanged(app, event)
            app.drawClusters();
        end

        % Value changed function: PrinCompY
        function PrinCompYValueChanged(app, event)
            app.drawClusters();
        end

        % Value changed function: NumberofPCstouseEditField
        function NumberofPCstouseEditFieldValueChanged(app, event)
            app.doClustering();
        end

        % Value changed function: EpsilonEditField
        function EpsilonEditFieldValueChanged(app, event)
            app.doClustering();
        end

        % Value changed function: MinimumpointswithinepsilonEditField
        function MinimumpointswithinepsilonEditFieldValueChanged(app, event)
            app.doClustering();
        end

        % Button pushed function: DrawinclusionzoneButton
        function DrawinclusionzoneButtonPushed(app, event)
            %TODO: make the app non-interactable

            roi = drawpolygon(app.feature_axes,'color','g','interactionsallowed','none');
            %TODO: perhaps it would be nice to allow people to drag the roi
            % can do this with the addlistener method of the roi object...
            
            if isempty(roi.Position)
                return
            end

            [x,y] = app.getFeats();
            pts = inROI(roi,x,y);
            
            
            ipts = mat2cell(pts', 1, app.event_counts)';

            app.is_spike = app.is_spike | pts;
            app.spike_indices = cellfun(@(x,y,z) union(y(x), z), ipts, app.event_indices, app.spike_indices, 'uni', 0); %TODO: need multiple inclusions...
            
            app.updateSpikeIndices();
            %we need some visual indication when the spikes are included...
            % 1) count the number of spikes in the epoch set view?
            % 2) change feature_axes / epoch_axes markers to filled?
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @keybinds, true);
            app.dataset_axes = uiaxes(app.UIFigure);
            title(app.dataset_axes, 'Epochs Summary')
            xlabel(app.dataset_axes, 'Time (hr)')
            ylabel(app.dataset_axes, 'Dataset')
            zlabel(app.dataset_axes, 'Z')
            app.dataset_axes.ButtonDownFcn = createCallbackFcn(app, @dataset_axesButtonDown, true);
            app.epoch_axes = uiaxes(app.UIFigure);
            title(app.epoch_axes, 'Data for Selected Epoch')
            xlabel(app.epoch_axes, 'Time (s)')
            ylabel(app.epoch_axes, 'Membrane current (m.a.d.)')
            zlabel(app.epoch_axes, 'Z')
            app.feature_axes = uiaxes(app.UIFigure);
            title(app.feature_axes, 'Spike Clusters')
            zlabel(app.feature_axes, 'Z')
            app.waveform_axes = uiaxes(app.UIFigure);
            title(app.waveform_axes, 'Spike Waveforms')
            xlabel(app.waveform_axes, 'Time (ms)')
            ylabel(app.waveform_axes, 'Current (pA)')
            zlabel(app.waveform_axes, 'Z')
            app.ExperimentDateDatePickerLabel = uilabel(app.UIFigure);
            app.ExperimentDateDatePickerLabel.Text = 'Experiment Date';
            app.ExperimentDateDatePicker = uidatepicker(app.UIFigure);
            app.ExperimentDateDatePicker.DisplayFormat = 'MMddyy';
            app.ExperimentDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @ExperimentDateDatePickerValueChanged, true);
            app.CellListBoxLabel = uilabel(app.UIFigure);
            app.CellListBoxLabel.Text = 'Cell';
            app.CellListBox = uilistbox(app.UIFigure);
            app.CellListBox.Items = {'Cell 1 (source_id=2)', 'Cell 2 (source_id=3)', 'Pair 1, Cell 2 (source_id=3)', 'Pair 1, Cell 3 (source_id=4)'};
            app.CellListBox.Value = 'Cell 1 (source_id=2)';
            app.EventthresholdpAEditFieldLabel = uilabel(app.UIFigure);
            app.EventthresholdpAEditFieldLabel.Text = 'Event threshold (pA)';
            app.EventthresholdpAEditField = uieditfield(app.UIFigure, 'numeric');
            app.EventthresholdpAEditField.ValueChangedFcn = createCallbackFcn(app, @EventthresholdpAEditFieldValueChanged, true);
            app.EventthresholdpAEditField.Value = -6;
            app.DisplaySwitchLabel = uilabel(app.UIFigure);
            app.DisplaySwitchLabel.Text = 'Display';
            app.DisplaySwitch = uiswitch(app.UIFigure, 'slider');
            app.DisplaySwitch.Items = {'Raw', 'Filtered'};
            app.DisplaySwitch.ValueChangedFcn = createCallbackFcn(app, @DisplaySwitchValueChanged, true);
            app.DisplaySwitch.Value = 'Filtered';
            app.EpochsetListBoxLabel = uilabel(app.UIFigure);
            app.EpochsetListBoxLabel.Text = 'Epoch set';
            app.EpochsetListBox = uilistbox(app.UIFigure);
            app.EpochsetListBox.Items = {'1 (0 events, 0 spikes)', '', '', ''};
            app.EpochsetListBox.Value = '1 (0 events, 0 spikes)';
            app.SplitepochsButton = uibutton(app.UIFigure, 'push');
            app.SplitepochsButton.Text = 'Split epochs';
            app.MergeepochsButton = uibutton(app.UIFigure, 'push');
            app.MergeepochsButton.Enable = 'off';
            app.MergeepochsButton.Text = 'Merge epochs';
            app.SelectnewfilterButton = uibutton(app.UIFigure, 'push');
            app.SelectnewfilterButton.Text = 'Select new filter';
            app.CurrentfilterspikeFiltermatLabel = uilabel(app.UIFigure);
            app.CurrentfilterspikeFiltermatLabel.Text = {'Current filter:'; '.../spikeFilter.mat'};
            app.RefractoryperiodmsEditFieldLabel = uilabel(app.UIFigure);
            app.RefractoryperiodmsEditFieldLabel.Text = 'Refractory period (ms)';
            app.RefractoryperiodmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RefractoryperiodmsEditField.Value = 1.5;
            app.EventdurationmsEditFieldLabel = uilabel(app.UIFigure);
            app.EventdurationmsEditFieldLabel.Text = 'Event duration (ms)';
            app.EventdurationmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EventdurationmsEditField.Value = 2;
            app.MaximumalignmentoffsetmsEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumalignmentoffsetmsEditFieldLabel.Text = 'Maximum alignment offset (ms)';
            app.MaximumalignmentoffsetmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumalignmentoffsetmsEditField.Value = 1;
            app.NumberofPCstouseEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPCstouseEditFieldLabel.Text = 'Number of PCs to use';
            app.NumberofPCstouseEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPCstouseEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPCstouseEditFieldValueChanged, true);
            app.NumberofPCstouseEditField.Value = 5;
            app.EpsilonEditFieldLabel = uilabel(app.UIFigure);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpsilonEditField.ValueChangedFcn = createCallbackFcn(app, @EpsilonEditFieldValueChanged, true);
            app.EpsilonEditField.Value = 1;
            app.MinimumpointswithinepsilonEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumpointswithinepsilonEditFieldLabel.Text = 'Minimum points within epsilon';
            app.MinimumpointswithinepsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumpointswithinepsilonEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumpointswithinepsilonEditFieldValueChanged, true);
            app.MinimumpointswithinepsilonEditField.Value = 5;
            app.ThresholdonSwitchLabel = uilabel(app.UIFigure);
            app.ThresholdonSwitchLabel.Text = 'Threshold on';
            app.ThresholdonSwitch = uiswitch(app.UIFigure, 'slider');
            app.ThresholdonSwitch.Items = {'Raw', 'Filtered'};
            app.ThresholdonSwitch.ValueChangedFcn = createCallbackFcn(app, @ThresholdonSwitchValueChanged, true);
            app.ThresholdonSwitch.Value = 'Filtered';
            app.FiltersettingsLabel = uilabel(app.UIFigure);
            app.FiltersettingsLabel.Text = 'Filter settings';
            app.ThresholdsettingsLabel = uilabel(app.UIFigure);
            app.ThresholdsettingsLabel.Text = 'Threshold settings';
            app.PCAsettingsLabel = uilabel(app.UIFigure);
            app.PCAsettingsLabel.Text = 'PCA settings';
            app.ClusteringsettingsLabel = uilabel(app.UIFigure);
            app.ClusteringsettingsLabel.Text = 'Clustering settings';
            app.DrawexclusionzoneButton = uibutton(app.UIFigure, 'push');
            app.DrawexclusionzoneButton.Text = 'Draw exclusion zone';
            app.KeepexcludedpointsSwitchLabel = uilabel(app.UIFigure);
            app.KeepexcludedpointsSwitchLabel.Text = 'Keep excluded points';
            app.KeepexcludedpointsSwitch = uiswitch(app.UIFigure, 'slider');
            app.KeepexcludedpointsSwitch.Value = 'On';
            app.KeepexcludedpointsSwitch_2Label = uilabel(app.UIFigure);
            app.KeepexcludedpointsSwitch_2Label.Text = 'Keep excluded points';
            app.KeepexcludedpointsSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.KeepexcludedpointsSwitch_2.Value = 'On';
            app.KeepincludedpointsSwitchLabel = uilabel(app.UIFigure);
            app.KeepincludedpointsSwitchLabel.Text = 'Keep included points';
            app.KeepincludedpointsSwitch = uiswitch(app.UIFigure, 'slider');
            app.KeepincludedpointsSwitch.Value = 'On';
            app.KeepincludedpointsSwitch_2Label = uilabel(app.UIFigure);
            app.KeepincludedpointsSwitch_2Label.Text = 'Keep included points';
            app.KeepincludedpointsSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.KeepincludedpointsSwitch_2.Value = 'On';
            app.DrawinclusionzoneButton = uibutton(app.UIFigure, 'push');
            app.DrawinclusionzoneButton.ButtonPushedFcn = createCallbackFcn(app, @DrawinclusionzoneButtonPushed, true);
            app.DrawinclusionzoneButton.Text = 'Draw inclusion zone';
            app.XaxisDropDownLabel = uilabel(app.UIFigure);
            app.XaxisDropDownLabel.Text = 'X axis';
            app.XaxisDropDown = uidropdown(app.UIFigure);
            app.XaxisDropDown.Items = {'Peak-to-peak current', 'Maximum slope', 'Principal component', ''};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 'Principal component';
            app.YaxisDropDownLabel = uilabel(app.UIFigure);
            app.YaxisDropDownLabel.Text = 'Y axis';
            app.YaxisDropDown = uidropdown(app.UIFigure);
            app.YaxisDropDown.Items = {'Peak-to-peak current', 'Maximum slope', 'Principal component', ''};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.YaxisDropDown.Value = 'Peak-to-peak current';
            app.ResetexclusionlistButton = uibutton(app.UIFigure, 'push');
            app.ResetexclusionlistButton.Text = 'Reset exclusion list';
            app.ResetinclusionlistButton = uibutton(app.UIFigure, 'push');
            app.ResetinclusionlistButton.Text = 'Reset inclusion list';
            app.RunonSwitchLabel = uilabel(app.UIFigure);
            app.RunonSwitchLabel.Text = 'Run on';
            app.RunonSwitch = uiswitch(app.UIFigure, 'slider');
            app.RunonSwitch.Items = {'Raw', 'Filtered'};
            app.RunonSwitch.Value = 'Filtered';
            app.frommfileButton = uibutton(app.UIFigure, 'push');
            app.frommfileButton.Text = 'from *.m file';
            app.fromanonymousfunctionButton = uibutton(app.UIFigure, 'push');
            app.fromanonymousfunctionButton.Text = 'from anonymous function';
            app.AddfeatureLabel = uilabel(app.UIFigure);
            app.AddfeatureLabel.Text = 'Add feature';
            app.PrinCompX = uieditfield(app.UIFigure, 'numeric');
            app.PrinCompX.ValueChangedFcn = createCallbackFcn(app, @PrinCompXValueChanged, true);
            app.PrinCompX.Visible = 'off';
            app.PrinCompX.Value = 1;
            app.PrinCompY = uieditfield(app.UIFigure, 'numeric');
            app.PrinCompY.ValueChangedFcn = createCallbackFcn(app, @PrinCompYValueChanged, true);
            app.PrinCompY.Visible = 'off';
            app.PrinCompY.Value = 2;
            app.SavesettingstofileButton = uibutton(app.UIFigure, 'push');
            app.SavesettingstofileButton.Text = 'Save settings to file';
            app.LoadsettingsfromfileButton = uibutton(app.UIFigure, 'push');
            app.LoadsettingsfromfileButton.Text = 'Load settings from file';
            app.PushtodatabaseButton = uibutton(app.UIFigure, 'push');
            app.PushtodatabaseButton.Text = 'Push to database';
            app.RigSwitchLabel = uilabel(app.UIFigure);
            app.RigSwitchLabel.Text = 'Rig';
            app.RigSwitch = uiswitch(app.UIFigure, 'slider');
            app.RigSwitch.Items = {'A', 'B'};
            app.RigSwitch.Value = 'A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end